classdef imlook4d_App_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        Save_manually               matlab.ui.container.Menu
        OpenFile                    matlab.ui.container.Menu
        OpenRecent                  matlab.ui.container.Menu
        OpenAndMerge                matlab.ui.container.Menu
        OpenFromPACS                matlab.ui.container.Menu
        NetworkHostsMenu            matlab.ui.container.Menu
        Open_manually               matlab.ui.container.Menu
        OpenECAT                    matlab.ui.container.Menu
        OpenDICOMPET                matlab.ui.container.Menu
        DirtyDICOMOpen              matlab.ui.container.Menu
        OpenDICOMDIR                matlab.ui.container.Menu
        OpenSHR                     matlab.ui.container.Menu
        OpenBinary                  matlab.ui.container.Menu
        OpenMat                     matlab.ui.container.Menu
        SaveFile                    matlab.ui.container.Menu
        Save_nifti_short            matlab.ui.container.Menu
        LoadROI                     matlab.ui.container.Menu
        SaveROI                     matlab.ui.container.Menu
        Save_manually_1             matlab.ui.container.Menu
        SaveECAT                    matlab.ui.container.Menu
        SavePCtoECAT                matlab.ui.container.Menu
        DirtyDICOMSave              matlab.ui.container.Menu
        Savemat                     matlab.ui.container.Menu
        SaveMat2                    matlab.ui.container.Menu
        save_all_windows            matlab.ui.container.Menu
        Print                       matlab.ui.container.Menu
        Close_many                  matlab.ui.container.Menu
        Close_one                   matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        Undo                        matlab.ui.container.Menu
        Redo                        matlab.ui.container.Menu
        Copy_Image                  matlab.ui.container.Menu
        copy_TACT_to_clipboard      matlab.ui.container.Menu
        Edit_window_title           matlab.ui.container.Menu
        Zoom                        matlab.ui.container.Menu
        MatlabMenu                  matlab.ui.container.Menu
        WindowLevelsMenu            matlab.ui.container.Menu
        EditScale                   matlab.ui.container.Menu
        Cmaps                       matlab.ui.container.Menu
        ROI_menu                    matlab.ui.container.Menu
        GuessRoiColor               matlab.ui.container.Menu
        ColorfulROI                 matlab.ui.container.Menu
        GrayROI                     matlab.ui.container.Menu
        MultiColoredROIs            matlab.ui.container.Menu
        preferences                 matlab.ui.container.Menu
        show_patient_info           matlab.ui.container.Menu
        interpolate2                matlab.ui.container.Menu
        interpolate4                matlab.ui.container.Menu
        WorkspaceMenu               matlab.ui.container.Menu
        exportAsViewedToWorkspace   matlab.ui.container.Menu
        exportToWorkspace           matlab.ui.container.Menu
        importFromWorkspace         matlab.ui.container.Menu
        importUntouchedToWorkspace  matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        Help                        matlab.ui.container.Menu
        Acknowledgements            matlab.ui.container.Menu
        About                       matlab.ui.container.Menu
        upgrade_menu                matlab.ui.container.Menu
        windows                     matlab.ui.container.Menu
        uitoolbar1                  matlab.ui.container.Toolbar
        cd                          matlab.ui.container.toolbar.PushTool
        ZoomIntoggletool            matlab.ui.container.toolbar.ToggleTool
        ZoomOuttoggletool           matlab.ui.container.toolbar.ToggleTool
        Pantoggletool               matlab.ui.container.toolbar.ToggleTool
        DataCursortoggletool        matlab.ui.container.toolbar.ToggleTool
        rotateToggleButton          matlab.ui.container.toolbar.ToggleTool
        A                           matlab.ui.container.toolbar.ToggleTool
        B                           matlab.ui.container.toolbar.ToggleTool
        C                           matlab.ui.container.toolbar.ToggleTool
        markerToggleTool            matlab.ui.container.toolbar.ToggleTool
        measureTool                 matlab.ui.container.toolbar.PushTool
        PolyVOI                     matlab.ui.container.toolbar.ToggleTool
        record_toolbar_button       matlab.ui.container.toolbar.ToggleTool
        resizePushButton            matlab.ui.container.toolbar.PushTool
        helpToggleTool              matlab.ui.container.toolbar.ToggleTool
        orientationButton           matlab.ui.container.toolbar.PushTool
        updateNeededText            matlab.ui.control.Label
        infoText1                   matlab.ui.control.Label
        floatingTextEdit1           matlab.ui.control.EditField
        versionText                 matlab.ui.control.Label
        FrameNumEdit                matlab.ui.control.EditField
        SliceNumEdit                matlab.ui.control.EditField
        SliceNumSlider              matlab.ui.control.Slider
        FrameNumSlider              matlab.ui.control.Slider
        SliceText                   matlab.ui.control.Label
        FrameText                   matlab.ui.control.Label
        uipanel6                    matlab.ui.container.Panel
        FirstFrame                  matlab.ui.control.EditField
        PC_low_edit                 matlab.ui.control.EditField
        PC_high_edit                matlab.ui.control.EditField
        PC_low_slider               matlab.ui.control.Slider
        PC_high_slider              matlab.ui.control.Slider
        text22                      matlab.ui.control.Label
        ExplainedFractionText       matlab.ui.control.Label
        KaiserText                  matlab.ui.control.Label
        uipanel7                    matlab.ui.container.ButtonGroup
        ResidualRadiobutton         matlab.ui.control.RadioButton
        PCImageRadioButton          matlab.ui.control.RadioButton
        ImageRadioButton            matlab.ui.control.RadioButton
        uipanel5                    matlab.ui.container.Panel
        orientationMenu             matlab.ui.control.DropDown
        uipanel1                    matlab.ui.container.Panel
        transparancyPanel           matlab.ui.container.Panel
        transparancyEdit            matlab.ui.control.EditField
        transparancyText            matlab.ui.control.Label
        PCAutoInvert                matlab.ui.control.CheckBox
        uipanel2                    matlab.ui.container.Panel
        ClearRoiPushButton          matlab.ui.control.Button
        BrushSize                   matlab.ui.control.EditField
        ROILevelEdit                matlab.ui.control.EditField
        ROILeveltext                matlab.ui.control.Label
        ROINumberMenu               matlab.ui.control.DropDown
        ContourCheckBox             matlab.ui.control.CheckBox
        hideROIcheckbox             matlab.ui.control.CheckBox
        text7                       matlab.ui.control.Label
        TACT                        matlab.ui.control.Button
        frameTimeText               matlab.ui.control.Label
        frameTimeMinutesText        matlab.ui.control.Label
        matrixSize                  matlab.ui.control.Label
        axes1                       matlab.ui.control.UIAxes
        ROI                         matlab.ui.container.ContextMenu
        ClearROIContextMenu         matlab.ui.container.Menu
        RenameROI                   matlab.ui.container.Menu
        ROIcolor                    matlab.ui.container.Menu
        MergeROIs                   matlab.ui.container.Menu
        SetRefROIs                  matlab.ui.container.Menu
        HideROI                     matlab.ui.container.Menu
        HideAllROIS                 matlab.ui.container.Menu
        ShowAllROIS                 matlab.ui.container.Menu
        OnlyRefROIs                 matlab.ui.container.Menu
        Lock_ROI                    matlab.ui.container.Menu
        Remove_ROI                  matlab.ui.container.Menu
        RemoveAllROIS               matlab.ui.container.Menu
        AxesContextualMenu          matlab.ui.container.ContextMenu
        Untitled_1                  matlab.ui.container.Menu
        clearROIcontextualMenu      matlab.ui.container.Menu
        SetROILevel                 matlab.ui.container.Menu
        ResetROILevel               matlab.ui.container.Menu
        Copy_ROI                    matlab.ui.container.Menu
        Untitled_2                  matlab.ui.container.Menu
        Threshold_within_ROI        matlab.ui.container.Menu
        Threshold_ROI               matlab.ui.container.Menu
        Clean_ROI                   matlab.ui.container.Menu
        Fill_ROI_Slice_Gaps         matlab.ui.container.Menu
        copyRoiBetweenYokes         matlab.ui.container.Menu
        Untitled_4                  matlab.ui.container.Menu
        Untitled_5                  matlab.ui.container.Menu
        CloseAxesContextMenu        matlab.ui.container.Menu
        ROILevelContextualMenu      matlab.ui.container.ContextMenu
        ResetROILevel2              matlab.ui.container.Menu
    end

    
    methods (Access = private)
        function Color(app, hObject, eventdata, handles, functionName)
                            % General callback for Colormap in COLORMAPS folder, which
                            % has name functionName
                            try
                                % Determine correct object (dynamically generated callbacks)
                                temp=findobj('Tag', strrep(functionName,'_', ' '));
                                %temp=findobj('Tag', functionName);
                                tempObject=temp(1);
            
            
                                % Checkmarks
                                hColorMenuObjects=get( get(tempObject,'Parent'), 'Children');  % All other
                                for i=1:length(hColorMenuObjects)
                                    set(hColorMenuObjects(i),'Checked','off')
                                end
                                set(tempObject,'Checked','on')
            
                                % Clean out spaces and replace with "_"
                                htable = feval(functionName);
                                set(handles.figure1,'Colormap',htable);
                                if handles.imSize(3)> 1
                                    %set(handles.SliceNumEdit,'BackgroundColor',[.1 .1 .1],'ForegroundColor','r');
                                end
            
                                handles.image.ColormapName = functionName;
                                imlook4d_set_ROIColor(app, handles.figure1, eventdata, handles)
                                guidata(handles.figure1,handles)
                            catch
                                dispRed('Problem if you see this: Fix function color');
                            end
        end
        
        function ColorBar_Callback(app, hObject, eventdata, handles)
            %
            % Clean up
            %
            
                    %oldVal= round(get(hObject,'Value'));
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             %set(hObject,'Value',oldVal);
                             %return
                         end
        end
        
        function Color_Callback(app, hObject, eventdata, handles, functionName)
            % Color submenu
            
            %            % General callback for all Colormaps in COLORMAPS folder
            %
            %            % Determine correct object (dynamically generated callbacks)
            %            temp=findobj('Label', functionName);
            %            hObject=temp(1);
            %
            %
            %            % Checkmarks
            %            hColorMenuObjects=get( get(hObject,'Parent'), 'Children');  % All other
            %            for i=1:length(hColorMenuObjects)
            %                set(hColorMenuObjects(i),'Checked','off')
            %            end
            %            set(hObject,'Checked','on')
            %
            %
            %
            %            %hObject=findobj(handles,'Label', functionName)
            %            %set(hObject,'Label', 'Color_Callback');
            
                       % Display HELP and get out of callback
                       %
                       %    This requires a separate help file for each color map
                       %    For instance Sokolof.txt
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
                             %disp( functionName);
            
                             Color(app, handles.figure1, eventdata, handles,functionName)
                             newhandles = guidata(handles.figure1);
                             imlook4d_set_ROIColor(app, newhandles.figure1, eventdata, newhandles)
                             %newhandles.image.ColormapName = functionName;
                             handles.image.ColormapName = functionName;
                             guidata(newhandles.figure1,newhandles)
            
                        updateImage(app, hObject, eventdata, handles)
                        updateROIs(app, newhandles)
        end
        
        function trueIfChecked = DisplayHelp(app, hObject, eventdata, handles)
            % --------------------------------------------------------------------
            % Help and HTML functions
            % --------------------------------------------------------------------
            
                       % HELP, and SCRIPT RECORDING
                       %
                       % Note: To make a new Help text work:
                       % 1) Make a text file in HELP directory named after the element Tag, Name, or String
                       % 2) Modify helpToggleTool_OnCallback, and helpToggleTool_OffCallback functions above
            
                            % Turn scripting ON (true)  or  OFF (false)
                           ScriptFlag = handles.record.enabled;
                           HelpFlag=strcmp( get(handles.helpToggleTool, 'State'), 'on') ;
            
                           % Default
                           GENERATED_HTML = true; % This is standard.  False if fixed html file should be opened


                           EOL=sprintf('\r\n');  % Windows end-of-line (used for nicer formatting of page source for generated HTML file)
                           trueIfChecked=false;
                           temp=dbstack();
                           callBackFunctionName=temp(2,1).name;
                           callbackString=['imlook4d(''' callBackFunctionName ''', imlook4d_current_handles.' get(hObject,'Tag') ',{} ,imlook4d_current_handles)'];
            
            
                               % Notes:buttonsSameNameAsPressed
                               % SCRIPT, MODEL, COLOR, WINDOW LEVEL  submenu - called directly from callback, must be intercepted directly in script
                               % See commented example in SCRIPTS menu defintions, on
                               % how to call two callbacks.b.
            
            
                           title= 'imlook4d help';
            
                           % --------------------------------------
                           %     HELP
                           % --------------------------------------
            
            
                           %
                           % If toolbar Help button is pressed, display html-help
                           %
                               %if strcmp(get(handles.helpToggleTool, 'State'), 'on') || strcmp(callBackFunctionName, 'Help_Callback')
                               if HelpFlag
            
                                     % Get path to help directory
                                       [imlook4dFolder,name,ext] = fileparts(which('imlook4d'));
                                       helpFolder = [ imlook4dFolder filesep 'HELP'];
            
            
                                    % Get name from Object Label, Tag, String
                                        try
                                            objectName = get(hObject,'Label');
                                            if strcmp( objectName(1:2),'<h') % <html> starts with <h
                                                objectName=get(hObject,'Tag'); % For html labels, I use tag as name
                                            end
                                            objectName0=strrep(objectName,' ','_');
                                            objectName=strrep(objectName0,'_',' ');
                                        catch
                                            objectName = '';
                                        end
            
                                        try
                                            altObjectName=get(hObject,'Tag');
                                            altObjectName0=strrep(altObjectName,' ','_');
                                            altObjectName=strrep(altObjectName0,'_',' ');
                                        catch
                                            altObjectName = '';
                                        end
            
            
                                        try
                                            alt2ObjectName=get(hObject,'String');
                                            if iscellstr(alt2ObjectName)
                                                alt2ObjectName=alt2ObjectName{1}; % For instance, ROI number menu can be cell array of Strings
                                            end
            
                                            alt2ObjectName0=strrep(alt2ObjectName,' ','_');
                                            alt2ObjectName=strrep(alt2ObjectName0,'_',' ');
                                        catch
                                            alt2ObjectName = '';
                                        end
            
                                   % Read help text
                                       try
                                           % Get possible file names
                                            helpFileName = [ helpFolder filesep objectName '.txt' ];
                                            altHelpFileName = [ helpFolder filesep altObjectName '.txt' ];
                                            altHelpFileName2 = [ helpFolder filesep alt2ObjectName '.txt' ];
            
                                            % Select correct file name
                                            correctFileName = helpFileName; % Initial guess -- fallback if fails
                                            if isfile(helpFileName)
                                                correctFileName = helpFileName;
                                            end
            
                                            if isfile(altHelpFileName)
                                                correctFileName = altHelpFileName;
                                            end
            
                                            if isfile(altHelpFileName2)
                                                correctFileName = altHelpFileName2;
                                            end
            
                                            % If Pharmacokinetic model -- set static help file
                                            if strcmp(hObject.Type,'uimenu')
                                                if strcmp(hObject.Parent.Text,'Models on ROIs') || strcmp(hObject.Parent.Text,'MODELS')
                                                    docFolder = [ imlook4dFolder filesep 'DOCS'];
                                                    correctFileName = [ docFolder filesep 'Models_exported_to_html' filesep 'Models.html' ];
                                                    GENERATED_HTML = false; % Fixed html file, instead of generated by imlook4d
                                                end
            
                                            end
            
                                            % Read help text
                                            text =  fileread(correctFileName );
            
                                       catch
                                           text = ['WARNING - could not find help file = ' helpFileName ];
                                           disp(text);
                                           disp('One of the following help file names are plausible, and should be created :');
                                           disp(['<a href="matlab:edit(''' helpFileName ''')">'  helpFileName '</a>']);
                                           disp(['<a href="matlab:edit(''' altHelpFileName ''')">'  altHelpFileName '</a>']);
                                           disp(['<a href="matlab:edit(''' altHelpFileName2 ''')">'  altHelpFileName2 '</a>']);
                                       end
            
                                   % Read foother
                                       footerFilePath=[ 'Footer.txt' ];
                                       footer =  fileread(footerFilePath);
            
                                    % Developers info
                                        [~, name,ext] = fileparts(correctFileName);
            
                                        displayStyle='font-size: 9px;';
                                        developersText=[  '<div id="test" style="display:block;' displayStyle ' ">' '<HR>' '<h3><U>Developers reference:</U></h3>' EOL];
                                        try
                                            developersText=[ developersText  parseHTMLTableRow(  ...
                                                 ['Callback = '  callBackFunctionName '</a> &nbsp;&nbsp;' ...
                                                 'Help file = <a href="matlab:edit(''' correctFileName ''')">'   name ext '</a>']) ...
                                                 ];
                                        catch
                                        end
                                        developersText=[ developersText '</div>' EOL];
            
                                    % Display help in  browser
                                       if GENERATED_HTML
                                            webhandle = displayHTML(helpFolder, title, text,footer,developersText);  % Display html, adding footer and developers reference
                                       else
                                           url = ['file:///',correctFileName];
                                           [stat, webhandle] =web(url, '-noaddressbox');
                                       end
            
                                       % Action on Web-window close button
                                       set(webhandle, 'AncestorRemovedCallback', @closeWebRequest);
            
                                       % Store handle to web browser
                                       handles.webbrowser=webhandle;
                                       guidata(hObject,handles);
            
                                    % Return value true (which is used to stop callback function from being further executed
                                       trueIfChecked=true;
            
            
                                end
            
            
                           % --------------------------------------
                           %    SCRIPTING
                           % --------------------------------------
                               if ScriptFlag
                                   EOL = sprintf('\n');
            
                                   try
                                       switch get(hObject,'Type')
                                           case 'figure'
                                               % This is for instance figures from such
                                               % as water_control
                                               try
                                                   if ~strcmp( get(hObject,'Tag'), 'imlook4d')
                                                        cmd = [ 'Menu(''' get(hObject,'Tag') ''')' ];
                                                   end
                                               catch
                                               end
            
                                           case 'uimenu'
                                               % This is the important one -- the
                                               % others are only visual things which
                                               % should not be scriptable
            
                                               % Guess that not SCRIPTS menu
                                               label = get(hObject,'Label');
                                               % Clean if html in label (the
                                               if contains(label,'<html>')
                                                   label = get(hObject,'Tag');
                                               end
            
            
                                               cmd = [ 'Menu(''' label ''')' ];
            
                                               % Open menu
                                               try
                                                   menuName = get(hObject,'Label');
                                                   if strcmp( 'Open', menuName )
                                                       %cmd = [cmd '; imlook4d_current_handle = gcf;']
                                                       cmd = ['imlook4d_current_handle = Open(INPUTS{1}); % Handle to imlook4d window']
                                                   end
                                               catch
                                               end
            
            
                                               % SCRIPTS menu
                                               try
                                                   menuName = get( get( get(hObject,'Parent'),'Parent' ),'Label');
                                                   if strcmp( 'SCRIPTS', menuName )
                                                       cmd = get(hObject,'Label');
                                                       cmd = strrep( cmd, ' ', '_');  % Make proper names ('_' was removed when making menues far above)
            
                                                       % Duplicate
                                                       if strcmp( 'Duplicate', get(hObject,'Label') )
                                                            cmd = [cmd '; MakeCurrent;'];
                                                       end
                                                   end
            
                                               catch
                                                   disp('Not SCRIPTS');
                                               end
            
            
            %                                    % Windows menu
            %                                    try
            %                                        menuName = get( get( hObject,'Parent' ),'Label');  % Is "Windows" if we are in a submenu of windows
            %                                        if strcmp( 'Windows', menuName )
            %                                            cmd = [ 'Windows' get(hObject,'Position') ];
            %                                        end
            %                                    catch
            %                                        disp('Not SCRIPTS');
            %                                    end
            
            %
            %
            %
            %                                case 'uitoggletool'
            %                                    cmd = (['REC: ' get(hObject,'Tag') '(' get(hObject,'State') ')']);
                                            case 'uipushtool'
                                                %cmd = (['REC: ' get(hObject,'Tag') ]);
                                                cmd = [ 'ToolbarButton(''' get(hObject,'Tag') ''')' ];
            %                                case 'figure'
            %                                    % Do nothing
                                          case 'uicontrol'
                                                switch get(hObject,'Style')
                                                    case 'popupmenu'
                                                       cmd = get(hObject,'Tag');
                                                       cmd = ['SelectROI( ' num2str(get(hObject,'Value')) ' );'];
                                                       % ROINumberMenu
                                                       if strcmp( get(hObject,'Tag'), 'ROINumberMenu')
                                                           if (  get(hObject,'Value') == length( get(hObject,'String') ) )
                                                               cmd = 'MakeROI';
                                                           else
                                                               cmd = ['SelectROI( ' num2str(get(hObject,'Value')) ' );'];
                                                           end
                                                       end
            
                                                       % orientationMenu
                                                       if strcmp( get(hObject,'Tag'), 'orientationMenu')
                                                           %cmd = ['SelectOrientation( ' num2str(get(hObject,'Value')) ' );'];
                                                           index=get(hObject,'Value');
                                                           strings=get(hObject,'String');
                                                           cmd = ['SelectOrientation(''' strings{index} ''');'];
                                                       end
            
                                                    case 'radiobutton'
                                                        cmd = [ 'Radiobutton(''' get(hObject,'String') ''')' ];
            %                                        case 'checkbox'
            %                                            cmd = (['REC: ' get(hObject,'Tag') '(' num2str(get(hObject,'Value')) ')']);
                                                   case 'pushbutton'
                                                        %cmd = (['REC: ' get(hObject,'String') ]);
                                                        cmd = [ 'Button(''' get(hObject,'String') ''')' ];
                                                   case 'checkbox'
                                                        %cmd = (['REC: ' get(hObject,'String') ]);
                                                        cmd = [ 'Checkbox(''' get(hObject,'String') ''')' ];
                                                    case 'edit'
                                                        cmd = (['EditField(''' get(hObject,'Tag') ''', ''' get(hObject,'String') ''')']);
            %                                        case 'slider'
            %                                            cmd = (['REC: ' get(hObject,'Tag') '(' num2str(get(hObject,'Value')) ')']);
            
            %
            %                                        otherwise
            %                                            disp('NO MATCH Style');
                                                end
            %
                                            otherwise
            %                                    disp('NO MATCH Type');
            
                                       end % switch get(hObject,'Type')
            
            
                                       handles.record.editor.appendText([cmd EOL EOL]);  % Insert text at caret
            
                                   catch
                                   end
            
                               end % if ScriptFlag


                               if ScriptFlag   % true is normal mode, false displays command that could be useful for scripting
                                       % Show scripting command
                                       disp(callbackString)
                                       disp(' ')
                               end
            
                           %
                           % If toolbar Help button is pressed,
                           % toggle radio-buttons and check boxes
                           % (thus keeping state before being pressed)
                           %
                               try
                                   if trueIfChecked
            
                                       if get( hObject,'Value')==1
                                           set( hObject,'Value', 0);
                                       else
                                           set(hObject,'Value', 1);
                                       end
            
                                       restoreImageRadioButtonGroup(app, handles);
                                   end
                               catch
                                   % Property 'Value' not existing for this GUI widget
                               end
        end
        
        function FrameNumEdit_CreateFcn(app, hObject, eventdata, handles)
                    % hObject    handle to FrameNumEdit (see GCBO)
                    % eventdata  reserved - to be defined in a future version of MATLAB
                    % handles    empty - handles not created until after all CreateFcns called
            
                    % Hint: edit controls usually have a white background on Windows.
                    %       See ISPC and COMPUTER.
                    if ispc
                        set(hObject,'BackgroundColor','white');
                    else
                        set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
                    end
        end
        
        function GenerateScreenDump(app, hObject, eventdata, handles, DISPLAY_ON_SCREEN)
            
                        % Display clipboard on screen?
                          %DISPLAY_ON_SCREEN=false;
                          %DISPLAY_ON_SCREEN=true;
            
                        % Display HELP and get out of callback
                           if DisplayHelp(app, hObject, eventdata, handles)
                               return
                           end
            
                        % New figure
                        h1=figure('Visible','off','NumberTitle','off','Name', 'Clipboard');         % New HIDDEN figure
                        if DISPLAY_ON_SCREEN
                            set(h1,'Visible','on');
                        end
            
                        % Copy axes
                        copyobj(handles.axes1,h1); % Hx(2) is one of the axes objects on your
            
                        % Copy colorbar
                        colormap( colormap(handles.axes1) );                % Copy current colormap to current figure
                        colorBarHandle=colorbar('FontSize', 9);            % Display
            
            
                        % Set size in new figure
                        c=get(h1,'Children');
                        set(c(2),'Unit','normalized')
                        set(c(2),'Position', [ (0.05-0.05) 0.05 0.9 0.9] ) % Subtract 0.05 for approximate width of color bar
            
            
                        h1.Units='centimeters'
                        h1.PaperSize(1) = h1.OuterPosition(3)+0.5;
                        h1.PaperSize(2) = h1.OuterPosition(4)+0.5;
                        set(h1,'PaperPositionMode','auto')


                        try
                            set(h1, 'InvertHardCopy', 'off');   % off = Use the same colors as the colors on the display.
                            h1.Color = [ 1 1 1];                % Make background of figure white
            
                            if ismac
                                disp('Mac OS, copying as PDF');
                                print(h1,'-clipboard','-dpdf');
                            elseif isunix
                                %disp('Linux OS, copying as PDF');
                                %print(h1,'-clipboard','-dpdf');  % Does not work into Excel, but works into inkscape (with Poppler option selected)
                                disp('Linux OS, , copying as bitmap');
                                print(h1,'-clipboard','-dbitmap');
                            elseif ispc
                                %disp('Windows OS, copying as Enhanced metafile');
                                %print(h1,'-clipboard','-dmeta'); % Does not work on Windows 10
                                disp('Linux OS, , copying as bitmap');
                                print(h1,'-clipboard','-dbitmap');
                            else
                                disp('Could not determine OS, copying as bitmap');
                                print(h1,'-clipboard','-dbitmap');
                            end
            
                        catch
                            disp('Error, fallback copying  bitmap');
                            print(h1,'-dbitmap')
                        end
            
                        if ~DISPLAY_ON_SCREEN
                            close(h1)
                        end
        end
        
        function LocalOpenBinary(app, hObject, eventdata, handles, file, path, fileType)
                            % Used for opening different types of binary files
                            % 1) Pure binary
                            % 2) Interfile, where the header is parsed to fill in the defaultanswers
                            % 3) Analyze
                            % 4) ITK formats (MHD, MHA)
            
                            % TODO : phase out Analyze from this code
            
                            scaleFactor=1;
                            offset=0;
            
            
                            fullPath=[path file];
            
                            %
                            % Input pixels and slices
                            %
                                prompt={'xPixels',...
                                        'yPixels',...
                                        'Slices (formula such as 1269/47 is allowed)',...
                                        'Frames (formula allowed)',...
                                        'Byte order - b or l (little L)',...
                                        'Format int16, float32, short, ... (see MATLAB fread documentation)'};
                                title='Input data dimensions';
                                numlines=1;
                                %defaultanswer={'128','47','b'};
            
                                % IF BINARY
                                if strcmp(fileType,'BINARY')
                                    defaultanswer={'128','128', '47','1','l','int16'};
                                end
            
                                % IF INTERFILE
                                if strcmp(fileType,'INTERFILE')
                                    [defaultanswer fullPath]=interfileHeaderInformation( fullPath);
                                    try
                                        frameDuration=str2num( interfileHeader( filePath, 'image duration (sec)')  );  % Not used yet
                                    catch
                                    end
                                end
            
                                %
                                % IF MHD, MHA (ITK format)
                                %
                                if strcmp(fileType,'ITK')
            
                                    % Pixel dimensions
                                    pixelString=mhdHeader( fullPath, 'ElementSpacing');    % in x y z
                                    [dX, remain] = strtok(pixelString,' ');    % dX
                                    [dY, dZ] = strtok(remain,' ');              % dY dZ
                                    dX=str2num(dX);
                                    dY=str2num(dY);
                                    dZ=str2num(dZ);
            
                                    % Populate dialog that comes below, and retrieve
                                    % path to binary file
                                    [defaultanswer fullPath]=mhdHeaderInformation( fullPath);
                                end
            
                                %
                                % IF ANALYZE
                                %
                                if strcmp(fileType,'ANALYZE') || strcmp(fileType,'NIFTY_ONEFILE')
                                    [path,name,ext] = fileparts(fullPath);
            
                                    % Read header
                                    if strcmp(fileType,'ANALYZE') && strcmp(ext, '.hdr')
                                        fid=fopen(fullPath);
                                    end
                                    if strcmp(fileType,'ANALYZE') && strcmp(ext, '.img')
                                       fullPath=[path filesep name '.hdr'];  % hdr file (img file was opened)
                                       fid=fopen(fullPath);
                                    end
                                    if strcmp(fileType,'NIFTY_ONEFILE')
                                        fid=fopen(fullPath);
                                    end
            
                                    headerFile=fullPath;  % Remember header file path
            
                                    % Probe little or big-endian
                                    headerSize=fread(fid,[1],'int32'); % Header size (348 if not extended mode)
                                    if headerSize>5000
                                        machineFormat='b';
                                        disp('Big endian');
                                    else
                                        machineFormat='l';
                                        disp('Little endian');
                                    end
            
                                    % Read rest of header
                                    fread(fid,[10],'char');
                                    fread(fid,[18],'char');
                                    fread(fid,[1],'int32',0,machineFormat); % 16384 -- This could be better test if little-endian
                                    fread(fid,[1],'int16',0,machineFormat);
                                    fread(fid,[1],'char');  % r - if not extended mode
                                    fread(fid,[1],'char');
            
                                    pixels=fread(fid,[8],'int16',0,machineFormat);   % Voxels per dimension, pixels(1) is number of dimensions
                                    disp(['Pixels=' num2str(pixels(2:pixels(1)+1)')]);
            
                                    disp(['Voxel Units=' fread(fid,[4],'char')']);  % Units
                                    disp(['Cal Units=' fread(fid,[8],'char')']);  % Units
                                    fread(fid,[1],'int16',0,machineFormat); %
            
                                    DT_xxx=fread(fid,[1],'int16',0,machineFormat);
                                    disp(['DT_xxx=' num2str(DT_xxx) ' (1=binary, 2= 8bits/voxel, 4=16bits/voxel, 8=32bits/voxel)']);
            
                                    disp(['Bits per pixel=' fread(fid,[1],'int16',0,machineFormat)]);
            
                                    fread(fid,[1],'int16',0,machineFormat); % unused
            
                                    fread(fid,[1],'float',0,machineFormat); %
                                    dX=fread(fid,[1],'float',0,machineFormat); % Voxel width
                                    dY=fread(fid,[1],'float',0,machineFormat); % Voxel height
                                    dZ=fread(fid,[1],'float',0,machineFormat); % Voxel thickness
                                    fread(fid,[1],'float',0,machineFormat); % undoc
                                    fread(fid,[1],'float',0,machineFormat); % undoc
                                    fread(fid,[1],'float',0,machineFormat); % undoc
                                    fread(fid,[1],'float',0,machineFormat); % undoc
            
                                    disp(['Pixel dimensions= (' num2str(dX) ',' num2str(dY) ',' num2str(dZ) ')' ]);
            
                                    vox_offset=fread(fid,[1],'float',0,machineFormat); % vox offset
                                    disp(['vox offset='  num2str(vox_offset) ]);  % vox offset
            
                                    scaleFactor=fread(fid,[1],'float',0,machineFormat);
                                    offset=fread(fid,[1],'float',0,machineFormat);
                                    disp(['SPM Scale factor=' num2str(scaleFactor) ]); % SPM Scale factor
                                    disp(['SPM offset=' num2str(offset) ]);  % SPM offset
                                    fread(fid,[1],'float',0,machineFormat); %
            
                                    fread(fid,[1],'float',0,machineFormat); % Max calibration value
                                    fread(fid,[1],'float',0,machineFormat); % Min calibration value
                                    fread(fid,[1],'float',0,machineFormat); % compressed
                                    fread(fid,[1],'float',0,machineFormat); % verified
                                    fread(fid,[1],'int32',0,machineFormat); % max pixel value for entire database
                                    fread(fid,[1],'int32',0,machineFormat); % min pixel value for entire database
                                    fread(fid,[2],'int32',0,machineFormat); % TEST
            
                                    disp(['hist=' fread(fid,[80],'char=>char',0,machineFormat)']); % hist
                                    fread(fid,[1],'int32',0,machineFormat); % TEST
                                    disp(['aux file=' fread(fid,[24],'char=>char',0,machineFormat)']); % aux file
            
                                    fread(fid,[1],'char',0,machineFormat); % slice orientation
                                    fread(fid,[5],'int16',0,machineFormat); % originator
            
                                    % SKIP rest of file
            
                                    fclose(fid);
            
                                    valueType='int16';
                                    if DT_xxx==2
                                        valueType='uint8';
                                     end
            
                                    if DT_xxx==4
                                        valueType='int16';
                                    end
            
                                    if DT_xxx==8
                                        valueType='int32';
                                    end
            
                                    if DT_xxx==16
                                        valueType='float';
                                    end
            
                                    if DT_xxx==32
                                        valueType='complex';
                                    end
            
                                    if DT_xxx==64
                                        valueType='double';
                                    end


                                    % Populate dialog that comes below, and retrieve
                                    % path to binary file
            
                                    defaultanswer={num2str(pixels(2)),num2str(pixels(3)), num2str(pixels(4)),num2str(pixels(5)),machineFormat,valueType};
            
            
                                    if strcmp(fileType,'NIFTY_ONEFILE')
                                        % Use same fullPath
                                    else
                                        fullPath=[path filesep name '.img'];   % img file
                                    end
            
                                end
            
                                answer=inputdlg(prompt,title,numlines,defaultanswer);
            
                                XPIXELS=str2num(answer{1});
                                YPIXELS=str2num(answer{2});
            
                                FORMAT=answer{6};
                                MACHINEFORMAT=answer{5};
            
                                SLICES=eval(answer{3});
                                FRAMES=eval(answer{4});
            
                            %
                            % READ MATRIX
                            %
                                %fid=fopen(fullPath,'r','b');
                                fid=fopen(fullPath,'r',answer{5});
                                disp([ 'Opening binary file=' fullPath ]);
            
                                if strcmp(fileType,'NIFTY_ONEFILE')
                                        % Skip to voxoffset
                                end
            
            
                                Data=zeros(XPIXELS,YPIXELS,SLICES,FRAMES);    % Create empty matrix
            
                                for j=1:FRAMES
                                    for i=1:SLICES
                                       Data(:,:,i,j)=fread(fid,[XPIXELS YPIXELS],FORMAT,0,MACHINEFORMAT);
                                    end;
                                end;
            
                                fclose(fid);
            
            
                                disp(['Matrix size=' num2str(size(Data))]);
                                disp('DONE opening binary file ');
            
                                % Scaling
                                Data=scaleFactor*Data+offset;
            
            
                            %
                            % Get times from sif file - if exists (Turku data has that)
                            %
            
            
                                try
                                    fullPath=[path filesep name '.sif'];  % hdr file (img file was opened)
                                    fid=fopen(fullPath);
            
                                    C = textscan(fid, '%f %f %f %f', 'headerLines', 1);
                                    time=C{1}';
                                    duration= (C{2}-C{1})';
                                    [ 'time' 'duration'];
                                    [time duration];
            
                                    fclose(fid);
                                catch
                                    % Sif file does not exist
                                end


                            %
                            % Operate on the new imlook4d instance
                            %
                                if exist('time')&&exist('duration')
                                    h=imlook4d(single(Data),time, duration);
                                else
                                     h=imlook4d(single(Data));
                                end
            
                                set(h,'Name', [file]);
                                newhandles = guidata(h);
            
                                % Save information of file format in new imlook4d
                                try
                                    newhandles.image.fileType=fileType;
                                catch
                                    newhandles.image.fileType='BINARY';
                                end
            
                               % Turn off menues that do not apply to binary data
                               set(newhandles.SaveFile,'Enable','off');
            
                               % Set fake dimensions
                                    newhandles.image.pixelSizeX=1;
                                    newhandles.image.pixelSizeY=1;
                                    newhandles.image.sliceSpacing=1;
            
                               %
                               % ITK
                               %
                                if strcmp(fileType,'ITK')
                                    try
                                        % Set radio button and call callback function
                                            set(newhandles.FlipAndRotateRadioButton,'Value',0);
                                            imlook4d('FlipAndRotateRadioButton_Callback', h,{},newhandles);
                                        % Set pixel dimensions
                                            newhandles.image.pixelSizeX=dX;
                                            newhandles.image.pixelSizeY=dY;
                                            newhandles.image.sliceSpacing=dZ;
                                    catch
                                        disp('ERROR:  mhdHeader.m  : Could not find matrix size');
                                    end
                                end
            
                                %
                                % ANALYZE
                                %
                                if strcmp(fileType,'ANALYZE')
                                    try
                                        % Enable menues
                                            set(newhandles.SaveFile,'Enable','on');
            
                                        % Set radio button and call callback function
                                            %set(newhandles.FlipAndRotateRadioButton,'Value',0);
                                            %imlook4d('FlipAndRotateRadioButton_Callback', h,{},newhandles);
            
                                        % Store header file path
                                            newhandles.image.headerFile=headerFile;
                                            newhandles.image.valueType=valueType;
                                            newhandles.image.machineformat=MACHINEFORMAT;
                                             FILETYPE=handles.image.fileType;
            
                                         % Set pixel dimensions
                                            newhandles.image.pixelSizeX=dX;
                                            newhandles.image.pixelSizeY=dY;
                                            newhandles.image.sliceSpacing=dZ;
                                    catch
                                        disp('ERROR:  mhdHeader.m  : Could not find matrix size');
                                    end
                                end
            
                                % Save guidata
                                guidata(h, newhandles);
        end
        
        function h = LocalOpenDirtyDICOM3(app, hObject, eventdata, handles, file, directoryPath, optionalArgumentForceToolbox)
                            % This function is a new version which relies on new
                            % external functions
                            %
                            % optionalArgumentForceToolbox --  is optional parameter
                            % (true means openening with imaging toolbox if exists)
            
                                selectedFile=file;
            
            
                                % Force reading dicom with imaging toolbox?
                                if ~exist('optionalArgumentForceToolbox','var')
                                    % parameter does not exist, so default it to something
                                    FORCE_OPEN_WITH_IMAGING_TOOLBOX = false;
                                else
                                    FORCE_OPEN_WITH_IMAGING_TOOLBOX = optionalArgumentForceToolbox;
                                end
            
            
                            %
                            % Select files
                            %
                                prompt={'Filter (for instance PT* )'};
                                title='Input File filter';
                                numlines=1;
                                [pathstr,name,ext] = fileparts(file);
            
                                % Give a pattern
                                defaultanswer={['*']};answer=defaultanswer;
            
                                %answer=inputdlg(prompt,title,numlines,defaultanswer); % Dialog for input of pattern
            
                                fileFilter=answer{1};
            
                                % Create list of file names
                                fileNames=dir([directoryPath fileFilter]);
            
                            %
                            % Open scaled image
                            %
            
                                try
                                    [outputMatrix, outputStruct]=JanOpenScaledDICOM(directoryPath, fileNames, selectedFile, FORCE_OPEN_WITH_IMAGING_TOOLBOX);
                                catch
                                    disp('imlook4d ERROR: Failed opening images (when calling JanOpenScaledDICOM) ');
                                    disp(lasterr)
            
                                    return
                                end
                                %numberOfSlices=str2num(outputStruct.dirtyDICOMSlicesString);
            
                            %
                            % Display list of tags
                            %
                                %headers=outputStruct.dirtyDICOMHeader;
                                mode=outputStruct.dirtyDICOMMode;
            
                                dummy1=1;dummy3='l'; [Data, headers, dummy]=Dirty_Read_DICOM(directoryPath, dummy1,dummy3, file); % selected file
            
                                % Initial tags
                                disp('INFORMATION FROM DICOM FILE=');
                                disp([ '   ' directoryPath name ext] );
            
                                % Display tags
                                displayDicomListOfTags(app,  headers, mode);
            
                            %
                            % Sort
                            %
                                try
                                    [outputMatrix, outputStruct]=dirtyDICOMsort( outputMatrix, outputStruct);
                                catch
                                    disp('imlook4d: Failed sorting images');
                                end
            
                                sliceLocations=outputStruct.dirtyDICOMsortedIndexList(:,3);
            
            
                            %
                            % Find the different series, and select which one to open
                            %
                                [b, m, n]=unique(outputStruct.dirtyDICOMsortedIndexList(:,6),'first');  % m is index to row
            
                                % If more than one series - redo opening with selected scan
                                % Exception, File/Open and Merge
            
                                multipleSeries=(size(m,1)>1)
                                try
                                    openAndMergeMode=strcmp(get(hObject,'Label'),'Open and merge');
                                catch
                                    openAndMergeMode=0;
                                end
            
            
                                if (multipleSeries & ~openAndMergeMode)
                                %if size(m,1)>1
            
                                    % Populate listdlg selection box
                                    for i=1:size(m,1)
                                        TAB='   ';
            
                                        try
                                            patientName=dirtyDICOMHeaderData(outputStruct.dirtyDICOMHeader, m(i),'0010', '0010',mode);
                                        catch
                                            patientName.string='';
                                        end
            
                                        try
                                            patientID=dirtyDICOMHeaderData(outputStruct.dirtyDICOMHeader, m(i),'0010', '0020',mode);
                                        catch
                                            patientID.string='';
                                        end
                                        try
                                            studyDesc=dirtyDICOMHeaderData(outputStruct.dirtyDICOMHeader, m(i),'0008', '1030',mode);
                                        catch
                                            studyDesc.string='';
                                        end
            
                                        try
                                            seriesDesc=dirtyDICOMHeaderData(outputStruct.dirtyDICOMHeader, m(i),'0008', '103E',mode);
                                        catch
                                            seriesDesc.string='';
                                        end
            
            
                                       % Default colors
                                       str{i}=[patientName.string TAB '(' patientID.string ')' TAB studyDesc.string TAB '---' TAB seriesDesc.string];
            
                                       % Colored text in listdlg
                                       % strColor{i}=[ '<HTML><FONT color="blue">' patientName.string TAB '</FONT>' ...
                                       %          '<HTML><FONT color="gray">' '(' patientID.string ')' TAB '</FONT>' ...
                                       %          '<HTML><FONT color="blue">' studyDesc.string TAB '</FONT>' ...
                                       %          '<HTML><FONT color="gray">' TAB seriesDesc.string '</FONT>' ...
                                       %          '</HTML>' ];
                                       strColor{i} = [ patientName.string ' -- ' patientID.string ' -- ' studyDesc.string ' -- ' seriesDesc.string ];
                                    end
            
            
                                    % Select
                                    try
                                        % Colored text in listdlg
                                        [selected,v] = listdlg('PromptString','Select scan to open:',...
                                              'SelectionMode','single',...
                                              'ListSize', [800 400], ...
                                              'ListString',strColor);
                                    catch
                                        % Default black and white text
                                         [selected,v] = listdlg('PromptString','Select scan to open:',...
                                              'SelectionMode','single',...
                                              'ListSize', [800 400], ...
                                              'ListString',str);
                                    end
            
                                    % Make file list from selected series
                                    counter=0;
                                    for i=1:size(outputStruct.dirtyDICOMsortedIndexList,1)
                                        if outputStruct.dirtyDICOMsortedIndexList(i,6)==b(selected)
                                            counter=counter+1;
                                            [pathstr, name, ext] = fileparts(outputStruct.dirtyDICOMFileNames{ i });
                                            newFileNames(counter,1).name=[name ext];
            
                                            temp=dir(newFileNames(counter,1).name);
                                            newFileNames(counter,1).bytes=temp.bytes;
                                        end
                                    end
            
                                    selectedFile=newFileNames(1,1).name;


                                    % Open selected series
                                    try
                                        [outputMatrix, outputStruct]=JanOpenScaledDICOM(directoryPath, newFileNames, selectedFile, FORCE_OPEN_WITH_IMAGING_TOOLBOX);
                                    catch
                                        disp('imlook4d ERROR: Failed opening images');
                                    end
            
                                    % Sort selected series
                                    try
                                        [outputMatrix, outputStruct]=dirtyDICOMsort( outputMatrix, outputStruct);
                                    catch
                                        disp('imlook4d: Failed sorting images');
                                    end
            
                                end % End selection if more than one series
            
            
                            %
                            % Display information about the tags used in sorting
                            %
                                sortedIndexList=outputStruct.dirtyDICOMsortedIndexList;
                                disp(' ');
                                disp('   Sorting parameters (sort order from left to right)');
            
            %                     disp( [sprintf( '   %-10s %-20s %-23s %-20s %-20s\n', '    ', '2)Frame ref time','Original image index','3)Slice location','1)Acquisition time' ) ...
            %                         sprintf( '   %-10s %-20s %-23s %-20s %-20s\n', '    ', '(0054,1300)','(File input order)','(0020,1041)','(0008,0032)' ) ...
            %                         sprintf('   %-10s %-20d %-23d %-20.3f %-20f\n' , 'min:', min(sortedIndexList) )  ...
            %                         sprintf('   %-10s %-20d %-23d %-20.3f %-20f\n' , 'max:', max(sortedIndexList) )]...
            %                     )
            
            
                                tempMin=min(sortedIndexList);
                                tempMax=max(sortedIndexList);
                                myFormat=       '   %-10s %-25f  %-10d %-17.3f %-15d %-10.0f %-20d\n';
                                try
                                disp( [sprintf( '   %-10s %-25s %-10s %-17s %-15s %-10s %-20s\n', ...
                                                        '    ', 'Series Instance UID', 'Acq date','Slice location', 'Frame ref time',  'Acq time','Original image index' ) ...
                                    sprintf( myFormat , 'min:' ,    tempMin(6),tempMin(5),tempMin(3),tempMin(1),tempMin(4),tempMin(2) )  ...
                                    sprintf( myFormat , 'max:' ,    tempMax(6),tempMax(5),tempMax(3),tempMax(1),tempMax(4),tempMax(2) )  ...
                                    sprintf('   %-10s\n','  ') ...
                                    sprintf( myFormat , '1st row:' , sortedIndexList(1,6),  sortedIndexList(1,5), sortedIndexList(1,3), sortedIndexList(1,1),   sortedIndexList(1,4), sortedIndexList(1,2) )...
                                    sprintf( myFormat , '2nd row:' , sortedIndexList(2,6),  sortedIndexList(2,5), sortedIndexList(2,3), sortedIndexList(2,1),   sortedIndexList(2,4), sortedIndexList(2,2) )...
                                    sprintf('   %-10s\n',' ... ') ...
                                    sprintf( myFormat , 'end row:' , sortedIndexList(end,6),  sortedIndexList(end,5), sortedIndexList(end,3), sortedIndexList(end,1),   sortedIndexList(end,4), sortedIndexList(end,2) ) ]...
                                )
                                catch
                                end
            
            
                                myFormat=       '   %-10s %-25.0f  %-10d %-10d %-17.3f %-15d %-12.0u %10.0f %-20d\n';
                                try
                                    disp(' ');
                                    disp( sprintf( '   %-10s %-25s %-10s %-10s %-17s %-15s %-12s %-10s %-20s\n', ...
                                                            '    ', 'Series Instance UID', 'Acq date','Trig Time' , 'Slice location', 'Frame ref time',  'Acq time','Instance No', 'Original image index' ) );
                                    disp(  sprintf( myFormat , 'min:' ,    tempMin(6),tempMin(5),tempMin(7),tempMin(3),tempMin(1),tempMin(4),tempMin(8),tempMin(2) )  );
                                    disp(  sprintf( myFormat , 'max:' ,    tempMax(6),tempMax(5),tempMax(7),tempMax(3),tempMax(1),tempMax(4),tempMax(8),tempMax(2) )  );
                                    disp(  sprintf('   %-10s\n','  ')  );
                                    for i=1:size(sortedIndexList,1)
                                       %disp( sprintf( myFormat , ['row=' num2str(i)] , sortedIndexList(i,6),  sortedIndexList(i,5),sortedIndexList(i,7), sortedIndexList(i,3), sortedIndexList(i,1),   sortedIndexList(i,4),sortedIndexList(i,8), sortedIndexList(i,2) ) )
                                    end
            
                                    disp( sprintf( myFormat , 'end row:' , sortedIndexList(end,6),  sortedIndexList(end,5),sortedIndexList(end,7), sortedIndexList(end,3), sortedIndexList(end,1),   sortedIndexList(end,4),sortedIndexList(end,8), sortedIndexList(end,2) ) )
                                catch
                                end


                            %
                            % Get time, duration, and halflife
                            %
                                try
                                    [outputStruct]=dirtyDICOMTimeAndDuration( outputStruct);
                                catch
                                    disp('Time and duration missing');
                                end
            
                                % If "File/Open and merge" is selected, the time is calculated
                                % from Acquisition time, relative first slice.
                                try
                                    %if strcmp(get(hObject,'Label'),'Open and merge')
                                    if openAndMergeMode
            
                                        [outputStruct]=dirtyDICOMTimeFromAcqTime(outputStruct);
                                    end
                                catch
                                end
            
                            %
                            % Calculate number of slices and number of frames
                            %
                                % Get number of patient positions (column 3) that equals first patient position
                                % => number of frames /gates/ phases
                                numberOfFrames=sum( sortedIndexList(:,3)==sortedIndexList(1,3)); % Number of frames
            
                                % numberOfFrames can be wrong in MR which is scanned Sagital in same position
                                numberOfImages = size(sortedIndexList,1);
                                if (numberOfImages == numberOfFrames)
                                    numberOfFrames = 1;
                                end
            
                                % Get number of slices from total number of images, and number
                                % of frames
                                numberOfSlices=size(sortedIndexList,1) / numberOfFrames;
                                if mod( size(sortedIndexList,1), numberOfFrames )
                                   h=errordlg({'Number of slices and number of images do not match',...
                                       ['Number of images i=' num2str( size(sortedIndexList,1) )], ...
                                       ['Number of frames f=' num2str(numberOfFrames) '  at position=' num2str( sortedIndexList(1,3) )]...
                                       ['giving number of slices i/f=' num2str( numberOfSlices ) '  (which should be an integer)'], ...
                                       [''], ...
                                       ['Make sure that only files acquired at same positions are opened'], ...
                                       });
                                   uiwait(h);
                                end
            
                            %
                            % Special - multiple images in same file
                            %
            
                                % if a single DICOM file had multiple images, then above method does not work.
                                % Use that the filename is the same for all images, to determine that this is the case.
                                % Correct above and follow the Frame increment pointer order
                                if sum(strcmp(outputStruct.dirtyDICOMFileNames(:),outputStruct.dirtyDICOMFileNames(1)))>1
                                    numberOfImages=size(outputStruct.dirtyDICOMFileNames(:),1);
                                    numberOfSlices = length( outputStruct.imagePosition )
            
                                    % Find all Frame Increment Pointers
                                    out0=dirtyDICOMHeaderData(headers, 1, '0028', '0009',mode); % Frame increment pointer
                                    counter = 0;
                                    dim = [];
                                    for k = 1 : (out0.valueLength / 4)
                                        start = (k-1)*4 + 1;
                                        tagString = out0.bytes( start : (start+3) );
                                        tag = [ uint8_to_hex( tagString(2)) uint8_to_hex( tagString(1)) uint8_to_hex( tagString(4)) uint8_to_hex( tagString(3))];
            
                                        % Leave slices, and handle separately outside loop
                                        if ~strcmp(tag, '00540080')
                                            counter = counter + 1;
                                            frameIncrementPointers{counter} = tag;
                                            vectorTag = dirtyDICOMHeaderData(headers, 1, tag(1:4), tag(5:8),mode,2); % Second instance (since found in 00280009)
                                            vector = 256 * vectorTag.bytes(2:2:end) + vectorTag.bytes(1:2:end);
                                            dim(counter) = max(vector); % vector with number of elements in each dimension
                                        end
                                    end
            
            
                                    % Remove slices from dim, and handle separately (so
                                    % slices always in 3:d dimension
                                    numberOfSlices = 1;
                                    try
                                        out0=dirtyDICOMHeaderData(headers, 1, '0054', '0081',mode); % Number of slices
                                        numberOfSlices = 256 * out0.bytes(2) + out0.bytes(1);
                                    catch
            
                                    end
            
            
                                    % Reshape matrix
                                    nx = size(outputMatrix,1);
                                    ny = size(outputMatrix,2);
                                    %dims = [ nx ny numberOfSlices dim];
                                    %outputMatrix = reshape( outputMatrix, dims);
            
                                    outputMatrix = reshape( outputMatrix, nx, ny, numberOfSlices, []);


            %                         numberOfFrames=1;
            %
            %                         % NM (0054,0028) Number of frames
            %                          try
            %                              numberOfFramesInFile=dirtyDICOMHeaderData(outputStruct.dirtyDICOMHeader, 1,'0028', '0008',mode);
            %                              numberOfFrames=str2num( numberOfFramesInFile.string);
            %                              numberOfSlices=numberOfImages/numberOfFrames;
            %                          catch
            %                              numberOfFrames=1;
            %                          end
            
            
            %                          % NM (0054,0021) US #2 [2] Number of Detectors
            %                          try
            %                              numberOfDetectorsInScan=dirtyDICOMHeaderData(outputStruct.dirtyDICOMHeader, 1,'0054', '0021',mode);
            %                              numberOfDetectors=numberOfDetectorsInScan.bytes(1)+256*numberOfDetectorsInScan.bytes(2);
            %                              numberOfFrames=numberOfFrames/numberOfSlices/numberOfDetectors;
            %                          catch
            %                              numberOfDetectors=1;
            %                          end
            %
            %                          % Make 5D matrix: x,y,slice,frame,detector
            %                          outputMatrix = reshape(outputMatrix,size(outputMatrix,1),size(outputMatrix,2),numberOfSlices,numberOfFrames, numberOfDetectors);
            %
            %                          % If one slice, allow detectors to go into slice
            %                          % position
            %                          if (numberOfSlices == 1)
            %                              % Exchange Detectors and Slices columns (so
            %                              % that detectors will be in slice slider in
            %                              % imlook4d)
            %                              outputMatrix = permute( outputMatrix, [1 2 5 4 3]);
            %                              numberOfSlices = numberOfDetectors;  % Treat detectors as slices in opening new imlook4d, below
            %                          end
            
            
            %                          % assume NM, calculate slice locations
            %
            %                          startLocation=sliceLocations(1);
            %                          try
            %                             out=dirtyDICOMHeaderData(outputStruct.dirtyDICOMHeader, 1, '0018', '0088',mode);  %Spacing Between Slices (can be negative number)
            %                             sliceStep=str2num(out.string);
            %                          catch
            %                             sliceStep=outputStruct.sliceSpacing;  % This is not a negative number
            %                          end
            %
            %                          for i=1:numberOfSlices
            %                              sliceLocations(i)=startLocation+(i-1)*sliceStep;
            %                          end
            
                                     % Set time
            
                                     try
                                        out=dirtyDICOMHeaderData(outputStruct.dirtyDICOMHeader, 1, '0018', '1242',mode);
                                        timeStep=str2num(out.string) / 1000; % Timestep in seconds
                                        outputStruct.duration = timeStep * ones( 1 , size(outputMatrix,4) );
                                        outputStruct.duration2D = repmat( outputStruct.duration, size(outputMatrix,3), 1);
            
                                        outputStruct.time = cumsum( outputStruct.duration) - outputStruct.duration;  % Start from time zero
                                        outputStruct.time2D = repmat( outputStruct.time, size(outputMatrix,3), 1);
                                     catch
                                     end
            
            
                                end
            
            
                            %
                            % Fix DICOM y-axis
                            %
                                outputMatrix=imlook4d_fliplr(outputMatrix);  % Flip row vector (which is the y direction of the matrix)
            
                            %
                            % New imlook4d
                            %
                                try
                                    h=imlook4d(single(reshape(outputMatrix,size(outputMatrix,1),size(outputMatrix,2),numberOfSlices,[]) ),outputStruct.time, outputStruct.duration);
                                catch
                                    h=imlook4d(single(reshape(outputMatrix,size(outputMatrix,1),size(outputMatrix,2),numberOfSlices,[]) ));
                                end
            
                                set(h,'Name', ['[' outputStruct.modality '] ' outputStruct.title]); % Window title
                                newhandles = guidata(h);
            
                                % Save header and subheader
                                newhandles.image.dirtyDICOMHeader=outputStruct.dirtyDICOMHeader;
                                newhandles.image.dirtyDICOMFileNames=outputStruct.dirtyDICOMFileNames;
                                newhandles.image.dirtyDICOMPixelSizeString=outputStruct.dirtyDICOMPixelSizeString;
                                newhandles.image.dirtyDICOMSlicesString=outputStruct.dirtyDICOMSlicesString;
                                newhandles.image.dirtyDICOMMachineFormat=outputStruct.dirtyDICOMMachineFormat;
                                newhandles.image.dirtyDICOMIndecesToScaleFactor=outputStruct.dirtyDICOMIndecesToScaleFactor;
                                newhandles.image.dirtyDICOMMode=outputStruct.dirtyDICOMMode;   % Explicit or implicit 2 or 0
                                newhandles.image.fileType='DICOM';
                                newhandles.image.pixelSizeX=outputStruct.pixelSizeX;
                                newhandles.image.pixelSizeY=outputStruct.pixelSizeY;
                                newhandles.image.sliceSpacing=outputStruct.sliceSpacing;
                                newhandles.image.imagePosition=outputStruct.imagePosition;
                                newhandles.image.modality=outputStruct.modality;
            
                                newhandles.image.sliceLocations=sliceLocations;
                                newhandles.image.DICOMsortedIndexList=outputStruct.dirtyDICOMsortedIndexList;
            
            
                                try
                                    unit=dirtyDICOMHeaderData(headers, 1, '0054', '1001' ,mode); % Unit
                                    newhandles.image.unit=unit.string;
                                catch
                                end
            
                                try
                                    newhandles.image.time2D=outputStruct.time2D;
                                    newhandles.image.duration2D=outputStruct.duration2D;
                                catch
                                end
            
                                try
                                    newhandles.image.halflife=outputStruct.halflife;
                                catch
                                end;
            
                                try
                                    newhandles.image.time=outputStruct.time;
                                    newhandles.image.duration=outputStruct.duration;
                                catch
                                end;
                            %
                            % Read DICOM image orientation vector
                            %
                            try
                                out=dirtyDICOMHeaderData(outputStruct.dirtyDICOMHeader, 1, '0020', '0037',mode);  %Spacing Between Slices (can be negative number)
                                sliceStep=str2num(out.string);
                                DicomImageOrientationVector =  str2num( strrep(out.string,'\',',') )
                                % Orientation vector of x axis (3 first numbers) and y axis (3 last numbers)
                                newhandles.image.DicomImageOrientationVector = DicomImageOrientationVector;
            
                            catch
                                DicomImageOrientationVector = [1 0 0 0 1 0];  % Assumption
                            end


                                % Save guidata
                                guidata(h, newhandles);
            
                                % Set Colorscale and modality
                                %imlook4d_set_colorscale_from_modality(h, eventdata, newhandles);
            
                                % Not sure why this works - since hObject is handle to
                                % FILE/OPEN uimenu on the imlook4d instance that opened
                                % this file.
            
                                %imlook4d_set_colorscale_from_modality( get( findobj(hObject, 'Label', 'Gray') ), eventdata, newhandles);
            
                                handleToColorMenu=findobj(newhandles.EditMenu, 'Label', 'Color');
        end
        
        function LocalOpenECAT(app, hObject, eventdata, handles, file, path)
            
                                %
                                % Open file and create new imlook4d instance
                                %
                                    %[file,path] = uigetfile('*.v','ECAT Open file name');
                                    fullPath=[path file];
            
                                    disp([ 'Opening ECAT file=' fullPath ]);
                                    [Data, mainHeader, subHeader, dirstruct]=ReadECAT(fullPath, @DummyGeneral);
            
                                    unit=char(nonzeros(mainHeader(466:466+31)'))';
                                    disp(['UNIT="' unit '"']);
            
                                    disp(['Matrix size=' num2str(size(Data))]);
                                    disp('DONE opening ECAT file ');
            
                                 % Get pixel size information
            
                                    pixelSizeX=10*ECAT_readHeaderReal(subHeader(:,1), 34);        % PixelSize in mm, from ECAT X_PIXEL_SIZE
                                    pixelSizeY=10*ECAT_readHeaderReal(subHeader(:,1), 38);       % PixelSize in mm, from ECAT Y_PIXEL_SIZE
                                    sliceSpacing=10*ECAT_readHeaderReal(subHeader(:,1), 42);     % PixelSize in mm, from ECAT Z_PIXEL_SIZE
            
            
                                 %
                                 % Get time information
                                 %
                                    try
                                        [time, duration]=timeFromECATInput(subHeader);
                                        %h=imlook4d(Data, time, duration);
                                    catch
                                        %h=imlook4d(Data);
                                        disp('NO TIME INFO FOUND');
                                    end
                                %
                                % Sort on time (Very rare: some dynamic ECAT files can be scrambled in time)
                                %
                                    if exist('time') % Sort if time exist
                                        % Build index with columns:  [ time,  original_index ]
                                        last=size(subHeader,2);  % Used when iterating files
                                        for i=1:last
                                            indexlist(i,1)=time(i);
                                            indexlist(i,2)=i;
                                        end
            
                                        % Sort on time
                                        disp('sorting ');
                                        sortedIndexList=sortrows(indexlist,[1]);  % Sort on time
            
                                        % Sort according to index list
                                        disp('sorting data');
                                        for i=1:last
                                            imageNumber=sortedIndexList(i,2);           % original image number for i:th image
                                            sortedData(:,:,:,i)=Data(:,:,:,imageNumber);
            
                                            sortedSubHeaders(:,i)=subHeader(:,imageNumber);
                                            sortedTime(i)=time(imageNumber);
                                            sortedDuration(i)=duration(imageNumber);
                                        end
                                    end % if time exist
            
                                % Swap head and feet
                                %sortedData=flipdim(sortedData,3);
            
            
                                 %
                                 % New imlook4d instance
                                 %
                                    try
                                        %[time, duration]=timeFromECATInput(subHeader);
                                        %h=imlook4d(Data, time, duration);
                                        h=imlook4d(sortedData, sortedTime, sortedDuration);
                                    catch
                                        h=imlook4d(Data);
                                        %disp('NO TIME INFO FOUND');
                                    end
            
                                %
                                % Operate on the new imlook4d instance
                                %
                                    set(h,'Name', [file]);
                                    newhandles = guidata(h);
            
                                    % Save header and subheader
                                    %newhandles.image.subHeader=subHeader;
                                    newhandles.image.subHeader=sortedSubHeaders;
                                    newhandles.image.mainHeader=mainHeader;
                                    newhandles.image.ECATDirStruct=dirstruct;
                                    newhandles.image.halflife=ECAT_readHeaderReal(mainHeader, 74);
                                    disp(['Halflife=' num2str(newhandles.image.halflife)]);
                                    newhandles.image.pixelSizeX=pixelSizeX;
                                    newhandles.image.pixelSizeY=pixelSizeY;
                                    newhandles.image.sliceSpacing=sliceSpacing;
                                    newhandles.image.unit=unit;
                                    newhandles.image.modality='PT';  % Assume PET
            
                                    % Set radio button and call callback function
                                    set(newhandles.FlipAndRotateRadioButton,'Value',1);
                                    imlook4d('FlipAndRotateRadioButton_Callback', h,{},newhandles);
            
                                    %Remember path
                                        cd(path);
            
                                    % Set colorscale
                                        %imlook4d_set_colorscale_from_modality(h, eventdata, newhandles);
                                        handleToColorMenu=findobj(newhandles.EditMenu, 'Label', 'Color');  % Menu Color
                                        imlook4d_set_colorscale_from_modality(app,  handleToColorMenu , eventdata, newhandles);     % Sets color according to modality
            
                                    % Save information of file format in new imlook4d
                                    newhandles.image.fileType='ECAT';
            
            
                                    % Save guidata
                                    guidata(h, newhandles);
        end
        
        function LocalOpenMGH(app, hObject, eventdata, handles, file, path)
                            % Test if Freesurfer files exist
                                if strcmp('', which('MRIread'))
                                    warndlg({'MGH not read because Freesurfer Matlab files not in path.',...
                                        'Add these files to path, or',...
                                        'download Freesurfer Matlab files'});
                                    displayMessageRow([''   ]);
                                    return;
                                end;
            
                            % Read files
                                fullPath=[path file];
                                [pathstr, name, ext] = fileparts(fullPath);
                                disp([ 'Opening MGH/MGZ from path=' fullPath ]);
                                mri = MRIread(fullPath,0);
                                if strcmp(ext,'.mgz')
                                    mri.vol = permute(mri.vol,[2 3 1]);  % Freesurfer direction
                                end
            
                            % Operate on the new imlook4d instance
                                h=imlook4d(mri.vol);
                                set(h,'Name', [name]);
                                newhandles = guidata(h);
            
                            % Save information of file format in new imlook4d
                                newhandles.image.fileType='MGH';
                                newhandles.image.modality='';  % Assume PET
                                newhandles.image.mri=mri;  % Save MGH data structure
            
                            % Set pixel dimensions
            %                     newhandles.image.pixelSizeX=mri.ysize;
            %                     newhandles.image.pixelSizeY=mri.zsize;
            %                     newhandles.image.sliceSpacing=mri.xsize;
            
                                % Freesurfer direction
                                newhandles.image.pixelSizeX=mri.xsize;
                                newhandles.image.pixelSizeY=mri.ysize;
                                newhandles.image.sliceSpacing=mri.zsize;
            
                                newhandles.image.unit='';  % Unit not in header, but needed in some scripts
            
                                % Save guidata
                                guidata(h, newhandles);
            
                            % Set colorscale
                                imlook4d_set_colorscale_from_modality(app, h, eventdata, newhandles);
        end
        

        function LocalOpenMat(app, hObject, eventdata, handles, file, path)
            
                                fullPath=[path file];
            
                                disp([ 'Opening matlab matrix file=' fullPath ]);
                                load(fullPath);
                                whos
            
                                % If m4-object
            
                                %if (isa(inpargs,'Matrix4D'))
                                try
                                    if isobject('m4Map')
                                      imlook4d(m4Map);
                                      return
                                    end
                                catch end
            
                                % If m4 mat-file (non-object)
                                if exist('m4ADCDataw')
                                    [pathstr, variableName, ext] = fileparts(fullPath);  % name will be same as variable
            
                                   numberOfm4s=eval(['size(' variableName ',2)']);
                                    for i=1:numberOfm4s
                                        m4=eval( [variableName '{' num2str(i) '}']);  % The m4-object
                                        h=imlook4d(real(m4.matrix()) );
                                        set(h,'name', [ variableName '(' num2str(i) ') (' m4.name() ')']);
            
                                        % Show also imaginary data
                                        if not( isreal(m4.matrix()) )
                                            h=imlook4d(real(m4.matrix()) );
                                            set(h,'name', [ '[Imag]' variableName '(' num2str(i) ') (' m4.name() ')']);
                                        end
            
                                    end
            
                                    return
            
                                end


                                disp(['Matrix size=' num2str(size(Data))]);
                                disp('DONE opening matlab matrix file ');
            
                                % Try using time information
                                % If missing, obtain time information:
                                %   try ECAT
                                %   try DICOM
                                %   fallback on only Data matrix
            
                                try
                                    disp('Try imlook4d with time information');
                                    h=imlook4d(Data, time, duration(app));
                                catch  % IF PROBLEM with missing time/duration, try to obtain them:
                                    disp('Time information did not exist, try to obtain this from possible header information');
            
                                    try
                                        disp('DICOM header did not work');
                                        [time, myDuration]=timeFromDICOMInput(header);
                                        h=imlook4d(Data, time, duration(app));
                                    catch
                                        try
                                            disp('Try ECAT header');
                                            [time, myDuration]=timeFromECATInput(subHeader);
                                            h=imlook4d(Data, time, duration(app));
            
                                        catch
                                            disp('ECAT header did not work');
                                            h=imlook4d(Data);
                                        end
                                        %h=imlook4d(Data);
                                    end
                                end
            
                                %h=imlook4d(Data);
                                set(h,'Name', [file]);
                                newhandles = guidata(h);
            
                                % Pixel dimensions
                                try
                                     [newhandles.image.pixelSizeX newhandles.image.pixelSizeY newhandles.image.sliceSpacing]=pixel_dims;
                                catch
            
                                    disp('imlook4d/OpenMat_Callback: Failed Importing pixel_dims');
                                end
            
            
                                % Store ECAT headers
                                try
                                    disp('Importing ECAT headers');
                                    %newhandles = guidata(h);
                                    % Store header and subheader
                                    newhandles.image.subHeader=subHeader;
                                    newhandles.image.mainHeader=mainHeader;
                                    newhandles.image.ECATDirStruct=dirstruct;
                                    disp('Sizes subHeader mainHeader dirstruct');
                                    size(subHeader)
                                    size(mainHeader)
                                    size(dirstruct)
                                catch
            
                                    disp('imlook4d/OpenMat_Callback: Failed Importing ECAT headers');
                                end
            
                                % Store DICOM headers
                                try
                                    disp('Importing DICOM headers');
                                    %newhandles = guidata(h);
                                    % Store DICOM header
                                    newhandles.image.dirtyDICOMHeader=DICOMHeader;
                                catch
            
                                    disp('imlook4d/OpenMat_Callback: Failed Importing DICOM headers');
                                end
            
                                % Store isotope half time
                                %
                                try
                                    disp('Importing isotope halflife');
                                    %newhandles = guidata(h);
                                    newhandles.image.halflife=halflife;
                                catch
                                    disp('imlook4d/OpenMat_Callback: Failed Importing isotope halflife');
                                end
            
                                % Store file type
                                %
                                try
                                    disp('Importing fileType');
                                    newhandles.image.fileType=fileType;
                                catch
                                    disp('imlook4d/OpenMat_Callback: Failed importing fileType');
                                end
            
            
                                % Store pixel dimensions
                                %
                                try
                                    disp('Importing pixel dimensions');
                                    newhandles.image.pixelSizeX=pixel_dims(1);
                                    newhandles.image.pixelSizeY=pixel_dims(2);
                                    newhandles.image.sliceSpacing=pixel_dims(3);
            
                                catch
                                    disp('imlook4d/OpenMat_Callback: Failed importing pixel_dims');
                                end
            
            
                                cd(path);  %Remember path
            
            
                                % Save guidata
                                guidata(h, newhandles);
            
                                updateImage(app, h, eventdata, newhandles);
        end
        
        function LocalOpenModernRDF(app, hObject, eventdata, handles, file, path, ForcedRaw4D)
                            fullPath=[path file];
                            [path,name,ext] = fileparts(fullPath);
                            disp([ 'Opening Modern RDF (GE Raw data) from path=' fullPath ]);
                            if ForcedRaw4D
                                [~, SINO4D] = jan_readNewRdf(fullPath); % Reads a 4D sinogram
                                h=imlook4d(SINO4D);
                            else
                                SINO3D = jan_readNewRdf(fullPath); % Reads a 3D sinogram summing ToF Dimension
                                h=imlook4d(SINO3D);
                            end
                            set(h,'Name', [file]);
                            Color_Callback(app, h, [],guidata(h), 'Sokolof'); % TODO Why does it make it gray after this ?
                            newHandles = guidata(h);
                            newHandles.image.fileType = 'ModernRDF';
                            guidata(h, newHandles);
        end
        
        function LocalOpenNifti(app, hObject, eventdata, handles, file, path, fileType)
                            fullPath=[path file];
                            [path,name,ext] = fileparts(fullPath);
            
                            disp([ 'Opening Analyze/Nifti from path=' fullPath ]);
            
                            % Load Analyze/Nifti
                            %
                            % load_nii transforms image to radiological RAS
                            % coordinate system, and outputs the nii structure.
                            %--------------------------------------------------
                            %  nii structure:
                            %	hdr -		struct with NIFTI header fields.
                            %	filetype -	Analyze format .hdr/.img (0);
                            %			NIFTI .hdr/.img (1);
                            %			NIFTI .nii (2)
                            %	fileprefix - 	NIFTI filename without extension.
                            %	machine - 	machine string variable.
                            %	img - 		3D (or 4D) matrix of NIFTI data.
                            %	original -	the original header before any affine transform.
                                try
                                    nii = load_nii(fullPath);
                                    openingMode='load_nii';
                                catch
                                    %  Load NIFTI or ANALYZE dataset, but not applying any appropriate affine
                                    %  geometric transform or voxel intensity scaling.
                                    warndlg({'WARNING - load_nii failed.',  'Trying load_untouch_nii.',  'The data will not go through geometrical transforms'});
                                    nii = load_untouch_nii(fullPath);
                                    openingMode='load_untouch_nii';
                                end
            
                            TimeInfoExists = false;
            
                            % Get times from PMOD nifti-format - if exists
                                try
                                    ext = load_nii_ext(fullPath);
            
                                    if (ext.num_ext == 1)
            
                                        % Private creator
                                        out = dirtyDICOMHeaderData( {ext.section.edata'}, 1, '0055', '0010',2);
            
                                        if  strcmp( out.string, 'PMOD_1')
            
                                            % [Frame Start Times Vector]
                                            out = dirtyDICOMHeaderData( {ext.section.edata'}, 1, '0055', '1001',2);
                                            time = typecast( uint8(out.bytes), 'double')';
            
                                            % [Frame Durations (ms) Vector]
                                            out = dirtyDICOMHeaderData( {ext.section.edata'}, 1, '0055', '1004',2);
                                            duration = 0.001 * typecast( uint8(out.bytes), 'double')';
            
                                            [ 'time' 'duration'];
                                            [time duration];
            
                                            TimeInfoExists = true;
                                        end
                                    end
            
                                catch
                                    % TODO : have warning display once for missing time
                                    % info in BOTH pmod and sif
                                end
            
            
                            % Get times from sif file - if exists (Turku data has that)
                                try
                                    fullPath=[path filesep name '.sif'];  % hdr file (img file was opened)
                                    fid=fopen(fullPath);
            
                                    C = textscan(fid, '%f %f %f %f %f', 'headerLines', 1);
                                    time=C{1}';
                                    duration= (C{2}-C{1})';
                                    [ 'time' 'duration'];
                                    [time duration];
            
                                    fclose(fid);
            
                                    TimeInfoExists = true;
                                catch
                                    % Sif file does not exist
                                end
            
            %               % Show warning if time-info did not exist
            %                    if (TimeInfoExists == false)
            %                        try
            %                            sz = size(nii.img);
            %                            if length(sz)>3
            %                                % Dynamic scan -- times are importand
            %                                opts = struct('WindowStyle','modal', 'Interpreter','tex');
            %                                warndlg({...
            %                                    'Time information missing', ...
            %                                    ' ', ...
            %                                    'Nifti does not know time, and this is a dynamic scan so time can be important'...
            %                                    'Please import time information from .sif file', ...
            %                                    '(Menu "SCRIPTS/Matrix/Import times sif")'}, ...
            %                                    'WARNING', ...
            %                                    opts ...
            %                                    );
            %
            %                            end
            %                        catch
            %                        end
            %                    end
            
            
                          % New imlook4d
                                %nii.img = flipdim( nii.img, 2);
                                nii.img(isnan(nii.img))=0;
                                if exist('time')&&exist('duration')
                                    h=imlook4d(single(nii.img),time, duration);
                                else
                                     h=imlook4d(single(nii.img));
                                end
            
                                set(h,'Name', [file]);
                                newhandles = guidata(h);
            
                          % Display message
                                if strcmp(openingMode,'load_untouch_nii')
                                    displayMessageRow({'WARNING - load_nii failed.  Trying load_untouch_nii.',  'The data did not go through geometrical transforms'})
                                end
            
                          % Set Y-direction
                              %%  newhandles.axes1.YDir = 'normal';
                              %set(newhandles.axes1, 'YDir', 'reverse');
                              set(newhandles.axes1, 'YDir', 'normal');
            
            
                           % Enable menues
                                set(newhandles.SaveFile,'Enable','on');
            
                           % Save nii struct (except images)
                                nii = rmfield(nii, 'img');
                                newhandles.image.nii=nii;
            
            
                           % Set pixel dimensions
                                newhandles.image.fileType=fileType;
                                newhandles.image.pixelSizeX=nii.hdr.dime.pixdim(2);
                                newhandles.image.pixelSizeY=nii.hdr.dime.pixdim(3);
                                newhandles.image.sliceSpacing=nii.hdr.dime.pixdim(4);
                                newhandles.image.unit='';  % Unit not in header, but needed in some scripts
            
                                newhandles.image.openingMode=openingMode;


                            % Set radio button and call callback function
            %                     set(newhandles.SwapHeadFeetRadioButton,'Value',1);
            %                     imlook4d('SwapHeadFeetRadioButton_Callback', h,{},newhandles);
            
                           % Save guidata
                                guidata(h, newhandles);
                                updateImage(app, newhandles.figure1, [], newhandles);
        end
        
        function LocalOpenSHR(app, hObject, eventdata, handles, file, path)
                                pixelx=256;
                                pixely=256;
            
                                fullPath=[path file];
                                disp([ 'Opening SHR path=' path ]);
            
                                % Read data
                                [Data, time, duration]=SHR_readMultipleFiles(  fullPath ,  pixelx,  pixely);
            
            
                                % Operate on the new imlook4d instance
                                    h=imlook4d(Data, time, duration);
                                    [pathstr, name, ext] = fileparts(fullPath);
                                    set(h,'Name', [name]);
                                    newhandles = guidata(h);
            
                                    % Turn off menues that do not apply to SHR data
                                    set(newhandles.SaveFile,'Enable','off');
            
                                % Save information of file format in new imlook4d
                                    newhandles.image.fileType='SHR';
                                    newhandles.image.modality='PT';  % Assume PET
            
                                    % Save guidata
                                    guidata(h, newhandles);
            
            
                                % Set colorscale
                                    imlook4d_set_colorscale_from_modality(app, h, eventdata, newhandles);
            
                                %Remember path
                                    cd(path);
        end
        
        function LocalSaveAnalyze(app, handles, matrix)
            
                                % New file name
                                    suggestedFileName=get(handles.figure1, 'Name');
                                    cleanedFileName=regexprep(suggestedFileName, '[*"/[]:;|=\\]', '_');  % Avoid characters that are not accepted in Windows file names
                                    [path,name,ext] = fileparts([cleanedFileName]);
            
                                    [file,path] = uiputfile(['*.hdr'] ,'Save as .hdr AND .img file ( unmodified header and matrix)', name);
            
                                    if file==0
                                        error('imlook4d/LocalSaveAnalyze:ERROR')
                                    end
            
                               % Copy header file from original
                                    destination=[path file];
                                    copyfile(handles.image.headerFile, destination,'f')
                                    disp(['Copying Analyze header file='  destination ]);
                                    displayMessageRow(['Copying Analyze header file='  destination ]);
                                    fclose('all');
            
                               % Manipulate copied file header
                                    machineFormat=handles.image.machineformat;
                                    fileattrib( destination, '+w')
                                    fid1 = fopen(destination, 'r+');
            
                                    % Number format
                                    fseek(fid1, 70, 'bof');
                                    fwrite(fid1, 16 ,'int16',0, machineFormat); % 16=Float
            
                                    % Write matrix size
                                    fseek(fid1, 42, 'bof');
                                    dimensions=[ size(matrix,1) size(matrix,2) size(matrix,3) size(matrix,4)]
                                    fwrite(fid1, dimensions,'int16',0, machineFormat);
            
                                    fclose(fid1);
            
                               % Save image file (assuming zero offset to data in file)
                                    [path,name,ext] = fileparts([path file]);
                                    destination=[path filesep name '.img'];
                                    disp(['Writing Analyze image file='  destination ]);
            
                                    displayMessageRow(['Writing Analyze image file='  destination ]);
            
                                    % Write transposed image matrices (because opened that way)
                                    fid=fopen(destination,'w');
                                    for i=1:size(matrix,3)*size(matrix,4)
                                        %fwrite(fid,matrix(:,:,i), handles.image.valueType);
                                        fwrite(fid,matrix(:,:,i), 'float');
                                    end
                                    fclose(fid);
                                    disp('DONE writing ANALYZE file');
                                    displayMessageRow(['DONE writing ANALYZE file'   ]);
        end
        
        function LocalSaveDICOM(app, handles, matrix)
            
                                    % Store dimensions
                                        numberOfSlices = size(matrix,3);
                                        numberOfFrames = size(matrix,4);
            
                                    % Fix DICOM y-axis
                                        matrix=imlook4d_fliplr(matrix);  % Flip row vector (which is the y direction of the matrix)
            
                                    % Remove out of range values
                                        matrix(isnan(matrix)) = 0;
                                        matrix(isinf(matrix)) = 0;
            
                                    % Read stored information
                                        rows=size(matrix,2);
                                        cols=size(matrix,1);
            
                                        headers=handles.image.dirtyDICOMHeader;
                                        matrix=reshape(matrix,rows,cols,[]);  % Matrix reshaped to 3D matrix (x,y,file nr)
            
                                        fileNames=handles.image.dirtyDICOMFileNames;
                                        %indecesToScaleFactor=handles.image.dirtyDICOMIndecesToScaleFactor;
                                        mode=handles.image.dirtyDICOMMode;
            
            
            %                        % Intensity image from color (ultrasound is typical case where header is marked as color)
                                        out3=dirtyDICOMHeaderData(headers, 1, '0028', '0002',mode);  % samples per pixel
                                        numberOfsamplesPerPixel=out3.bytes(1)+256*out3.bytes(2);
            
                                        if (numberOfsamplesPerPixel == 3)
                                            for i = 1: length(headers)
                                                headers{i}=dirtyDICOMModifyHeaderUS(headers{i}, '0028', '0002',mode, 1); %
                                            end
                                        end
            
            
                                    % USER INPUT:  Modify Patient ID, Patient Name, Series Description
            
                                        try
                                            % Get struct ,where patientName1.string is the
                                            % string itself
                                            try
                                                patientName1=dirtyDICOMHeaderData(headers, 1,'0010', '0010',mode);
                                            catch
                                                patientName1.string=''
                                            end
                                            try
                                                patientID1=dirtyDICOMHeaderData(headers, 1,'0010', '0020',mode);
                                            catch
                                                patientID1.string=''
                                            end
                                            try
                                                seriesDesc1=dirtyDICOMHeaderData(headers, 1,'0008', '103E',mode);
                                            catch
                                                seriesDesc1.string=''
                                            end
                                            try
                                                accessionNumber=dirtyDICOMHeaderData(headers, 1,'0008', '0050',mode);
                                            catch
                                                accessionNumber.string='';
                                            end
                                            seriesNo1=dirtyDICOMHeaderData(headers, 1,'0020', '0011',mode);
            
                                            try
                                                modalityString=handles.image.modality;
                                            catch
                                                modalityString='OT';
                                            end
            
                                            defaultSeriesDescription=[ handles.image.history ' ' seriesDesc1.string];
            
                                            prompt={'Patient Name: ',...
                                                    'Patient ID: ',...
                                                    'Series Description: ',...
                                                    'Series number',...
                                                    'Accesion number',...
                                                    'Modality (2 chars, MR,CT,PT,NM,OT,...)'};
                                            title='Modify DICOM header info';
                                            numlines=1;
                                            defaultanswer={patientName1.string,patientID1.string, defaultSeriesDescription ,seriesNo1.string, accessionNumber.string, modalityString};
                                            answer=inputdlg(prompt,title,numlines,defaultanswer);
                                            % Strings containing the patientName etc
                                            patientName=answer{1};
                                            patientID=answer{2};
                                            seriesDesc=answer{3};
                                            seriesNo=answer{4};
                                            accessionNumberString=answer{5};
                                            modalityString=answer{6};
                                        catch
                                        end
            
                                    % USER INPUT:  Modify DICOM mode (commented out)
            
                                        defaultanswer{1}=handles.image.dirtyDICOMPixelSizeString;
                                        defaultanswer{2}=handles.image.dirtyDICOMSlicesString;
                                        defaultanswer{3}=handles.image.dirtyDICOMMachineFormat;
                                        answer=defaultanswer;
            
                                        % Input pixels and slices
                %                         prompt={'Pixels',...
                %                                 'Slices',...
                %                                 'Byte order - b or l (little L)'};
                %                         title='Input data dimensions';
                %                         numlines=1;
                %                         answer=inputdlg(prompt,title,numlines,defaultanswer);
                %
                                         ByteOrder=answer{3};
            
                                    % Calculate DICOM volume's min and max
                                        volumeMax=max(matrix(:));
                                        volumeMin=min(matrix(:));
            
            
                                    % Get new file paths
                                        suggestedDirName=get(handles.figure1, 'Name');
                                        cleanedDirName=regexprep(suggestedDirName, '[*"/[]:;|=\\]', '_');  % Avoid characters that are not accepted in Windows file names
            
                                        % Create new directory describing DICOM file
                                        previousDirectory=pwd();
                                        cd ..
                                        guessedDirectory=pwd();
                                        %%guessedDirectory=[previousDirectory filesep cleanedDirName];
                                        %%mkdir(previousDirectory,cleanedDirName);        % Guess a directory
                                        %%cd(guessedDirectory);                           % Go to guessed directory (uigetdir will be placed here)
            
                                       %newPath=uigetdir(guessedDirectory,'Select directory to save files to');
                                      %newPath=java_uigetdir(guessedDirectory,'Make an empty directory to save all DICOM files within'); % Use java directory open dialog (nicer than windows)
                                      newPath=java_uigetdir(previousDirectory,'Select/create directory to save files to'); % Use java directory open dialog (nicer than windows)
                                      if newPath == 0
                                          disp('Cancelled by user');
                                          return
                                      end
            
            
                                      % Make directory if not existing
                                      fn = fullfile(newPath);
                                      if ~exist(fn, 'dir')
                                          disp(['Make directory = ' newPath ]);
                                          mkdir(fn);
                                      end
            
                                        try
                                            cd(newPath);                                    % Go to selected directory
                                        catch
                                            try
                                                mkdir(newPath);
                                            catch
                                                error(['imlook4d ERROR - failed creating directory' newPath]);
                                            end
                                        end
            
                                        if( ~strcmp( guessedDirectory,newPath) )                   % If guessed wrong directory
                                            try
                                                rmdir([previousDirectory filesep cleanedDirName])
                                            catch
                                            end
                                        end


                %                         newPath=uigetdir(pwd(),'Select directory to save files to');
                %                         cd(newPath);


                                    % Modify headers
            
                                        iNumberOfHeaders = size(headers,2);
                                        iNumberOfSelectedFiles=size(matrix,3);  % This definition allows for truncated matrices, as for instance from Patlak model
            
                                        % Fill in headers, if more images than original
                                            s = size( handles.image.Cdata);
            
                                            switch length(s)
                                                case 2
                                                numberOfImages = 1;
                                                case 3
                                                numberOfImages = s(3);
                                                case 4
                                                numberOfImages = s(3)*s(4);
                                            end
            
                                            % Keep original headers, and modify them below
            %                                % Copy headers from template
            %                                 template = headers{1};
            %                                 for i = 1:numberOfImages
            %                                     newHeaders{i} = template;
            %                                 end
            %                                 headers = newHeaders;
            
                                        % Build slice Locations
                                            slices = size( handles.image.Cdata,3);
                                            frames = size( handles.image.Cdata,4);
                                            corner = handles.image.imagePosition{1};
                                            for i = 1:frames
                                                for j= 1:slices
                                                    x = corner(1);
                                                    y = corner(2);
                                                    z = corner(3) + (j-1) * handles.image.sliceSpacing ;
                                                    index = (i-1) * slices + j;
                                                    handles.image.imagePosition{ index } = [ x y z ];
            
                                                    handles.image.sliceLocations(index) = z;
                                                end
                                            end


                                        waitBarHandle = waitbar(0,'Saving DICOM files');	% Initiate waitbar with text
            
            
                                        seriesInstanceUID=generateUID();
                                        interval = round( iNumberOfSelectedFiles/50);
                                        for j = 1:frames
                                           for k= 1:slices
                                           %for i=1:iNumberOfSelectedFiles
                                             i = k + (j-1) * slices;
                                             %disp(i)
                                             if (mod(i, interval)==0) waitbar(i/iNumberOfSelectedFiles); end
            
                                                % Set Modality
                                                 headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0008', '0060',mode, modalityString); % ImageType
            
                                                 % Change image properties
                                                 % headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0008', '0008',mode, 'DERIVED\SECONDARY'); % ImageType
                                                 % headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0008' ,'2111',mode, 'imlook4d - not for clinical use'); % Derivation Description
            
            
                                             % Set scale factors (Fails if scale factor not existing)
                                             try
            
                                                % Get indeces to header
                                                    scaleFactor=dirtyDICOMHeaderData(headers, i, '0028', '1053',mode);
                                                    intercept=dirtyDICOMHeaderData(headers, i, '0028', '1052',mode);
            
                                                %Calculate scale factor
                                                    maxval = max(max(abs(matrix(:,:,i))));  %Maximum absolute value in image.
                                                    scale_factor = maxval/32767;
                                                    scale_factor=1.01*scale_factor;   % Play it safe
            
                                                    if scale_factor == 0
                                                        scale_factor = 1;
                                                    end
            
            
                                                    valueString=num2str(scale_factor);
            
                                                % Find out scaling to use for matrix data, assuming zero intercept
                                                     scale_factor=str2num(valueString);  % This is a smaller value due to truncation of decimals in scale factor
            
                                                % Divide by scale factor
                                                    matrix(:,:,i) = matrix(:,:,i)./scale_factor;  %Divide by scale factor.
            
                                                % Slope + Intercept
                                                    headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0028', '1053',mode, valueString);
                                                    headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0028', '1052',mode, '0');
                                             catch
                                                    disp('Scale factor probably missing');
                                             end
            
            
                                            % Pixel representation (Make it signed)
                                            try
                                                headers{i}=dirtyDICOMModifyHeaderUS(headers{i}, '0028', '0103',mode, 0); % Make signed (0=unsigned)
                                            catch
                                            end
            
                                            % Pixels (Value representation US)
                                                headers{i}=dirtyDICOMModifyHeaderUS(headers{i}, '0028', '0010',mode, rows); % rows
                                                headers{i}=dirtyDICOMModifyHeaderUS(headers{i}, '0028', '0011',mode, cols); % columns
            
                                                % Set value length for image (7FE0,0010)
                                                goalValueLength=2*rows*cols;
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '7FE0', '0010',mode, num2str(goalValueLength)); % New valuelength for image
            
                                                % Set pixel size
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0028', '0030',mode, [ num2str(handles.image.pixelSizeX) '\' num2str(handles.image.pixelSizeY)]);
            
            
                                            % Unit
                                                try
                                                    headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0054', '1001',mode, handles.image.unit);  % Unit
                                                    headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0028', '1054',mode, handles.image.unit);  % Rescale type (unit after rescale slope/intercept is applied)
                                                catch end
            
                                            % UIDs
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0020', '000E',mode, seriesInstanceUID);  % Series Instance UID
            
                                                SOPInstanceUID=generateUID();
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0008', '0018',mode, SOPInstanceUID);      % SOP Instance UID
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0002', '0003',mode, SOPInstanceUID);      % Media Storage UID
            
                                             % Patient
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0010', '0010',mode,patientName);
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0010', '0020',mode,patientID);
            
                                             % Series
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0008', '103E',mode,seriesDesc);              % Study number
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0020', '0011',mode, seriesNo);
            
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0008', '0050',mode, accessionNumberString);
            
                                             % File names
                                                %newFileNames{i}=SOPInstanceUID;
                                                newFileNames{i}=num2str(i);


                                             % image position
            
            
                                                % 1) Image Position Patient
                                                location = handles.image.imagePosition{i};
                                                imagePositionString = [ num2str( location(1) ) '\' num2str( location(2) ) '\' num2str( location(3) )];
            
                                                % Breaks in implicit
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0020', '0032',mode, imagePositionString);
            
                                                % 2) Slice Location
                                                try
                                                    headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0020', '1041',mode, num2str(  handles.image.sliceLocations(i) ) );
                                                catch
            
                                                end
            
            
                                                % 3) Slice Spacing
                                                try
                                                    sliceSpacing = handles.image.sliceLocations(2) - handles.image.sliceLocations(1);  % Assume same spacing
                                                    headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0018', '0050',mode, num2str(  sliceSpacing ) );
                                                catch
            
                                                end
            
                                                try
                                                    out2=dirtyDICOMHeaderData(header, i, '0018', '0088',mode); % Spacing Between Slices
                                                    headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0018', '0088',mode, num2str(  sliceSpacing ) );
                                                catch
            
                                                end
            
            
                                            % Time and duration
                                                try
                                                    time = handles.image.time(j);
                                                    duration = handles.image.duration(j);
            
                                                    % Frame Reference Time (in ms)
                                                    time_ms = time * 1000;
                                                    headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0054', '1300',mode, num2str(  time_ms ) );
            
                                                    % Actual Frame Duration (in ms)
                                                    duration_ms = duration * 1000;
                                                    headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0018', '1242',mode, num2str(  duration_ms ) );
            
                                                    % TODO: update acquisition time if
                                                    % possible (see
                                                    % dirtyDICOMTimeFromAcqTime.m)
            
                                                catch
                                                    disp('Error in making time and duration');
                                                end


                                             % TO DO -
                                             % number of images
            
            
                                             % instance number
                                             headers{i} = dirtyDICOMModifyHeaderString( headers{i}, '0020', '0013',mode, num2str(  i ) ); % instance number
                                             headers{i} = dirtyDICOMModifyHeaderUS( headers{i}, '0054', '1330',mode, i  ); % image index US
            
            
                                             % Make static
                                             if size(handles.image.Cdata,4)==1
                                             %if size(matrix,4)==1
                                                headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0054', '1000',mode, 'STATIC\IMAGE');  % Series Type
                                                headers{i}=dirtyDICOMModifyHeaderUS(headers{i}, '0054', '0101',mode, 1);  % Number of Time Slices
                                             end
            
                                             %
                                           end
                                        end
                                        close(waitBarHandle);
            
            
                                    % If multiple images in one dicom file, reshape
                                        if size( unique(fileNames),2 )==1   % Multiple images in the same file is characterized by having the same file name for each slice
            
            %                               % If Detectors column was opene into slice
            %                               % column, then exchange back
            %
            %                                  % NM (0054,0021) US #2 [2] Number of Detectors
            %                                  try
            %                                      numberOfDetectorsInScan=dirtyDICOMHeaderData(handles.image.dirtyDICOMHeader, 1,'0054', '0021',mode);
            %                                      numberOfDetectors=numberOfDetectorsInScan.bytes(1)+256*numberOfDetectorsInScan.bytes(2);
            %                                  catch
            %                                      numberOfDetectors=1;
            %                                  end
            %                                  if (numberOfDetectors == numberOfSlices) % Assume detectors in 3d column
            %                                     matrix = reshape( matrix, rows, cols, [], numberOfFrames);
            %                                     matrix = permute( matrix, [1 2 5 4 3]);  % swap back to what is whas in original Dicom file
            %                                  end
            
                                            % Assume same order as displayed
                                            % Allow only changing number of slices
            
                                            % Change Slices vector if exist
                                            numberOfSlices = size(matrix,3);
                                            try
                                                out0=dirtyDICOMHeaderData(headers, 1, '0054', '0080',mode,2); % Slice vector
                                                newString = out0.string( 1 : 2*numberOfSlices);
                                                headers{1} = dirtyDICOMModifyHeaderString( headers{1}, '0054', '0080',mode, newString, 2); % Occurs first in '00280009' Frame increment pointer
            
                                                headers{1} = dirtyDICOMModifyHeaderUS(headers{1}, '0054', '0081',mode, numberOfSlices)
                                            catch
                                                disp('Error modifying slice vector');
                                            end


                                             matrix=matrix(:);
            
                                           %tempFileName=fileNames(1);
                                           tempFileName=newFileNames(1);
                                           fileNames=0;
                                           fileNames=tempFileName;
            
                                           tempHeader=headers(1);
                                           headers=0;
                                           headers=tempHeader;
            
                                           iNumberOfSelectedFiles=1;
            
                                           % Set value length for image (7FE0,0010)
                                           i=1;
                                           headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '7FE0', '0010',mode, num2str(length(matrix)*1 )); % New valuelength for image
                                        end
            
                                    % Set Transfer Syntax UID
                                        out = dirtyDICOMHeaderData(headers, 1, '0002', '0010',2);
                                        Default_TSUID = '1.2.840.10008.1.2.1'; % Explicit Little Endian is imlook4d default
            
                                        if (mode == 2)
                                            % Change to default, if explicit mode   (implicit = 0)
                                            if ~strcmp( Default_TSUID, out.string)
                                                for i = 1 : length(headers)
                                                    headers{i}=dirtyDICOMModifyHeaderString(headers{i}, '0002', '0010',mode, Default_TSUID);
                                                end
                                            end
                                        end
            
            
                                    % Write to DICOM
                                        % This function reuses file names (good for traceability)
            
                                        %Dirty_Write_DICOM(matrix, headers(1:iNumberOfSelectedFiles), fileNames(1:iNumberOfSelectedFiles), ByteOrder);
                                        Dirty_Write_DICOM(matrix, headers(1:iNumberOfSelectedFiles), newFileNames(1:iNumberOfSelectedFiles), ByteOrder, handles.image.dirtyDICOMMode);
            
                                    % Change data folder for this imlook4d instance
                                        cd(newPath);
                                        handles.image.folder = [ newPath filesep newFileNames{1}]; % Use first file name
                                        guidata(handles.figure1, handles);
                                        storeOpenedFilePaths(app,  handles.image.folder);  % remember new file in history list
                                        showOpenedFilePaths(app)
        end
        
        function LocalSaveECAT(app, handles, Data)
                             % Save Data and header as .v file
                             %
                             % The headers and ECATDirStruct are updated according to
                             % - values in handles.image.time
                             % - values in handles.image.duration
                             % - number of frames (i.e. size(Data,4)  )
                             %
            
                             numberOfFrames=size(Data,4);
                             disp(['Number of frames=' num2str(numberOfFrames) ]);
            
            
                             % Modify ECAT main header from dialog
                             mainHeader=handles.image.mainHeader;
                             %mainHeader=modifyECATHeader(mainHeader);  % I don't think this is useful
            
                             %
                             % Make static if matrix has fewer dimensions than before (after models, such as Patlak)
                             %
                                  if (numberOfFrames==1)
                                        disp('STATIC image');
                                        byte=328;length=2;handles.image.mainHeader(byte+1:byte+length)=[0 3]; % Set static mode
                                        byte=354;length=2;handles.image.mainHeader(byte+1:byte+length)=[0 1]; % Set 1 frame
                                        handles.image.ECATDirStruct(9:end)=0; % Make directory structure reflect one frame
                                        handles.image.ECATDirStruct=handles.image.ECATDirStruct(:,1);  % Make directory structure length what is needed for less than 31 frames
                                  else
                                       disp('DYNAMIC image');
                                       byte=354;length=2;handles.image.mainHeader(byte+1:byte+length)=[0 numberOfFrames]; % Set number of frames
                                  end
            
                             %----------------------------------
                             %
                             % Modify directory structure to reflect number of frames
                             %
            
                                % Base it on old directory structure, by copying directory structure frame by frame.
                                    newPETDirStruct=zeros( size(handles.image.ECATDirStruct(:,:) ));    % Make all directory structures zero
            
                                    newPETDirStruct(1,1)=31-numberOfFrames;                             % First directory structure
                                    newPETDirStruct(2:4,1)=handles.image.ECATDirStruct(2:4,1);          % First directory structure
            
            
                                % Make zero for frames that are not existing
                                    PETDirStructNumber=1;  % Number of Directory Structures
                                    positionCounter=1;
                                    for i=1:numberOfFrames
                                        positionCounter=positionCounter+4;  % Move to next position in structure
            
                                        if (i==31*1+1)||(i==31*2+1)||(i==31*3+1)
                                            % If end of directory structure,
                                            % Move to next directory structure
                                            PETDirStructNumber=PETDirStructNumber+1;
                                            positionCounter=1;
                                            positionCounter=positionCounter+4;
                                        end
            
                                        % Update directory structure for current frame
                                        newPETDirStruct(positionCounter:positionCounter+3,PETDirStructNumber)=...
                                            handles.image.ECATDirStruct(positionCounter:positionCounter+3,PETDirStructNumber);
                                    end
            
            
                                % Fix last record to point to first record
                                    % newPETDirStruct(1,PETDirStructNumber) % I don't know the function of this, but it works
                                    newPETDirStruct(2,PETDirStructNumber)=2;  % Point to first record of first directory structure
                                    %newPETDirStruct(3,PETDirStructNumber)  % Points to previous directory structure.  This is unaffected
                                    newPETDirStruct(4,PETDirStructNumber)=numberOfFrames-(PETDirStructNumber-1)*31;  % Number of frames in last directory structure
            
                                    handles.image.ECATDirStruct=newPETDirStruct;  % Copy new directory structure to old directory structure
            
            
                             %----------------------------------
                             %
                             % ECAT subheaders - Update times and duration in
                             %
                                    for i=1:numberOfFrames
            
                                       % Start time of frame
                                            hexValue=uint32_to_hex(1000*handles.image.time(i) );    % ms-Time in hexadecimal notation
                                            byte=50;length=1;
                                            handles.image.subHeader(byte+1:byte+length, i)              =hex_to_uint8( hexValue(1:2) ); % Write each byte
                                            byte=byte+1;handles.image.subHeader(byte+1:byte+length, i)  =hex_to_uint8( hexValue(3:4) );
                                            byte=byte+1;handles.image.subHeader(byte+1:byte+length, i)  =hex_to_uint8( hexValue(5:6) );
                                            byte=byte+1;handles.image.subHeader(byte+1:byte+length, i)  =hex_to_uint8( hexValue(7:8) );
                                            %disp(['Time=' num2str(1000*handles.image.time(i) )    '    hexvalue=' hexValue '    Header value=' num2str( ECAT_readHeaderInt4(handles.image.subHeader(:,i), 50)  ) 'ms']);
            
                                       % Duration of frame
                                            hexValue=uint32_to_hex(1000*handles.image.duration(i) );    % ms-Duration in hexadecimal notation
                                            byte=46;length=1;
                                            handles.image.subHeader(byte+1:byte+length, i)              =hex_to_uint8( hexValue(1:2) ); % Write each byte
                                            byte=byte+1;handles.image.subHeader(byte+1:byte+length, i)  =hex_to_uint8( hexValue(3:4) );
                                            byte=byte+1;handles.image.subHeader(byte+1:byte+length, i)  =hex_to_uint8( hexValue(5:6) );
                                            byte=byte+1;handles.image.subHeader(byte+1:byte+length, i)  =hex_to_uint8( hexValue(7:8) );
            
                                            %disp([num2str(i) '   SCRIPTS/SumFrames ECAT header Frame start time=' num2str( ECAT_readHeaderInt4(handles.image.subHeader(:,i), 50)  ) 'ms']);
                                            %disp([num2str(i) '   SCRIPTS/SumFrames ECAT header Frame duration=' num2str( ECAT_readHeaderInt4(handles.image.subHeader(:,i), 46)  ) 'ms']);
                                            %disp(' ');
                                    end
            
                             %----------------------------------
                             %
                             % Save ECAT file
                             %
            
                                %[file,path] = uiputfile(['filename' '.v'] ,'Save as .v file ( matrix and header)');
                                suggestedFileName=get(handles.figure1, 'Name');
                                cleanedFileName=regexprep(suggestedFileName, '[*"/[]:;|=\\]', '_');  % Avoid characters that are not accepted in Windows file names
            
                                [file,path] = uiputfile(['*.v'] ,'Save as .v file ( matrix and header)', cleanedFileName);
            
                                if file==0
                                    error('imlook4d/SaveECAT_Callback:ERROR')
                                end
            
            
                                fullPath=[path file];
                                disp(['Writing ECAT-file='  path file ]);
            
                                % Swap head and feet
                                Data=flipdim(Data,3);
            
            
                                % Save ECAT
                                unit=handles.image.unit;
                                disp(['UNIT="' unit '"']);
                                %unit='test';
            
                                    disp('Writing dynamic ECAT - filtered images');
                                        WriteECAT( fullPath, ...
                                            Data,...
                                            mainHeader, ...
                                            handles.image.subHeader, ...
                                            handles.image.ECATDirStruct, ...
                                            unit)...
                                            ;
            
            
                                disp('Finished writing ECAT file');
        end
        
        function LocalSaveM4(app, handles, file, path)
                                                    suggestedFileName=get(handles.figure1, 'Name');
                                    cleanedFileName=regexprep(suggestedFileName, '[*"/[]:;|=\\]', '_');  % Avoid characters that are not accepted in Windows file names
                                    [path,name,ext] = fileparts([cleanedFileName]);
            
            
                                        [file,path] = uiputfile(['*.hdr'] ,'Save as .hdr AND .img file', name);
        end
        
        function LocalSaveMGH(app, handles, file, path)
                                % Test if Freesurfer files exist
                                    if strcmp('', which('MRIwrite'))
                                        displayMessageRow([' ']);
                                        warndlg({'MGH OR MGZ not read because Freesurfer Matlab files not in path.',...
                                            'Add these files to path, or',...
                                            'download Freesurfer Matlab files'});
                                        return;
                                    end;
            
                                % Prepare file path
                                suggestedFileName=get(handles.figure1, 'Name');
                                cleanedFileName=regexprep(suggestedFileName, '[*"/[]:;|=\\]', '_');  % Avoid characters that are not accepted in Windows file names
                                [path,name,ext] = fileparts([cleanedFileName]);
            
            
                                [file,path] = uiputfile({'*.mgh'; '*.mgz'; '*.nii'} ,'Save as .mgh, .mgz  ( Freesurfer) or .nii (nifti)', name);
                                %[file,path] = uiputfile({'*.mgh'} ,'Save as .mgh  ( Freesurfer)', name);
                                fullPath=[path file];
                                disp([ 'Saving MGH/MGZ from path=' fullPath ]);
                                [path,name,ext] = fileparts([fullPath]);
                                mri=handles.image.mri;  % recall saved mri struct
                                mri.vol=handles.image.Cdata;
            
                                % TODO:
                                % Correct directions, as they were modified in opening MGH
            
                                % Set pixel dimensions
                                    mri.xsize=handles.image.pixelSizeX;
                                    mri.ysize=handles.image.pixelSizeY;
                                    mri.zsize=handles.image.sliceSpacing;
                                    mri.volsize=size(handles.image.Cdata);
                                    mri.nframes=size(handles.image.Cdata,4);
            
                                % Save data


                %                     outfile = ['JAN' ts{1} '.nii'];       % Make name for nii file (also gives instruction to create nifti)
                %                     MRIwrite(temp, outfile, 'float'); % Save nifti (because file extension .nii)


                                    %MRIwrite(mri,fullPath ,'int');
                                    disp(['Writing to:' fullPath]);
                                    MRIwrite(mri,fullPath ,'float');  % Writes mgh (but will call it mgz if that is selected from uiputdlg)
                                    % Make mgz, if that was selected
                                    if strcmp( '.mgz', ext)
                                       gzip( fullPath);
                                       movefile( [fullPath '.gz'], fullPath);
                                    end
        end
        
        function LocalSaveNifti(app, handles, tempData, SaveAsShort)
            
                                % New file name
                                    suggestedFileName=get(handles.figure1, 'Name');
                                    cleanedFileName=regexprep(suggestedFileName, '[*"/[]:;|=\\]', '_');  % Avoid characters that are not accepted in Windows file names
                                    [path,name,ext] = fileparts([cleanedFileName]);
            
            
                                    if strcmp(handles.image.fileType,'ANALYZE') || strcmp(handles.image.fileType,'NIFTY_TWOFILES')
                                        [file,path] = uiputfile(['*.hdr'] ,'Save as .hdr AND .img file', name);
                                    end
                                    if strcmp(handles.image.fileType,'NIFTY_ONEFILE')
                                        [file,path] = uiputfile(['*.nii'] ,'Save as .nii file', name);
                                    end
            
                                    if file==0
                                        error('imlook4d/LocalSaveNifti:ERROR')
                                    end
            
                                    destination=[path file];
            
            
                                % Setup struct
                                    nii=handles.image.nii;
                                    nii.img=tempData;
                                    nii.hdr.dime.dim= nii.hdr.dime.dim;
                                    nii.hdr.dime.dim(2)=size(tempData,1);
                                    nii.hdr.dime.dim(3)=size(tempData,2);
                                    nii.hdr.dime.dim(4)=size(tempData,3);
                                    nii.hdr.dime.dim(5)=size(tempData,4);
            
                                    % Pixel size
                                    nii.hdr.dime.pixdim(2:4) = [ handles.image.pixelSizeX, handles.image.pixelSizeY, handles.image.sliceSpacing ];
            
                                    % Make 3D if required
                                    if (nii.hdr.dime.dim(5)==1)
                                        nii.hdr.dime.dim(1)=3;
                                    end
            
                                    % Set output format to float
                                    nii.hdr.dime.datatype=16;
                                    nii.hdr.dime.bitpix=32;
            
                                    % Set output format signed short
                                    if (SaveAsShort)
                                        nii.hdr.dime.datatype=4;
                                        nii.hdr.dime.bitpix=16;
                                    end
            
                                    if strcmp(handles.image.openingMode,'load_nii')
                                        save_nii(nii, destination);
                                    end
            
                                    if strcmp(handles.image.openingMode,'load_untouch_nii')
                                        save_untouch_nii(nii, destination);
                                    end
            
                                    % Save .sif file if time-data exists
                                    try
                                        [folder file extension] = fileparts(destination);
                                        cd(folder); % Make current directory
                                        sifFilePath = [ folder filesep file '.sif'];
                                        write_sif( handles, sifFilePath);
                                    catch
                                    end
        end
        
        function NetworkHostsSubMenu_Callback(app, hObject, eventdata, handles)
                                % Display HELP and get out of callback
                                 if DisplayHelp(app, hObject, eventdata, handles)
                                     return
                                 end
            
                                % Get file name
                                 disp(['Selected Network Hosts submenu item' get(hObject,'Label')] );
                                 pacsDataFileName=get(hObject,'UserData');
            
                                % Save PACS file to use
                                handles.image.PACS.HostFile=pacsDataFileName;
            
            
                                % Checkmarks
                                   hPACSMenuObjects=get( get(hObject,'Parent'), 'Children');  % All other
                                   for i=1:length(hPACSMenuObjects)
                                       set(hPACSMenuObjects(i),'Checked','off')
                                   end
                                   set(hObject,'Checked','on')
            
            
                                   guidata(hObject,handles);
            
            
                                return
        end
        
        function OpenFile_OldCallback(app, hObject, eventdata, handles, varargin)
                                    %
                                    % Option 1: varargin contains no filePath -> file dialog
                                    % Option 2: varargin contains filePath (char array 1*N) -> opens file
            
                                     % Display HELP and get out of callback
                                         if DisplayHelp(app, app, hObject, eventdata, handles)
                                             return
                                         end
            
                                    % General method to automatically open correct image type
                                    dispLine;
            
                                    % Make these state variables defined (in case try-catch error)
                                    SelectedRaw3D = false;
                                    SelectedRaw4D = false;
                                    DicomWithToolbox = false;
            
                                    % Administrative tasks figuring out if fileDialog is asked for by Menu/Open
                                    openWithDialog = isempty(varargin);
                                    if length(varargin) == 1
                                        if strcmp( varargin, 'File dialog')
                                            openWithDialog = true;
                                        end
                                    end
            
                                    % If empty, or file dialog is needed
                                    try
                                        if ( isempty(varargin) || openWithDialog )
                                            historyFile = [ '' prefdir filesep 'imlook4d_file_open_history.mat' ''];
            
                                            % Handle response
                                            if (isempty(varargin) )
            
                                                % Select way to open file
                                                if ( exist(historyFile) == 2 ) % Only if historyFile exists, otherwise assume file dialog
                                                    answer = questdlg('What do you want to open', ...
                                                        'Open options', ...
                                                        'File dialog','Recent File','Cancel','File dialog');
            
                                                    % Handle response
                                                    switch answer
                                                        case 'File dialog'
                                                            % Just continue below
                                                        case 'Recent File'
                                                            openRecent_Callback(handles.figure1, [], handles)
                                                            return
                                                        case 'Cancel'
                                                            return
                                                    end
            
                                                end
                                            end
            
            
                                            try
                                                try
                                                    currentFilePath = [handles.image.folder, filesep,  handles.image.file ];  % Used to pre-select same file.
                                                catch
                                                    currentFilePath = '';  % Gets here if not opening from menu
                                                end
            
                                                % Select file
                                                [file,path,indx] = uigetfile( ...
                                                    {'*',  'All Files'; ...
                                                    '*',  'DICOM imaging toolbox'; ...
                                                    '*.roi',  'ROI files (*.roi)'; ...
                                                    '*.dcm',  'DICOM files (*.dcm)'; ...
                                                    '*.v','ECAT Files (*.v)'; ...
                                                    '*.img;*.hdr','Analyze Files (*.img, *.hdr)'; ...
                                                    '*.nii,*.img;*.hdr','Nifti Files (*.nii, *.img, *.hdr)'; ...
                                                    '*.nii.gz','Nifti Files (*.nii.gz)'; ...
                                                    '*.mhd;*.mha',  'ITK files (*.mhd, *.mha)'; ...
                                                    '*.mgh;*.mgz',  'Freesurfer files (*.mgh, *.mgz)'; ...
                                                    '*.ima*','SHR files (*.ima)'; ...
                                                    'SINO*','GE RAW (3D, sum ToF)'; ...
                                                    'SINO*','GE RAW (4D, w ToF)'; ...
                                                    '*.mat','State files (*.mat)';...
                                                    '*.mat','m4 object (*.mat)'} ...
                                                    ,'Select one file to open', ...
                                                    currentFilePath);
            
                                                DicomWithToolbox = (indx == 2); % 'Force DICOM using Imaging Toolbox'
            
                                                SelectedRaw3D = (indx == 11); % 'GE RAW (3D, sum ToF)'
                                                SelectedRaw4D = (indx == 12); % 'GE RAW (4D, w ToF)'
                                                fullPath=[path file];
                                                cd(path);
                                            catch
                                               %Canceled ?
                                               return
                                            end
                                        else
                                            args = varargin;
                                            fullPath=args{1};
                                            [path,name,ext] = fileparts(fullPath);
                                            path=[path filesep];
                                            file=[name ext];
                                            cd(path);
                                        end
                                            disp(['file=' file]);
                                            disp(['path=' path]);
            
                                        %
                                        % Store history
                                        %
                                            storeOpenedFilePaths(app, app,  fullPath);
            
                                        %
                                        % Determine file type
                                        %
                                            FILETYPE='UNKNOWN';
                                            [pathstr,name,ext] = fileparts(file);
            
                                            % Test if ECAT, MATLAB, SHR, ITK (mhd, mha), RDF
                                                try
                                                    if strcmp(ext,'.v') FILETYPE='ECAT'; end
                                                    if strcmp(ext,'.mhd') FILETYPE='ITK'; end
                                                    if strcmp(ext,'.mha') FILETYPE='ITK'; end
                                                    if strcmp(ext,'.mgh') FILETYPE='MGH'; end
                                                    if strcmp(ext,'.mgz') FILETYPE='MGH'; end
                                                    if strcmp(ext,'.mat') FILETYPE='MATLAB'; end
                                                    if strcmp(ext,'.ima') FILETYPE='SHR'; end
                                                    if strcmp(ext,'.img') FILETYPE='ANALYZE'; end % Assume Analyze format (modify below if Nifti)
                                                    if strcmp(ext,'.hdr') FILETYPE='ANALYZE'; end % Assume Analyze format (modify below if Nifti)
                                                    if strcmp(ext,'.nii') FILETYPE='NIFTI'; end
            
                                                    % If ROI then open linked file and load ROI afterwards
                                                    if strcmp(ext,'.roi')
                                                        %load(file,'parentVolume','-mat'); % Read ROI file to get parentVolume
                                                        try
                                                            load(file,'-mat'); % Read ROI file to get parentVolume
            
                                                            % If absolute path fails (moved parentVolume file)
                                                            % then try to derive relative path
                                                            if ~isfile(parentVolume)
                                                                disp('Looking for relative path for Parent Volume');
                                                                parentVolume = lookForRoiParentFilePath( path, parentVolume)
                                                                disp(['Identified probable parentVolume = ' parentVolume]);
                                                            end
            
            
                                                            % Open image file
                                                            disp([ 'parentVolume = ' parentVolume]);
                                                            OpenFile_Callback(hObject, [], handles, parentVolume);
                                                            disp( 'Done opening parentVolume ');
            
                                                            newHandles = guidata(gcf);
            
                                                            % Load ROI
                                                            disp([ 'Loading ROI = ' fullPath]);
                                                            LoadRoiPushbutton_Callback(gcf, [], newHandles, fullPath);
                                                            disp( 'Done loading ROI ');
            
                                                            % Apply settings stored in ROI file
                                                            set(newHandles.SliceNumSlider,'Value',GuiSettings.slice);
                                                            set(newHandles.SliceNumEdit,'String',num2str(GuiSettings.slice) );
            
                                                            set(newHandles.figure1,'Name',GuiSettings.windowTitle );
            
                                                            set(newHandles.FrameNumSlider,'Value',GuiSettings.frame);
                                                            set(newHandles.FrameNumEdit,'String',num2str(GuiSettings.frame) );
            
                                                            set(newHandles.ROINumberMenu,'Value',GuiSettings.selectedROI);
            
                                                        catch
                                                            dispRed('Failed Open on .roi file -- try using Load ROI instead');
                                                        end
                                                        return
                                                    end
            
                                                    % if .nii.gz   gunzip
                                                    if strcmp(ext,'.gz')
                                                       [pathstr2,name2,ext2] = fileparts(name);
                                                       if strcmp(ext2,'.nii')
                                                           FILETYPE='NIFTI';
                                                           disp([ 'Unzipping gz-file = ' file ]);
                                                           file_in_cell  = gunzip(file); % gunzipped path => file
                                                           file = file_in_cell{1};
                                                       end
                                                    end
            
                                                    % Test if RDF (HDF-format) GE Raw data
                                                    try
                                                        info_sino = h5info( file ,'/SegmentData/Segment2');
                                                        FILETYPE='ModernRDF';
                                                    catch
                                                    end
            
                                                    % Dynamic SHR
                                                    if size(ext,2)>3
                                                        if strcmp(ext(1:4),'.ima') FILETYPE='SHR'; end
                                                    end
                                                catch
                                                end;
            
            
                                            % Test if ANALYZE or NIFTI
                                                if( strcmp(FILETYPE,'ANALYZE') ||strcmp(FILETYPE,'NIFTI') )
                                                    fid = fopen(file, 'r');
                                                    tempHeader= fread(fid, 348,'char');                     % Binary header in memory
                                                    fclose(fid);
            
                                                    %"n+1" means that the image data is stored in the same file as the header information (.nii)
                                                    if strfind( char(tempHeader)', 'n+1')  FILETYPE='NIFTY_ONEFILE';end
            
                                                    %"ni1" means that the image data is stored in the ".img" file corresponding to the header file (starting at file offset 0).
                                                    if strfind( char(tempHeader)', 'ni1')  FILETYPE='NIFTY_TWOFILES';end
            
            
                                                    % Assume Single-file NIFTI if did not match NIFTY_ONEFILE or NIFTY_TWOFILES
                                                    if( strcmp(FILETYPE,'NIFTI'))
                                                        FILETYPE='NIFTY_ONEFILE';
                                                        LocalOpenNifti(app, app, hObject, eventdata, handles, file,path,FILETYPE );
                                                    end
            
                                                end
            
                                            % Test if INTERFILE
                                                if( strcmp(FILETYPE,'UNKNOWN'))
                                                    text =  fileread(file);
                                                    if findstr(text(1:200),'INTERFILE')  % In beginning
                                                        FILETYPE='INTERFILE';
                                                    end
                                                end
            
                                            % Test if DICOMDIR
                                                if( strcmp(FILETYPE,'UNKNOWN'))
                                                    if strcmp(name, 'DICOMDIR')  FILETYPE='DICOMDIR';end
                                                end
            
                                            % Test if DICOM
                                                fid = fopen( fullPath, 'r', 'l');
                                                tempHeader= fread(fid, 132);                     % Binary header in memory
                                                fclose(fid);
                                                if strcmp(char(tempHeader(129:132))', 'DICM')  FILETYPE='DICOM';end
                                                if (DicomWithToolbox) FILETYPE='DICOM_TOOLBOX';end
            
                                            % Test if HERMES (taken from CD cache)
                                                if( strcmp(FILETYPE,'UNKNOWN'))
                                                    dummy1=1;dummy3='l'; % Assume a stupidly small image
                                                    [Data, headers, fileNames]=Dirty_Read_DICOM(path, dummy1,dummy3, file); % selected file
                                                    if strcmp(char(headers{1}(129:132))', '1000')
                                                        FILETYPE='DICOM';
                                                        disp([ 'Probably a Hermes DICOM-like image, trying to open it.  Bytes that should read DICM=' char(headers{1}(129:132))' ]);
                                                    end
                                                end


                                            % Open if M4
                                            if( strcmp(FILETYPE,'MATLAB'))
                                                try
                                                    m4=load( [path filesep file] ); % Load file
                                                    fn = fieldnames(m4);
                                                    m4_field = fn{1};  % Assume just m4 objects in this struct
                                                    a = getfield(m4,m4_field);
                                                    if isa(a,'Matrix4D')
                                                        FILETYPE='Matrix4D';  % Modify -- because M4 object
            
                                                        % Open real part
                                                        handle=imlook4d( single( real( a.matrix) ));
                                                        handles = guidata(handle);
                                                        handles.image.fileType = 'Matrix4D';
                                                        try handles.image.pixelSizeX = a.voxelSize(1); catch end
                                                        try handles.image.pixelSizeY = a.voxelSize(2); catch end
                                                        try handles.image.sliceSpacing = a.voxelSize(3); catch end
                                                        try handles.image.modality = a.imagingInfo.Modality; catch end
                                                        try handles.image.time = a.timeStamp(1,:); catch end
            
                                                        set(handle,'Name', file);
                                                        guidata(handle, handles);
            
                                                        % Open imaginary part
                                                        if ~isreal(a.matrix)
                                                            % Modify name of Real
                                                            set(handle,'Name', ['REAL ' ' ' get(handles.figure1,'Name') ]);
                                                            guidata(handle, handles);
            
                                                            handle=imlook4d( single( imag( a.matrix) ) );
                                                            handles = guidata(handle);
                                                            handles.image.fileType = 'Matrix4D';
                                                            try handles.image.pixelSizeX = a.voxelSize(1); catch end
                                                            try handles.image.pixelSizeY = a.voxelSize(2); catch end
                                                            try handles.image.sliceSpacing = a.voxelSize(3); catch end
                                                            try handles.image.modality = a.imagingInfo.Modality; catch end
                                                            try handles.image.time = a.timeStamp(1,:); catch end
            
            
                                                            set(handle,'Name', ['IMAGINARY ' ' ' file ]);
            
                                                            guidata(handle, handles);
                                                        end
                                                    end
                                                catch
                                                end
                                            end
            
            
                                            % If UNKNOWN, guess binary format
                                                if( strcmp(FILETYPE,'UNKNOWN'))
                                                    FILETYPE='BINARY';
                                                 end
            
                                                %disp(['OpenFile_Callback:  Filetype=' FILETYPE]);
                                                disp(['Filetype=' FILETYPE]);
                                        %
                                        % Call function to open correct file format
                                        %
                                            if( strcmp(FILETYPE,'ECAT'))    LocalOpenECAT(app, app, hObject, eventdata, handles, file,path);end
                                            if( strcmp(FILETYPE,'MATLAB'))  LocalOpenMat(app, app, hObject, eventdata, handles, file,path);end
                                            if( strcmp(FILETYPE,'SHR'))     LocalOpenSHR(app, app, hObject, eventdata, handles, file,path);end
                                            if( strcmp(FILETYPE,'DICOM'))   LocalOpenDirtyDICOM3(app, app, hObject, eventdata, handles, file,path);end
                                            if( strcmp(FILETYPE,'DICOM_TOOLBOX'))   LocalOpenDirtyDICOM3(app, app, hObject, eventdata, handles, file,path, true);end
                                            if( strcmp(FILETYPE,'DICOMDIR')) LocalOpenDICOMDIR(hObject, eventdata, handles, file,path);end
                                            if( strcmp(FILETYPE,'BINARY'))  LocalOpenBinary(app, app, hObject, eventdata, handles, file,path,'BINARY');end
                                            if( strcmp(FILETYPE,'ITK'))     LocalOpenBinary(app, app, hObject, eventdata, handles, file,path,'ITK' );end
                                            if( strcmp(FILETYPE,'MGH'))     LocalOpenMGH(app, app, hObject, eventdata, handles, file,path );end
                                            if( strcmp(FILETYPE,'INTERFILE'))  LocalOpenBinary(app, app, hObject, eventdata, handles, file,path,'INTERFILE' );end
                                            if( strcmp(FILETYPE,'ModernRDF'))  LocalOpenModernRDF(app, app, hObject, eventdata, handles, file,path, SelectedRaw4D);end
            
                        % Own analyze and nifty reader
                        %                     if( strcmp(FILETYPE,'ANALYZE'))  LocalOpenBinary(hObject, eventdata, handles, file,path,'ANALYZE' );end
                        %                     if( strcmp(FILETYPE,'NIFTY'))  LocalOpenBinary(hObject, eventdata, handles, file,path,'ANALYZE' );end  % NIFTY treated as Analyze
                        %                     if( strcmp(FILETYPE,'NIFTY_ONEFILE'))  LocalOpenBinary(hObject, eventdata, handles, file,path,'NIFTY_ONEFILE' );end  % Single-file NIFTY is special
            
                        % Jonny Seans Analyze and Nifty reader
                                            if( strcmp(FILETYPE,'ANALYZE'))  LocalOpenNifti(app, app, hObject, eventdata, handles, file,path,FILETYPE);end         % ANALYZE
                                            if( strcmp(FILETYPE,'NIFTY_TWOFILES'))  LocalOpenNifti(app, app, hObject, eventdata, handles, file,path,FILETYPE);end  % NIFTY behaving as Analyze (img+hdr files)
                                            if( strcmp(FILETYPE,'NIFTY_ONEFILE'))  LocalOpenNifti(app, app, hObject, eventdata, handles, file,path,FILETYPE );end  % Single-file NIFTY
            
            
                                            if( strcmp(FILETYPE,'UNKNOWN')) end
            
                                            %Remember path
                                            cd(path);
                                            handles.image.folder = path;
                                            handles.image.file = file;
            
                                            newHandle = gcf;
                                            newHandles = guidata(newHandle);
                                            newHandles.image.folder = path;
                                            newHandles.image.file = file;
                                            newHandles.cd.TooltipString = [ 'Go to folder = ' handles.image.folder];
            
                                            % Show/hide extra save menu-item for Nifti
                                            if startsWith(app, app, FILETYPE, 'NIFTY')
                                                newHandles.Save_nifti_short.Visible = 'on';
                                            else
                                                newHandles.Save_nifti_short.Visible = 'off';
                                            end
            
            
                                            guidata(newHandle,newHandles);
            
            
                                    catch % Error
            
                                        try
                                            disp(['Fallback to BINARY - because ' FILETYPE ' failed']);
                                            LocalOpenBinary(app, app, hObject, eventdata, handles, file,path,'BINARY');
                                        catch % Fallback to binary ( for instance when .img is not an Analyze file)
                                             disp('ERROR:  imlook4d/OpenFile_Callback');
                                             disp(lasterr)
                                            %MExeption.last
                                            %errordlg({'Error opening file','', [file],'',['(' path  file ')']},'Error opening file')
                                        end
                                    end
            
                                    try
                                        % Set colorscale
                                        imlook4d_set_colorscale_from_modality(app, app, gcf, {}, guidata(gcf));
                                        imlook4d_set_ROIColor(app, app, gcf, {}, guidata(gcf));
                                        % Print file path
                                        %dispOpenWithImlook4d( [path file] );
                                    catch
                                    end
            
                                    showOpenedFilePaths(app, app)
        end
        
        function ROILevelEdit_CreateFcn(app, hObject, eventdata, handles)
                    if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                        set(hObject,'BackgroundColor','white');
                    end
        end
        

        function SetColorBarUpdateState(app, axis_handle, state)
                        % Stop listeners for update (to save time when doing ROI updates)
                        % Possible states are:
                        %  state = 'on'
                        %  state = 'off'
                        % where 'off' does not update the ColorBar when graphics is
                        % refreshed
            %
            %            try
            %                a=get(gca,'LegendColorbarListeners');
            %                 for i=1:length(a)
            %                     set(a(i),'Enabled',state);
            %                 end
            %             catch
            %                 disp('error')
            %            end
                        try
                            for a=get(axis_handle,'LegendColorbarListeners')
                                set(a,'Enabled',state);
                            end
                        catch
                            %disp('error')
                        end
        end
        
        function SliceNumEdit_CreateFcn(app, hObject, eventdata, handles)
                    % hObject    handle to SliceNumEdit (see GCBO)
                    % eventdata  reserved - to be defined in a future version of MATLAB
                    % handles    empty - handles not created until after all CreateFcns called
                    % Hint: edit controls usually have a white background on Windows.
                    %       See ISPC and COMPUTER.
                    if ispc
                        set(hObject,'BackgroundColor','white');
                    else
                        set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
                    end
        end
        
        function adjustSliderRanges(app, handles)
                   % This function adjusts the slider ranges
                   % when imlook4d is changed.
                   %
                   % For instance:
                   % - import from workspace may change number of frames or slices
                   [r,c,z,frames]=size(handles.image.Cdata);
                   middleSlice = round(z/2);
            
                    % one or multiple slides
                    if z == 1
                        % Make slice slider and edit invisible
                        % disp('Single slice')
                        set(handles.SliceNumSlider,'visible','off','Value', 1);
                        set(handles.SliceNumEdit,'visible','off');
                        set(handles.SliceText,'visible','off');
                    else
                        % disp('Multiple slices')
            
                         % wrong number of slices?
                         if ( get(handles.SliceNumSlider,'Max')~=z)
                            % Change slider range
                            set(handles.SliceNumSlider,'visible','on','Min',1,'Max',z,...
                                'SliderStep',[1.0/double(z-1) 1.0/double(z-1)], 'Value', middleSlice);
                            set(handles.SliceNumEdit,'String',num2str(middleSlice) );
                         end
            
                        % slice number > number of slices ?
                        if ( get(handles.SliceNumSlider,'Value')>z)
                            set(handles.SliceNumSlider,'visible','on','Min',1,'Max',z,...
                                'Value',round(z/2),...
                                'SliderStep',[1.0/double(z-1) 1.0/double(z-1)]);
                            set(handles.SliceNumEdit,'String',num2str(round(z/2)) );
                        end
            
            
                        % Show slice slider and edit box
                        set(handles.SliceNumEdit,'visible','on');
                        set(handles.SliceText,'visible','on');
                    end
            
                    % one or multiple frames
                    if frames == 1
                        % Make frame slider and edit invisible
                        % disp('Single frame')
                        set(handles.FrameNumSlider,'visible','off','Value',1);
                        set(handles.FrameNumEdit,'visible','off');
                        set(handles.FrameText,'visible','off');
            
                        set(handles.PC_low_slider,'visible','off');
                        set(handles.PC_high_slider,'visible','off');
                        set(handles.PC_low_edit,'visible','off');
                        set(handles.PC_high_edit,'visible','off');
                    else
                        % disp('Multiple frames')
            
            
                        % frame number > number of frames ?
                        if ( get(handles.FrameNumSlider,'Value')>frames)
            
                            set(handles.FrameNumSlider,'visible','on','Min',1,'Max',frames,...
                                'SliderStep',[1.0/double(frames-1) 1.0/double(frames-1)]);
            
                            set(handles.FrameNumSlider,'Value', round(frames/2) );
                            set(handles.FrameNumEdit,'String',num2str(round(frames/2)) );
                        end
            
                        set(handles.FrameNumSlider,'visible','on','Min',1,'Max',frames,...
                             'SliderStep',[1.0/double(frames-1) 1.0/double(frames-1)]);
                        set(handles.FrameNumEdit,'visible','on');
                        set(handles.FrameText,'visible','on');
            
                        set(handles.PC_low_slider,'visible','on');
                        set(handles.PC_high_slider,'visible','on');
                        set(handles.PC_high_slider, 'Max',frames);
                        set(handles.PC_high_edit, 'String',num2str(frames));
            
                        % Change number of Principal components if needed
                        %if ( get(handles.PC_high_slider,'Value') > frames)
                        %if ( true )
                        if ( get(handles.PC_high_slider,'Value') > get(handles.PC_high_slider,'Max') )
                            set(handles.PC_low_slider,'Min',1,'Max',frames,...
                                'SliderStep',[1.0/double(frames-1) 1.0/double(frames-1)]);
                            set(handles.PC_high_slider,'Min',1,'Max',frames,...
                                'SliderStep',[1.0/double(frames-1) 1.0/double(frames-1)],...
                                'Value', frames);
                        end
            
            %             set(handles.PC_high_slider,'Min',1,'Max',frames,...
            %                 'SliderStep',[1.0/double(frames-1) 1.0/double(frames-1)],...
            %                 'Value', frames);
                        set(handles.PC_high_edit,'visible','on','String',num2str(frames));
            
                        set(handles.PC_low_edit,'visible','off');
                        set(handles.PC_low_slider,'visible','off');
            
                        guidata(handles.figure1, handles)
                    end
        end
        
        function contourRoi(app, axisHandle, binaryMatrix, color)
                                linestyle = '-';
                                linewidth = 2;
                                b = bwboundaries(binaryMatrix);
            
                                hold(axisHandle, 'on')
                                for k=1:numel(b)
                                    h = plot( b{k}(:,2) - 0.5, b{k}(:,1) - 0.5, 'color', color, 'linestyle', linestyle,'Tag','contourROI', 'Parent',axisHandle); % 0.5 to make nodes in middle of pixels
                                    h.LineWidth = linewidth;
                                end
                                hold(axisHandle, 'off')
        end
        
        function convertAllPolysVoiToROI(app, hObject, eventdata, handles)
            
                        lobj = findobj(gcf, 'Type','images.roi.polygon');
                        for i = 1:length(lobj)
                            roi = lobj(i);
                            handles = convertSinglePolyToROI(app, handles, roi);
                            guidata(handles.figure1, handles);
                            updateROIs(app, handles);
                        end
            
                        handles = storeUndoROI(app, handles);
                        guidata(handles.figure1, handles);
        end
        
        function convertPolyVoiToROI(app, hObject, eventdata, handles)
                        roi = hObject.Parent.UserData.polygon;
                        handles = convertSinglePolyToROI(app, handles, roi);
                        handles = storeUndoROI(app, handles);
                        guidata(handles.figure1, handles);
                        updateROIs(app, handles);
        end
        
        function handles = convertSinglePolyToROI(app, handles, roi)
                            sz = size(handles.image.ROI);
            
                            slice = roi.ContextMenu.UserData.slice;
                            roi_number = roi.ContextMenu.UserData.roi_number;
            
                            ROI2d = handles.image.ROI(:,:,slice);
            
            
                            % Make 2d matrix of locked pixels
                            lockedMatrix = zeros( size(ROI2d) ,'logical'); % Assume all locked
                            numberOfROIs = length( handles.image.LockedROIs );
                            for i=1:numberOfROIs
                                lockedMatrix( ROI2d == i ) = handles.image.LockedROIs(i); % Pixels = 0 if locked, 1 if not locked
                            end


                            % Populate ROI pixels
                            if get(handles.FlipAndRotateRadioButton,'Value')
                                BW = poly2mask( roi.Position(:,2)', roi.Position(:,1)', sz(1),sz(2) );
                            else
                                BW = poly2mask( roi.Position(:,1)', roi.Position(:,2)', sz(1),sz(2) );
                            end
            
            
                            % Remove pixels that are locked from newROI
                            BW( lockedMatrix) = false;
            
                            roi2D = handles.image.ROI(:,:,slice);
                            roi2D( BW ) =  roi_number;
                            handles.image.ROI(:,:,slice) = roi2D;
            
                            % Delete polygon
                            delete(roi);
        end
        
        function handles = createUndoROI(app, handles, UNDOSIZE)
            % Print current Undo Level and number of pixels in all Undo-ROIs
            %printUndoInfo(handles);
            
                        handles.image.UndoROI.ROI = cell(1,UNDOSIZE);
                        handles.image.UndoROI.ROI{1}.roiSlices = cell(1,size(handles.image.ROI,3));
                        handles.image.UndoROI.ROI{1}.nonzeroSlices = zeros( 1, size(handles.image.ROI,3));  % Vector
            
                        handles.image.UndoROI.roiNames = cell(1,UNDOSIZE);
                        handles.image.UndoROI.roiNames{1} = get(handles.ROINumberMenu,'String'); % Cell array
            
                        handles.image.UndoROI.VisibleROIs = cell(1,UNDOSIZE);
                        handles.image.UndoROI.VisibleROIs{1} = cell(1,UNDOSIZE);
                        handles.image.UndoROI.VisibleROIs{1} = handles.image.VisibleROIs;
            
                        handles.image.UndoROI.LockedROIs = cell(1,UNDOSIZE);
                        handles.image.UndoROI.LockedROIs{1} = cell(1,UNDOSIZE);
                        handles.image.UndoROI.LockedROIs{1} = handles.image.LockedROIs;
        end
        
        function displayDicomListOfTags(app, headers, mode)
            
                                % MACHINE INFO
                                disp('MACHINE INFO');
                                displayDicomTag(app, headers, 1, 'Modality', '0008', '0060', mode);
                                displayDicomTag(app, headers, 1, 'Manufacturer', '0008', '0070', mode);
                                displayDicomTag(app, headers, 1, 'Model', '0008', '1090', mode);
            
                                % OPENING
                                disp('OPENING');
                                displayDicomTag(app, headers, 1, '*Transfer Syntax UID', '0002', '0010', mode);
                                displayDicomTag(app, headers, 1, ' Number of slices', '0054', '0081', mode);
                                displayDicomTag(app, headers, 1, ' Number of images in acq', '0020', '1002', mode);
                                displayDicomTag(app, headers, 1, '*Number of pixels', '0028', '0010', mode);
                                displayDicomTag(app, headers, 1, ' Study time', '0008', '0031', mode);
                                displayDicomTag(app, headers, 1, '*Rescale Slope', '0028', '1053', mode);
                                displayDicomTag(app, headers, 1, '*Rescale Intercept', '0028', '1052', mode);
                                displayDicomTag(app, headers, 1, ' Rescale Type', '0028', '1054', mode);
                                displayDicomTag(app, headers, 1, ' Unit', '0054', '1001', mode);
                                % SORTING
                                disp('SORTING');
                                displayDicomTag(app, headers, 1, '*Frame reference time', '0054', '1300', mode);
                                displayDicomTag(app, headers, 1, '*Slice location', '0020', '1041', mode);
                                displayDicomTag(app, headers, 1, ' Patient position', '0020', '0032', mode);
                                displayDicomTag(app, headers, 1, '*Acquisition time', '0008', '0032', mode);
                                displayDicomTag(app, headers, 1, ' Temporal Position Identifer', '0020', '0100', mode);
                                displayDicomTag(app, headers, 1, ' Image Number', '0020', '0013', mode);
                                % Get time, duration, and halflife
                                disp('TIME & DURATION');
                                displayDicomTag(app, headers, 1, 'Frame duration', '0018', '1242', mode);
                                displayDicomTag(app, headers, 1, 'Radioactive half life', '0018', '1075', mode);
        end
        
        function displayDicomTag(app, headers, number, name, group, element, mode)
                                       % This function displays OK if tag is found, otherwise BAD
                                       % String representation of tag is displayed (the first
                                       % characters)
                                       %
                                       % INPUT
                                       % - headers  cell array of binary headers
                                       % - number   which element in headers to display
                                       % - name     a string to display
                                       % - group    hexadecimal string, 4 characters, giving the tag group
                                       % - element  hexadecimal string, 4 characters, giving the tag element
                                       % - mode     0 or 2
                                       %
                                        NAMELENGTH=35;   % Length of name string to display
                                        STRINGLENGTH=35; % Length of output string
                                        name=[name '                                                        '];                  % fill with spaces
            
                                        try
                                            temp=dirtyDICOMHeaderData(headers, number, group, element,mode);
                                            outputString=[temp.string '                                                        '];   % fill with spaces
                                            disp([ '   [ OK ]' '  (' group ',' element ')   ' name(1:NAMELENGTH) '=' outputString(1:STRINGLENGTH) ]);
                                        catch
                                            disp([ '   [    ]' '  (' group ',' element ')   ' name(1:NAMELENGTH) '=' 'not defined' ]);
                                        end
        end
        
        function [measureLength, pixels, angle_degrees] = displayLineCoordinates(app, contextMenuItem, pos)
            % Create contextual menus for measure :
            
            
                        try
                            % Fails if no imaging toolbox
                            contextMenu = contextMenuItem.Parent;
                            labelHandle = contextMenu.UserData.textHandle;
                            name = labelHandle.String;
                        catch
                            name = 'unnamed';
                        end
            
            
                        handles = guidata(gcf);
                        %disp(mat2str(pos,3));
            
                        dx = pos(2,1) - pos(1,1) ;
                        dy = pos(2,2) - pos(1,2) ;
            
                        pixels  = sqrt( dx^2 + dy^2 ); % length in pixels
            
                        % side in mm
                        try
                            dx_mm = dx * handles.image.pixelSizeX;
                            dy_mm = dy * handles.image.pixelSizeY;
                        catch
                            dx_mm = dx;
                            dy_mm = dy;
                        end
                        measureLength = sqrt( dx_mm^2 + dy_mm^2 ); % length in pixels
            
                        % angle in degrees
                        plotboxAspectRatio = handles.axes1.PlotBoxAspectRatio;
                        ratio = plotboxAspectRatio(1) /plotboxAspectRatio(2);
                        angle_degrees = atan2d(  dy / plotboxAspectRatio(2) ,dx / plotboxAspectRatio(1)   );
                        angle_degrees = atan2d(  dy_mm ,dx_mm   );
            
            
                        msg = [ 'Name = ' name '   Length = ' num2str( measureLength) ' mm (' num2str( pixels) ' pixels long).  Angle = ' num2str(angle_degrees) ' degrees'];
                        displayMessageRow(msg);
        end
        
        function displayMessageRowImageInfo(app, hObject, eventdata, handles)
            %                -------------------------------------------------
            %                Print DICOM info to MATLAB window
            %                -------------------------------------------------
                                numberOfSlices=size(handles.image.Cdata,3);
                                numberOfPixelsX=size(handles.image.Cdata,1);
                                numberOfPixelsY=size(handles.image.Cdata,2); %Y
            
                                slice=round(get(handles.SliceNumSlider,'Value'));
                                frame=round(get(handles.FrameNumSlider,'Value'));
                           try
                                if strcmp(handles.image.fileType,'DICOM')
                                    mode=handles.image.dirtyDICOMMode;
                                    i=slice+numberOfSlices*(frame-1);
                                    sortedHeaders=handles.image.dirtyDICOMHeader;
                                    %versn will be removed new matlab versions:  [pathstr, name, ext, versn] = fileparts(handles.image.dirtyDICOMFileNames{i});
                                    [pathstr, name, ext] = fileparts(handles.image.dirtyDICOMFileNames{i});
            
                                    try
                                        out1=dirtyDICOMHeaderData(sortedHeaders, i, '0008', '0032',mode);
                                        acqTime=out1.string;
                                    catch
                                        acqTime='      ';
                                    end
            
                                    try
            %                             %out2=dirtyDICOMHeaderData(sortedHeaders, i, '0020', '0032',mode);
            %                             %temp=strfind(out2.string,'\'); location=out2.string(temp(2)+1:end);  % Position
            %
            %                             out2=dirtyDICOMHeaderData(sortedHeaders, i, '0020', '1041',mode);
            %                             location=out2.string;
            %
            %                             location=[location '               '];
            %                             location=location(1:7);
            
                                        % NEW
                                        location=num2str( handles.image.sliceLocations(i));
            
            
                                    catch
                                        location='      ';
                                    end
            
                                    try
                                        out3=dirtyDICOMHeaderData(sortedHeaders, i, '0018', '1242',mode);
                                        duration=[num2str(str2num(out3.string)/1000 ) '      ']; duration=duration(1:5);
                                    catch
                                        duration='     ';
                                    end
            
                                    try
                                        out4=dirtyDICOMHeaderData(sortedHeaders, i, '0054', '1300',mode);
                                        time=    [num2str(str2num(out4.string)/1000 ) '      ']; time=time(1:6);
                                    catch
                                        time='      ';
                                    end
            
                                    % Different displays for different Plane Views
                                    % (Axial, Coronal, Sagital)
                                    if strcmp(handles.image.plane, 'Axial')
                                        infoString={[ 'Acq. Time=' acqTime(1:6) ...
                                            '   Frame Time=' time ...
                                            ' (s)   Duration=' duration ...
                                            ' (s)  Slice location =' location     ], ...
                                            ['File=' name ext ]};
                                    else
                                       infoString={[ 'Acq. Time=' acqTime(1:6) ...
                                            '   Frame Time=' time ...
                                            ' (s)   Duration=' duration  ]};
                                    end
            
            
                                    %disp(infoString{1,1});
                                    %set(handles.infoText1, 'String', infoString);
                                    displayMessageRow(infoString);
                                end
                           catch
                           end
            
                           % Empty message row if not DICOM
                           try
                               if ~strcmp(handles.image.fileType,'DICOM')
                                    displayMessageRow('');
                               end
                           catch
                               % fileType variable non-existing
                               displayMessageRow('');
                           end
        end
        
        function drawCursorInYokes2(app, thisHandles)
                         yokes=getappdata( thisHandles.figure1, 'yokes');
            
                         % Bail out checks
            
                          if strcmp(get(thisHandles.markerToggleTool,'State'), 'off')
                              %return
                          end
            
                         % Lock cursor when pressing 'shift'
                         if strcmp( get(gcf,'CurrentModifier'),'shift')
                             return
                         end
            
                        % -------------------------------------------------
                        % drawCursorInYokes(axesPoint, this_imlook4d_instance)
                        % -------------------------------------------------
                         coordinates=get(gca,'currentpoint');
            
                         x=round(coordinates(1,1) + 0.5);
                         y=round(coordinates(1,2) + 0.5);
                         z=round(get(thisHandles.SliceNumSlider,'Value'));
                         current3DPoint = [x,y,z];
                         ingoingCurrentPlane = thisHandles.image.plane;
            
                         %disp( [ 'x, y, z = ('  num2str(x)  ', '  num2str(y)  ', '  num2str(z) ')' ]);
            
            
                            % Loop through yoke'd images
                            for i=1:length(yokes)
                                handles=guidata(yokes(i));
            
                                %tempData=zeros(size(handles.image.CachedImage));  % Read cached image
            
                               % if thisHandles.figure1~=yokes(i)
                                    handles=guidata(yokes(i));     % The i:th other imlook4d-instance
            
                                    % Get point in this plane
                                    outgoing3DPoint = get3DpointInPlane(app, handles, current3DPoint, ingoingCurrentPlane);
            
                                    newX = outgoing3DPoint(1);
                                    newY = outgoing3DPoint(2);
                                    slice = outgoing3DPoint(3);
            
                                    %disp(['x, y ,z =>(' num2str(newX) ', ' num2str(newY) ', ' num2str(z) ')' ]);
            
            
                                    %if strcmp(get(thisHandles.markerToggleTool,'State'), 'on')
            
                                        % Move to new slice in other yokes
                                        % (triggered by moving marker in current imlook4d window)
                                        if thisHandles.figure1~=yokes(i)
                                            imlook4d( 'setSliceWithoutUpdatingYokes', handles, slice, handles);
                                        end
            
                                        % Draw cross marker
                                        tempData = zeros( size(handles.image.Cdata,1), size(handles.image.Cdata,2) );
            
                                        crossIntensity = 1;
                                        scale = 100000;
                                        alpha = 0.9;
            
                                        try
                                            tempData(newX,:)=crossIntensity;
                                        catch
                                        end
            
                                        try
                                            tempData(:,newY)=crossIntensity;
                                        catch
                                        end
            
                                        try
                                            tempData(newX-1 : newX+1,newY-1 : newY+1)=0;
                                        catch
                                        end
            
                                        tempData=orientImage(app, tempData);
            
                                        set(handles.ImgObject4,'Cdata',scale * tempData);
                                        set(handles.ImgObject4,'AlphaData',alpha * tempData);
            
            
                                    %end
                                        updateImage(app, yokes(i), [], guidata(yokes(i)));
            
                            end
        end
        
        function drawROI(app, hObject, eventdata, handles)
            
                            % Bail out if hand (such as in Measure Tape movement)
                            pointerIcon = get(handles.figure1, 'Pointer');
                            if strcmp(pointerIcon,'hand')
                                return;
                            end
            
                            % For instance when dragging measurement-line
                            if strcmp(pointerIcon,'custom')
                                return;
                            end
            
                            contents = get(handles.ROINumberMenu,'String'); % Cell array
                            numberOfROIs=size(contents,1)-1;
            
                            activeROI=get(handles.ROINumberMenu,'Value');
                            if ( handles.image.LockedROIs(activeROI) )
                                return
                            end
            
                            % Get 4D coordinates
            
                            x_old = handles.image.lastMousePosition(1);
                            y_old = handles.image.lastMousePosition(2);
            
                            coordinates=get(gca,'currentpoint');
            
                            x=round(coordinates(1,1) + 0.5);
                            y=round(coordinates(1,2) + 0.5);
            
                            x_new = x;
                            y_new = y;
                            handles.image.lastMousePosition = [x_new y_new];
            
                            slice=round(get(handles.SliceNumSlider,'Value'));
                            frame=round(get(handles.FrameNumSlider,'Value'));
            
                            % Exchange x and y if image in original orientation (not FlipAndRotate)
                            if ~get(handles.FlipAndRotateRadioButton,'Value')
                                xSize=size(handles.image.ROI,2);
                                ySize=size(handles.image.ROI,1);
                                XLim=get(gca,'YLim');
                                YLim=get(gca,'XLim');
                                temp=x_new;   x_new=y_new; y_new=temp;
                                temp=x_old;  x_old=y_old; y_old=temp;
                            else
                                xSize=size(handles.image.ROI,2);
                                ySize=size(handles.image.ROI,1);
                                XLim=get(gca,'XLim');
                                YLim=get(gca,'YLim');
                            end
            
                            ROIslice =  handles.image.ROI( :,:, slice);
            
                            % Draw ROI
                            r0=round( str2num(get(handles.BrushSize,'String')) );
                            r2=r0^2;
                            %center=r+1;
            
            
                            % Hints: contents = get(hObject,'String') returns ROINumberMenu contents as cell array
                            %contents{get(handles.ROINumberMenu,'String')}
                            activeROI=get(handles.ROINumberMenu,'Value');
            
                                % Cache the slice
                                %roiImage = handles.image.ROI( :, :, slice); % Cache ROI for this slice
            
            
                                % step along line
                                dX = ( x_new - x_old );
                                dY = ( y_new - y_old );
                                r = sqrt( dX^2 + dY^2 ); % length
                                brushSteps = r / r0;  % Number of brush radii
                                rStep = 0.7 / brushSteps; % Steps
            
                                indeces=find(handles.image.brush>0);  % Use only non-zero brush values
            
                                for ir = 0:rStep:1
                                    % Equal steps along diagonal
                                    ix = round( x_old + 1 + ir * dX);
                                    iy = round( y_old + 1 + ir * dY);
            
                                    % Bounding box
                                    rx=size(handles.image.brush,1);
                                    ry=size(handles.image.brush,2);
            
                                    % New ROI center position
                                    ixx=ix-round(rx/2)+mod(rx,2);
                                    iyy=iy-round(ry/2)+mod(ry,2);
            
                                    % Draw in
                                    if (ixx>XLim(1))&&(ixx<=XLim(2)-rx+1)&&(iyy>YLim(1))&&(iyy<=YLim(2)-ry+1)
                                        subMatrix= ROIslice( ixx:(ixx+rx-1),(iyy):(iyy+ry-1));  % Same matrix size as brush
            
                                        % Make matrix with locked pixels
                                        ROILock = zeros( size(subMatrix) ,'uint8');
                                        for i=1:numberOfROIs
                                            ROILock(subMatrix == i ) =  handles.image.LockedROIs(i) ; % Set to 1 if Locked ROI
                                        end


                                        if get(handles.ROIEraserRadiobutton,'Value')  | strcmp( get(handles.figure1, 'currentmodifier'),'shift')
                                            % In brush AND non-locked pixel
                                            nonLockedROIPixels = find( ROILock==0 & handles.image.brush>0 );
                                            % Set pixels
                                            subMatrix( nonLockedROIPixels ) = 0;
                                        else
                                            % Draw over any pixels in brush
                                            %subMatrix( nonLockedROIPixels ) = activeROI;  % Draw over any pixels in brush
            
            %                                 % Draw over pixels above level
            %                                 level = str2num( get(handles.ROILevelEdit,'String') );
                                             subDataMatrix= handles.image.Cdata( ixx:(ixx+rx-1),(iyy):(iyy+ry-1), slice, frame);
            %
            %                                 % In brush AND non-locked AND above level
            %                                 nonLockedAboveLevelPixels = find( (ROILock==0) & (handles.image.brush>0) & (subDataMatrix >= level) );
            
                                            % Above / below level
                                            levelInterval = get(handles.ROILevelEdit,'String');
                                            if strcmp( '<', levelInterval(1) )
                                                % draw below level
                                                level = str2num( levelInterval(2:end));
                                                nonLockedAboveLevelPixels = find( (ROILock==0) & (handles.image.brush>0) & (subDataMatrix <= level) );
                                            else
                                                if strcmp( '>', levelInterval(1) )
                                                    levelInterval = levelInterval(2:end); % Remove '>'
                                                end
                                                % draw above level
                                                level = str2num( levelInterval);
                                                nonLockedAboveLevelPixels = find( (ROILock==0) & (handles.image.brush>0) & (subDataMatrix >= level) );
                                            end


                                            % Set pixels
                                            subMatrix( nonLockedAboveLevelPixels ) = activeROI;  % Draw over any pixels in brush
            
                                            %subMatrix( find( subDataMatrix> level) ) = activeROI;  % Draw over any pixels in brush
            
                                        end
            
                                        ROIslice( (ixx):(ixx+rx-1),(iyy):(iyy+ry-1)) = subMatrix;


                                    end
                                end
                                handles.image.ROI( :,:, slice) = ROIslice;
            
                                % Trim ROI matrix to fit image size
                                xsize=size(handles.image.Cdata,1);
                                ysize=size(handles.image.Cdata,2);
                               %handles.image.ROI=handles.image.ROI(1:xsize,1:ysize,:); % Trim ROI matrix (because indeces higher than image size may have enlarged ROI matrix)
            
            
                               % Save changes to handles
                               guidata(handles.figure1,handles);% Save handles
            
                            %
                            % Update image
                            %
            
                               updateROIs(app, handles);
        end
        
        function [modelOutput, explainedFraction, fullEigenValues] = generateImage(app, handles, outputSliceRange, outputFrameRange)
                        % function generateImage
                        %
                        % Jan Axelsson 081105
                        %
                        % PURPOSE:
                        % --------
                        % GenerateImage is the key function in imlook4d.
                        % The purpose of this function is to generate (but not display) one
                        % of the following:
                        %   a) a PCA-filtered image modified by a modelling function
                        %   b) a residual (difference):
                        %      [modelfunction applied on a non-filtered time series]-[modelfunction applied on a PCA-filtered time series]
                        %   c) a PC-image (model function is not applied)
                        % If a model function is not selected, the modelling is ignored.
                        %
                        %
                        % USEAGE:
                        % -------
                        % The generateImage function is called from one of the following:
                        % 1) updateImage  - GUI update.
                        %     Description:  generateImage gives for instance a single [128,128] image
                        %
                        % 2) generateTACT - TACT calculations.
                        %     Description:
                        %     Alt 1:  if VOI in a single slice, generateImage gives [128,128,1,16] matrix
                        %             (special case, handled in generateTACT)
                        %     Alt 2:  if VOI in more than one slice, generateImage gives [128,128,47,16] matrix
                        %             with zeros in slices that does not have a VOI (same dimension as dynamic data)
                        %
                        %
                        % 3) SaveFile_Callback - Save image
                        %     Description: calculates all slices and frames, generateImage gives [128,128,47,16] matrix
                        %             with all slices calculated
                        %
                        % (Examples above is for a 47 slice 16 time frame dynamic PET scan, with 128*128 pixel images)
                        %
                        %
                        % INPUTS/OUTPUTS
                        % --------------
                        % The GUI gives the following input directly to this function:
                        %    - Radiobuttons: PC-image and Residual radiobuttons
                        %    - Sliders:      PC-sliders defines the PCA-filter
                        %
                        % input arguments:
                        %   - handles
                        %   - outputSliceRange     range or single value
                        %   - outputFrameRange     range or single value
                        %
                        %       ALLOWED combinations:
                        %       outputSliceRange, outputFrameRange are single values;  example: generateImage(handles, 3,17)
                        %       outputSliceRange, outputFrameRange are ranges;         example: generateImage(handles, 3:10 ,1:numberOfFrames)
                        %
                        % output arguments:
                        %   - modelOutput  - 2D image when outputSliceRange, outputFrameRange are single values
                        %                  - 3D or 4D image when outputSliceRange, outputFrameRange are ranges.
                        %                    Model function decides.
                        %
            
                            %
                            % Initialize
                            %
            
                                numberOfFrames=size(handles.image.Cdata,4);
                                numberOfSlices=size(handles.image.Cdata,3);
            
                                PC=outputFrameRange;
            
            
                                inputSliceRange=outputSliceRange;   % Store input slices
            
                                % If single time-series, modify output into slice 1
                                if (size(outputSliceRange(:))==1)
                                    outputSliceRange=1; %Place output into slice 1 of temp
                                end
            
                            % ---------------------------
                            % FLOW CHART
                            % ---------------------------
                            %
                            % FOR GUI UPDATES
                            % ---------------
                            %
                            % When:  size(outputSliceRange(:))=1
                            %
                            % PCA-filter generates 4D image
                            % time-serie => 1-slice time-serie [:,:,1,:] (outputSliceRange is a number)
                            % filterOutput will be in slice 1 of output matrix
                            %
                            % Model
                            % 1-slice time-serie => 2Dimage
                            %
                            % No model
                            % 1-slice time-serie => 2Dimage
                            %
                            %
                            % FOR SAVE OPERATIONS
                            % -------------------
                            %
                            % When:  size(outputSliceRange(:))>1
                            %
                            % PCA-filter generates 4D image
                            % multi-slice time-series => multi-slice time-series  [:,:,:,:](outputSliceRange is a range)
                            %
                            % Model
                            % multi-slice time-series => 4D image, or 3D image
                            %
                            % No model
                            % multi-slice time-series => 4D image
            
                            % -------------------------------------------------
                            % PCA filter both single and multi-slice time-series
                            % -------------------------------------------------
            
                                IsNormalImage = get(handles.ImageRadioButton,'Value');
                                IsPCAFilter = not( (get(handles.PC_low_slider, 'Value')==1) &&  (get(handles.PC_high_slider, 'Value')==numberOfFrames) ); % PCA-filter selected with sliders
                                IsPCImage = get(handles.PCImageRadioButton,'Value');      % PC images radio button selected
            
                                IsModel =  isa(handles.model.functionHandle, 'function_handle');
            
                                IsDynamic = (numberOfFrames>1);
            
                                % Default guess
                                fullEigenValues=1;   % Must be defined so it can be stored.
                                explainedFraction=1; % Must be defined so it can be stored.
            
                                % Shortcut if normal image (not PCA or Residual image)
                                % AND NONE of Model or PCA-filtered
                                if ( IsNormalImage && ~IsModel && ~IsPCAFilter)
                                    modelOutput=handles.image.Cdata(:,:,inputSliceRange,outputFrameRange);
                                    %disp('shortcut calculation - no model no PCA');
                                    return
                                end
            
                                % Define default size for output from PCA-filter
                                %filterOutput=zeros(size(handles.image.Cdata));  % THIS IS TOO BIG FOR MANY CASES


                                % Handles both theses scenarios:
                                % 1) outputSliceRange=1         => filteredOutput [:,:,1,:]
                                % 2) outputSliceRange=range     => filteredOutput [:,:,range,:]
                                filterOutput=zeros( size(handles.image.Cdata,1), size(handles.image.Cdata,2), length(outputSliceRange), numberOfFrames, 'single');
                                filterOutput(:,:,outputSliceRange,:)=handles.image.Cdata(:,:,inputSliceRange,:);
            
            
                                %
                                % Do PCA calculations if needed
                                %
            
            
                                % Test if needs to calculate following (save time otherwise)
                                if ((IsPCAFilter || IsPCImage   ) && IsDynamic  )   % PC image or PCA-filtering   (AND, of course, dynamic scan)
            
                                    % Calculate PCA filter always
                                    %
                                        try
                                            firstFrame=str2num(get(handles.FirstFrame,'String'));
                                            lastFrame=size(handles.image.Cdata,4);
                                        catch
                                           firstFrame=1;
                                        end
            
            %                              [filterOutput(:,:,outputSliceRange,:), explainedFraction, fullEigenValues,fullEigenVectors,PCMatrix]= ...
            %                                  PCAFilter(handles.image.Cdata(:,:,inputSliceRange,:),...
            %                                  round(get(handles.PC_low_slider, 'Value')),...
            %                                  round(get(handles.PC_high_slider, 'Value')));
            
                                        % Build output from
                                        % 1) not filtered first frames
                                        % 2) filtered frames
                                        if (firstFrame>1)
                                            filterOutput(:,:,outputSliceRange,1:firstFrame-1)=handles.image.Cdata(:,:,inputSliceRange,1:firstFrame-1);
                                        end
            
                                        [filterOutput(:,:,outputSliceRange,firstFrame:lastFrame), explainedFraction, fullEigenValues,fullEigenVectors,PCMatrix]= ...
                                            PCAFilter(handles.image.Cdata(:,:,inputSliceRange,firstFrame:lastFrame),...
                                                round(get(handles.PC_low_slider, 'Value')),...
                                                round(get(handles.PC_high_slider, 'Value'))...
                                             );
            
                                        % Set PC components to zero to have something to display when not using all frames.
                                        % (This reflects that all data is explained
                                        % when getting same number of principal
                                        % components as we had frames).
                                        PCMatrix(:,:,:, (size(handles.image.Cdata,4)-firstFrame+1):size(handles.image.Cdata,4) )=0;
            
            
                                    % Test if PC image
                                    if IsPCImage
                                          %filterOutput(:,:,outputSliceRange,PC)=PCMatrix(:,:,outputSliceRange,PC);  % PC Image (not flipped or rotated)
                                          filterOutput(:,:,outputSliceRange,PC)=PCMatrix(:,:,:,PC);  % PC Image (not flipped or rotated)
                                          %filterOutput=PCMatrix;  % PC Image (not flipped or rotated)
                                    end
            
                                end %if
            
            
                            % -------------------------------------------------
                            % If MODEL is selected, apply model on PCA-filtered data
                            % -------------------------------------------------
            
                            % Run if a function handle is defined, BUT not if PC image (should not be modelled)
                            if isa(handles.model.functionHandle, 'function_handle')&& not(IsPCImage)
            
                               %
                               % Single image
                               %
                               if (outputSliceRange==1)
                                   % modelOutput should be a single 2D image
                                   frame=outputFrameRange;            % Some models generate a dynamic time-series.  Select frame to use.
                                                                      % For models generating static images from a time-series, frame is ignored.
                                   input=filterOutput(:,:,1,:);       % Time series created by PCA-filter, used for model
            
                                   % Calculate model output for PCA-filtered data
                                   % [MODEL ON PCA-FILTERED DATA]
                                   modelOutput=handles.model.functionHandle( handles, input, frame); % One image [:,:,1,1]
            
                                    % IF RESIDUAL IS SELECTED:
                                    %
                                    % THE DIFFERENCE
                                    % [MODEL ON ORIGINAL DATA] -  [MODEL ON PCA-FILTERED DATA]
                                    % COULD BE CALCULATED HERE.
                                    %
                                    if get(handles.ResidualRadiobutton,'Value')
            %                             modelOutput=handles.model.functionHandle( handles, handles.image.Cdata(:,:,inputSliceRange,:) , outputFrameRange)...
            %                             - modelOutput;
                                        modelOutput=modelOutput-handles.model.functionHandle( handles, handles.image.Cdata(:,:,inputSliceRange,:) , outputFrameRange);
                                        disp('Residual image, single image, model');
                                    end
                               %
                               % Multiple time series
                               %
                               else
                                   % Initialize
                                    waitBarHandle = waitbar(0,'Calculating model for slices');
                                    modelOutput4D=zeros(size(handles.image.Cdata),'single' );  % Fill 4D matrix
                                    mode='3D';  % Guess
            
            
                                    % LOOP multiple slices and fill 4D matrix
                                    for j=1:length(inputSliceRange(:))
                                        i=inputSliceRange(j);
            
                                        waitbar(i/inputSliceRange(end) );
                                        input=filterOutput(:,:,i,:);   % Time series created by PCA-filter, used for model
            
                                        % Calculate model output for PCA-filtered data
                                        % [MODEL ON PCA-FILTERED DATA]
                                        tempModelOutput=handles.model.functionHandle( handles, input, outputFrameRange);
            
                                        % IF RESIDUAL IS SELECTED:
                                        %
                                        % THE DIFFERENCE
                                        % [MODEL ON ORIGINAL DATA] -  [MODEL ON PCA-FILTERED DATA]
                                        % COULD BE CALCULATED HERE.
                                        %
                                        if get(handles.ResidualRadiobutton,'Value')
                %                            tempModelOutput=handles.model.functionHandle( handles, handles.image.Cdata(:,:,inputSliceRange,:) , outputFrameRange)...
                %                             - tempModelOutput;
            %                                tempModelOutput=handles.model.functionHandle( handles, handles.image.Cdata(:,:,i,:) , outputFrameRange)...
            %                                 - tempModelOutput;
                                           tempModelOutput=tempModelOutput-handles.model.functionHandle( handles, handles.image.Cdata(:,:,i,:) , outputFrameRange);
                                        end
            
                                        % Handle 3D or 4D output depending on model
            
                                        if (size(tempModelOutput,4)>1)
                                            % 3D from model of one slice, gives 4D after looping slices
                                            modelOutput4D(:,:,i,:)=tempModelOutput;
                                            mode=3';
                                        else
                                            % 2D from model of one slice, gives 3D after looping slices
                                            modelOutput4D(:,:,i,1)=tempModelOutput;
                                            mode=2;
                                        end
                                    end % LOOP
                                    close(waitBarHandle);
            
                                    % In case the model creates 3 dimensions after loop (instead of 4)
                                    % the original 4D output should be truncated.
                                    % Otherwise, the output is 4 dimensions.
                                    if (mode==3)
                                        % Model gives all frames
                                        modelOutput=modelOutput4D;
                                    else
                                        % Model gives only one frame
                                        modelOutput=modelOutput4D(:,:,:,1);
                                    end
            
            
                               end %IF (outputSliceRange==1)
            
            
                            % -------------------------------------------------
                            % If NOT MODEL
                            % -------------------------------------------------
                            else
                                if (outputSliceRange==1)
                                    % single slice (outputSlice=1 in initialization above)
                                    modelOutput=filterOutput(:,:,1,outputFrameRange);
            
                                    % If Residual
                                    if get(handles.ResidualRadiobutton,'Value')
            %                             modelOutput=handles.image.Cdata(:,:,inputSliceRange,outputFrameRange) - modelOutput;
                                        modelOutput=modelOutput-handles.image.Cdata(:,:,inputSliceRange,outputFrameRange);
                                    end
                                else
                                    % multiple slices
                                    modelOutput=zeros(size(handles.image.Cdata),'single');
                                    modelOutput(:,:,inputSliceRange,outputFrameRange)=filterOutput(:,:,outputSliceRange,outputFrameRange);
            
                                    % If Residual
                                    if get(handles.ResidualRadiobutton,'Value')
                                        %modelOutput=handles.image.Cdata(:,:,inputSliceRange,outputFrameRange) - modelOutput(:,:,inputSliceRange,outputFrameRange);
                                        %modelOutput=handles.image.Cdata - modelOutput;
                                        modelOutput=modelOutput-handles.image.Cdata;
            
                                    end
                                end
                            end
        end
        
        function outGoing3DPoint = get3DpointInPlane(app, handles, ingoing3DPoint, ingoingCurrentPlane)
                                    % Numerical constants
                            AXIAL=1;
                            CORONAL=2;
                            SAGITAL=3;
            
                        % Recipy for coordinate permutation and back-permutation
                            PERMUTE={[1 2 3 4], [1 3 2 4],  [2 3 1 4] };
                            BACKPERMUTE={[1 2 3 4], [1 3 2 4],  [3 1 2 4] };
            
                        %
                        % Rotate back to Axial (by applying inverted transformation)
                        %
            
                            switch ingoingCurrentPlane
                                case 'Axial'
                                    V = BACKPERMUTE{AXIAL};
                                case 'Coronal'
                                    V = BACKPERMUTE{CORONAL};
                                case 'Sagital'
                                    V = BACKPERMUTE{SAGITAL};
                                otherwise
                            end
            
                            axial3DPoint = [ ingoing3DPoint( V(1) ),  ingoing3DPoint( V(2) ),  ingoing3DPoint( V(3) ) ];
            
            
                            % Get current orientation
                            try
                                CurrentOrientation = handles.image.plane;
                            catch
                                CurrentOrientation = 'Axial';
                            end
            
                            switch CurrentOrientation
                                case 'Axial'
                                    V = PERMUTE{AXIAL};
                                case 'Coronal'
                                    V = PERMUTE{CORONAL};
                                case 'Sagital'
                                    V = PERMUTE{SAGITAL};
                                otherwise
                            end
            
            
                            x = axial3DPoint( V(1) );
                            y = axial3DPoint( V(2) );
                            z = axial3DPoint( V(3) );
            
                            outGoing3DPoint = [x y z];
        end
        
        function color = getColor(app, handles, index)
            % --------------------------------------------------------------------
            % Other functions
            % --------------------------------------------------------------------
            
                       % Different colors
                       colors = get(0,'DefaultAxesColorOrder'); % Matrix with colors in rows 1-7
                       colorIndex = mod(index, size(colors,1))+1;
                       color = colors(colorIndex,:);
            
                       color = handles.roiColors( index, :);
        end
        
        function fig = getParentFigure(app, fig)
                                % if the object is a figure or figure descendent, return the
                                % figure. Otherwise return [].
                                while ~isempty(fig) && ~strcmp('figure', get(fig,'type'))
                                    fig = get(fig,'parent');
                                end
        end
        
        function [windowDescriptions, sortedListOfWindows] = htmlWindowDescriptions(app, listOfWindows)
                    % Create html-formatted list of windows
                    % Input:    handles to all windows
                    % Output:   cell array of html-strings, sorted according to
                    % rules in end of this function
                        %disp('ENTERED htmlWindowDescriptions');


                                        TAB=' . .  ';
            
                        %
                        % Generate list
                        %
            
                        for i=1:size(listOfWindows,1)
                            figureName=get(listOfWindows(i),'Name');
                            windowDescriptions{i}=figureName;
            
                            % Guess
                            table{i,1}=i;          % original index
                            table{i,2}='';
                            table{i,3}='';
                            table{i,4}='';
                            table{i,5}='';
                            table{i,6}=1e8; % This is time when window opened


                            %
                            % Fill in table, and generate html
                            %
                            if not( isempty( guidata(listOfWindows(i) ) ) )
            
                                % Here we have gui applications (i.e. imlook4d)
                                tempHandles=guidata(listOfWindows(i));
            
                                % Window opened time
                                try
                                    table{i,6} = tempHandles.image.windowOpenedTime;
                                catch
                                    table{i,6} = 1e8; % Will be sorted at end (higher value than windowOpenedTime
                                end
            
                                %
                                % Yoke
                                %
                                try
                                    currentYoke=get( getappdata(tempHandles.figure1,'currentYoke'), 'Tag');
                                    if isempty(currentYoke)
                                        Yoke=' ';
                                    else
                                        Yoke=currentYoke;
                                    end
                                    switch Yoke
                                        case 'A'
                                            yokeBackgroundColor='style="background-color: #FF0000;"';
                                        case 'B'
                                            yokeBackgroundColor='style="background-color: #00FF00;"';
                                        case 'C'
                                            yokeBackgroundColor='style="background-color: #FFC000;"';
                                        otherwise
                                            yokeBackgroundColor='style="background-color: #FFFFFF;"';
                                    end
                                catch
                                end
            
                                    try
            
                                        tempDicomHeader=tempHandles.image.dirtyDICOMHeader;
                                        mode=tempHandles.image.dirtyDICOMMode;
            
                                        Modality=tempHandles.image.modality;table{i,2}=Modality;
            
                                        %dirtyDICOMHeaderData(tempDicomHeader, 1, group, element,mode);
                                        %trypatientID=dirtyDICOMHeaderData(tempDicomHeader, 1,'0010', '0020',mode); catch end
                                        try patientName=dirtyDICOMHeaderData(tempDicomHeader, 1,'0010', '0010',mode); table{i,3}=patientName.string; catch end
                                        try studyDesc=dirtyDICOMHeaderData(tempDicomHeader, 1,'0008', '1030',mode); table{i,4}=studyDesc.string; catch end
                                        try seriesDesc=dirtyDICOMHeaderData(tempDicomHeader,1,'0008', '103E',mode); table{i,5}=seriesDesc.string;catch end
            
                                        try StudyDate=dirtyDICOMHeaderData(tempDicomHeader, 1, '0008', '0020',mode);StudyDate=StudyDate.string; catch end
                                        try ScanTime=dirtyDICOMHeaderData(tempDicomHeader, 1, '0008', '0031',mode);ScanTime=ScanTime.string; catch end
                                        DateTime=[StudyDate '@' ScanTime(1:end-2)];
            
            
                                        History=tempHandles.image.history;
                                        table{i,1}=i;          % original index
            
                                      % Colored text in listdlg
                                      % PatientName [Modality] SeriesDesc figureName
                                      % temp=[ '<HTML>' ...
                                      %               '<FONT ' yokeBackgroundColor ' color="blue">' Yoke '</FONT> '...
                                      %               '<FONT color="blue">' patientName.string TAB '</FONT>' ...
                                      %               '<FONT color="gray">[' Modality ']' TAB  '</FONT>'...
                                      %               '<FONT color="black">' seriesDesc.string TAB '</FONT>'...
                                      %               '<FONT color="red">' figureName TAB '</FONT>' ...
                                      %               '</HTML>' ];
                                      temp = [ patientName.string ' [' Modality '] seriesDesc.string ' -- ' ' figureName ];
                                      % Remove null-characters (DICOM can have that)
                                      windowDescriptions{i}=strrep(temp,char(0),'');
            
            
                                    catch
                                    % NOT DICOM
                                    %disp('htmlWindowDescriptions - PROBLEM');
                                       % temp=[ '<HTML>' ...
                                       %              '<FONT ' yokeBackgroundColor ' color="blue">' Yoke '</FONT> '...
                                       %              '<FONT color="black">' figureName TAB '</FONT>' ...
                                       %              '</HTML>' ];
                                       temp = yokeBackgroundColor -- figureName
                                    windowDescriptions{i}=strrep(temp,char(0),'');
                                end
            
                            else
                                    % Here we have non-gui applications (such as normal plot figures)
                                    temp = get(listOfWindows(i),'Tag');
                                    if strcmp(temp, 'TMWWaitbar')
                                       temp = ['Waitbar -- ' listOfWindows(i).Children(1).Title.String ];
                                    end
            
                                    windowDescriptions{i}=strrep(temp,char(0),'');
                            end
                        end
            
                        %
                        % Sort in a nice order
                        %
            
                            % Sort rows
                            %[table, index] = sortrows(table,[3,4,2,5]);
                            [table, index] = sortrows(table,[6,3,4,2,5]); % Sort on window opened time (column 6)
            
                            % Set sorted handles
                            sortedListOfWindows=listOfWindows(index);
                            windowDescriptions=windowDescriptions(index);
        end
        
        function imlook4d_set_ROIColor(app, hObject, eventdata, handles)
            
                        if ( strcmp( get(handles.GuessRoiColor,'Checked'),'on') )
                            if (  strcmp( handles.image.ColormapName, 'Gray') || strcmp( handles.image.ColormapName, 'Gray_inverted')  )
                                handles.image.ROIColor = 'Colored';
                            else
                                handles.image.ROIColor = 'Gray';
                            end
                        end
            
                        if strcmp( get(handles.ColorfulROI,'Checked'),'on')
                            handles.image.ROIColor = 'Colored';
                        end
            
                        if strcmp( get(handles.GrayROI,'Checked'),'on')
                            handles.image.ROIColor = 'Gray';
                        end
            
                        if strcmp( get(handles.MultiColoredROIs,'Checked'),'on')
                            handles.image.ROIColor = 'MultiColoredROIs';
                        end
            
            
                        guidata(handles.figure1, handles);
        end
        
        function imlook4d_set_colorscale_from_modality(app, hObject, eventdata, handles)
                           %
                           % Set Colorscale according to modality
                           %
                           %disp('Entered imlook4d_set_colorscale_from_modality');
                                try
            
                                    switch handles.image.modality
                                        case 'PT'
                                            handles.image.ColormapName = 'Sokolof';
                                            Color_Callback(app, hObject, eventdata, handles, 'Sokolof')
                                        case 'CT'
                                            handles.image.ColormapName = 'Gray';
                                            Color_Callback(app, hObject, eventdata, handles, 'Gray')
                                        case 'MR'
                                            handles.image.ColormapName = 'Gray';
                                            Color_Callback(app, hObject, eventdata, handles, 'Gray')
                                        otherwise
                                            handles.image.ColormapName = 'Gray';
                                            Color_Callback(app, hObject, eventdata, handles, 'Gray')
                                    end %switch
            
                                catch
                                    if isfield(handles.image, 'modality') % Only do if Modality known and
                                        handles.image.ColormapName = 'Gray';
                                        Color_Callback(app, hObject, eventdata, handles, 'Gray')
                                    end
                                end
            
                                guidata(handles.figure1, handles);
        end
        
        function imlook4d_set_defaults(app, hObject, eventdata, handles)
                        imlook4d_set_colorscale_from_modality(app, hObject, eventdata, handles);     % Sets colorscale according to modality (if known)
                        %imlook4d_set_ROIColor(hObject, eventdata, handles);                     % Sets ROI color
            
                        guidata(handles.figure1, handles);
            
                        resizePushButton_ClickedCallback(hObject, eventdata, handles);                      % Resize up one step
                        try
                            if size(handles.image.Cdata,1)<257
                                interpolate2_Callback( handles.interpolate2, [], guidata( handles.interpolate2));   % Sets default to interpolate x2 if less than 257 pixels
                            end
                        catch
                        end
            
                        % Brush size
                        BrushSize_Callback(hObject, eventdata, handles)
        end
        
        function newMatrix2D = interpolate(app, hObject, matrix2D, pixelMultiplicator)
                                    newMatrix2D=matrix2D;
                                    %return
            
                            % -------------------------------------------------
                            % Interpolated image (current image)
                            % -------------------------------------------------
            
                                % The CachedImage is handled as follow:
                                % 1) updateROI (this function):
                                %       It is stored in  with the interpolated
                                %       resolution (higher than original resolution)
                                % 2) updateImage:
                                %       new CachedImage with original resolution is
                                %       created when new frame or slice is created
            
                              % 1) Values if no interpolation
                                  % Set coordinate values for pixels
            %                       numberOfPixels=size(matrix2D,1);
            %                       numberOfPixels2=size(matrix2D,2);
                                  numberOfPixelsX=size(matrix2D,1);
                                  numberOfPixelsY=size(matrix2D,2); %Y
                       %           set(handles.ImgObject, 'XData', [0 numberOfPixels]+0.5,'YData',[0 numberOfPixels2]+0.5);
            
            % %                             numberOfPixels=size(matrix2D,1);
            %                             step=1/pixelMultiplicator;
            %                             start=0.5+step/2;
            %                             stop=numberOfPixelsX+0.5-step/2;
            
            
                                        % New grid
                                        %[x,y]   = meshgrid(1:1:numberOfPixels );        % Old grid
                                        %[xi,yi] = meshgrid(start:step:stop);            % New grid, more steps but same x,y coordinate system
            
                                        %New grid (non-square images)
                                            %set(handles.axes1,'XTickMode', 'auto', 'YTickMode', 'auto');
            %                                 numberOfPixels=size(matrix2D,1);
                                            step=1/pixelMultiplicator;
                                            start=0.5+step/2;
                                            stop=numberOfPixelsX+0.5-step/2;
            
            
                                            numberOfPixels2=size(matrix2D,2);
                                            step2=1/pixelMultiplicator;
                                            start2=0.5+step2/2;
                                            stop2=numberOfPixelsY+0.5-step2/2;
            
                                            [x,y]   = meshgrid(1:1:numberOfPixelsX,1:1:numberOfPixelsY );        % Old grid
                                            [xi,yi] = meshgrid(start:step:stop, start2:step2:stop2);            % New grid, more steps but same x,y coordinate system
            
                                            % Zoom-bug fixed:  Commented out, because it caused zoomed window to reset when changing slice
                                             %set(handles.axes1,'XLim',[1 numberOfPixelsX]);
                                             %set(handles.axes1,'YLim',[1 numberOfPixelsY]);
                                             g=hObject;
                                             set(g.ImgObject, 'XData', [0 numberOfPixelsX]+0.5,'YData',[0 numberOfPixelsY]+0.5);
                                             set(g.ImgObject, 'XData', [0 numberOfPixelsX]+0.5,'YData',[0 numberOfPixelsY]+0.5);
            
            
                                        % Interpolate
                                            %newMatrix2D =interp2(x,y,matrix2D',xi,yi,'bicubic')';
                                           newMatrix2D = interp2(x,y,matrix2D',xi,yi,'bilinear')';
                                            %newMatrix2D = interp2(x,y,matrix2D',xi,yi,'nearest')';
            
                                        % Fix NaNs at border
                                        newMatrix2D(isnan(newMatrix2D))=0;
        end
        
        function flag = isMultipleCall(app)
                          flag = false;
                          % Get the stack
                          s = dbstack();
                          if numel(s)<=2
                            % Stack too short for a multiple call
                            return
                          end
            
                          % How many calls to the calling function are in the stack?
                          names = {s(:).name};
                          TF = strcmp(s(2).name,names);
                          count = sum(TF);
                          if count>1
                            % More than 1
                            flag = true;
                          end
                        disp(['isMultipleCall depth=' num2str(numel(s))]);
        end
        
        function handles = makeSubMenues(app, handles, parentMenuHandle, subMenuFolder)
                    if strcmp( subMenuFolder(end), '.') % Ignore '.' and '..'
                        return
                    end
            
                    % Add to path (really only necessary for USER SCRIPTS, but it is fast.)
                    addpath(subMenuFolder);      % Add folder to path (in case you made a new one)
            
                    % Identify files in folder
                    [filesInDir dirs]=listDirectory(subMenuFolder);
            
                    % Identify files in sort.txt
                    filesListedInFile = [];
                    sortIndexPath = [ subMenuFolder filesep 'sort.txt'];
                    if exist(sortIndexPath, 'file')
                        fileID = fopen(sortIndexPath,'r');
                        formatSpec = '%s';
                        all = textscan(fileID,formatSpec);
                        filesListedInFile = all{1}';
                    end
            
                    % Identify acceleratorKeys; for instance '(D)Duplicate' for accelerator D,
                    % in sort.txt
                    acceleratorKeys = {};
                    for i=1:length(filesListedInFile)
                        rowName = filesListedInFile{i};
                        acceleratorKeys{i} = '';
                        if strcmp( rowName(1), '(' )
                            acceleratorKeys{i} = rowName(2);
                            filesListedInFile{i} = rowName(4:end); % Clean out accelerator from name
                        end
                    end
            
                    % Find which files not listed in sort.txt (or sort.txt is missing)
                    missingFiles = setdiff( filesInDir, filesListedInFile);
                    properMissingFiles = {};
                    properMissingAcceleratorKeys = {};
                    for i=1:length(missingFiles)
                       if strcmp( missingFiles{i}(end-1:end), '.m')
                           properMissingFiles = [ properMissingFiles missingFiles(i) ];
                           properMissingAcceleratorKeys = [ properMissingAcceleratorKeys {''}]; % Add empty accelerator
                       end
                    end
            
                    % Add files missing in sort.txt
                    if length(filesListedInFile)>0
                        menuItemNames = [ filesListedInFile  {'---'} properMissingFiles];
                        acceleratorKeys = [ acceleratorKeys {''} properMissingAcceleratorKeys ];
                    else
                        menuItemNames =  properMissingFiles;
                        acceleratorKeys = properMissingAcceleratorKeys;
                    end
            
            
                    % Make submenues
                    lineOnOff = 'off';
                    for j=1:length(menuItemNames)
                        [pathstr,name,ext] = fileparts(menuItemNames{j});
                        if isempty(name)
                           name = pathstr(1:end-1);  % Fix for older Matlab
                        end
            
                        % Make line separator above next item
                        if startsWith(app,  name, '---')
                            lineOnOff = 'on';  % Store this flag until next row is processed. Set lineOnOff = 'off'; after line set to 'on' on next item
                        end
            
            
                        % Comment-row
                        if startsWith(app,  name, '#')
                            nameWithSpaces= regexprep(name(2:end),'_', ' ');  % Replace '_' with ' '
                            callBack='';
                            label = nameWithSpaces;
                            tag = name;
                            handles.scriptsMenuSubItemHandle(j) = ...
                                uimenu(parentMenuHandle,'Label',label, 'Callback', callBack , 'Tag', tag);
            
                            handles.scriptsMenuSubItemHandle(j).Separator= lineOnOff;
                            handles.scriptsMenuSubItemHandle(j).ForegroundColor = [0    0.4510    0.7412];
                            lineOnOff = 'off';
                        end
            
                        % Make submenu item
                        if strcmp(ext,'.m')
                            nameWithSpaces= regexprep(name,'_', ' ');  % Replace '_' with ' '
            
                            % For SCRIPTS, MODEL,
                            callBack = [ ...
                                'if imlook4d(''DisplayHelp'',gcbo,[],guidata(gcbo));return;end;' ...
                                'assignin(''base'', ''imlook4d_current_handle'', gcf );' ...
                                'eval(''' name ''') ' ];
                            label = nameWithSpaces;
                            tag = nameWithSpaces;
            
            %                % Special for OLD MODEL menu
            %                 if strcmp( get(parentMenuHandle, 'Label'), 'MODELS')
            %                     callBack = [name '_control(gcbo)']; % For Models
            %                 end
            
                            % Special for COLOR menu
                            if strcmp( get(parentMenuHandle, 'Label'), 'Color')
                                callBack=['imlook4d(''Color_Callback'',gcbo,[],guidata(gcbo), ''' name ''' )'];
                                [pathstr2,name2,ext2] = fileparts( which(name));
                                %label = [ '<html> <img width=100 height=15  src="file:///' pathstr2 filesep name2 '.png" ></img><font color="white">--</font>'  nameWithSpaces '</html>'];
                                label = nameWithSpaces
                                tag = nameWithSpaces;
                            end
            
                            % Special for Window Levels menu
                            if strcmp( get(parentMenuHandle, 'Tag'), 'WindowLevelsMenu')
                                % Setup submenu callback
                                %callBack=['imlook4d(''Color_Callback'',gcbo,[],guidata(gcbo), ''' name ''' )'];
                                callBack=[name '( gcbo, [], guidata(gcbo) )'];  % Callback using ui object
                                callBack=[callBack '; ' 'imlook4d(''updateImage'',gcbo,[],guidata(gcbo) ) '];
            
                                %disp(['name=' name '   WINDOW_LEVELS Callback=' callbackString]);% Display paths
                                %handles.image.WindowLevelsSubMenuHandle(i) = uimenu(handles.WindowLevelsMenu, 'Label',nameWithSpaces, 'Callback', callbackString);
                                tag = nameWithSpaces;
                            end
            
            
                            % Advanced callback to allow
                            % - help files for scripts
                            % - set imlook4d_current_handle
                            % - run script
                            handles.scriptsMenuSubItemHandle(j) = ...
                                uimenu(parentMenuHandle,'Label',label, 'Callback', callBack , 'Tag', tag);
            
            
            %                  % Submenu items
            %                  for i=1:length(temp)
            %                     [pathstr,name,ext] = fileparts(temp{i});
            %                     nameWithSpaces= regexprep(name,'_', ' ');  % Replace '_' with ' '
            %
            %                     if strcmp(ext,'.m')
            %                         % Setup submenu callback
            %                        callbackString=['imlook4d(''Color_Callback'',gcbo,[],guidata(gcbo), ''' name ''' )'];
            %
            %                        % html text
            %                        [pathstr2,name2,ext2] = fileparts( which(name));
            %                        % label = [ '<html> <img width=100 height=15  src="file://' pathstr1 filesep 'COLORMAPS' filesep name2 '.png" ></img><font color="white">--</font>'  nameWithSpaces '</html>'];
            %                        label = [ '<html> <img width=100 height=15  src="file:///' pathstr2 filesep name2 '.png" ></img><font color="white">--</font>'  nameWithSpaces '</html>'];
            %
            %                        handles.image.colorSubMenuHandle(i) = uimenu(handles.Cmaps, 'Label',label,'Tag',name, 'Callback', callbackString);
            %                     end
            %                  end
            
            
                            % Add Accelerator key
                            set( handles.scriptsMenuSubItemHandle(j), 'Accelerator', acceleratorKeys{j} );
            
                            handles.scriptsMenuSubItemHandle(j).Separator= lineOnOff;
                            lineOnOff = 'off';
            
            
                            % Missing toolboxes, disable menu
                            [ satisfied, missing ] = requiredToolboxSatisfied( name, 'requiredToolboxesForSCRIPTS');
                            if ~satisfied
                                set( handles.scriptsMenuSubItemHandle(j), 'Enable', 'off' );
                                newLabel = [ label ' (missing ' missing{1} ')'];
                                set( handles.scriptsMenuSubItemHandle(j), 'Label', newLabel );
                                for k = 1 : length(missing)
                                    disp([ missing{k} ' is required for script "' name '"' ]);
                                end
                            end


                        end
            
            
                    end
        end
        
        function measureTapeContextualMenusImageToolbox(app, h, name, slice, orientation)
            
                                addlistener(h,'MovingROI',@(src,evnt) displayLineCoordinates(app, h, h.Position));
            
            
                                % Text label
                                x = h.Position(1,1);
                                y = h.Position(1,2);
                                d = 2;
                                htext = text(x(1)+d,y(1)+d,name,'Color','red','FontSize',11);
            
            
                                %
                                % Add to contextual menu of roi.line
                                %
            
                                contextMenu = h.UIContextMenu;
                                contextMenu.Tag = 'measureLineContextMenu';
            
            
                                % Submenu displaying name of measurement
                                contextMenuItem = uimenu(contextMenu,'Text',name,'Tag','nameContextMenuItem','ForegroundColor', [0   0.4510  0.7412] );
            
                                % Submenu for changing name of measurement
                                contextMenuItem = uimenu(contextMenu,'Text','Rename');
                                contextMenuItem.MenuSelectedFcn = @(hObject,eventdata) imlook4d( 'measureTape_Rename', hObject, eventdata, guidata(hObject));
            
            
                                contextMenuItem = uimenu(contextMenu,'Text','Copy values');
                                contextMenuItem.MenuSelectedFcn = @(hObject,eventdata) imlook4d( 'measureTape_CopyValues', hObject, eventdata, guidata(hObject));
            
                                contextMenuItem = uimenu(contextMenu,'Text','Delete all lines');
                                contextMenuItem.MenuSelectedFcn = @(hObject,eventdata) imlook4d( 'measureTape_Delete_All', hObject, eventdata, guidata(hObject));
                                contextMenuItem.Separator = 'on';
            
            
                                % Modify existing "delete" contextual menu to remove both label + imline
                                % (dirty hack, calling multiple functions --
                                % https://www.mathworks.com/matlabcentral/answers/10664-multiple-callback-functions=
                                deleteSubMenu = findobj(contextMenu,'Text','Delete Line');
                                deleteSubMenu.MenuSelectedFcn =  @(h,e)(cellfun( @(x)feval(x,h,e), {...
                                        @(h,e)delete(contextMenu.UserData.textHandle), ...
                                        @(~,~)delete(contextMenu.UserData.imline) ...
                                     }));
            
            
                                % Store data in struct within contextMenu.UserData
                                % (because Line object cannot store UserData)
                                % (used above when deleting imline object and text label together)
                                data.textHandle = htext;
                                data.imline = h;
                                data.orientation = orientation;
                                data.slice = slice;
                                contextMenu.UserData = data;
            
                                % Move my new submenus to top
                                contextMenu.Children = circshift(contextMenu.Children,1);
            
                                % Display measure
                                lineContextMenuItem = h.UIContextMenu;
                                [ measureLength, pixels, angle_degrees ] = displayLineCoordinates(app, lineContextMenuItem, h.Position);
        end
        
        function measureTapeContextualMenusNoToolbox(app, h, name, slice, orientation)
            
                                % Text label
                                d = 2;
            
                                x = h.XData';
                                y = h.YData';
                                htext = text(x(1)+d,y(1)+d,name,'Color','red','FontSize',11);
            
                                disp('Imaging toolbox missing -- fallback ');
            
            
                                % Make contextual menu
                                contextMenu = uicontextmenu(gcf);
                                h.UIContextMenu = contextMenu;
            
                                contextMenuItem = uimenu(contextMenu,'Text',name,'Tag','nameContextMenuItem','ForegroundColor', [0   0.4510  0.7412] );
            
            
                                contextMenuItem = uimenu(contextMenu,'Text','Rename');
                                contextMenuItem.MenuSelectedFcn = @(hObject,eventdata) imlook4d( 'measureTape_Rename', hObject, eventdata, guidata(hObject));
            
                                contextMenuItem = uimenu(contextMenu,'Text','Copy values');
                                contextMenuItem.MenuSelectedFcn = @(hObject,eventdata) imlook4d( 'measureTape_CopyValues', hObject, eventdata, guidata(hObject));
            
                                deleteSubMenu = uimenu(contextMenu,'Text','delete');
                                deleteSubMenu.MenuSelectedFcn = 'o=gcbo;delete(o.Parent.UserData.lineHandle)'; % Finds and deletes the line object stored in contextMenu.UserData (= gcbo.Parent.UserData)
                                deleteSubMenu.Separator = 'on';
                                deleteSubMenu.MenuSelectedFcn =  @(h,e)(cellfun( @(x)feval(x,h,e), {...
                                        @(h,e)delete(contextMenu.UserData.textHandle), ...
                                        @(~,~)delete(contextMenu.UserData.lineHandle) ...
                                     }));
            
                                contextMenuItem = uimenu(contextMenu,'Text','delete all lines');
                                contextMenuItem.MenuSelectedFcn = @(hObject,eventdata) imlook4d( 'measureTape_Delete_All', hObject, eventdata, guidata(hObject));
            
                                % Store data in struct within contextMenu.UserData
                                % (because Line object cannot store UserData)
                                data.textHandle = htext;
                                data.lineHandle = h;
                                data.orientation = orientation;
                                data.slice = slice;
                                contextMenu.UserData = data;
            
                                pos(:,1) = x;
                                pos(:,2) = y;
                                displayLineCoordinates(app,  h, pos);
        end
        
        function measureTape_Delete_All(app, contextMenuItem, eventdata, handles)
            
                        % Verify deletion
                        answer = questdlg('Really want to delete all measurements ?','Verify deletion','Yes','No', 'No');
                        if strcmp( answer, 'No')
                            return;
                        end
            
                        % With imaging toolbox
                        lobj = findobj(gcf, 'Type','images.roi.line');
                        for i = 1 : length(lobj)
                           delete( lobj(i).UIContextMenu.UserData.textHandle );
                           delete( lobj(i).UIContextMenu.UserData.imline);
                        end
            
                        % Without imaging toolbox
                        lobj2 = findobj(gcf, 'Type','Line','Tag','imlook4d_measure');
                        for i = 1 : length(lobj2)
                           delete( lobj2(i).UIContextMenu.UserData.textHandle );
                           delete( lobj2(i).UIContextMenu.UserData.lineHandle);
                        end
        end
        
        function measureTape_Rename(app, renameContextMenuItem, eventdata, handles)
                        % Edits the measurement's name
            
                        contextMenu = renameContextMenuItem.Parent;
                        labelHandle = contextMenu.UserData.textHandle;
            
                        % Dialog new name
                        defaultAnswer = {labelHandle.String};
                        answer = inputdlg('Name','Edit name',1,defaultAnswer);
            
                        % Text label
                        labelHandle.String = answer{1};
            
                        % Name in contextual menu
                        nameContextMenuItem = contextMenu.Children(end);
                        nameContextMenuItem.Text = labelHandle.String;
        end
        
        function mainHeader = modifyECATHeader(app, mainHeader)
            
            
                         % Modify ECAT main header
            
            
                            prompt={'Patient ID: (16 chars)',...
                                    'Patient name: (32 chars)',...
                                    'Study Type: (12 chars)',...
                                    'Study Description (32 chars):',...
                                    'User Process Code (10 chars):'};
                            title='ECAT main header scan info';
                            numlines=1;
                            defaultanswer={char( mainHeader(166+1:166+16))',...
                                    char( mainHeader(182+1:182+32))',...
                                    char( mainHeader(154+1:154+12))',...
                                    char( mainHeader(296+1:296+32))',...
                                    char( mainHeader(434+1:434+10))'};
                            answer=inputdlg(prompt,title,numlines,defaultanswer);
            
                            % Display
            
                            byte=166;length=16; disp([ num2str(mainHeader(byte+1:byte+length)) mainHeader(byte+1:byte+length)]' );disp('-----');
                            byte=182;length=32; disp([ num2str(mainHeader(byte+1:byte+length)) mainHeader(byte+1:byte+length)]' );disp('-----');
                            byte=154;length=12; disp([ num2str(mainHeader(byte+1:byte+length)) mainHeader(byte+1:byte+length)]' );disp('-----');
                            byte=296;length=32; disp([ num2str(mainHeader(byte+1:byte+length)) mainHeader(byte+1:byte+length)]' );disp('-----');
                            byte=434;length=10; disp([ num2str(mainHeader(byte+1:byte+length)) mainHeader(byte+1:byte+length)]' );disp('-----');
            
                            % Convert
                            oldString=answer{1};
                            byte=166;length=16; newString=[ oldString repmat(char(0),1,length)];newString=newString( 1:length);
                            mainHeader(byte+1:byte+length)=newString;
            
                            oldString=answer{2};
                            byte=182;length=32; newString=[ oldString repmat(char(0),1,length)];newString=newString( 1:length);
                            mainHeader(byte+1:byte+length)=newString;
            
                            oldString=answer{3};
                            byte=154;length=12; newString=[ oldString repmat(char(0),1,length)];newString=newString( 1:length);
                            mainHeader(byte+1:byte+length)=newString;
            
                            oldString=answer{4};
                            byte=296;length=32; newString=[ oldString repmat(char(0),1,length)];newString=newString( 1:length);
                            mainHeader(byte+1:byte+length)=newString;
            
                            oldString=answer{5};
                            byte=434;length=10; newString=[ oldString repmat(char(0),1,length)];newString=newString( 1:length);
                            mainHeader(byte+1:byte+length)=newString;
        end
        

        function myhistogram(app, handles, htype)
                        %img = getimage(gcbf);
                        slice=round(get(handles.SliceNumSlider,'Value'));
                        frame=round(get(handles.FrameNumSlider,'Value'));
                        img=handles.image.Cdata(:,:,slice,frame);
            
                        ROINumberMenu=get(handles.ROINumberMenu);
                        currentROI=ROINumberMenu.Value;
            
                    % NEW plot displayed and complete histograms
            
                        figure;
            
                        % Define histogram within displayed range
                        limits=get(handles.ColorBar,'Ylim');
            
                        minvalue=limits(1);
                        maxvalue=limits(2);
                        step=(maxvalue-minvalue)/30;
            
                        % Get approximate step length (from whole range)
                        NMAX=100;           % Max number of steps
                        SAFENUMBER=200;     % Break out if more than 200 bins (takes forever)
                        stepLength=(max(img(:))-min(img(:)) )/NMAX;
            
                        % Calculate exact step length (fit exactly within display range)
                        NDisplayRange=round((maxvalue-minvalue)/stepLength)+1;  % Can afford one extra histogram bin
                        stepLength=(maxvalue-minvalue)/NDisplayRange;
            
                        % Create values for histogram BELOW displayed range
                        %(going down from minvalue)
                        edges1=minvalue;
                        while (edges1(1)>=min(img(:)) )&& length(edges1)<=SAFENUMBER
                            edges1=[ (edges1(1)-stepLength) edges1];
                        end
                        [freq1,value1]=histc(img(:),edges1);
            
                        % Create edge values for histogram WITHIN displayed range
                        % between minvalue and maxvalue (=displayed range)
                        i=1;
                        edges2(i)=minvalue;
                        while (edges2(i)<=maxvalue)&& length(edges2)<=SAFENUMBER
                            i=i+1;
                            edges2(i)=edges2(i-1)+stepLength;
                        end
                        [freq2,value2]=histc(img(:),edges2);
            
                        % Create values for histogram ABOVE displayed range
                        edges3=maxvalue;
                        while (edges3(end)<=max(img(:)) )&& length(edges3)<=SAFENUMBER
                            edges3=[ edges3 (edges3(end)+stepLength) ];
                            length(edges3)
                        end
                        [freq3,value3]=histc(img(:),edges3);
            
                        % Create values for histogram in current ROI
                        edges4 = unique( [edges1(1:end-1) edges2(1:end-1) edges3] );
                        indeces4=find( handles.image.ROI(:,:,slice) == currentROI  );
                        [freq4,value4]=histc( img(indeces4), edges4);
            
            
                        % Create statistics
                        img=img(:);
                        indeces2=find( (img>=minvalue & img<=maxvalue)  );
                        indeces3=find( (img>=minvalue));
            
                        myAnnotation={...
                            '\bfPixels WITHIN displayed range:\rm', ...
                        	[num2str( (mean(img(indeces2))) )...
                            ' +/- ' num2str( (std( img(indeces2) ) )) ...
                        	'  (median=' num2str( (median(img(indeces2))) ) ')'] , ...
                            ...
                            '\bfPixels WITHIN and ABOVE displayed range :\rm', ...
                        	[num2str( (mean(img(indeces3))) )...
                            ' +/- ' num2str( (std( img(indeces3) ) )) ...
                        	'  (median=' num2str( (median(img(indeces3))) ) ')']  , ...
                            ...
                            '\bfAll pixels IN IMAGE :\rm', ...
                        	[num2str( (mean(img(:))) )...
                            ' +/- ' num2str( (std( img(:) ) )) ...
                        	'  (median=' num2str( (median(img(:))) ) ')'], ...
                            ...
                            '\bfPixels IN ROI :\rm', ...
                        	[num2str( (mean(img(indeces4))) )...
                            ' +/- ' num2str( (std( img(indeces4) ) )) ...
                        	'  (median=' num2str( (median(img(indeces4))) ) ')'] ...
                            };
            
            
                        % Plot histograms
                        % (stepLength/2 lets histogram bar-width accurately show range)
                        bar( edges1+stepLength/2,freq1,'w');
                        hold on
                        bar(edges2+stepLength/2,freq2, 'r');
                        hold on
                        bar(edges3+stepLength/2,freq3, 'b');
            
                        bar(edges4+stepLength/2,freq4, 'g');
            
                        legend('pixels below displayed range', 'pixels within displayed range', 'pixels above display range', 'pixels in ROI');
                        xlabel('pixel value');
                        ylabel('frequency');
            
                        annotation('textbox', [0.3,0.6,0.1,0.1],'FontSize', 9, 'String', myAnnotation);
            
                        hold off
            
                        % Draw lines indicating ranges
                        %YPOS=max([freq1; freq2; freq3])/10; % Position 1/10th of height
                        %YPOS=[YPOS YPOS];
                        YPOS1=max(freq1); YPOS1=[YPOS1 YPOS1];
                        YPOS2=max(freq2); YPOS2=[YPOS2 YPOS2];
                        YPOS3=max(freq3); YPOS3=[YPOS3 YPOS3];
                        line('XData', [min(img) minvalue],'YData', YPOS1,'Color','k')
                        line('XData', [minvalue maxvalue ],'YData', YPOS2,'Color','r')
                        line('XData', [maxvalue max(img)],'YData', YPOS3,'Color','b')
            
            
                        % Make editable
                        plotedit(gcf,'on')
                        return
        end
        
        function newScriptDuplicateWindow(app)
                        EOL=sprintf('\r\n');
            
                        % Put text in new editor
                        text = fileread('UserScript1.m');
                        a = com.mathworks.mde.editor.MatlabEditorApplication.getInstance();
                        editor = a.newEditor( text );
        end
        
        function newScriptSameWindow(app)
                        EOL=sprintf('\r\n');
            
                        % Put text in new editor
                        text = fileread('UserScript2.m');
                        a = com.mathworks.mde.editor.MatlabEditorApplication.getInstance();
                        editor = a.newEditor( text );
        end
        
        function imgOut = orientImage(app, img)
                            imgOut=imlook4d_fliplr(rot90(img,3));
        end
        
        function handles = permuteUndoROI(app, handles, permutation)
                    UNDOSIZE = length(handles.image.UndoROI.ROI);
                    for j=1:UNDOSIZE
                        if ~isempty(handles.image.UndoROI.ROI{j}) % can't rotate empty cells!
            
                            % Unpack undoROI
                            roi = zeros( size( handles.image.ROI )  ,'uint8');
                            % Loop nonzero slices
            
                            for i = 1:size(handles.image.ROI,3)
                                try
                                if handles.image.UndoROI.ROI{j}.nonzeroSlices(i) == 1;
                                    roi(:,:,i) = handles.image.UndoROI.ROI{j}.roiSlices{i};
                                end
                                catch
                                    disp('Catch error in permutateUndoROI');
                                end
                            end
            
            
                            % Rotate
                            roi = permute( roi, permutation); % UndoROI
            
                            % Pack back undo ROI
                            slicesWithRois = sum( sum(roi,1) >0 , 2); % New slices for this orientation
                            handles.image.UndoROI.ROI{j}.roiSlices = cell( 1, length(slicesWithRois));
                            handles.image.UndoROI.ROI{j}.nonzeroSlices = zeros( 1, length(slicesWithRois));
                            for i = 1: size(roi,3)
                                if slicesWithRois(i)
                                    handles.image.UndoROI.ROI{j}.roiSlices{i} = roi(:,:,i);
                                    handles.image.UndoROI.ROI{j}.nonzeroSlices(i) = 1;  % Vector
                                end
                            end
                        end
                    end
        end
        
        function hObject = pressedToggleButton(app, hObject)
            % Shading of Pressed Toolbar Buttons
            
            
                       if ( strcmp(hObject.Type , 'uitoggletool') )
                           hObject.State = 'on';
                       end
        end
        
        function printUndoInfo(app, handles)
                        undoMax = length(handles.image.UndoROI.ROI);
                        N=length(handles.image.UndoROI.ROI{1}.nonzeroSlices);
            
                        position = handles.image.UndoROI.position;
            
                        % Print current Undo Level and number of pixels in all Undo-ROIs
                        try
                            R = [];
                            try
                                for i=1:undoMax
                                    S = 0; % Sum
                                    for j=1:N
                                        if handles.image.UndoROI.ROI{i}.nonzeroSlices(j)
                                            S = S + sum( handles.image.UndoROI.ROI{i}.roiSlices{j}(:) > 0 );
                                        end
                                    end
            
                                    if position ~= i
                                        R = [ R ' ' num2str(S) ' ']; % Store sum for each Undo Level
                                    else
                                        R = [ R '[' num2str(S) ']']; % Store sum for each Undo Level
                                    end
                                end
                            catch
                                % handles.image.UndoROI.ROI == {}
                            end
            
                            %disp([ '(' num2str(handles.image.UndoROI.position) ') ' R]);
                            %disp([ 'ROI: ' num2str( sum(handles.image.ROI(:)>0)) '    Undo: ' R]);
                            disp([ 'ROI: ' nnz( sum(handles.image.ROI(:)>0)) '    Undo: ' R]);
                        catch
                            disp('Error in %printUndoInfo');
                        end
        end
        
        function handles = redoRoi(app, handles)
                    handles = retrieveUndoROI(app, handles, -1);
        end
        
        function hObject = releasedToggleButton(app, hObject)
            % Special shade mac button background
            % if ismac
            %
            %     % First time only
            %     if ( isempty(hObject.UserData)  )
            %         hObject.UserData = hObject.CData; % Remember original icon
            %     end
            %
            %     % Set to original icon
            %     icon = hObject.UserData;
            %
            %     % Determine background from NaN in first dimension (which is
            %     % what Matlab seems to use for built in togglebuttons)
            %     background(:,:,3) = isnan( icon(:,:,1) );
            %     background(:,:,2) = isnan( icon(:,:,1) );
            %     background(:,:,1) = isnan( icon(:,:,1) );
            %
            %     % Make shaded icon
            %     icon( background) = 0.8;
            %     hObject.CData = icon;
            %
            % end
            
            
                       handles = guidata(hObject);
            
                       if ( handles.matlabReleaseYear < 2025 )
                           if ismac
                               hObject.CData = hObject.UserData;  % Set to original icon
                           end
                       end
            
                       if ( strcmp(hObject.Type , 'uitoggletool') )
                           hObject.State = 'off';
                       end
        end
        
        function handles = removeSingleRoi(app, handles, ROINumber, keepLockedROI)
                        ROINumberMenu=get(handles.ROINumberMenu);
                        contents = ROINumberMenu.String; % Cell array
            
                        if ( ( handles.image.LockedROIs(ROINumber) == 1 ) && keepLockedROI )
                            disp(['LOCKED ROI - not allowed to remove ROI = ' contents{ROINumber}] );
                        else
            
                            % Delete ROI pixels, and Shift down ROIs in ROI-matrix
                            handles.image.ROI( handles.image.ROI == ROINumber ) = 0; % Delete ROI pixels
                            handles.image.ROI( handles.image.ROI > ROINumber ) = handles.image.ROI( handles.image.ROI > ROINumber ) -1;
            
                            % Shift down ROI names
                            contents = {contents{1:ROINumber-1} , contents{ROINumber+1:end}};
                            set(handles.ROINumberMenu,'String', contents)
            
                            % Shift down Visible and Locks
                            handles.image.VisibleROIs = [ handles.image.VisibleROIs(1:ROINumber-1) handles.image.VisibleROIs(ROINumber+1:end)   ];
                            handles.image.LockedROIs = [ handles.image.LockedROIs(1:ROINumber-1) handles.image.LockedROIs(ROINumber+1:end)   ];
            
                            % Shift down ROI colors
                            handles.roiColors =[ handles.roiColors(1:ROINumber-1,:); ...
                                handles.roiColors(ROINumber+1:end,:); ...
                                handles.roiColors(ROINumber,:) ]; % Shift ROI colors, and put current at end of color list
            
                            %
                            % Handle Reference ROIs (stored ROI numbers)
                            %
                            try
                                % Find position in Reference ROIs list
                                indexToRemove = find( handles.model.common.ReferenceROINumbers== ROINumber); % index in list
            
                                % Subtract 1 from ROIs larger than current ROINumber
                                handles.model.common.ReferenceROINumbers( handles.model.common.ReferenceROINumbers > ROINumber ) = ...
                                    handles.model.common.ReferenceROINumbers( handles.model.common.ReferenceROINumbers > ROINumber ) - 1;
            
                                % Remove from Reference ROIs, if in list
                                if ~isempty(indexToRemove)
                                    handles.model.common.ReferenceROINumbers = [ ...
                                        handles.model.common.ReferenceROINumbers( 1:(indexToRemove-1) ), ...
                                        handles.model.common.ReferenceROINumbers( (indexToRemove+1):end ) ...
                                        ];
                                end
                            catch
                                disp('Exception removing reference ROIs (probably because no reference ROI has been set)');
                            end
                        end
        end
        
        function handles = resetOrientation(app, handles)
            %             disp(['resetOrientation  size(ROI)=' num2str(size(handles.image.ROI)) ]);
            %             disp(['resetOrientation  size(Cdata)=' num2str(size(handles.image.Cdata)) ]);
            
            %             % Go back to original orientation
            %             set(handles.orientationMenu,'Value',1);
            %             orientationMenu_Callback(handles.orientationMenu, [], handles)
            %             % Get modified handles
                         %handles = guidata(handles.figure1);
            
                        set(handles.orientationMenu,'Value',1);
                        handles = setOrientation(app, handles, 1);
        end
        
        function handles = resetUndoROI(app, handles)
            % ROI undo
            
                    UNDOSIZE = length(handles.image.UndoROI.ROI);
                    handles = createUndoROI(app,  handles, UNDOSIZE);
        end
        
        function restoreImageRadioButtonGroup(app, handles)
                    set(handles.ImageRadioButton,'Value',0)
                    set(handles.PCImageRadioButton,'Value',0)
                    set(handles.ResidualRadiobutton,'Value',0)
                    set(handles.imageRadioButtonGroupActiveButton,'Value',1)
        end
        
        function handles = retrieveUndoROI(app, handles, steps)
            % Print current Undo Level and number of pixels in all Undo-ROIs
            %printUndoInfo(handles);
            
                  % Call with steps = +1 to undo, steps = -1 to redo
                  %
            
                    % undoMax = length(handles.image.UndoROI.ROI);
                    undoMax = sum(~cellfun('isempty', handles.image.UndoROI.ROI)); % Number of non-empty undo-ROI matrices
                    roiSize = size( handles.image.ROI);
                    position = handles.image.UndoROI.position;
                    position = position + steps; % Point at next position
                    if (position > undoMax)
                        position = undoMax;
                    end
                    if (position < 1)
                        position = 1;
                    end
            
            
                    activeROI = get(handles.ROINumberMenu,'Value');
                    handles.image.ROI = zeros( size(handles.image.ROI),'uint8');
                    try
                        for i = 1:length(handles.image.UndoROI.ROI{position}.nonzeroSlices)
                            if handles.image.UndoROI.ROI{position}.nonzeroSlices(i) == 1;
                                handles.image.ROI(:,:,i) = handles.image.UndoROI.ROI{position}.roiSlices{i};
                            end
                        end
                    catch
                    end
            
                    set(handles.ROINumberMenu,'String', handles.image.UndoROI.roiNames{position} );
            
                    handles.image.VisibleROIs = handles.image.UndoROI.VisibleROIs{position};
                    handles.image.LockedROIs = handles.image.UndoROI.LockedROIs{position};
            
                    handles.image.UndoROI.position = position; % Remember position
            
            
                    guidata(handles.figure1,handles);
        end
        
        function [handles, newOrientation] = rotateOrientation(app, handles, PERMUTE, numericOrientation)
                            AXIAL=1;
                            CORONAL=2;
                            SAGITAL=3;
            
                            UNDOSIZE = length(handles.image.UndoROI.ROI);
            
                            X=1; Y=2; Z=3;
            
                            PERMUTE{numericOrientation};
            
            
                          % Get current pixel dimensions
                            try
                                voxelSize(1)=handles.image.pixelSizeX;
                                voxelSize(2)=handles.image.pixelSizeY;
                                voxelSize(3)=handles.image.sliceSpacing;
                            catch
                                disp('Pixelsizes undefined, setting them to 1');
                                voxelSize(1)=1;
                                voxelSize(2)=1;
                                voxelSize(3)=1;
                            end
            
                        % Guess returned orientation, which is what should be returned
                        newOrientation = 'Axial';
            
                        % Axial
                        if ( numericOrientation == AXIAL )
                            newOrientation = 'Axial';
                            V = PERMUTE{AXIAL};
                            newVoxelSize = [ voxelSize( V(X) ), voxelSize( V(Y) ) , voxelSize( V(Z) ) ];
                        end
            
            
                        % Coronal
                        if ( numericOrientation == CORONAL )
                            % Reorient data and ROI
                            handles = permuteUndoROI(app, handles,PERMUTE{CORONAL});
                            handles.image.Cdata=permute(handles.image.Cdata, PERMUTE{CORONAL});  % Image
                            handles.image.ROI=permute(handles.image.ROI, PERMUTE{CORONAL});      % ROI
                            tic
            
            %                 for i=1:UNDOSIZE
            %                     handles.image.UndoROI.ROI{i}=permute(handles.image.UndoROI.ROI{i}, PERMUTE{CORONAL});% UndoROI
            %                 end
                            toc
                            newOrientation = 'Coronal';
                            V = PERMUTE{CORONAL};
                            newVoxelSize = [ voxelSize( V(X) ), voxelSize( V(Y) ) , voxelSize( V(Z) ) ];
                        end
            
            
                        % Sagital
                        if ( numericOrientation == SAGITAL )
                            % Reorient data and ROI
                            handles = permuteUndoROI(app, handles,PERMUTE{SAGITAL}); % Must be first call, since reads dimensions from original matrices in handles
            
                            handles.image.Cdata=permute(handles.image.Cdata, PERMUTE{SAGITAL});  % Image
                            handles.image.ROI=permute(handles.image.ROI,  PERMUTE{SAGITAL});      % ROI
                            tic
            %                 for i=1:UNDOSIZE
            %                     handles.image.UndoROI.ROI{i}=permute(handles.image.UndoROI.ROI{i}, PERMUTE{SAGITAL});% UndoROI
            %                 end
                            toc
                            newOrientation = 'Sagital';
                            V = PERMUTE{SAGITAL};
                            newVoxelSize = [ voxelSize( V(X) ), voxelSize( V(Y) ) , voxelSize( V(Z) ) ];
                        end
            
                        % Pixelsizes
                            handles.image.pixelSizeX = newVoxelSize(X);
                            handles.image.pixelSizeY = newVoxelSize(Y);
                            handles.image.sliceSpacing = newVoxelSize(Z);
        end
        
        function [matrix, roi] = rotateUsingIsotropic(app, handles, matrix, az, roi)
            
                              % Bail out if zero rotation
                              if az == 0
                                 return
                              end
            
                              % Turn off angle textbox (created by rotate2d_jan.m)
                              hManager = uigetmodemanager(handles.figure1);
                              hManager.CurrentMode.ModeStateData.textBoxText.Visible = 'off';
                              drawnow % Force update of textBoxText to non-visible
            
                              try
                                dx = abs( handles.image.pixelSizeX ); % pixel size in mm
                                dy = abs( handles.image.pixelSizeY );
                              catch
                                 dx = 1;
                                 dy = 1;
                              end
            
                              DX = abs( 0.5 * dx * size(matrix,1) - 0.5 * dx ); % Half image width in mm
                              DY = abs( 0.5 * dy * size(matrix,2) - 0.5 * dy );
            
                              halfSide = max(DX,DY); % Required squared image halfside
            
                              pixels = 2* max( size(matrix,1), size(matrix,2) );
                              step = 2*halfSide/pixels ; % Number of pixels required2
            
            
                              % Define meshes
                              [x,y]   = meshgrid(-DX : dx : DX , -DY : dy : DY);        % Old grid
                              [xi,yi] = meshgrid(-halfSide:step:halfSide, -halfSide:step:halfSide);            % New grid, more steps but same x,y coordinate system
            
            
                              %
                              % Rotate ROI -- Loop slices
                              %
            
                              % Define interpolations
                              method = 'linear';
                              method = 'nearest';
                              F = griddedInterpolant( x',y', zeros( size(matrix(:,:,1,1) )), method, 'none'); % 2D only, no extrapolation
                              G = griddedInterpolant( xi',yi', zeros( length(xi), length(yi) ), method, 'none' ); % 2D only, no extrapolation
            
                              % Rotate ROI (if ROI exists)
                              if ( nnz(roi) > 0  ) % at least one non-zero ROI pixel
                                  for i = 1 : size( roi,3)
                                      if ~mod(i,20)
                                          %disp([ num2str(i) ' of ' num2str( size(matrix,3)) ]);
                                          displayMessageRow([ 'Rotating ROIs slice  ' num2str(i) ' of ' num2str( size(roi,3))  ]);
                                          drawnow limitrate % Force update at max 20 fps
                                      end
                                      ROIslice = roi(:,:,i);
                                      % Save time -- only if ROI pixels in slice
                                      if sum(ROIslice(:)) > 0
                                          F.Values = single( ROIslice );
                                          newMatrix2D =  F(xi',yi') ;  % Make large matrix
            
                                          G.Values  = imrotate( newMatrix2D, az, 'nearest','crop'); % TODO: allow 'loose' if matrix should grow.  Next row does not work then.  Solve how?
                                          roi(:,:,i) = uint8( G(x',y')); %  Back to org size
                                      end
                                  end
                              end
            
                              %
                              % Rotate image -- Loop frames and slices
                              %
            
                              % Define interpolations
                              method = 'linear';
                              F = griddedInterpolant( x',y', zeros( size(matrix(:,:,1,1) )), method, 'none'); % 2D only, no extrapolation
                              G = griddedInterpolant( xi',yi', zeros( length(xi), length(yi) ), method, 'none' ); % 2D only, no extrapolation
            
                              for frame = 1 : size(matrix,4)
                                  for i = 1 : size( matrix,3)
                                      if ~mod(i,20)
                                          %disp([ num2str(i) ' of ' num2str( size(matrix,3)) ]);
                                          displayMessageRow([ 'Rotating frame ' num2str(frame) ' of ' num2str( size(matrix,4)) ' ( slice  ' num2str(i) ' of ' num2str( size(matrix,3)) ')' ]);
                                          drawnow limitrate % Force update at max 20 fps
                                      end
                                      F.Values = matrix(:,:,i,frame);
                                      newMatrix2D = F(xi',yi');  % Make large matrix
            
                                      G.Values  = imrotate( newMatrix2D, az, 'bilinear','crop'); % TODO: allow 'loose' if matrix should grow.  Next row does not work then.  Solve how?
                                      matrix(:,:,i,frame) = G(x',y'); %  Back to org size
                                  end
                              end


                              matrix( isnan(matrix) ) = min(handles.image.Cdata(:)); % Use lowest value in orginal matrix
                              roi( isnan(roi) ) = 0; % Set to non-roi pixel
            
                              displayMessageRow( 'Done!');
                              pause(1)
        end
        
        function save_cellarray(app, cellarr, filename, header, latex)
                        % function save_cellarray(cellarr, filename, header, latex)
                        %
                        % Save a cell array to an ASCII openmenu,
                        % cells separated by an arbitrary delimiter
                        % (default: tab). In Latex-Mode, the delimiter
                        % is ' & ', and the newline is indicated by '\\ \n'.
                        %
                        % cellarr: 'm' times 'n' Cell Array
                        % filename: string, '.txt' is appended if no extension provided
                        %    'filename=[]' prints to stdout.
                        % header: arbitrary string that is written to
                        %    the openmenu in the first line, e.g. 'date';
                        %    can be ommitted or empty.
                        % latex: (boolean)
            
                        % Rev.1.0, 02.11.98 (Armin Gunter)
                        % Rev.1.1, 16.02.99 (A.G.: 'header' can be ommitted or empty)
                        % Rev.1.2, 11.11.99 (A.G.: '.txt' appended only if apparently no
                        %    other extension exists)
                        % Rev.1.3, 12.07.2000 (A.G.: '\r\n' as newline after the header
                        % line;
                        %    optional Latex-Mode)
                        % Rev.1.4, 26.08.2001 (A.G.: 'filename=[]' prints to stdout.)
            
                        if ~exist('latex','var'), latex = 0; end
                        if latex
                           nl = '\\\\\r\n';
                           del = ' & ';
                        else
                           nl = '\r\n';
                           del = '\t';
                        end
            
                        % create new openmenu or write to stdout:
                        if ~isempty(filename)
                           % check for openmenu extension
                           ipt = find(imlook4d_fliplr(filename) == '.'); %#ok<EFIND>
                           if isempty(ipt)
                              filename = [filename '.txt'];
                           end
            
                           fid = fopen(filename, 'W');
                           if fid == -1
                              error(['imlook4d/save_cellarray ERROR1: Problem writing to the file ' filename])
                           end
                        else
                           fid = 1;
                        end
            
                        % write header if existent
                        if exist('header') %#ok<EXIST>
                           if ~isempty(header)
                              success = fprintf(fid, ['%s' nl], header);
                              if ~success
                                 error(['imlook4d/save_cellarray ERROR2:Problem writing to the file ' filename])
                              end
                           end
                        end
                        [m, n] = size(cellarr);
                        for i = 1:m
                           % create new line
                           line = [];
                           for j = 1:n-1
                              line = [line sprintf(['%s' del], num2str(cellarr{i, j})) ]; %#ok<AGROW>
                           end
                           line = [line sprintf('%s', num2str(cellarr{i, n}))]; %#ok<AGROW>
                           success = fprintf(fid, ['%s' nl], line);
                           if ~success
                              error(['imlook4d/save_cellarray ERROR3:Problem writing to the file ' filename])
                           end
                        end
            
                        if fid ~= 1, fclose(fid); end
        end
        
        function scrollSlices(app, hObject, eventdata, handles, direction)
            
                    % Initialize
                        numberOfSlices=size(handles.image.Cdata,3);
                        numberOfFrames=size(handles.image.Cdata,4);
                        currentSlice = get(handles.SliceNumSlider,'Value');
                        currentFrame = get(handles.FrameNumSlider,'Value');
            
                        newSlice=round(currentSlice+direction);
                        newFrame=round(currentFrame+direction);
                        %newSlice=currentSlice+direction
            
                    % Slice
                        if (newSlice>numberOfSlices)
                            newSlice=numberOfSlices;
                        end
            
                        if (newSlice<1)
                            newSlice=1;
                        end
            
                    % Frame
                        if (newFrame>numberOfFrames)
                            newFrame=numberOfFrames;
                        end
            
                        if (newFrame<1)
                            newFrame=1;
                        end
            
                    % Set Slice slider and edit box
                        if ( strcmp( get(handles.figure1, 'currentmodifier'),'control') | strcmp( get(handles.figure1, 'currentmodifier'),'shift'))
                            set(handles.FrameNumEdit,'String',num2str(newFrame));
                            set(handles.FrameNumSlider,'Value',newFrame);
                            updateImage(app, hObject, eventdata, handles);
                        else
                            %%set(handles.SliceNumEdit,'String',num2str(newSlice));
                            %%set(handles.SliceNumSlider,'Value',newSlice);
                            setSlice(app,  handles, newSlice , handles.figure1);
                        end
        end
        
        function setColorBar(app, thisHandles, CLim)
                        %
                        % setColorBar from
                        % inputs
                        %           handles structure containing ColorBar, autoColorScaleRadioButton
                        %           Clim    [low high] limits
                        %
            
            
                        colorBarHandle=thisHandles.ColorBar;                    % Get handle to colorbar
                        set(thisHandles.autoColorScaleRadioButton,'Value', 0);  % Set autoscale to off
            
                        if CLim(1)~=0
                            set(thisHandles.removeNegativesRadioButton,'Value', 0);  % Set autoscale to off
                        end
            
                        set(colorBarHandle,'YLim', CLim);    % Set min and max scale values, for new colorbar
                        set(thisHandles.axes1,'CLim', CLim);
            
                        % Loop through yoke'd images which have same folder and title
                        yokes=getappdata( thisHandles.figure1, 'yokes');
                        try
                            folder = thisHandles.image.folder;
                        catch
                            folder = '';
                            thisHandles.image.folder = '';
                        end
            
                        title = get(thisHandles.figure1, 'Name');
                        for i=1:length(yokes)
                            handles=guidata(yokes(i));
            
                            if strcmp( get(handles.figure1, 'Name'), title) && strcmp( thisHandles.image.folder, folder)
                                colorBarHandle=handles.ColorBar;                    % Get handle to colorbar
                                set(handles.autoColorScaleRadioButton,'Value', 0);  % Set autoscale to off
            
                                if CLim(1)~=0
                                    set(handles.removeNegativesRadioButton,'Value', 0);  % Set autoscale to off
                                end
            
                                set(colorBarHandle,'YLim', CLim);    % Set min and max scale values, for new colorbar
                                set(handles.axes1,'CLim', CLim);
            
                                updateImage(app, handles.figure1, [], handles);
                            end
                        end
        end
        
        function handles = setOrientation(app, handles, newNumericOrientation)
            
                    % Numerical constants
                        AXIAL=1;
                        CORONAL=2;
                        SAGITAL=3;
            
                    % Recipy for coordinate permutation and back-permutation
                        PERMUTE={[1 2 3 4], [1 3 2 4],  [2 3 1 4] };
                        BACKPERMUTE={[1 2 3 4], [1 3 2 4],  [3 1 2 4] };
            
                    %
                    % Rotate back to Axial (by applying inverted transformation as was in image)
                    %
                        % Get current orientation
                        try
                            CurrentOrientation = handles.image.plane;
                        catch
                            CurrentOrientation = 'Axial';
                        end
            
                        % Rotate back from current image orientation to axial
                        switch CurrentOrientation
                            case 'Axial'
                                [handles, newOrientation] = rotateOrientation(app,  handles, BACKPERMUTE, AXIAL);
                            case 'Coronal'
                                [handles, newOrientation] = rotateOrientation(app,  handles, BACKPERMUTE, CORONAL);
                            case 'Sagital'
                                [handles, newOrientation] = rotateOrientation(app,  handles, BACKPERMUTE, SAGITAL);
                            otherwise
                        end
            
                    %
                    % Rotate and flip to selected
                    %
                        %disp(['2) newNumericOrientation = ' num2str(newNumericOrientation)]);
                        [handles, newOrientation] = rotateOrientation(app,  handles, PERMUTE, newNumericOrientation);
            
                        % Set new orientation
                        handles.image.plane = newOrientation;
            
                    % Finish
                         % Set scale in new X and Y direction
                         if handles.FlipAndRotateRadioButton.Value
                            set(handles.axes1, 'XLim', [1 size( handles.image.Cdata,1)])
                            set(handles.axes1, 'YLim', [1 size( handles.image.Cdata,2)])
                         else
                            set(handles.axes1, 'XLim', [1 size( handles.image.Cdata,2)])
                            set(handles.axes1, 'YLim', [1 size( handles.image.Cdata,1)])
                         end
            
                            guidata(handles.figure1, handles);
                         % Set sliders
                            adjustSliderRanges(app, handles);
            
                         % Update image
                            updateROIs(app, handles);
                            updateImage(app, handles.figure1, [], handles);
                            figure1_ResizeFcn(handles.figure1, [], handles, 0)
        end
        
        function setSlice(app, handles, slice, this_imlook4d_instance)
                        % This function is called from all callbacks changing slice
                        % When yokes (coupled imlook4d-instances):
                        %   function setSlicesInYokes is called
                        highestSlice= size(handles.image.Cdata,3);
                        newSlice=round(slice);
                        if ( newSlice>=1 && newSlice<=highestSlice )
                            set(handles.SliceNumEdit,'String',num2str(newSlice));
                            set(handles.SliceNumSlider,'Value',newSlice);
                        end
            
                        % Alternative 1: No markers
                        if strcmp(get(handles.markerToggleTool,'State'), 'off')
                            setSlicesInYokes(app, slice, handles.figure1);
                            %drawCursorInYokes2(handles)
                        end
            
            
                        % Alternative 2: Markers,
                        %   setSlice can be called from
                        %       - slice-slider movement
                        %       - drawCursorInYokes2
                        if strcmp(get(handles.markerToggleTool,'State'), 'on')
            
                            setSlicesInYokes(app, slice, handles.figure1);
                            drawCursorInYokes2(app, handles)
                        end
            
                        %
                        % Turn on or off measurement lines
                        %
            
                            % With imaging toolbox
                            lobj = findobj(gcf, 'Type','images.roi.line');
                            for i = 1 : length(lobj)
                                if ( strcmp(handles.image.plane, lobj(i).UIContextMenu.UserData.orientation) ) && ...
                                        ( lobj(i).UIContextMenu.UserData.slice == newSlice)
                                    % Only turn on if both same slice AND same orientation
                                    lobj(i).Visible = 'on';
                                    lobj(i).UIContextMenu.UserData.textHandle.Visible = 'on';
                                else
                                    lobj(i).Visible = 'off';
                                    lobj(i).UIContextMenu.UserData.textHandle.Visible = 'off';
                                end
                            end
            
            
                            % Without imaging toolbox
                            lobj2 = findobj(gcf, 'Tag','imlook4d_measure');
                            for i = 1 : length(lobj2)
                                if ( strcmp(handles.image.plane, lobj2(i).UIContextMenu.UserData.orientation) ) && ...
                                        ( lobj2(i).UIContextMenu.UserData.slice == newSlice)
                                    % Only turn on if both same slice AND same orientation
                                    lobj2(i).Visible = 'on';
                                    lobj2(i).UIContextMenu.UserData.textHandle.Visible = 'on';
                                else
                                    lobj2(i).Visible = 'off';
                                    lobj2(i).UIContextMenu.UserData.textHandle.Visible = 'off';
                                end
                            end
            
                        %
                        % Turn on or off poly VOIs (requires imaging toolbox)
                        %
            
                            lobj = findobj(gcf, 'Type','images.roi.polygon');
                            for i = 1 : length(lobj)
                                try % Error if still drawing polygon
                                    if ( lobj(i).UIContextMenu.UserData.slice == newSlice)
                                        lobj(i).Visible = 'on';
                                        lobj(i).UIContextMenu.UserData.textHandle.Visible = 'on';
                                    else
                                        lobj(i).Visible = 'off';
                                        lobj(i).UIContextMenu.UserData.textHandle.Visible = 'off';
                                    end
                                catch
                                end
                            end
        end
        
        function setSliceWithoutUpdatingYokes(app, handles, slice, this_imlook4d_instance)
                        highestSlice= size(handles.image.Cdata,3);
                        newSlice=round(slice);
                        if ( newSlice>=1 && newSlice<=highestSlice )
                            set(handles.SliceNumEdit,'String',num2str(newSlice));
                            set(handles.SliceNumSlider,'Value',newSlice);
                        end
        end
        
        function setSlicesInYokes(app, slice, this_imlook4d_instance)
                        yokes=getappdata( this_imlook4d_instance, 'yokes');
                        this_handles=guidata(this_imlook4d_instance);
                        for i=1:length(yokes)
            
                            if isgraphics(yokes(i)) % Otherwise deleted figure handle
                                handles=guidata(yokes(i));
                                if this_imlook4d_instance~=yokes(i)
                                    if strcmp( handles.image.plane, this_handles.image.plane)
                                        set(handles.SliceNumEdit,'String', num2str(slice));
                                        set(handles.SliceNumSlider,'Value',slice);
                                        imlook4d('updateImage', handles.figure1,{}, handles);
                                        updateImage(app, yokes(i), [], handles);
                                    end
                                end
                            end
            
                        end
        end
        
        function showOpenedFilePaths(app)
            % Show links to open  previous files
            
            
                            % Read old history
                                historyFile = [ '' prefdir filesep 'imlook4d_file_open_history.mat' ''];
                                try
                                    load(historyFile); % struct "history" should be loaded
                                catch
                                    % make empty struct "history"
                                    history = [];
                                    history.time = {};
                                    history.filePath = {};
                                end
            
                            % Show in command window
                                N_ENTRIES = 20;
                                i = length(history.time) - N_ENTRIES + 1;  % Show this many entries
                                if ( i <= 0)
                                    i = 1;
                                end
                                disp('RECENT FILES  (CLICK LINK TO OPEN) :');
                                disp('------------------------------------');
                                while i <= length(history.time)
                                    t = history.time{i};
                                    p = history.filePath{i};
                                    command = [ 'imlook4d(''' p ''')' ];
                                    disp(  [ t ' : <a href="matlab:' command '" >' p  '</a>' ] );
                                    i = i + 1;
                                end
        end
        
        function ok = startsWith(app, s1, s2)
                        % Replaces default startsWidh (allowing imlook4d prior to 2016b
                        % to work)
                        n = min( length(s1), length(s2));
                        ok = strncmpi(s1,s2,n);
        end
        
        function storeOpenedFilePaths(app, fullPath)
            % Remember opened / saved file paths
            
            
                        % Read old history
                        historyFile = [ '' prefdir filesep 'imlook4d_file_open_history.mat' ''];
                        try
                            load(historyFile); % struct "history" should be loaded
                        catch
                            % make empty struct "history"
                            history = [];
                            history.time = {};
                            history.filePath = {};
                        end
            
                        % Append
                        history.time{ end + 1 } = datestr(datetime());
                        history.filePath{ end + 1 } = fullPath;
            
                        % Shorten history to save
                        N = 20;
                        if length(history.filePath) > N
                            history.time = history.time( end-N+1 : end );
                            history.filePath = history.filePath( end-N+1 : end);
                        end
            
                        % Save
                        save(historyFile, 'history', '-v7.3');
        end
        
        function handles = storeUndoROI(app, handles)
                    % Undo positions counted from recent (1) to last (UNDOSIZE)
                    % Current ROI will be stored in position 1
                    tic
                    UNDOSIZE = length(handles.image.UndoROI.ROI);
                    ROI3D = handles.image.ROI;
            
                    % Shift UNDOs back, leave room in position 1
                    try
                        handles.image.UndoROI.position = 1;
                        for i=(UNDOSIZE-1):-1:1
                            handles.image.UndoROI.ROI{i+1} = handles.image.UndoROI.ROI{i};
                            handles.image.UndoROI.roiNames{i+1} = handles.image.UndoROI.roiNames{i};
                            handles.image.UndoROI.VisibleROIs{i+1} = handles.image.UndoROI.VisibleROIs{i};
                            handles.image.UndoROI.LockedROIs{i+1} = handles.image.UndoROI.LockedROIs{i};
                        end
                    catch
                        handles.image.UndoROI.ROI{1} = ROI3D;
                    end
            
                    % Store only slices (efficient for large matrices and smaller ROIs)
                        %activeROI = get(handles.ROINumberMenu,'Value');
                        %slice = round(get(handles.SliceNumSlider,'Value'));
                        slicesWithRois = sum( sum(handles.image.ROI,1) >0 , 2);
                        for i = 1: size(handles.image.ROI,3)
                            if slicesWithRois(i)
                                %disp([ 'found ROI in slice = ' num2str(i) ]);
                                handles.image.UndoROI.ROI{1}.roiSlices{i} = handles.image.ROI(:,:,i);
                                handles.image.UndoROI.ROI{1}.nonzeroSlices(i) = 1;  % Vector
                            else
                                handles.image.UndoROI.ROI{1}.roiSlices{i} = [];
                                handles.image.UndoROI.ROI{1}.nonzeroSlices(i) = 0;  % Vector
                            end
                        end
            
                    % If drawing and position in Undo was different from 1, then
                   % if (handles.image.UndoROI.position ~= 1)
                        handles.image.UndoROI.position = 1; % Always set to 1 when drawing
                        %storeUndoROI(handles);
                 %   end
            
                     handles.image.UndoROI.roiNames{1} = get(handles.ROINumberMenu,'String'); % Cell array
                     handles.image.UndoROI.VisibleROIs{1} = handles.image.VisibleROIs;
                     handles.image.UndoROI.LockedROIs{1} = handles.image.LockedROIs;
        end
        
        function text7_ButtonDownFcn(app, hObject, eventdata, handles)
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             %set(hObject,'State', 'off')
                             return
                         end
        end
        
        function handles = undoRoi(app, handles)
                    handles = retrieveUndoROI(app, handles, +1);
        end
        
        function updateAspectRatio(app, handles, matrix2D)
                            % -------------------------------------------------
                            % Calculate aspect ratio
                            % -------------------------------------------------
                                try
                                     nX=size(matrix2D',1);
                                     nY=size(matrix2D',2);
            
                                     mmX=handles.image.pixelSizeX; % X pixel size (mm)
                                     mmY=handles.image.pixelSizeY; % Y pixel size (mm)
                                     set(handles.axes1,'DataAspectRatio', [ 1  mmY/mmX  1]);
            
                                     if get(handles.FlipAndRotateRadioButton,'Value')
                                         XLim0 = get(handles.axes1,'XLim');
                                         YLim0 = get(handles.axes1,'YLim');
                                         set(handles.axes1,'XLim', YLim0 );
                                         set(handles.axes1,'YLim', XLim0 );
                                     end
            
                                catch
                                    mmX=1;
                                    mmY=1;
                                    set(handles.axes1,'DataAspectRatio', [  mmY mmX  1]);
                                    disp('imlook4d/updateROI:  Error calculating DataAspectRatio');
                                end
        end
        
        function updateImage(app, hObject, eventdata, handles)
            % --------------------------------------------------------------------
            % Image functions
            % --------------------------------------------------------------------
            
                        % ------------------------------------------------------------------------------------------
                        % This routine caches a 2D image which is massaged according to radio buttons.
                        % The image is generated by function generateImage.
                        % The display is modified by this function according to GUI
                        % features such as Intensity scale and Orientation buttons.
                        % Flip-and-rotate radio button does NOT affect cached image (but displayed image is affected)
            
                            SetColorBarUpdateState(app, handles.axes1, 'off');  % Stop updating colorbar (othwerwise slowing down image refresh alot)
            
                             numberOfFrames=size(handles.image.Cdata,4);
                             numberOfSlices=size(handles.image.Cdata,3);
                             Y=size(handles.image.Cdata,2);
                             X=size(handles.image.Cdata,1);
                            try
                                slice=round(get(handles.SliceNumSlider,'Value'));
                                frame=round(get(handles.FrameNumSlider,'Value'));  % Used for both PC or frame
                                PC=frame;
                            catch
                                slice=1;
                                frame=1;  % Used for both PC or frame
                                PC=frame;
                            end
            
                            if frame>numberOfFrames
                                frame=numberOfFrames;
                                %set(handles.ImgObject3,'Cdata',tempData);
                                adjustSliderRanges(app, handles);
                            end
                            if slice>numberOfSlices
                                slice=numberOfSlices;
                            end
            
                            % Generate 2D image according to slice and frame defined in GUI
                            % (REAL pixel coordinates)
                            [tempData, explainedFraction, fullEigenValues]=generateImage(app, handles,slice,frame);
            
                            handles.image.eigenValues = fullEigenValues;
            
                            % -------------------------------------------------
                            % Information texts
                            % -------------------------------------------------
                            try
                                if numberOfFrames > 1
                                    set(handles.text22,'Visible', 'on');
                                    set(handles.FirstFrame,'Visible', 'on');
                                    set(handles.ExplainedFractionText,'Visible', 'on');
                                else
                                    set(handles.text22,'Visible', 'off');
                                    set(handles.FirstFrame,'Visible', 'off');
                                    set(handles.ExplainedFractionText,'Visible', 'off');
                                end
            
                                if get(handles.PCImageRadioButton,'Value')
                                    % PC image
                                    explainedFraction=fullEigenValues(PC)/sum(fullEigenValues);  % Calculate explanation factor for the single PC image
                                    set(handles.ExplainedFractionText,'String', [sprintf('%6.3f',100*explainedFraction) '%']);
                                    set(handles.KaiserText,'String', ['EV:' sprintf('%6.3f', fullEigenValues(PC)) ]);
                                    try set(handles.frameTimeText,'String', ''); catch end
                                    try set(handles.frameTimeMinutesText,'String', ''); catch end
            
                                    % Guess if PC image needs to be inverted, and set invert radio button
                                    if get(handles.PCAutoInvert,'Value')
                                        if (max(tempData(:)) + min(tempData(:)) <0)
                                            set(handles.invertRadiobutton,'Value',1)
                                        else
                                            set(handles.invertRadiobutton,'Value',0)
                                        end
                                    end
            
                                else
                                    % Not PC image
            
                                    % Set time texts
            %                         try
            %                             set(handles.frameTimeMinutesText,'String', [sprintf('%5.1f',handles.image.time(frame)/60) ' (' sprintf('%3.1f',handles.image.duration(frame)/60) ') min']);
            %                         catch
            %                             set(handles.frameTimeMinutesText,'String','unknown');
            %                         end
            %
            %                         try set(handles.frameTimeText,'String', [num2str(handles.image.time(frame)) ' (' num2str(handles.image.duration(frame)) ') s']);
            %                         catch
            %                         end
            
                                    try
                                        timeTextSec = sprintf('%5.1f', handles.image.time(frame)) ;
                                        timeTextMin = sprintf('%5.1f', handles.image.time(frame)/60) ;
                                    catch
                                        timeTextSec = ' ';
                                        timeTextMin = ' ';
                                    end
                                    try
                                        durationTextSec = sprintf('%5.1f', handles.image.duration(frame)) ;
                                        durationTextMin = sprintf('%5.1f', handles.image.duration(frame)/60);
                                    catch
                                        durationTextSec = ' ';
                                        durationTextMin = ' ';
                                    end
                                    try
                                        set(handles.frameTimeText,'String', [ timeTextSec ' (' durationTextSec ') s'] );
                                        set(handles.frameTimeMinutesText,'String', [ timeTextMin ' (' durationTextMin ') min'] );
                                    catch
                                        set(handles.frameTimeText,'String','unknown');
                                        set(handles.frameTimeMinutesText,'String','unknown');
                                    end


                                     % Set matrix size text
                                    try
                                        set(handles.matrixSize,'String', [ '[' num2str(X) ',' num2str(Y) '] pixels' ]);
                                    catch
                                        set(handles.matrixSize,'String','unknown');
                                    end
            
            
                                    % Set PCA info
                                    set(handles.ExplainedFractionText,'String', [sprintf('%6.3f',100*explainedFraction) '%']);
            
                                    if (size(fullEigenValues,1)>1 )
                                        set(handles.KaiserText,'String', ['EV:' sprintf('%6.3f',fullEigenValues(round(get(handles.PC_high_slider, 'Value')))) ]);
                                    else
                                        set(handles.ImgObject,'Cdata',tempData);
                                        set(handles.KaiserText,'String','');
                                    end
                                end
                            catch
                                %disp('imlook4d/updateImage ERROR: Caught error in displaying information texts');
                            end
            
                            % -------------------------------------------------
                            % Manipulate Intensity values (of current image)
                            % -------------------------------------------------
            
                               % Invert image
                               if get(handles.invertRadiobutton,'Value')
                                    tempData=-tempData;
                               end
            
                               % Remove negatives - delete negroiative values
                               if get(handles.removeNegativesRadioButton,'Value')
                                    tempData(tempData<0) = 0;
                               end
            
                            % -------------------------------------------------
                            % Create picture
                            % -------------------------------------------------
            
                                % Update Colorbar (based on intensity color scale from current image )
                                  try
                                      h=handles.ColorBar;                        % Get handle to colorbar
                                      LOWEST = realmin('single');
                                      LOWEST = 1e-23;
                                      if get(handles.autoColorScaleRadioButton,'Value')==1
                                             %CLim=[( min(tempData(:))-realmin('single')) ...
                                             %    (max(tempData(:) )+ LOWEST)]; % Calculated min and max (put minimal values if min=max=0)
            
                                             % Remove INF from range
                                             CLim=[   min( tempData(~isinf(tempData(:) ))) - realmin('single')  ,   ...
                                                  max( tempData(~isinf(tempData(:) ))) + LOWEST  ]; % Calculated min and max (put minimal values if min=max=0)
            
                                             set(h,'YLim', CLim)    % Set min and max scale values, for new colorbar
            
                                            set(handles.axes1,'CLim', CLim);
                                       end
                                  catch
                                      %disp('Error in imlook4d updateImage');
                                  end


                            %
                            % Cache foreground image
                            %
                                     % Rotate and flip
                                    if get(handles.FlipAndRotateRadioButton,'Value')
                                        tempData=orientImage(app, tempData);
                                    else
            
                                    end
            
            
                                    % Store original size
                                        XLim = [ 0 size(tempData,2)];
                                        YLim = [ 0 size(tempData,1)];
            
            
                                    % interpolate
                                     if strcmp( get(handles.interpolate2,'Checked'),'on')
                                          tempData = interpolate(app, handles, tempData ,2);
                                     end
                                     if strcmp( get(handles.interpolate4,'Checked'),'on')
                                          tempData = interpolate(app, handles, tempData ,4);
                                     end
            
            
                                    % Make RGB, and Cache
            
                                    CLimits=get(handles.axes1,'CLim');
                                    minValue=CLimits(1);
                                    maxValue=CLimits(2);
            
                                    colormap=get(handles.figure1,'Colormap');
                                    faktor=(size(colormap,1)+1);
                                    tempDataRGB=ind2rgb(round( faktor*( tempData-minValue) /(maxValue -minValue) ),get(handles.figure1,'Colormap'));
            
                                    handles.image.CachedImage = tempDataRGB;
            
                            %
                            % Cache  background image
                            %
            
                                try
                                    handles.image.CachedImage2 = [];
                                    tempData2 = [];
                                    if ~isempty(handles.image.backgroundImageHandle)
            
                                        h2 = handles.image.backgroundImageHandle;
                                        handles2 = guidata(h2);
                                        frame2 = round(get(handles2.FrameNumSlider,'Value'));
            
                                        [tempData2, explainedFraction, fullEigenValues]=generateImage(app, handles2,slice,frame2); %same size
            
                                        % Rotate and flip
                                        if get(handles.FlipAndRotateRadioButton,'Value')
                                            tempData2=orientImage(app, tempData2);
                                        end
            
                                    % Store original size
            %                         XLim = [ 0 size(tempData2,2)];
            %                         YLim = [ 0 size(tempData2,1)];
            
                                    % interpolate
            
                                      if strcmp( get(handles.interpolate2,'Checked'),'on');
                                          tempData2 = interpolate(app, handles, tempData2 ,2);
                                      end
                                      if strcmp( get(handles.interpolate4,'Checked'),'on');
                                          tempData2 = interpolate(app, handles, tempData2 ,4);
                                      end
            
                                        % Make RGB, and Cache
                                        CLimits2=get(handles2.axes1,'CLim');
                                        minValue2=CLimits2(1);
                                        maxValue2=CLimits2(2);
                                        colorMap2 = get(handles2.figure1,'Colormap');
                                        factor2 = size(colorMap2,1);
                                        tempData2=ind2rgb(  round( factor2 * (tempData2-minValue2) /(maxValue2 -minValue2) ),colorMap2);
            
                                        handles.image.CachedImage2 = tempData2;
            
                                    end
                                catch
                                   %disp('No background image');
                                end
            
                            %
                            % Put images in layers
                            %
            
                                transparancy = 1 - 0.01 * str2num(handles.transparancyEdit.String);
            
                                imAlphaData1 = 1;   % bottom-layer
                                imAlphaData2 = transparancy; % overlay-layer (background image in imlook4d vocabulary)
                                imAlphaDataROI =0.5;% roi-layer
            
                                % First image
                                try
                                    set(handles.ImgObject,'CData',tempDataRGB);
                                    set(handles.ImgObject,'AlphaData',imAlphaData1);
                                catch
                                    disp('First image drawing failed');
                                end
            
                                % Second image
                                try
                            %        hold on
                                    % Foreground layer
                                    set(handles.ImgObject2,'CData',tempData2);
                                    set(handles.ImgObject2,'AlphaData',imAlphaData2);
            
                                    % Set transparent below color range
                                    if ~isempty(tempData2)
                                        imAlphaData1 = tempData > minValue;
                                        set(handles.ImgObject,'AlphaData',imAlphaData1);
                                    end
                                catch
                                end
            
                            %
                            % Setup
                            %
                                try
                                        mmX=handles.image.pixelSizeX; % X pixel size (mm)
                                        mmY=handles.image.pixelSizeY; % Y pixel size (mm)
                                        mmZ=handles.image.sliceSpacing; % Y pixel size (mm)
                                catch
                                        mmX=1;
                                        mmY=1;
                                        mmZ=1;
                                end
            
                                try
                                        set(handles.ImgObject, 'XData',XLim);
                                        set(handles.ImgObject, 'YData',YLim);
                                        set(handles.ImgObject2,'XData',XLim);
                                        set(handles.ImgObject2,'YData',YLim);
                                        set(handles.ImgObject3,'XData',XLim);
                                        set(handles.ImgObject3,'YData',YLim);
                                        set(handles.ImgObject4,'XData',XLim);
                                        set(handles.ImgObject4,'YData',YLim);
            
                                        %tempAlphaData = get(handles.ImgObject3,'AlphaData');
                                        %set(handles.ImgObject3,'CData', zeros( [size(tempData) 3]), 'AlphaData', zeros(size(tempData) ) );
            
                                       % set(handles.axes1,'XLim', XLim);
                                       % set(handles.axes1,'YLim', YLim);
                                        set(handles.axes1,'DataAspectRatio', [ 1 1 1]);
            
            
                                        if get(handles.FlipAndRotateRadioButton,'Value')
                                            set(handles.axes1,'DataAspectRatio', abs( [ 1/mmX 1/mmY 1] ));
                                        else
                                            set(handles.axes1,'DataAspectRatio', abs( [ 1/mmY 1/mmX 1] ));
                                        end
            
            
                                       % Set axes1 size
                                       %
                                       % NOTE to remember:
                                       % This causing zoomed image to go up to unzoomed.  If I comment out,
                                       % the zoom remains, but then changing between Ax, Cor, Sag gives wrong view-port.
                                       %
                                       % Now I try to set these in orientationMenu_callback instead
            
                                        %handles.axes1.XLim = XLim;
                                        %handles.axes1.YLim = YLim;
            
                                catch
                                    disp('caught error updateImage');
                                end


                            % Update message row
                                displayMessageRowImageInfo(app, hObject, eventdata, handles)
            
                            % Draw ROIs
                                guidata(hObject,handles); %Save handles
                                updateROIs(app, handles)
        end
        
        function updateROIs(app, handles)
                        % ------------------------------------------------------------------------------------------
                        % This function draws ROIs on top of cached image (not flipped and
                        % rotated)
                        % Flip-and-rotate is performed on both ROI and image
                        %
                        % Interpolation of displayed image is performed here.
                        %
                        % This routine is responsible for updating the screen display
                        % It is called two different ways:
                        % 1) wmb                (when drawing ROI with mouse)
                        %       drawROI         (draws ROI pixels in ROI matrix)
                        %           updateROI   (CachedImage is used here, so it does not need to be recalculated)
                        %
                        % 2) updateImage        (for instance when changing slice or frame.  CachedImage is generated here)
                        %       updateROI       (CachedImage is used here, so it does not need to be recalculated)
                        %
                        % THEORY:
                        %
                        % ImgObject data is plotted inside axes1
                        % axes1     -coordinate system is defined by XLim=[min max], YLim=[min max]
                        %           -DataAspectRatio defines how much space the plot takes in x,y direction
                        %
                        % ImgObject (data) coordinates are defined by XData, YData
                        %           This is the coordinate system used when clicking
                        %           the mouse
                        %
            
                            IMAGINGTOOLBOX = ~isempty( which('bwboundaries') );
                            if IMAGINGTOOLBOX
                                updateROIsImagingToolbox(app, handles);
                                return
                            end
            
                            % -------------------------------------------------
                            % Initialize
                            % -------------------------------------------------
                                numberOfSlices=size(handles.image.Cdata,3);
                                numberOfPixelsX=size(handles.image.Cdata,1);
                                numberOfPixelsY=size(handles.image.Cdata,2); %Y
            
                                slice=round(get(handles.SliceNumSlider,'Value'));
                                frame=round(get(handles.FrameNumSlider,'Value'));
            
                                rois=handles.image.ROI(:,:,slice);                           % ROIs in this slice
            
                                % Rotate and flip
                                if get(handles.FlipAndRotateRadioButton,'Value')
                                    rois=orientImage(app, rois);
                                    tempAlphaData = get(handles.ImgObject3,'AlphaData');
                                    set(handles.ImgObject3,'CData', zeros( [size(rois) 3]), 'AlphaData', zeros(size(rois) ) );
                                else
                                    tempAlphaData = get(handles.ImgObject3,'AlphaData');
                                    set(handles.ImgObject3,'CData', zeros( [size(rois) 3]), 'AlphaData', zeros(size(rois) ) );
                                end
            
                                % Work matrices
            
                                activeRoiPicture=zeros(size(handles.image.Cdata,1), size(handles.image.Cdata,2),'single');
                                activeRoiPicture=zeros(size(rois),'single');
                                inActiveRoiPicture=zeros(size(activeRoiPicture),'single');


                            % -------------------------------------------------
                            % Create image and ROI pixel values
                            % -------------------------------------------------
            
            
                                % ROI display things
                                   activeROI=get(handles.ROINumberMenu,'Value');
            
            
                                   % Remove non-visible ROIs from pixels
                                   numberOfROIs = length(handles.image.VisibleROIs);
                                   for i=1:numberOfROIs
                                       if (handles.image.VisibleROIs(i)==0)
                                           rois(rois==i)=0;       % All ROIs
                                       end
                                   end
            
                                   % Keep only reference ROIs, if checked in GUI
                                   if strcmp( handles.OnlyRefROIs.Checked, 'on')
                                       roisToCalculate = handles.model.common.ReferenceROINumbers;
                                       roisToHide = setdiff( 1:numberOfROIs , roisToCalculate);
                                       rois=handles.image.ROI(:,:,slice); % Ignore above
                                       rois=orientImage(app, rois);
                                       for i = roisToHide
                                           rois(rois==i)=0;
                                       end
                                   end
            
            
                                   % Set pixels in active ROI                              set(handles.ImgObject3,'AlphaData', 0.5);
            
            
                                   % Set pixels in all other ROIs
                                   logicalA=(rois==activeROI);          % Active ROI
                                   logicalB=(rois~=0);                  % All ROIs
                                   logicalC=xor(logicalA , logicalB);   % Removes Active ROI from All ROIs
            
                                   activeRoiPicture(logicalA)=1;
                                   inActiveRoiPicture(logicalC) = 1;
            
                                   % Modify activeRoiPicture (to show only contour)
                                   if ( get(handles.ContourCheckBox,'Value')==1 )
                                       a = max( activeRoiPicture(:));
                                       b = max( inActiveRoiPicture(:));
            
                                        lineThickness = 1;
            
                                        xrange = (lineThickness + 1) : ( size(activeRoiPicture,1) - lineThickness-1 );
                                        yrange = (lineThickness + 1) : ( size(activeRoiPicture,2) - lineThickness-1 );
            
            
                                        activeRoiPicture( xrange, yrange) = ...
                                              activeRoiPicture( xrange , yrange+lineThickness) ...
                                              + activeRoiPicture( xrange , yrange-lineThickness) ...
                                              + activeRoiPicture( xrange+lineThickness , yrange) ...
                                              + activeRoiPicture( xrange-lineThickness , yrange) ...
                                            ;
            
            
                                        level = 3*a;
                                       activeRoiPicture( activeRoiPicture > level ) = 0; % hide inside pixels
            
            
                                        inActiveRoiPicture( xrange, yrange) = ...
                                              inActiveRoiPicture( xrange , yrange+lineThickness) ...
                                              + inActiveRoiPicture( xrange , yrange-lineThickness) ...
                                              + inActiveRoiPicture( xrange+lineThickness , yrange) ...
                                              + inActiveRoiPicture( xrange-lineThickness , yrange) ...
                                            ;
            
            
                                        level = 3*b;
                                        inActiveRoiPicture( inActiveRoiPicture > level) = 0;
                                   end
            
                            % -------------------------------------------------
                            % Draw ROIs in image (SKIP THIS IF HIDE ROIS IS ON)
                            % -------------------------------------------------
                                    try
                                        ColorfulROI = strcmp( handles.image.ROIColor, 'Colored');
                                        GrayROI = strcmp( handles.image.ROIColor, 'Gray');
                                        MultiColoredROIs = strcmp( handles.image.ROIColor, 'MultiColoredROIs');
                                    catch
                                        handles.image.ROIColor = 'Colored';
                                        ColorfulROI = true;
                                        GrayROI = false;
                                        MultiColoredROIs = false;
                                    end
            
                                    if ( get(handles.hideROIcheckbox,'Value')==0 )
            
                                        % 1) ColorFul ROI  (Green for active, Red for inactive)
            
                                        if ColorfulROI
                                            tempData = activeRoiPicture;
                                            xSize = size(get(handles.ImgObject3,'CData'),1);
                                            ySize = size(get(handles.ImgObject3,'CData'),2);
            
                                            act =  ( reshape( reshape(activeRoiPicture,1,[])' * [ 0 1 0 ] , xSize, ySize, []) > 0  );
                                            inact =  ( reshape( reshape(inActiveRoiPicture,1,[])' * [ 1 0 0 ]*0.6 , xSize, ySize, []) > 0  );
                                            set(handles.ImgObject3,'Cdata', act + inact  );
            
                                            % 1a) ColorFul ROI - contour
                                            if ( get(handles.ContourCheckBox,'Value')==1 )
                                                set(handles.ImgObject3,'AlphaData', 1*(activeRoiPicture>0) + 1*(inActiveRoiPicture>0)  );
            
                                            % 1b) ColorFul ROI - solid
                                            else
                                                set(handles.ImgObject3,'AlphaData', 0.5*(activeRoiPicture>0) + 0.3*(inActiveRoiPicture>0)  );
                                            end
            
                                        end
            
                                        % 2) Gray ROI
            
                                        if GrayROI
            
                                            set(handles.ImgObject3,'Cdata', zeros(size(activeRoiPicture) ) );
            
                                            % 2a) Gray ROI - contour
                                            if ( get(handles.ContourCheckBox,'Value')==1 )
                                                set(handles.ImgObject3,'AlphaData', 0.5*(activeRoiPicture>0) + 0.4*(inActiveRoiPicture>0)  );
            
                                            % 2b) Gray ROI - solid
                                            else
                                                set(handles.ImgObject3,'AlphaData', 0.5*(activeRoiPicture>0) + 0.3*(inActiveRoiPicture>0)  );
                                            end
                                        end
            
            
                                        % 3) MultiColor ROI
            
                                        if MultiColoredROIs
                                            xSize = size(get(handles.ImgObject3,'CData'),1);
                                            ySize = size(get(handles.ImgObject3,'CData'),2);
            
                                            % Set colors for ROI layer
                                            a  = zeros( [size(activeRoiPicture) 3 ]);
            
                                            %for i = 1:length(handles.image.VisibleROIs)
                                            roisInSlice = unique( rois( find( rois >0)));
                                            if length(roisInSlice) > 0
                                                for i = roisInSlice'  % Set ROI colors only for ROIs in slice
                                                    color = getColor(app, handles, i);
                                                    a = a +  reshape( reshape( rois == i ,1,[])' * color , xSize, ySize, []) ;
                                                end
                                                set(handles.ImgObject3,'Cdata', a  );  % ROI RGB-matrix
                                            end
            
                                            % Set transparency
            
                                            % 3a) MultiColor ROI - contour
                                            if ( get(handles.ContourCheckBox,'Value') == 1 )
                                                set(handles.ImgObject3,'AlphaData', 1*(activeRoiPicture + inActiveRoiPicture)  );
                                            % 3b) MultiColor ROI - solid
                                            else
                                                set(handles.ImgObject3,'AlphaData', 0.3*(activeRoiPicture + inActiveRoiPicture)  );
                                            end
            
                                        end
            
                                    else
                                          set(handles.ImgObject3,'Cdata', zeros(size(activeRoiPicture)));
                                          set(handles.ImgObject3,'AlphaData', 0.0);
                                    end
        end
        

        function updateROIsImagingToolbox(app, handles)
                        % ------------------------------------------------------------------------------------------
                        % This function draws ROIs on top of cached image (not flipped and
                        % rotated)
                        % Flip-and-rotate is performed on both ROI and image
                        %
                        % Interpolation of displayed image is performed here.
                        %
                        % This routine is responsible for updating the screen display
                        % It is called two different ways:
                        % 1) wmb                (when drawing ROI with mouse)
                        %       drawROI         (draws ROI pixels in ROI matrix)
                        %           updateROI   (CachedImage is used here, so it does not need to be recalculated)
                        %
                        % 2) updateImage        (for instance when changing slice or frame.  CachedImage is generated here)
                        %       updateROI       (CachedImage is used here, so it does not need to be recalculated)
                        %
                        % THEORY:
                        %
                        % ImgObject data is plotted inside axes1
                        % axes1     -coordinate system is defined by XLim=[min max], YLim=[min max]
                        %           -DataAspectRatio defines how much space the plot takes in x,y direction
                        %
                        % ImgObject (data) coordinates are defined by XData, YData
                        %           This is the coordinate system used when clicking
                        %           the mouse
                        %
                            % -------------------------------------------------
                            % Initialize
                            % -------------------------------------------------
                                axisHandle = handles.axes1;
            
                                numberOfSlices=size(handles.image.Cdata,3);
                                numberOfPixelsX=size(handles.image.Cdata,1);
                                numberOfPixelsY=size(handles.image.Cdata,2); %Y
            
                                slice=round(get(handles.SliceNumSlider,'Value'));
                                frame=round(get(handles.FrameNumSlider,'Value'));
            
                                rois=handles.image.ROI(:,:,slice);                           % ROIs in this slice
            
                                % Rotate and flip
                                if get(handles.FlipAndRotateRadioButton,'Value')
                                    rois=orientImage(app, rois);
                                    tempAlphaData = get(handles.ImgObject3,'AlphaData');
                                    set(handles.ImgObject3,'CData', zeros( [size(rois) 3]), 'AlphaData', zeros(size(rois) ) );
                                else
                                    tempAlphaData = get(handles.ImgObject3,'AlphaData');
                                    set(handles.ImgObject3,'CData', zeros( [size(rois) 3]), 'AlphaData', zeros(size(rois) ) );
                                end
            
                                % Work matrices
            
                                activeRoiPicture=zeros(size(handles.image.Cdata,1), size(handles.image.Cdata,2),'single');
                                activeRoiPicture=zeros(size(rois),'single');
                                inActiveRoiPicture=zeros(size(activeRoiPicture),'single');
            
                                % Clean line-contours
                                s = findobj(handles.figure1,'Tag','contourROI'); % Only find ROIs
                                delete(s); % Delete all
            
                            % -------------------------------------------------
                            % Create image and ROI pixel values
                            % -------------------------------------------------
            
            
                                % ROI display things
                                   activeROI=get(handles.ROINumberMenu,'Value');
            
            
                                   % Remove non-visible ROIs from pixels
                                   numberOfROIs = length(handles.image.VisibleROIs);
                                   for i=1:numberOfROIs
                                       if (handles.image.VisibleROIs(i)==0)
                                           rois(rois==i)=0;       % All ROIs
                                       end
                                   end
            
                                   % Keep only reference ROIs, if checked in GUI
                                   if strcmp( handles.OnlyRefROIs.Checked, 'on')
                                       roisToCalculate = handles.model.common.ReferenceROINumbers;
                                       roisToHide = setdiff( 1:numberOfROIs , roisToCalculate);
                                       rois=handles.image.ROI(:,:,slice); % Ignore above
                                       rois=orientImage(app, rois);
                                       for i = roisToHide
                                           rois(rois==i)=0;
                                       end
                                   end
            
            
                                   % Set pixels in active ROI                              set(handles.ImgObject3,'AlphaData', 0.5);
            
            
                                   % Set pixels in all other ROIs
                                   logicalA=(rois==activeROI);          % Active ROI
                                   logicalB=(rois~=0);                  % All ROIs
                                   logicalC=xor(logicalA , logicalB);   % Removes Active ROI from All ROIs
            
                                   activeRoiPicture(logicalA)=1;
                                   inActiveRoiPicture(logicalC) = 1;
            
            
                            % -------------------------------------------------
                            % Draw ROIs in image (SKIP THIS IF HIDE ROIS IS ON)
                            % -------------------------------------------------
                                    try
                                        ColorfulROI = strcmp( handles.image.ROIColor, 'Colored');
                                        GrayROI = strcmp( handles.image.ROIColor, 'Gray');
                                        MultiColoredROIs = strcmp( handles.image.ROIColor, 'MultiColoredROIs');
                                    catch
                                        handles.image.ROIColor = 'Colored';
                                        ColorfulROI = true;
                                        GrayROI = false;
                                        MultiColoredROIs = false;
                                    end
            
                                    if ( get(handles.hideROIcheckbox,'Value')==0 )
            
                                        % 1) ColorFul ROI  (Green for active, Red for inactive)
            
                                        if ColorfulROI
            
                                            % 1a) ColorFul ROI - contour
                                            if ( get(handles.ContourCheckBox,'Value')==1 )
                                                contourRoi(app, axisHandle,  logicalC, [ 1 0 0 ]);
                                                contourRoi(app, axisHandle, logicalA, [ 0 1 0 ]); % Draw green on top
            
                                            % 1b) ColorFul ROI - solid
                                            else
                                                tempData = activeRoiPicture;
                                                xSize = size(get(handles.ImgObject3,'CData'),1);
                                                ySize = size(get(handles.ImgObject3,'CData'),2);
            
                                                act =  ( reshape( reshape(activeRoiPicture,1,[])' * [ 0 1 0 ] , xSize, ySize, []) > 0  );
                                                inact =  ( reshape( reshape(inActiveRoiPicture,1,[])' * [ 1 0 0 ]*0.6 , xSize, ySize, []) > 0  );
                                                set(handles.ImgObject3,'Cdata', act + inact  );
            
                                                set(handles.ImgObject3,'AlphaData', 0.5*(activeRoiPicture>0) + 0.3*(inActiveRoiPicture>0)  );
                                            end
            
                                        end
            
                                        % 2) Gray ROI
            
                                        if GrayROI
            
                                            set(handles.ImgObject3,'Cdata', zeros(size(activeRoiPicture) ) );
            
                                            % 2a) Gray ROI - contour
                                            if ( get(handles.ContourCheckBox,'Value')==1 )
                                                contourRoi(app, axisHandle,  logicalA, [ 1 1 1 ]);
                                                contourRoi(app, axisHandle,  logicalC, 0.7* [ 1 1 1 ]);
            
                                            % 2b) Gray ROI - solid
                                            else
                                                set(handles.ImgObject3,'AlphaData', 0.5*(activeRoiPicture>0) + 0.3*(inActiveRoiPicture>0)  );
                                            end
                                        end
            
            
                                        % 3) MultiColor ROI
            
                                        if MultiColoredROIs
                                            roisInSlice = unique( rois( find( rois >0)));
            
                                            % 3a) MultiColor ROI - contour
                                            if ( get(handles.ContourCheckBox,'Value') == 1 )
            
                                               if length(roisInSlice) > 0
                                                   for i = roisInSlice'  % Set ROI colors only for ROIs in slice
                                                       color = getColor(app, handles, i);
                                                        contourRoi(app, axisHandle,  rois == i, color );
                                                   end
                                               end
            
                                            % 3b) MultiColor ROI - solid
                                            else
                                                xSize = size(get(handles.ImgObject3,'CData'),1);
                                                ySize = size(get(handles.ImgObject3,'CData'),2);
            
                                                % Set colors for ROI layer
                                                a  = zeros( [size(activeRoiPicture) 3 ]);
            
                                                if length(roisInSlice) > 0
                                                    for i = roisInSlice'  % Set ROI colors only for ROIs in slice
                                                        color = getColor(app, handles, i);
                                                        a = a +  reshape( reshape( rois == i ,1,[])' * color , xSize, ySize, []) ;
                                                    end
                                                    set(handles.ImgObject3,'Cdata', a  );  % ROI RGB-matrix
                                                end
            
            
                                                set(handles.ImgObject3,'AlphaData', 0.3*(activeRoiPicture + inActiveRoiPicture)  );
                                            end
            
                                        end
            
                                    else
                                          set(handles.ImgObject3,'Cdata', zeros(size(activeRoiPicture)));
                                          set(handles.ImgObject3,'AlphaData', 0.0);
                                    end
        end
        
        function wbm(app, h, evd, handles)
                     % executes while the mouse moves and mouse button is pressed
                    % tic;
            
                    try
                            drawROI(app, h, evd, handles);
                            if strcmp(get(handles.markerToggleTool,'State'), 'on')
                                drawCursorInYokes2(app, handles)
                            end
                    catch
                    end
        end
        
        function wbu(app, h, evd, handles)
                     % executes when the mouse button is released
                     % get the properties and restore them
                     %props = getappdata(h,'TestGuiCallbacks');
                     %set(h,props);
                     %set(h,'WindowButtonMotionFcn','');
                     set(h,'WindowButtonMotionFcn','imlook4d(''wbm2'',gcbo,[],guidata(gcbo))');
            
                     % Save ROI state
                     try
                        handles = storeUndoROI(app, handles);
                        guidata(handles.figure1, handles);
                     catch
                         disp('Exception in wbu');
                     end
        end
        
        function windowsSubMenu_Callback(app, hObject, eventdata, handles)
            
                    % Call Display HELP and get out of callback
                    % This is only used for the scripting in DisplayHelp
                    % since this submenu will never be reached for interactive help
                    % (due to windows_Callback being called before)
                        if DisplayHelp(app, hObject, eventdata, handles)
                             return
                        end
            
            
                    % This function chooses  the window-name selected in the "Windows" menu
                    h=get(gcbo,'UserData');
                    %set( h, 'Visible','off');
                    % set(findobj('Type', 'figure'),'Visible','off') DEMONSTRATION ON HOW TO HIDE WINDOWS FROM WINDOWS TASKBAR
                    %set(h, 'Visible', 'off');
                     %%set(h, 'Visible', 'on');
                     figure(h);
        end
        
        function yoke_inspection(app, this_imlook4d_instance, yokeString)
                      % Display stored yokes for all imlook4ds
                         imlook4d_instances=getappdata(this_imlook4d_instance,'yokes');
                         imlook4d_instances=findobj('Tag','imlook4d');
            
                         for i=1:length(imlook4d_instances)
                             yokes=getappdata(imlook4d_instances(i),'yokes');
                             currentYoke=get( getappdata(imlook4d_instances(i),'currentYoke'), 'Tag');
                             %disp([ num2str(imlook4d_instances(i)) '(' currentYoke ')   ' '   yokes=' num2str(yokes(:)')]);
                         end
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.ZoomIntoggletool, app.ZoomOuttoggletool, app.Pantoggletool, app.DataCursortoggletool];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.NextPlot = 'replacechildren';
            app.SetRefROIs.MenuSelectedFcn = 'assignin(''base'', ''imlook4d_current_handle'', gcf);Select_Reference_ROIs';
            app.Copy_ROI.MenuSelectedFcn = 'Copy_ROI';
            app.Threshold_within_ROI.MenuSelectedFcn = 'Threshold_within_ROI';
            app.Threshold_ROI.MenuSelectedFcn = 'Threshold_ROI';
            app.Clean_ROI.MenuSelectedFcn = 'Clean_ROI';
            app.Fill_ROI_Slice_Gaps.MenuSelectedFcn = 'Fill_ROI_Slice_Gaps';
            app.OpenECAT.MenuSelectedFcn = 'UNKNOWN Function';
            app.OpenDICOMPET.MenuSelectedFcn = 'UNKNOWN Function';
            app.DirtyDICOMOpen.MenuSelectedFcn = 'UNKNOWN Function';
            app.OpenDICOMDIR.MenuSelectedFcn = 'UNKNOWN Function';
            app.OpenSHR.MenuSelectedFcn = 'UNKNOWN Function';
            app.OpenBinary.MenuSelectedFcn = 'UNKNOWN Function';
            app.OpenMat.MenuSelectedFcn = 'UNKNOWN Function';
            app.SaveECAT.MenuSelectedFcn = 'UNKNOWN Function';
            app.SavePCtoECAT.MenuSelectedFcn = 'UNKNOWN Function';
            app.DirtyDICOMSave.MenuSelectedFcn = 'UNKNOWN Function';
            app.Savemat.MenuSelectedFcn = 'UNKNOWN Function';
            app.PC_low_edit.ValueChangedFcn = 'UNKNOWN Function';
            app.Help.MenuSelectedFcn = 'web( which(''Help.html'') );';
            app.upgrade_menu.MenuSelectedFcn = 'if imlook4d(''DisplayHelp'',gcbo,[],guidata(gcbo));return;end;eval(''Update_imlook4d'')';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function imlook4d_OpeningFcn(app, varargin)
            % Executes just before imlook4d is made visible
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                        % This function has no output args, see OutputFcn.
                        % hObject    handle to figure
                        % eventdata  reserved - to be defined in a future version of MATLAB
                        % handles    structure with handles and user data (see GUIDATA)
                        % varargin   command line arguments to imlook4d (see VARARGIN)
                        %
            
                        % Run script to fix Incompatibilities
                        fixIncompatibilities;
            
            
                        % Set recording to off
                        handles.record.enabled = false;
            
                        % Set data cursor function
                          dcm = datacursormode(hObject);
                          set(dcm,'UpdateFcn',@dataCursorUpdateFunction)
            
                        % Set screen resolution for MATLAB (make GUI look similar for
                        % different resolutions)
                        %set(0,'ScreenPixelsPerInch',96)
            
            
                        %
                        % Clear hidden image (I don't know how this got to be a default
                        % image, but lets simple clear it).
                        %
                            hc=get(handles.axes1,'Children');
                            delete(hc(1));
            
                        %
                        % Check if file path, instead of matrix, then read file
                        %
                            if nargin()==4
                                if iscellstr(varargin)
                                    disp('File path as input argument');
                                    OpenFile_Callback(hObject, eventdata, handles, varargin{1});  % Open new file dialog, puts into new imlook4d instance
                                    delete(hObject);  % Remove current imlook4d (the one called with empty arguments)
                                    return
                                end
                            end
            
            
                        % Choose default command line output for imlook4d if isempty(varargin{:})
                           if isempty(varargin)
                                 % If imlook4d is called with no arguments,
                                 %     h=imlook4d
                                 % an instance of imlook4d is created, and the execution will enter here.
                                 %
                                 % The idea is to delete this instance, and open a new imlook4d from a file.
                                 % This will be done in the following steps:
                                 % - an open dialog is displayed (which will create a new imlook4d instance)
                                 % - the current instance within this if statement (hObject) is killed
                                 %
                                 % The handle to the imlook4d instance created by OpenFile_Callback
                                 % is found by function gcf, (see imlook4d_OutputFcn)
            
                                  OpenFile_Callback(hObject, eventdata, handles);  % Open new file dialog, puts into new imlook4d instance
            
                                delete(hObject);  % Remove current imlook4d (the one called with empty arguments)
                                return
                             end
            
                        % This alternative should allow for multiple input arguments
                        % without giving an error
                            if isempty(varargin{1})
                               return;
                            end


                        %
                        % Setup display
                        %
                            handles.output = hObject;
                            Std.Interruptible = 'off';
                            Std.BusyAction = 'queue';
                            Ax = Std;
                            Ax.Units = 'Pixels';
                           % Ax.YDir = 'reverse';
                            Ax.XLim = [.5 128.5];
                            Ax.YLim = [.5 128.5];
                            Ax.CLimMode = 'auto';
                            Ax.XTick = [];
                            Ax.YTick = [];
                            set(handles.axes1,Ax);
                           % set(handles.foreground,Ax);
                           % set(handles.roiLayer,Ax);
            
                            % Set axis to auto-fit
                            axis(handles.axes1, 'auto')
                           % axis(handles.foreground, 'auto')
                          %  axis(handles.roiLayer, 'auto')
            
                            % Defaults for image axes
                            Img = Std;
                            Img.CData = [];
                            Img.Xdata = [];
                            Img.Ydata = [];
                            Img.CDataMapping = 'scaled';
                            %Img.Erasemode = 'none';
                            handles.image= Img;
            
                       %
                       % Setup saved parameters
                       %
            
                            %inpargs = varargin{:};
                            inpargs = varargin{1};    % Matrix
            
            
                            handles.image.time = 0:(size( inpargs,4)-1); % Default time for frames 0,1... (frames-1)
                            handles.image.duration = ones( size(handles.image.time)); % Default duration 1 for each frame
            
            
                            %INPUT time and duration (this is the only way to get them into
                            %imlook4d, as input arguments
                            if nargin()>=5
                                handles.image.time=varargin{2};
                                %disp('Second argument (time) detected');
                            end
                            if nargin()>=6
                                handles.image.duration=varargin{3};
                                %disp('Third argument (duration) detected');
            
                                % Calculating frame mid-time (assuming frame start-time)
                                %handles.image.time=handles.image.time+handles.image.duration/2;
                            end
            
            
                            [r,c,z,frames]=size(inpargs);
            
                            handles.image.Cdata=inpargs;            % put 4D matrix into handles.image.Cdata
            
                            handles.image.StoredFrameSliderValue=0; % Used to store the frame when jumping from image to PC-image.  Zero means no frame stored
            
                            handles.image.zoomFactorFormula='1';   % String used to calculate zoom factor


                        %
                        % Setup image, colorbar and ROI
                        %
            
                            % Set color order for ROI
                            colors = get(0,'DefaultAxesColorOrder');
                            tempColors = repmat(colors,37, 1);  % R
                            handles.roiColors = tempColors( 1:256, :);
            
            
                            %handles.image.ROI=zeros(size(inpargs),'int8'); % Matrix for ROIs
                            handles.image.ROI=zeros(size(inpargs,1),size(inpargs,2),size(inpargs,3),'uint8'); % 3D Matrix for ROIs
            
            
                            handles.image.UndoROI.position = 1; % Position in UndoROI.ROI fourth dimension, for current displayed undo level
            
                            handles.image.VisibleROIs=uint8([]);   % Allow 255 ROIs, make them all visible
                            handles.image.LockedROIs=uint8([]);    % Allow 255 ROIs
                            handles.imSize = [r,c,z];
                            cimg = handles.image.Cdata(:,:,1,1);    % put current image (slice 1, frame 1) into cimg
                            %cimg=orientImage(cimg);                 % correct orientation on first image to display
            
            
                            UNDOSIZE = 5;
                            handles = createUndoROI(app,  handles, UNDOSIZE);
            
                            % Create image object and set the properties
                            handles.ImgObject = image(Img,'Parent',handles.axes1);
                            handles.ImgObject2 = image(Img,'Parent',handles.axes1);
                            handles.ImgObject3 = imagesc(Img,'Parent',handles.axes1);
                            handles.ImgObject4 = imagesc(Img,'Parent',handles.axes1);
            % Warning: The EraseMode property is no longer supported and will error in a future release. Use the
            % ANIMATEDLINE function for animating lines and points instead of EraseMode 'none'. Removing instances
            % of EraseMode set to 'normal', 'xor', and 'background' has minimal impact.
            
            
                            handles.image.CachedImage=cimg;      % create cached image
            
                            set(handles.ImgObject,'Cdata',cimg);
                            set(handles.SliceNumEdit,'String',1);
            
                            htable = feval('gray');
                            set(handles.figure1,'Colormap',htable);
                            set(handles.ImgObject,'Cdata',cimg);
                            set(handles.ImgObject,'Xdata',[0 r]+0.5);
                            set(handles.ImgObject,'Ydata',[0 c]+0.5);
            
                            % ROI layer
                            set(handles.ImgObject3,'Cdata', zeros(size(cimg)));
                            set(handles.ImgObject3,'Xdata',[0 r]+0.5);
                            set(handles.ImgObject3,'Ydata',[0 c]+0.5);
            
                            set(handles.ImgObject3,'UIContextMenu',handles.AxesContextualMenu);


                            % Cursor layer (yokes)
            
                            set(handles.ImgObject4,'Cdata', zeros(size(cimg)));
                            set(handles.ImgObject4,'Xdata',[0 r]+0.5);
                            set(handles.ImgObject4,'Ydata',[0 c]+0.5);
                            set(handles.ImgObject4,'AlphaData',0);
                            set(handles.ImgObject4,'UIContextMenu',handles.AxesContextualMenu);
            
            
                            % Set the properties of the axes
                            set(handles.axes1,'XLim',[0 r]+0.5);
                            set(handles.axes1,'YLim',[0 c]+0.5);
                            set(handles.axes1,'XLim',[-0.5 r+0.5]);
                            set(handles.axes1,'YLim',[-0.5 c+0.5]);
            
                            % Colorbar
            
                           % colormap jet
                           handles.ColorBar=colorbar('peer',handles.axes1, 'FontSize', 9);
            
                           set(handles.ColorBar, 'HitTest', 'off'); % No contextual menu
            
            
                        %
                        % Setup GUI sliders
                        %
            
                            adjustSliderRanges(app, handles)
                            drawnow % force redraw, otherwise axis becomes misplaced in Matlab 2014b
            
            
                        %
                        % Make SCRIPT MENUS (from files in imlook4d SCRIPTS directory)
                        %      ------------
                        %    (Scripts are m-files saved in SCRIPTS subdirectories)
                        %
                        %   SCRIPTS menu            scriptsMenuHandle -
                        %       ITEM menu               scriptsMenuSubHandle -
                        %           script-file.m           scriptsMenuSubItemHandle
            
                              % Main menu item
                             handles.scriptsMenuHandle = uimenu(handles.figure1,'Label','SCRIPTS');
                             set(handles.scriptsMenuHandle, 'Callback', 'imlook4d(''ScriptsMenu_Callback'',gcbo,[],guidata(gcbo))');
            
                             [pathstr1,name,ext] = fileparts(which('imlook4d'));
            
            
                             [files dirs]=listDirectory([pathstr1 filesep 'SCRIPTS']);
                             for i=1:length(dirs)
                                 nameWithSpaces= regexprep(dirs{i},'_', ' ');  % Replace '_' with ' '
                                 if ~strcmp(dirs{i}(1),'.') % Skip if directory starts with '.'
                                    handles.scriptsMenuSubHandle = uimenu(handles.scriptsMenuHandle,'Label',nameWithSpaces);  % Make submenu to SCRIPTS (don't add callback - let SCRIPT menu do callback)
                                    handles = makeSubMenues(app,  handles, handles.scriptsMenuSubHandle, [pathstr1 filesep 'SCRIPTS' filesep dirs{i} ]);
                                 end
                             end
            
            
                        %
                        % Make USER SCRIPT MENUS (from files in imlook4d/../USER_SCRIPTS directory)
                        %      -----------------
            
                             % Path to look (imlook4d/../USER_SCRIPTS)
                             [pathstr1,name,ext] = fileparts(which('imlook4d'));
                             userScriptFolderPath = [pathstr1 filesep '..' filesep '..' filesep '..' filesep 'USER_SCRIPTS'];
                             addpath(userScriptFolderPath);      % Add folder to path (in case you made a new one)
            
            
                             %
                             % Prepare directory USER_SCRIPTS
                             %
                                % Make directory if not existing
                                 if ~exist(userScriptFolderPath, 'dir');
                                     try
                                        dispRed(['USER_SCRIPTS folder did not exist.  Try to create one in : ' userScriptFolderPath] );
                                        mkdir(userScriptFolderPath);
            
                                     catch
                                         warning(['Could not create folder = ' userScriptFolderPath] );
                                     end
                                 end
            
                                 % Copy README-file
                                 try
                                    %copyfile(which('README-USER_SCRIPTS.txt'), [userScriptFolderPath filesep 'README.txt']);
                                    copyfile( [pathstr1 filesep 'DOCS' filesep 'README-USER_SCRIPTS.txt'], [userScriptFolderPath filesep 'README.txt']);  % Throws error if already existing (since I don't rename it)
            
                                    copyfile( [pathstr1 filesep 'DOCS' filesep 'Scripting-imlook4d.pdf'], [userScriptFolderPath filesep 'Scripting-imlook4d.pdf']);  % Throws error if already existing (since I don't rename it)
                                 catch
                                 end
            
            
                             %
                             % Menu SCRIPTS/USER
                             %
            
                                  % Main menu item
                                 handles.scriptsMenuUserHandle = uimenu(handles.scriptsMenuHandle,'Label','USER','Separator','on'); % Under SCRIPTS
            
                                 % Menus for scripts at main level in USER_SCRIPTS
                                 handles = makeSubMenues(app,  handles, handles.scriptsMenuUserHandle, [userScriptFolderPath  ]);
            
                                 % Submenues (from folders in USER_SCRIPTS)
                                 [files dirs]=listDirectory( userScriptFolderPath );
                                 for i=1:length(dirs)
                                     nameWithSpaces= regexprep(dirs{i},'_', ' ');  % Replace '_' with ' '
                                     if ~strcmp(dirs{i}(1),'.') % Skip if directory starts with '.'
                                         handles.scriptsMenuSubHandle = uimenu(handles.scriptsMenuUserHandle,'Label',nameWithSpaces);  % Make submenu to SCRIPTS (don't add callback - let SCRIPT menu do callback)
                                         handles = makeSubMenues(app,  handles, handles.scriptsMenuSubHandle, [userScriptFolderPath filesep dirs{i} ]);
                                     end
                                 end
            
                                 % Find files and folders in USER_SCRIPTS
                                 [files dirs]=listDirectory(userScriptFolderPath);
            
            
                                 %
                                 % Add script menu "SCRIPTS/USER/Create Script"
                                 %
            
                                        handles.scriptsMenuNewScriptUserHandle = uimenu(handles.scriptsMenuUserHandle,'Label','Create Script','Separator','on'); % Under SCRIPTS
            
                                         % If no files or folders, remove divider
                                         if (length(files)==0) & (length(dirs)==2)
                                             set(handles.scriptsMenuNewScriptUserHandle,'Separator','off');
                                         end


                                        % Advanced callback to allow help files for scripts
                                        nameWithSpaces = 'New Script Duplicate Window';
                                        handles.userScriptsMenuNewScriptItemHandle1 = ...
                                            uimenu(handles.scriptsMenuNewScriptUserHandle,'Label',nameWithSpaces, 'Callback', [ ...
                                            'if imlook4d(''DisplayHelp'',gcbo,[],guidata(gcbo));return;end;' ...
                                            'cd(''' userScriptFolderPath '''); imlook4d(''newScriptDuplicateWindow'')' ...
                                            ]);
            
            
                                        nameWithSpaces = 'New Script Same Window';
                                        handles.userScriptsMenuNewScriptItemHandle2 = ...
                                            uimenu(handles.scriptsMenuNewScriptUserHandle,'Label',nameWithSpaces, 'Callback', [ ...
                                            'if imlook4d(''DisplayHelp'',gcbo,[],guidata(gcbo));return;end;' ...
                                            'cd(''' userScriptFolderPath '''); imlook4d(''newScriptSameWindow'')' ...
                                            ]);
            
            
                                        % Advanced callback to allow help files for scripts
                                        nameWithSpaces = 'Scripting Manual';
                                        handles.userScriptsMenuNewScriptItemHandle2 = ...
                                            uimenu(handles.scriptsMenuNewScriptUserHandle,'Label',nameWithSpaces, 'Callback', [ ...
                                            %'open(''Scripting-imlook4d.pdf'')' ...
                                             'openInFileManager( which(''Scripting-imlook4d.pdf''))' ...
                                            ]);
            
            
                                        % Separator
                                        set(handles.userScriptsMenuNewScriptItemHandle2,'Separator','on');
            
                                        % Advanced callback to allow help files for scripts
                                        nameWithSpaces = 'Open USER_SCRIPTS folder';
                                        handles.userScriptsMenuNewScriptItemHandle3 = ...
                                            uimenu(handles.scriptsMenuNewScriptUserHandle,'Label',nameWithSpaces, 'Callback', [ ...
                                            'if imlook4d(''DisplayHelp'',gcbo,[],guidata(gcbo));return;end;' ...
                                            'openInFileManager(''' userScriptFolderPath ''')' ...
                                            ]);
            
                        %
                        % Make MODELS menu (from files in imlook4d MODELS directory)
                        %      ------------
                        %   (Models are m-file functions saved in MODELS directory)
                             [pathstr1,name,ext] = fileparts(which('imlook4d'));
            
            
                             % Main menu item
                             handles.image.modelsMenuHandle = uimenu(handles.figure1,'Label','MODELS');
                             set(handles.image.modelsMenuHandle, 'Callback', 'imlook4d(''ModelsMenu_Callback'',gcbo,[],guidata(gcbo))');
            
                             handles = makeSubMenues(app,  handles, handles.image.modelsMenuHandle, [[pathstr1 filesep 'MODELS']]);
                             handles.model.functionHandle=[];
            
            
                        %
                        % Make COLOR menu (from files in imlook4d COLORMAPS directory)
                        %     ------------
                             [pathstr1,name,ext] = fileparts(which('imlook4d'));
                             temp=listDirectory([pathstr1 filesep 'COLORMAPS']);
                             handles = makeSubMenues(app,  handles, handles.Cmaps, [pathstr1 filesep 'COLORMAPS']);
            
            
                        %
                        % Make WINDOW LEVEL menu (from files in imlook4d WINDOW_LEVELS directory)
                        %     ------------------
                             [pathstr1,name,ext] = fileparts(which('imlook4d'));
            
                             temp=listDirectory([pathstr1 filesep 'WINDOW_LEVELS']);
            
                             % Submenu items
                             handles = makeSubMenues(app,  handles, handles.WindowLevelsMenu, [pathstr1 filesep 'WINDOW_LEVELS']);
            
            
                        %
                        % Make NETWORK HOSTS menu (from files in imlook4d PACS directory)
                        %     -------------------
                             [pathstr1,name,ext] = fileparts(which('imlook4d'));
            
                             temp=listDirectory([pathstr1 filesep 'PACS']);
            
                             % Submenu items
                             counter=0;
                             for i=1:length(temp)
                                [pathstr,name,ext] = fileparts(temp{i});
                                nameWithSpaces= regexprep(name,'_', ' ');  % Replace '_' with ' '
            
                                if strcmp(ext,'.m')
                                    counter=counter+1;
                                    % Setup submenu callback
                                    callbackString=['imlook4d(''NetworkHostsSubMenu_Callback'',gcbo,[],guidata(gcbo))'];
            
                                    %disp(['name=' name '   WINDOW_LEVELS Callback=' callbackString]);% Display paths
                                    handles.image.NetworkHostsSubMenuHandle(counter) = uimenu(handles.NetworkHostsMenu, 'Label',nameWithSpaces, 'Callback', callbackString, 'UserData', [name '.m']);
                                end
                             end
            
            
                             % Select first item, if not empty
                             try
                                    % Select the first item
                                    pacsDataFileName=get(handles.image.NetworkHostsSubMenuHandle(1),'UserData');
            
                                    handles.image.PACS.HostFile=pacsDataFileName;   % WHY IS THIS LOST after OpeningFunction is done?
                                    set(handles.image.NetworkHostsSubMenuHandle(1),'Checked','on')
                             catch
                                    % Gray out PACS menues if no PACS enabled
                                    set(handles.OpenFromPACS,'Enable','off')
                                    set(handles.NetworkHostsMenu,'Enable','off')
                             end
            
            
                        %
                        % Make HELP menu (let it display at far right)
                        %
                             set(handles.HelpMenu,'Position',7);
            
                        %
                        % Make Windows menu (let it display to the left of HelpMenu)
                        %
                            set(handles.windows,'Position',6);
            
                        %
                        % Store GUI layout information (use when resizing window)
                        %
            
                            units='Pixels';
            
                            % Set Font sizes
                            SMALL = 7;
                            MEDIUM = 8;
                            FONTNAME = 'Arial';
            
            
                           % Before 2022a
                           matlabVersion = ver('matlab');
                           handles.matlabReleaseYear = str2double( regexprep( matlabVersion.Release, '[()a-zA-Z]', '') );
                           if ( handles.matlabReleaseYear < 2022 )
                               if ismac()
                                   % MAC OSX detected; increase font sizes
                                   SMALL = 10;
                                   MEDIUM = 12;
                                   FONTNAME = 'Arial';
                               end
                           end


                            % Loop all GUI objects including figure1, axes1
                            guiNames=fieldnames(handles);
                            for i=1:size(guiNames,1)
                                h=eval(['handles.' guiNames{i}]);
                                try % Some things in handles struct do not have Units property
            
                                    set(h,'FontName',FONTNAME)
            
                                    % For all except edit boxes and text
                                    if ~strcmp( 'edit', get(h,'Style'))&&~strcmp( 'text', get(h,'Style'))
                                        set(h,'FontSize',MEDIUM)
                                        set(h,'FontUnit','pixels')
                                    end
            
                                    % For text
                                    if strcmp( 'text', get(h,'Style'))
                                        set(h,'FontSize',SMALL)
                                        set(h,'FontUnit','pixels')
                                    end
                                catch
                                end
                            end
            
            
                           guiHandles=findobj(hObject,'-property','Units');
                           for i=1:size(guiHandles,1)
                                try % Some things in handles struct do not have Units property
                                    set(guiHandles(i),'Units',units);
                                catch
                                end
                           end
            
            
                            % Figure and image
                            handles.GUILayout.figure1=get(handles.figure1, 'Position');
                            handles.GUILayout.axes1=get(handles.axes1, 'Position');
            
                            % Panels
                            handles.GUILayout.uipanel1=get(handles.uipanel1, 'Position');
                            handles.GUILayout.uipanel2=get(handles.uipanel2, 'Position');
                            handles.GUILayout.uipanel5=get(handles.uipanel5, 'Position');
                            handles.GUILayout.uipanel6=get(handles.uipanel6, 'Position');
                            handles.GUILayout.uipanel7=get(handles.uipanel7, 'Position');
            
                       %
                       % Fine adjusts (because I cannot use GUIDE anymore)
                       %
            
                           % After 2024b
                           if ( handles.matlabReleaseYear > 2024 )
            
                               handles.ROIEraserRadiobutton.Position(4) = handles.ROIEraserRadiobutton.Position(4) - 5;
                               handles.FlipAndRotateRadioButton.Position(4) = handles.FlipAndRotateRadioButton.Position(4) - 5;
                               handles.ImageRadioButton.Position(4) = handles.ImageRadioButton.Position(4) - 5;
            
                               handles.transparancyPanel.Position(4) = handles.transparancyPanel.Position(4) - 5;
            
                               uiPanels=findobj(hObject,'Type','uipanel');
                               uiPanels( end +1 ) = handles.uipanel7;
                               for i=1:size(uiPanels,1)
                                   try % Some things in handles struct do not have Units property
                                       set(uiPanels(i),'BorderColor', 0.5*[1 1 1]);
                                   catch
                                   end
                               end
            
            
                           end


                            % Textboxes that should move
                            handles.GUILayout.floatingTextEdit1=get(handles.floatingTextEdit1, 'Position');
            
            
                            set(handles.ColorBar,'Units','normalized');  % Correct above loop (otherwise interactive colorbar does not work)
            
                            handles.transparancyText.FontUnits='pixels';
                            handles.transparancyEdit.FontUnits='pixels';
                            handles.transparancyPanel.FontUnits='pixels';
                            handles.transparancyText.FontSize=MEDIUM;
                            handles.transparancyEdit.FontSize=MEDIUM;
                            handles.transparancyPanel.FontSize=MEDIUM;
            
            
                        %
                        % Set continous auto-update on sliders (when moving slider with mouse)
                        %
                            hs = handles.SliceNumSlider;
                            hListener = addlistener(hs,'Value','PostSet',...
                                @(hObject,eventdata)imlook4d('updateImage',gcf,eventdata,guidata(gcf)) ...
                                );
            
            
                            hs = handles.FrameNumSlider;
                            hListener = addlistener(hs,'Value','PostSet',...
                                @(hObject,eventdata)imlook4d('updateImage',gcf,eventdata,guidata(gcf)) ...
                                );
            
            
                            hs = handles.PC_high_slider;
                            hListener = addlistener(hs,'Value','PostSet',...
                                @(hObject,eventdata)imlook4d('updateImage',gcf,eventdata,guidata(gcf)) ...
                                );
            
            %             %
            %             % Set same background color on all widgets
            %             %
            %                figureBackgroundColor=get(hObject,'Color');
            %                guiHandles=findobj(hObject, '-not', 'uimenu', '-not', 'Style', 'edit', '-not', 'Style', 'popupmenu');
            %                for i=1:length(guiHandles,1)
            %                    try
            %                            % set(guiHandles(i),'BackgroundColor',  figureBackgroundColor);
            %                    catch
            %                    end
            %                end
            
            
                        %
                        % Set version number (from About.txt file)
                        %
            
            
                            version=getImlook4dVersion();
                            set(handles.versionText, 'String', ['imlook4d (' version ') /Jan Axelsson'  ]);


                        %
                        % Finalize
                        %
            
            
                            % Set sliders
                            adjustSliderRanges(app, handles);
            
                            set(handles.axes1, 'visible', 'off');  % hide
            
                            set(hObject, 'Name','Name');
                            set(hObject,'Tag', 'imlook4d');
            
                             % Store view type
                            handles.image.plane='Axial';
            
                            % Store history
                            handles.image.history='';
            
                            % Store currently selected radio button
                            handles.imageRadioButtonGroupActiveButton = handles.ImageRadioButton;
            
                            % Store time when this window was opened
                            handles.image.windowOpenedTime = now();
            
                            % Update handles structure
                            guidata(hObject, handles);
            
                            clear varargin;
                            figure1_ResizeFcn(hObject, eventdata, handles)
                            %imlook4d_set_defaults(hObject, eventdata, handles);
            
                            % Set windows position offset to current imlook4d handle
                            try
                                imlook4d_current_handle = evalin('base', 'imlook4d_current_handle');
                                dx = 24;
                                dy = 24;
                                oldPos = get( imlook4d_current_handle, 'Position');
                                newPos = get( gcf, 'Position'); % width and height is OK
                                newPos = [ oldPos(1) + dx, oldPos(2) - dy, newPos(3), newPos(4) ]; % Shift Pos from old window
                                set( hObject, 'Position', newPos);
                            catch
                            end
            
                            resizePushButton_ClickedCallback(hObject, [], handles, 0); % Updata layout with zero increase of size
            
                       initpos = get(handles.ColorBar,'Position');
                       initpos = [ initpos(1) ,  1.15*initpos(2) ,  initpos(3) , 0.85*initpos(4) ];
            
                       initfontsize = get(handles.ColorBar,'FontSize');
            
            
                       set(handles.ColorBar, 'Position',initpos','Location', 'EastOutside')
                       updateImage(app, handles.figure1, [], handles)
                       % Set HitTest (Matlab 2016b is sensitive to this, see my support issue to MathWorks #0235001)
                       h=handles.figure1;h.HitTest='on';
                       imlook4d_set_defaults(app, hObject, eventdata, handles);
            
                       try
                           if isempty( getappdata(0, 'doNotUpdateImlook4dThisSession') ) % Check if stored opt out answer
            
                               % Here because: have not opted out for update during
                               % this session !
            
                               if ( checkForNewVersion() )
            
                                   handles.updateNeededText.String = 'New version - click menu Help/Update' ;
                                   handles.updateNeededText.Visible = 1;
            
                                   % show update dialog
                                   answer = questdlg('There is a later version of imlook4d.  Do you want to upgrade ? ', ...
                                       'Upgrade imlook4d ?', ...
                                       'Yes','Not this session', ...
                                       'Yes');
            
                                   % Handle response
                                   switch answer
                                       case 'Not this session'
                                           setappdata(0, 'doNotUpdateImlook4dThisSession', true);  % Remember 'No' answer, this session (signalled by existence of variable 'doNotUpdateImlook4dThisSession'
                                           dispRed(' You will wait to upgrade.  This dialog will show next time you start matlab');
            
                                       case 'Yes'
                                           dispRed('Upgrade started :');
                                           evalin( 'base', 'Update_imlook4d')
                                           dispRed('--> Next imlook4d window will be opened in the new version. <--');
                                           dispRed(' ');
                                           msgbox({ '' 'Please run imlook4d command again' ''  'Next imlook4d window will be opened in the new version' ''}, 'Upgrade done');
            
                                           evalin( 'base', 'close gcf') % Kill the current (this) started imlook4d session
                                   end
            
            
                               end


                           end
                       catch
            
                       end
        end

        % Menu selected function: About
        function About_Callback(app, event)
            % --------------------------------------------------------------------
            % HELP
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    DisplayHelp(app, hObject, eventdata, handles)
                    % Display HELP and get out of callback
                     if DisplayHelp(app, hObject, eventdata, handles)
                         return
                     end
            
                     % Get path to help directory
                           [pathstr1,name,ext] = fileparts(which('imlook4d'));
                           helpFilePath=[pathstr1 filesep 'HELP' filesep get(hObject,'Label') '.txt' ];
                           %footerFilePath=[pathstr1 '\HELP\Footer.txt' ];
                           text =  fileread(helpFilePath);
                           %footer =  fileread(footerFilePath);
            
                           % Put imlook4d version into text place-holder
                           text = strrep(text, 'VERSIONPLACEHOLDER', getImlook4dVersion() );
            
            %                text = [ text, ...
            %                   '<p><b>Bugs: </b>' ...
            %                   '<a href="mailto:axelsson.jan@gmail.com?subject=imlook4d&body='...
            %                     'Bug report'...
            %                     'Java:' version('-java') ...
            %                     'Matlab:' version() ...
            %                   '">' 'Bug report</a> ' ...
            %                   '</p>' ...
            %                   'Please report the following:' ...
            %                   ...
            %                    '<p><b>Imlook4d . version:</b> ' getImlook4dVersion() '</p>' ...
            %                    '<p><b>OS:</b> ' computer '</p>' ...
            %                    '<p><b>Java:</b> ' version('-java') '</p>' ...
            %                    '<p><b>Matlab:</b> ' version() '</p>' ...
            %                    ];
                           text = [ text, ...
                              '</p>' ...
                              'Bug reports are highly appreciated. Please report the following:' ...
                              ...
                               '<p><b>Imlook4d version:</b> ' getImlook4dVersion() '</p>' ...
                               '<p><b>OS:</b> ' computer '</p>' ...
                               '<p><b>Java:</b> ' version('-java') '</p>' ...
                               '<p><b>Matlab:</b> ' version() '</p>' ...
                               ];
            
                           web(['text:// ' '<html>' text  '</html>' ],'-notoolbar','-noaddressbox')
        end

        % Menu selected function: Acknowledgements
        function Acknowledgements_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    DisplayHelp(app, hObject, eventdata, handles)
                    % Display HELP and get out of callback
                     if DisplayHelp(app, hObject, eventdata, handles)
                         return
                     end
            
                     % Get path to help directory
                           %[pathstr1,name,ext] = fileparts(which('imlook4d'));
                           %helpFilePath=[pathstr1 '\HELP\' get(hObject,'Label') '.txt' ];
                           helpFilePath=[get(hObject,'Label') '.txt' ];
                           %footerFilePath=[pathstr1 '\HELP\Footer.txt' ];
                           text =  fileread(helpFilePath);
                           %footer =  fileread(footerFilePath);
            
                           web(['text:// ' '<html>' text  '</html>' ],'-notoolbar','-noaddressbox')
        end

        % Value changed function: BrushSize
        function BrushSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             %set(hObject,'State', 'off')
                             return
                         end
            
                         r=round( str2num( get(handles.BrushSize,'String') ) );
                         %handles.image.brush=circleROI(zeros(2*r+1,2*r+1),1,r+1,r+1,r);
                         handles.image.brush=circleROI(zeros(2*r,2*r),1,r,r,r);
                         guidata(hObject,handles);
        end

        % Menu selected function: CloseAxesContextMenu
        function CloseAxesContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    return
        end

        % Menu selected function: Close_many
        function Close_many_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                     % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
            
                    % Find figures
                        g=findobj('Type', 'figure');
                        g=findall(0,'Type', 'figure');
            
                    % Show only figures and imlook4d instances
                        j=1;
                        for i=1:length(g)
                            % Mark current window with a checkbox
                            if ( strcmp( get(g(i),'Tag'), 'imlook4d' ) || ...
                                    strcmp( get(g(i),'Tag'), '' )  || ...
                                    strcmp( get(g(i),'Tag'), 'modelWindow' ) || ...
                                    strcmp( get(g(i),'Tag'), 'tactWindow' ) || ...
                                    strcmp( get(g(i),'Tag'), 'TMWWaitbar' ) ...
                                    )
                                 h(j,1) = g(i);
                                 get(h(j),'Tag');
                                 j = j+1;
                            end
                        end
                       % h=g;
            
                    % Create html-formatted list of windows
                        [windowDescriptions h]= htmlWindowDescriptions(app, h);  % Sorted html list
            
                    % Find index to current window
                        thisWindow=[];
                        for i=1:length(h)
            %                % Mark current window with a checkbox
                             if (h(i) == handles.figure1)
                                  %windowDescriptions{i} = strrep( windowDescriptions{i},'<HTML>','<HTML> --> <B> <I>');
                                  windowDescriptions{i} = windowDescriptions{i};
                             end
                            % Mark all except current window with a checkbox
                            if (h(i) ~= handles.figure1)
                                 thisWindow=[ thisWindow i];
                            end
                        end
            
                    % Display list
                        [s,v] = listdlg('PromptString','Select windows to close:',...
                            'SelectionMode','multiple',...
                            'ListSize', [700 400], ...
                            'ListString',windowDescriptions,...
                            'InitialValue', thisWindow);
            
            
                    % Close selected windows
                        for i=1:size(s,2)
                            close( h(s(i)) );
                        end
        end

        % Menu selected function: Close_one
        function Close_one_Callback(app, event)
            % Close window(s)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                     % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
                        close( handles.figure1 );
        end

        % Menu selected function: ColorfulROI
        function ColorfulROI_Callback(app, event)
            % EDIT/ROI submenu
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                       % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
            
                       % Checkmarks
                       hROIObjects=get( get(hObject,'Parent'), 'Children');  % All other
                       for i=1:length(hROIObjects)
                           set(hROIObjects(i),'Checked','off')
                       end
                       set(hObject,'Checked','on')
            
                       imlook4d_set_ROIColor(app, handles.figure1, eventdata, handles)
            
                       newhandles = guidata(handles.figure1);
            
                       updateImage(app, newhandles.axes1, eventdata, newhandles)
                       updateROIs(app, newhandles)
        end

        % Value changed function: ContourCheckBox
        function ContourCheckBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                   % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
                    updateImage(app, hObject, eventdata, handles);
                    updateROIs(app, handles);
        end

        % Menu selected function: Copy_Image
        function Copy_Image_Callback(app, event)
            % Copy Image
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                        GenerateScreenDump(app, hObject, eventdata, handles,false);
        end

        % Off callback: DataCursortoggletool
        function DataCursortoggletool_ReleasedCallback(app, event)
            % function DataCursortoggletool_ClickedCallback(hObject, eventdata, handles)
            %     % Display HELP and get out of callback
            %          if DisplayHelp(hObject, eventdata, handles)
            %              set(hObject,'State', 'off')
            %              return
            %          end
            %          pressedToggleButton( hObject);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             set(hObject,'State', 'off')
                             return
                         end
            
                         releasedToggleButton(app,  hObject);
            
                         a=findall(gcf,'Type','hggroup');
                         delete(a);
        end

        % Menu selected function: EditScale
        function EditScale_Callback(app, event)
            % Window-levels submenu
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, thisHandles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                     % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, thisHandles)
                             return
                         end
            
                       if nargin == 5
                            setColorBar(app,  thisHandles, [  varargin{1} , varargin{2}  ] );
                            updateImage(app, hObject, eventdata, thisHandles);
                            return
                       end
            
                       % Try to get input from workspace INPUTS variable
                      try
                          % Try to get input from workspace INPUTS variable
                          INPUTS=getINPUTS();
                          evalin('base','clear INPUTS'); % Clear INPUTS from workspace
                          setColorBar(app,  thisHandles, [ str2num( INPUTS{1} ), str2num( INPUTS{2} ) ] );
                          updateImage(app, hObject, eventdata, thisHandles);
                      catch
                          adjustLevel('adjustLevel',hObject,eventdata,guidata(hObject))
                      end
            
            
                     return
        end

        % Menu selected function: ROIcolor
        function Edit_ROI_Color_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    ROINumberMenu=get(handles.ROINumberMenu);
                    contents = ROINumberMenu.String; % Cell array
                    ROINumber=ROINumberMenu.Value;
                    handles.roiColors(ROINumber,:) = uisetcolor( handles.roiColors( ROINumber,:) ); % Open, current RGB as input
            
                    guidata(handles.figure1,handles);% Save handles
                    updateROIs(app, handles);
        end

        % Menu selected function: Edit_window_title
        function Edit_window_title_Callback(app, event)
            % Window Title
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                        % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
                        % Input dialog texts
                            prompt={'New window title'};
                            title='Edit window title';
                            defaultanswer={ get(handles.figure1, 'Name') };
            
                        % Define size of text field
                            numlines=1;
                            extraChars=12;  % Number of extra charactes that fit the dialog (over the current window title length)
                            width=50;       % Default max length
            
                            % Extend text field if needed
                            if length(defaultanswer{1})>width
                                width=length(defaultanswer{1});
                            end
                            numCols=width+extraChars;
            
                        % Show dialog, and set window title
                            answer=inputdlg(prompt,title,[numlines  numCols ],defaultanswer);
                            set(handles.figure1, 'Name', answer{1});
        end

        % Value changed function: FirstFrame
        function FirstFrameInPCAFilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
                    disp('FirstFrameInPCAFilter_Callback called');
            
                    % Do work
                    z= size(handles.image.Cdata,4);
                    strg = get(hObject,'String');
                    if str2num(strg)>=1 && str2num(strg)<=z
                        set(handles.FirstFrame,'String',strg);
                        updateImage(app, hObject, eventdata, handles)
                    end
        end

        % Value changed function: FrameNumEdit
        function FrameNumEdit_Callback(app, event)
            % --------------------------------------------------------------------
            % TEXTBOXES
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % Hints: get(hObject,'String') returns contents of FrameNumEdit as text
                    %        str2double(get(hObject,'String')) returns contents of FrameNumEdit as a double
            
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
                    % Do work
                        frames= size(handles.image.Cdata,4);
                        strg = get(hObject,'String');
            
                        if str2num(strg)>=1 && str2num(strg)<=frames
                            set(handles.FrameNumSlider,'Value',str2num(strg));
                            updateImage(app, hObject, eventdata, handles)
                        end
        end

        % Value changed function: FrameNumSlider
        function FrameNumSlider_Callback(app, event)
            % --------------------------------------------------------------------
            % SLIDERS
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    oldVal= round(get(hObject,'Value'));
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             set(hObject,'Value',oldVal);
                             return
                         end
                    NewVal= round(get(hObject,'Value'));
                    set(handles.FrameNumEdit,'String',num2str(NewVal));
        end

        % Menu selected function: GrayROI
        function GrayROI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                       % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
            
                       % Checkmarks
                       hROIObjects=get( get(hObject,'Parent'), 'Children');  % All other
                       for i=1:length(hROIObjects)
                           set(hROIObjects(i),'Checked','off')
                       end
                       set(hObject,'Checked','on')
            
                       imlook4d_set_ROIColor(app, handles.figure1, eventdata, handles)
                       newhandles = guidata(handles.figure1);
            
                       updateImage(app, newhandles.axes1, eventdata, newhandles)
                       guidata(hObject,newhandles)
            
                       updateROIs(app, newhandles)
        end

        % Menu selected function: GuessRoiColor
        function GuessRoiColor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                       % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
            
                       % Checkmarks
                       hROIObjects=get( get(hObject,'Parent'), 'Children');  % All other
                       for i=1:length(hROIObjects)
                           set(hROIObjects(i),'Checked','off')
                       end
                       set(hObject,'Checked','on')
            
                       imlook4d_set_ROIColor(app, handles.figure1, eventdata, handles)
                       newhandles = guidata(handles.figure1);
            
                       updateImage(app, newhandles.axes1, eventdata, newhandles)
                       guidata(hObject,newhandles)
            
                       updateROIs(app, newhandles)
        end

        % Menu selected function: LoadROI
        function LoadRoiPushbutton_Callback(app, event)
            % Load ROI
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                        % Display HELP and get out of callback
                        if DisplayHelp(app, hObject, eventdata, handles)
                            return
                        end
            
                        % Make axial
                            initialOrientation = handles.orientationMenu.Value;
                            handles = resetOrientation(app, handles);
            
                        % GUI input
                        if nargin==4
                            file = fullPath;
                        end
            
                        % Test
            
                        if nargin==3
                        % Dialog option
                           % MacOS Catalina has a bug, so that  '*', 'Any file'  has to be first
            
                            [file,path] = uigetfile( ...
                                        { '*', 'Any file'; ...
                                        '*.roi;*.nii;*.nii.gz', 'ROI file' ; ...
                                        '*.mat;*.roi','imlook4d ROI'; ...
                                         '*.nii', 'ROI from Nifti';   ...
                                        '*.nii.gz','Nifti Files (*.nii.gz)'; ...
                                        '*.*', 'RTSTRUCT'} ...
                                       ,'ROI Open file name');
            
                            fullPath=[path file];
                            %load(fullPath,'-mat');
                            cd(path);
                        end
            
                        [pathstr,name,ext] = fileparts(file);
            
                        % if .nii.gz   gunzip
                        if strcmp(ext,'.gz')
                           [pathstr2,name2,ext2] = fileparts(name);
                           if strcmp(ext2,'.nii')
                               FILETYPE='NIFTI';
                               disp([ 'Unzipping gz-file = ' file ]);
                               file_in_cell  = gunzip(file); % gunzipped path => file
                               file = file_in_cell{1};
                               fullPath = [path file];
                           end
                        end


                        % No GUI input
            
                        if nargin==4
                            %load(fullPath,'-mat');
                            cd(pathstr);
                        end
            
            
                        % Put ROI in rois
                        [pathstr,name,ext] = fileparts(fullPath);
            
                        if strcmp(ext,'.roi')|| strcmp(ext,'.mat')
                            load(fullPath,'-mat');
                        end
            
                        if strcmp(ext,'.nii')
                            %nii = load_nii(fullPath);
                            %nii = load_untouch_nii(fullPath);
            
            
                            try
                                    nii = load_nii(fullPath);
                                    openingMode='load_nii';
                            catch
                                    %  Load NIFTI or ANALYZE dataset, but not applying any appropriate affine
                                    %  geometric transform or voxel intensity scaling.
                                    %warndlg({'WARNING - load_nii failed.',  'Trying load_untouch_nii.',  'The data will not go through geometrical transforms'});
                                    nii = load_untouch_nii(fullPath);
                                    openingMode='load_untouch_nii';
                            end
            
            
                            rois= nii.img;
                            roiSize = size(rois);
            
                            %roiNames
                            rois( isnan(rois) ) = 0;
                            pixelValues = unique(rois);
                            if length(pixelValues) > 255
                                dispRed(['Cannot open.  Too many pixel values (' num2str(length(pixelValues)) ').  This is probably not a ROI file. ' ]);
                                return
                            end
            
                            pixelValues = pixelValues( ~isnan( pixelValues)); % Remove NaNs that are treated as unique
                            roiNames={};
                            roiValue = 1; % First ROI should have this value
            
                            for i=1:length(pixelValues)
                                if ( pixelValues(i) ~= 0)
                                    value = pixelValues(i);
                                    roiNames{end+1} =num2str(value);
                                    rois( rois == value ) = roiValue;  % Set pixel value in ROI to correct
                                    roiValue = roiValue +1;
                                end
                            end
            
                            % Visible and locked ROIs
                            try
                                handles.image.VisibleROIs = ones( [ 1 size(roiNames,2)-1] );
                                handles.image.LockedROIs = zeros( [ 1 size(roiNames,2)-1] );
                            catch
                            end
            
                            %Add ROI in menu
                            roiNames{end + 1}='Add ROI';
            
                        end
            
            
                        if ~exist('rois')
                            disp('Maybe RTSTRUCT?');
                            rtssfile = fullPath;
                            imagedir = handles.image.folder;
                            [rois, roiNames] = readRTSTRUCT( rtssfile, imagedir);
                            roiNames{end + 1}='Add ROI';
            
                            roiSize = size(rois);
            
                            % Reverse z-order
                            INSTANCE_NR_COL = 8;
                            try % Fails if not DICOM
                                if handles.image.DICOMsortedIndexList(1,INSTANCE_NR_COL) > 1
                                    rois = flip(rois,3);
                                end
                            catch
                            end
            
                            % Flip y-axis
                            rois = flip(rois,2);
                        end
            
                        handles.image.ROI= reshape(uint8(full(rois)), roiSize); % Make (sparse double 1D) matrix to (int 8 4D) matrix
                        %UNDOSIZE = length(handles.image.UndoROI.ROI);
                        %handles.image.UndoROI.ROI{1} = handles.image.ROI;
                        handles = storeUndoROI(app, handles);
                        %handles.image.UndoROI.position = 1; % Position in UndoROI.ROI fourth dimension, for current displayed undo level
            
                        % Flip y if old ROI format
                        if strcmp(ext,'.roi')|| strcmp(ext,'.mat')
                            % DICOM files before version variable was introduced were upside down
                            % No version variable, means DICOM files  were upside down.
                            % Hence, reverse ROI if no version variable
                            if ~exist('version','var')
                           		handles.image.ROI = imlook4d_fliplr(handles.image.ROI);
                            end
                        end
            
            
                        % Bail out if ROI doesn't match image
                        imageVolumeSize = size(handles.image.Cdata(:,:,:,1) );
            
                        if ( ~isequal( roiSize, imageVolumeSize ) )
                            warndlg({'ROI size does not match image matrix.', 'This ROI was made a different image volume'});
                            return
            
                            % This is a more forgiving ROI loading.  Enable, by
                            % commenting out the return, above
            
                            if ( roiSize(3) > imageVolumeSize(3) )
                                % Truncate ROI slices
                                 handles.image.ROI = handles.image.ROI( :, :, 1:imageVolumeSize(3) );
                            end
            
                            if ( roiSize(3) < imageVolumeSize(3) )
                                % Fill extra slices with zeros
                                 handles.image.ROI( :, :, ( 1+roiSize(3) ) : imageVolumeSize(3)  )  = ...
                                     zeros( [ imageVolumeSize(1) , imageVolumeSize(2), imageVolumeSize(3)-roiSize(3) ] );
                            end
            
                        end
            
                        numberOfROIs = length(roiNames) -1 ;
            
            
                        %% TEST disturb
                        %LockedROIs = LockedROIs( 1: end-1);
                        %VisibleROIs = VisibleROIs( 1: end-1);
            
            
                        % VisibleROIs
                        if ( exist('VisibleROIs') )
            
                            % Fix if missing LockedROIs-entry
                            for i = 1: numberOfROIs
                                if (i > length( VisibleROIs ) )
                                    VisibleROIs(i) = 1; % Set to visible
                                end
                            end
                            handles.image.VisibleROIs = VisibleROIs;  % From .roi file
            
                        else
                            handles.image.VisibleROIs = ones( [ 1 numberOfROIs ] );
                        end
            
            
                        % LockedROIs
                        if ( exist('LockedROIs') )
                            for i = 1: numberOfROIs
                                % Fix if missing LockedROIs-entry
                                if (i > length( LockedROIs ) )
                                    LockedROIs(i) = 0; % Set to unlocked
                                end
            
                                % Add locked-prefix
                                if ~startsWith(app,  roiNames{i}, '(locked)' )
                                    if LockedROIs(i)
                                        roiNames{i} = [ '(locked) ' roiNames{i}];
                                    end
                                end
                            end
                            handles.image.LockedROIs = LockedROIs; % From .roi file
                        else
                            LockedROIs = zeros( [ 1 numberOfROIs ] );
                            handles.image.LockedROIs = LockedROIs;
                        end
            
            
                        % Set locked marker for first ROI if needed
                        try
                            if LockedROIs(1)
                                handles.Lock_ROI.Checked = 'on'; % Lock check mark
                            end
                        catch
                        end
            
            
                        % Roi colors
                        try
                            handles.roiColors = roiColors;
                        catch
            
                        end
            
            
                        % Read ROI names from file
                        roiNameFile = [pathstr filesep name '.txt'];
                        if exist( roiNameFile )
                            roiNames = readRoiNamesFromFile( roiNameFile, roiNames);
                        end
            
                        % Set ROI names
                        set(handles.ROINumberMenu,'String', roiNames);
                        set(handles.ROINumberMenu,'Value', 1 );
            
                        % Set reference ROIs
                        counter = 0;
                        handles.model.common.ReferenceROINumbers = [];
                        for i = 1 : numberOfROIs
                            try
                                if strcmp( '*', roiNames{i}(1) )
                                    counter = counter + 1;
                                    handles.model.common.ReferenceROINumbers(counter) = i;
                                end
                            catch
                            end
                        end
            
                        %
                        % Re-create measures
                        %
                        try
                            for i = 1 : length(measure)
                                try
                                    h = drawline(gca, 'Position',measure(i).pos )
                                    measureTapeContextualMenusImageToolbox(app,  h, measure(i).name, measure(i).slice, measure(i).orientation);
                                catch
                                    dispred(['Failed recreating measure = ' measure(i).name]);
                                end
                            end
            
                        catch
            
                        end
            
                        guidata(handles.ROINumberMenu,handles);  % Save handles
            
                        % Return to orientation
                        handles.orientationMenu.Value = initialOrientation;
                        orientationMenu_Callback(handles.orientationMenu, [], handles);
        end

        % Menu selected function: MatlabMenu
        function MatlabMenu_Callback(app, event)
            % Matlab menu
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                         % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
                        %get(handles.figure1);
            
                        if  strcmp( get(handles.figure1, 'MenuBar') , 'none'  );
                            set(handles.figure1, 'MenuBar', 'figure');
                            set(handles.MatlabMenu, 'Checked', 'on');
                        else
                            set(handles.figure1, 'MenuBar', 'none');
                            set(handles.MatlabMenu, 'Checked', 'off');
                        end
        end

        % Menu selected function: MultiColoredROIs
        function MultiColoredROIs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                       % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
            
                       % Checkmarks
                       hROIObjects=get( get(hObject,'Parent'), 'Children');  % All other
                       for i=1:length(hROIObjects)
                           set(hROIObjects(i),'Checked','off')
                       end
                       set(hObject,'Checked','on')
            
                       imlook4d_set_ROIColor(app, handles.figure1, eventdata, handles)
                       newhandles = guidata(handles.figure1);
            
                       updateImage(app, newhandles.axes1, eventdata, newhandles)
                       guidata(hObject,newhandles)
            
                       updateROIs(app, newhandles)
        end

        % Menu selected function: NetworkHostsMenu
        function NetworkHostsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                            % Display HELP and get out of callback
                             %if DisplayHelp(hObject, eventdata, handles)
                                 %return
                             %end
            
                            return
        end

        % Menu selected function: OnlyRefROIs
        function Only_Ref_ROIs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    if DisplayHelp(app, hObject, eventdata, handles)
                        return
                    end
            
                    if strcmp( handles.OnlyRefROIs.Checked, 'on');
                        handles.OnlyRefROIs.Checked = 'off';
                    else
                        handles.OnlyRefROIs.Checked = 'on';
                    end
            
                    updateROIs(app, handles);
        end

        % Menu selected function: OpenFromPACS
        function OpenFromPacs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                            % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
                            getFromPacs(handles.image.PACS.HostFile);
                            return
        end

        % Value changed function: PCAutoInvert
        function PCAutoInvert_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
                    updateImage(app, hObject, eventdata, handles);
        end

        % Value changed function: PC_high_edit
        function PC_high_edit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
                    % Do work
                    frames= size(handles.image.Cdata,4);
                    strg = get(hObject,'String');
                    if str2num(strg)>=1 && str2num(strg)<=frames
                        set(handles.PC_high_slider,'Value',str2num(strg));
                        %updateImage(hObject, eventdata, handles)
                    end
        end

        % Value changed function: PC_high_slider
        function PC_high_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    oldVal= round(get(hObject,'Value'));
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             set(handles.PC_high_slider,'Value',oldVal);
                             return
                         end
            
                    lowPC=round(get(handles.PC_low_slider,'Value'));
                    NewVal= round(get(hObject,'Value'));
                    if (NewVal<lowPC) % Move low slider with high slider
                        set(handles.PC_low_slider,'Value',NewVal);
                        set(handles.PC_low_edit,'String',num2str(NewVal));
                    end
            
            
                    set(handles.PC_high_edit,'String',num2str(NewVal));
        end

        % Value changed function: PC_low_slider
        function PC_low_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    oldVal= round(get(hObject,'Value'));
            
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             set(handles.PC_low_slider,'Value',oldVal);
                             return
                         end
            
                    highPC=round(get(handles.PC_high_slider,'Value'));
                    NewVal= round(get(hObject,'Value'));
                    if (NewVal>highPC) % Move high slider with low slider
                        set(handles.PC_high_slider,'Value',NewVal);
                        set(handles.PC_high_edit,'String',num2str(NewVal));
                    end
            
            
                    set(handles.PC_low_edit,'String',num2str(NewVal));
                    updateImage(app, hObject, eventdata, handles)
        end

        % Off callback: Pantoggletool
        function Pantoggletool_ReleasedCallback(app, event)
            % function Pantoggletool_ClickedCallback(hObject, eventdata, handles)
            %     % Display HELP and get out of callback
            %          if DisplayHelp(hObject, eventdata, handles)
            %              set(hObject,'State', 'off')
            %              return
            %          end
            %          pressedToggleButton( hObject);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    releasedToggleButton(app,  hObject);
        end

        % Menu selected function: Print
        function Print_Image_Callback(app, event)
            % Print image
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                     % Display HELP and get out of callback
                     if DisplayHelp(app, hObject, eventdata, handles)
                         return
                     end
                        DISPLAY_ON_SCREEN = true;
                        GenerateScreenDump(app, hObject, eventdata, handles,DISPLAY_ON_SCREEN);
                        printpreview(gcf);
                        close(gcf);
        end

        % Value changed function: ROILevelEdit
        function ROILevelEdit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
        end

        % Value changed function: ROINumberMenu
        function ROINumberMenu_Callback(app, event)
            % --------------------------------------------------------------------
            % SELECTIONS
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                   % This function asks for a ROI name, and creates an empty ROI.
                   % If input parameter name is given, this will be the ROI name, and no dialog will be displayed.
                   try
                       %get(handles.figure1,'SelectionType')
            
                       % Display HELP and get out of callback
                             % Special case, needs to reset the value, otherwise the dropdown
                             % menu will not be drawn.
                             ROINumber=get(hObject,'Value');
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 set(hObject,'Value', ROINumber); % Reset value to when clicked
                                 return
                             end
            
                        % Hints: contents = get(hObject,'String') returns ROINumberMenu contents as cell array
                        %        contents{get(hObject,'Value')} returns selected item from ROINumberMenu
                        contents = get(hObject,'String'); % Cell array
                        ROINumber=get(hObject,'Value');
            
                        %IF Add ROI
                        if strcmp( contents{ROINumber},'Add ROI' )
                            n=size(contents,1);
            
                            % Default ROI names only
                            %contents={ contents{1:(n-1)}, ['ROI ' num2str(n)],contents{n}};
                            %set(hObject,'String', contents);
            
                            if nargin==3
                            % Dialog option
                                prompt={'Enter ROI name:'};
                                name='Input ROI name';
                                numlines=1;
                                defaultanswer={['ROI ' num2str(n)]};
                                answer=inputdlg(prompt,name,numlines,defaultanswer);
                                name=answer{1};
                            end
                            if nargin==4
                                %
                            end
            
                            contents={ contents{1:(n-1)}, name, contents{n}}; % Put "Add ROI" last
            
                            set(hObject,'String', contents);
                            handles.image.VisibleROIs=[ handles.image.VisibleROIs 1];
                            handles.image.LockedROIs=[ handles.image.LockedROIs 0];
            
                        end
            
            
                        % If an existing ROI - we will now:
                        %   - display the slice of that ROI
                        %   - set context menu hidden flag, so it will be displayed if right-clicked in future
                        if ~strcmp( contents{ROINumber},'Add ROI' )
            
                            % Set context menu hidden flag
                            if contains( contents{ROINumber}, '(hidden)' )
                                disp('hidden')
                                handles.HideROI.Checked = 'on';
                            else
                                disp('visible')
                                handles.HideROI.Checked = 'off';
                            end
            
                            % Set context menu locked flag
                            if handles.image.LockedROIs(ROINumber)
                                handles.Lock_ROI.Checked = 'on'; % Lock check mark
                            else
                                handles.Lock_ROI.Checked = 'off'; % Unlock
                            end
            
                            % Find first slice of ROI
                            numberOfSlices=size(handles.image.Cdata,3);
            
                            currentSlice= str2num(get(handles.SliceNumEdit,'String' ));
                            currentFrame= str2num(get(handles.FrameNumEdit,'String' ));
            
                            %ROISlice=handles.image.ROI(:,:,1,1);
                            ROISlice=handles.image.ROI(:,:,currentSlice);
            
            
                            if (size(ROISlice(ROISlice==ROINumber),1)  ==0 )
                                try
            
            
                                    % Find slice with highest pixel in ROI
            
                                    frame = handles.image.Cdata(:,:,:,currentFrame);
                                    dims = size(handles.image.ROI);
            
            %                         valuesInROIPixels = frame .* ( handles.image.ROI == ROINumber);
            %                         highestInEachslice = max(  reshape( valuesInROIPixels, dims(1)*dims(2), []));
            %                         highestValue = max( highestInEachslice);
            %                         slicesWithHighestValue = find( highestInEachslice==highestValue);
            %                         sliceWithHighestValue = slicesWithHighestValue(1);
            
                                    indeces = find( handles.image.ROI== ROINumber); % ROI pixel indeces
            
                                    valuesInROIPixels = frame( indeces ); % Values in ROI pixels
                                    highestValue = max( valuesInROIPixels);
                                    indexToHighestSingleValueInROIPixels = find( valuesInROIPixels == highestValue);
                                    indexToHighest = indeces(indexToHighestSingleValueInROIPixels); % Index to highest in ROI matrix
            
                                    [I,J,sliceWithHighestValue] = ind2sub(dims,indexToHighest);
                                    if length(sliceWithHighestValue) > 1
                                        index = round( length(sliceWithHighestValue)/2 );
                                        sliceWithHighestValue = sliceWithHighestValue( index);
                                    end
                                    %disp(['Found ROI in slice number = ' num2str(sliceWithHighestValue) ]);


                                    % Set SliceNumber in GUI
                                    if not( isempty(sliceWithHighestValue) )
                                        setSlice(app, handles, sliceWithHighestValue, handles.figure1); %
                                    end
            
                                    updateImage(app, handles, eventdata, handles);
                                    updateROIs(app, handles);
                                catch
                                    % No ROI found, catch error because i>numberOfSlices in while
                                    % loop
                                    %disp(['imlook4d/ROINumberMenu_Callback ERROR: No ROI with number ' num2str(ROINumber) 'found in any slice']);
                                end
                            end
                        end
            
                        % Set tooltip
                        names = get(hObject,'String');
                        name = names(ROINumber);
                        set(hObject,'TooltipString', name{1});
            
                        guidata(handles.figure1,handles);% Save handles
                        updateROIs(app, handles);
                   catch
                       disp('Exception in ROINumberMenu_Callback');
                   end
        end

        % Menu selected function: HideAllROIS
        function ROI_Hide_All_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    if DisplayHelp(app, hObject, eventdata, handles)
                        return
                    end
                    ROINumberMenu=get(handles.ROINumberMenu);
                    contents = ROINumberMenu.String; % Cell array
            
                    handles.image.VisibleROIs(1:size(ROINumberMenu.String,1)-1)=0;
            
                    for i=1:(size(ROINumberMenu.String,1)-1)
                        if not( contains( contents{i}, '(hidden)' ) ) % If not containing "(hidden)"
            
                            if startsWith(app, contents{i},'*')
                                contents{i}=['* (hidden) ' contents{i}(3:end)];   % Set (hidden) prefix
                            else
                                contents{i}=['(hidden) ' contents{i}];   % Set (hidden) prefix
                            end
                        end
                    end
            
                    set(handles.ROINumberMenu,'String', contents)
            
                    handles.HideROI.Checked = 'on'; % Set hide checkbox  on
            
                    guidata(hObject,handles);% Save handles
                    updateROIs(app, handles);
        end

        % Menu selected function: HideROI
        function ROI_Hide_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    if DisplayHelp(app, hObject, eventdata, handles)
                        return
                    end
            
                    if strcmp( handles.HideROI.Checked, 'on');
                        % Show
                        handles.HideROI.Checked = 'off'; % Set hide to off
            
                        ROINumberMenu=get(handles.ROINumberMenu);
                        contents = ROINumberMenu.String; % Cell array
            
                        ROINumber=ROINumberMenu.Value;
                        contents{ROINumber} = regexprep(contents{ROINumber}, '\(hidden\) ', ''); % Remove (hidden) prefix
                        set(handles.ROINumberMenu,'String', contents);
            
                        handles.image.VisibleROIs(ROINumber)=ROINumber;
                    else
                        % Hide
                        handles.HideROI.Checked = 'on'; % Set hide to on
            
                        ROINumberMenu=get(handles.ROINumberMenu);
                        contents = ROINumberMenu.String; % Cell array
                        ROINumber=ROINumberMenu.Value;
            
                        handles.image.VisibleROIs(ROINumber)=0;
            
                        if startsWith(app, contents{ROINumber},'*')
                            contents{ROINumber}=['* (hidden) ' contents{ROINumber}(3:end)];   % Set (hidden) prefix
                        else
                            contents{ROINumber}=['(hidden) ' contents{ROINumber}];   % Set (hidden) prefix
                        end
            
                        set(handles.ROINumberMenu,'String', contents)
                    end
            
                    guidata(hObject,handles);% Save handles
                    updateROIs(app, handles);
        end

        % Menu selected function: Lock_ROI
        function ROI_Lock_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    if DisplayHelp(app, hObject, eventdata, handles)
                        return
                    end
            
                    if strcmp( handles.Lock_ROI.Checked, 'on');
                        handles.Lock_ROI.Checked = 'off'; % Unlock
            
                        ROINumberMenu=get(handles.ROINumberMenu);
                        contents = ROINumberMenu.String; % Cell array
                        ROINumber=ROINumberMenu.Value;
            
                        handles.image.LockedROIs(ROINumber)=0;
                        contents{ROINumber} = regexprep(contents{ROINumber}, '\(locked\) ', ''); % Remove (locked) prefix
            
                        set(handles.ROINumberMenu,'String', contents)
                    else
                        handles.Lock_ROI.Checked = 'on'; % Lock
            
            
                        ROINumberMenu=get(handles.ROINumberMenu);
                        contents = ROINumberMenu.String; % Cell array
                        ROINumber=ROINumberMenu.Value;
            
                        handles.image.LockedROIs(ROINumber)=1;
                        contents{ROINumber}=['(locked) ' contents{ROINumber}];   % Set (locked) prefix
                        set(handles.ROINumberMenu,'String', contents)
                    end
            
                    guidata(hObject,handles);% Save handles
                    updateROIs(app, handles);
        end

        % Menu selected function: MergeROIs
        function ROI_Merge_Rois_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                        ROINames = handles.ROINumberMenu.String;
                        s = handles.ROINumberMenu.Value;
                        if length(ROINames) > 1
                            % Display list
                            [s,ok] = listdlg('PromptString','Select one or many ROIs as Reference Region',...
                                'SelectionMode','multiple',...
                                'ListSize', [700 400], ...
                                'ListString',ROINames(1:end-1),...
                                'InitialValue', s );
            
                            % Bail out if cancelled dialog
                            if ~ok
                                return
                            end
                        else
                            dispRed('Define one or more ROIs, and run this command again')
                            return
                        end
            
                        % ROI name
                        prompt={'Enter ROI name:'};
                        name='Input ROI name';
                        numlines=1;
                        defaultanswer= 'Merged';
                        for i = 1 : length(s)
                            defaultanswer=[defaultanswer ' ' ROINames{s(i)} ] ;
                        end
                        answer=inputdlg(prompt,name,numlines,{defaultanswer});
                        name=answer{1};
            
                        % Make new ROI at end of list
                        newROINumber = length(ROINames);
                        set(handles.ROINumberMenu,'Value',length(ROINames) ); % Add ROI is the last one
                        handles = ROINumberMenu_Callback(handles.ROINumberMenu, eventdata, handles, name);
            
                        % Make compund ROI
                        for i = s
                            handles.image.ROI( handles.image.ROI == i) = newROINumber;
                        end
            
                        % Set
                        guidata(handles.figure1,handles);% Save handles
                        updateROIs(app, handles);
        end

        % Menu selected function: RemoveAllROIS
        function ROI_Remove_All_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                            if DisplayHelp(app, hObject, eventdata, handles)
                                return
                            end


                            ROINumberMenu=get(handles.ROINumberMenu);
                            contents= ROINumberMenu.String; % Cell array
            
                            % Dialog, what to do about locked ROIs
                            if ( sum( handles.image.LockedROIs) > 0 )
            
                                switch questdlg('Threre are locked ROIs, do you want to delete these?','Locked ROIs')
            
                                    case 'Yes'
            
                                        for i = length(contents)-1 : -1: 1
                                            keepLockedROI = false;
                                            handles = removeSingleRoi(app, handles, i, keepLockedROI); % Remove locked ROIs
                                        end
            
            
                                    case 'No'
            
                                        for i = length(contents)-1 : -1: 1
                                            keepLockedROI = true;
                                            handles = removeSingleRoi(app, handles, i, keepLockedROI); % Don't remove locked ROIs
                                        end
            
            
                                    case 'Cancel'
                                        return
            
                                end
                            else
                                for i = length(contents)-1 : -1: 1
                                    keepLockedROI = false;
                                    handles = removeSingleRoi(app, handles, i, keepLockedROI); % Remove locked ROIs
                                    disp('Deleting ROI');
                                end
                            end
            
            
                            handles.model.common.ReferenceROINumbers = [];
            
                            set(handles.ROINumberMenu,'Value', 1 );
                            guidata(hObject,handles);% Save handles
                            updateROIs(app, handles);
        end

        % Menu selected function: Remove_ROI
        function ROI_Remove_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    if DisplayHelp(app, hObject, eventdata, handles)
                        return
                    end
            
                     ROINumberMenu=get(handles.ROINumberMenu);
                    ROINumber=ROINumberMenu.Value;
            
            
                        handles = removeSingleRoi(app, handles, ROINumber);
                        set(handles.ROINumberMenu,'Value', 1 );
                        guidata(hObject,handles);% Save handles
                        updateROIs(app, handles);
        end

        % Menu selected function: RenameROI
        function ROI_Rename_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    if DisplayHelp(app, hObject, eventdata, handles)
                        return
                    end
                        ROINumberMenu=get(handles.ROINumberMenu);
                        contents = ROINumberMenu.String; % Cell array
                        ROINumber=ROINumberMenu.Value;
            
                        % Default ROI names only
                        %contents={ contents{1:(n-1)}, ['ROI ' num2str(n)],contents{n}};
                        %set(hObject,'String', contents);
            
                        % Dialog option
                            prompt={'Enter ROI name:'};
                            name='Input ROI name';
                            numlines=1;
                            defaultanswer={contents{ROINumber}};
                            answer=inputdlg(prompt,name,numlines,defaultanswer);
                            name=answer{1};
            
                            %disp([ 'Visible = ' num2str(handles.image.VisibleROIs) ]);
                            %disp([ 'Locked  = ' num2str(handles.image.LockedROIs) ]);
                        contents{ROINumber}=name;
                        set(handles.ROINumberMenu,'String', contents);
                        updateROIs(app, handles);
        end

        % Menu selected function: ShowAllROIS
        function ROI_ShowAll_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    if DisplayHelp(app, hObject, eventdata, handles)
                        return
                    end
            
                    % Show all hidden
                    ROINumberMenu=get(handles.ROINumberMenu);
                    contents = ROINumberMenu.String; % Cell array
                    handles.image.VisibleROIs(1:length(ROINumberMenu.String,1)-1)=1;
                    ROINumber=ROINumberMenu.Value;
                    contents = regexprep(contents, '\(hidden\) ', ''); % Remove (hidden) prefix
                    set(handles.ROINumberMenu,'String', contents);
            
                    % Show ref ROIs
                    handles.OnlyRefROIs.Checked = 'off'; % Set hide to off
            
                    % Set Hide Checkmark to off
                    handles.HideROI.Checked = 'off'; % Set hide to off
            
                    guidata(hObject,handles);% Save handles
                    updateROIs(app, handles);
        end

        % Menu selected function: Redo
        function Redo_ROI_Callback(app, event)
            % Redo (ROI drawing)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                        % Display HELP and get out of callback
                        if DisplayHelp(app, hObject, eventdata, handles)
                            return
                        end
                        updateROIs(app,  redoRoi(app, handles));
        end

        % Menu selected function: ResetROILevel, ResetROILevel2
        function ResetROILevel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    lowestValue = min( handles.image.Cdata(:));
                    set(handles.ROILevelEdit,'String', num2str(lowestValue));
        end

        % Menu selected function: SaveFile, Save_nifti_short
        function SaveFile_Callback(app, event)
            % Save File
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                         % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
                        % Ask user : really want to save
                        % (for PC-image, Residual-image, or PCA-filtered image)
                            if handles.PCImageRadioButton.Value && ...
                               strcmp( 'Cancel', questdlg('PC images : Do you really want to save PC images','Warning','Save','Cancel','Cancel') )
                                    return
                            end
            
                            if handles.ResidualRadiobutton.Value && ...
                               strcmp( 'Cancel', questdlg('Residual Images : Do you really want to save residual images','Warning','Save','Cancel','Cancel') )
                                    return
                            end
            
            
                            if handles.ImageRadioButton.Value && ...
                               str2num( handles.PC_high_edit.String ) < size( handles.image.Cdata, 4) && ...
                               strcmp( 'Cancel', questdlg('PCA-filtered images : Do you really want to save filtered images','Warning','Save','Cancel','Cancel') )
                                    return
                            end
            
            
                        % Make axial
                        handles = resetOrientation(app, handles);
            
                        FILETYPE=handles.image.fileType;    % Obtain original file type
                        oldPath=pwd();                      % Store path (which in some cases is destroyed in writing files)
            
                        % Generate 4D image according to slice and frame defined in GUI
            
                            displayMessageRow(['Calculating data to save'   ]);
                            tempData=generateImage(app, handles, 1:size(handles.image.Cdata,3) ,  1:size(handles.image.Cdata,4)); % All slices, all frames
                        %
                        % Call function to open correct file format
                        %
                            SaveAsShort = strcmp(hObject.Tag,'Save_nifti_short');  % Nifti menu for short save
            
                            displayMessageRow(['Writing data'   ]);
                            %if( strcmp(FILETYPE,'ECAT'))    SaveECAT_Callback(hObject, eventdata, handles);end
            
                            if( strcmp(FILETYPE,'MGH'))    LocalSaveMGH(app, handles, tempData);end
                            if( strcmp(FILETYPE,'ECAT'))    LocalSaveECAT(app, handles, tempData);end
                            if( strcmp(FILETYPE,'MATLAB'))  SaveMat_Callback(hObject, eventdata, handles);end
                            if( strcmp(FILETYPE,'SHR'))     warndlg('Saving SHR is not supported');end
                            if( strcmp(FILETYPE,'DICOM'))   LocalSaveDICOM(app, handles, tempData);end
                            %if( strcmp(FILETYPE,'ANALYZE'))   LocalSaveAnalyze(handles, tempData);end
                            if( strcmp(FILETYPE,'ANALYZE'))  LocalSaveNifti(app, handles, tempData, SaveAsShort);end         % ANALYZE
                            if( strcmp(FILETYPE,'NIFTY_TWOFILES'))  LocalSaveNifti(app, handles, tempData, SaveAsShort);end  % NIFTY behaving as Analyze (img+hdr files)
                            if( strcmp(FILETYPE,'NIFTY_ONEFILE'))  LocalSaveNifti(app, handles, tempData, SaveAsShort);end  % Single-file NIFTY
                            if( strcmp(FILETYPE,'Matrix4D')) localSaveM4(handles, tempData); end  % M4 Ume format
            
                            if( strcmp(FILETYPE,'ModernRDF'))
                                templateFile = [handles.image.folder filesep handles.image.file];
                                name = handles.image.file;
            
                                [file,path] = uiputfile('*.*', 're-save file as', handles.image.file);
                                filepath_out = [path file];
            
                                % Call function for 3D or 4D raw data
                                s = size(handles.image.Cdata);
                                if length(s) == 4
                                    jan_writeNewRdf4D( handles.image.Cdata, templateFile, filepath_out);
                                end
                                if length(s) == 3
                                    jan_writeNewRdf3D( handles.image.Cdata, templateFile, filepath_out);
                                end
            
            
                            end
                            if( strcmp(FILETYPE,'BINARY'))  warndlg('Saving Binary is not supported');end
                            if( strcmp(FILETYPE,'UNKNOWN')) end
            
                            if( ~strcmp(FILETYPE,'DICOM'))
                                cd(oldPath);    % Restore path
                            end
            
                            try
                                displayMessageRow(['DONE writing file'   ]);
                                handles.cd.TooltipString = [ 'Go to folder = ' handles.image.folder];
                            catch
                                % Fails if gcf not an imlook4d (for instance, after error dialog)
                            end
        end

        % Menu selected function: SaveMat2
        function SaveMat_Callback(app, event)
            % Save State
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                         % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
                     % Save Data and header as .mat file
                     %
                     % Flip and rotate, and swap head-feet are performed on Save according
                     % to radio buttons
                     %
            
                        % Make axial
                        handles = resetOrientation(app, handles);
            
                        try
                            [file,path] = uiputfile(['filename' '.mat'] ,'Save as .mat file (only matrix, no header)');
                            fullPath=[path file];
                            disp(['Writing matlab-file='  path file ]);
                            %disp([path file ]);
                            Data=single(handles.image.Cdata);  % Single is enough for PET data, gives half the file size
            
                %             % Rotate and flip
                %             if get(handles.FlipAndRotateRadioButton,'Value')
                %                 disp('START Flip and rotate of images');
                %
                %                 slices= size(handles.image.Cdata,3);
                %                 frames= size(handles.image.Cdata,4);
                %
                %                 for i=1:slices
                %                     for j=1:frames
                %                         Data(:,:,i,j)=orientImage(Data(:,:,i,j));
                %                     end
                %                 end
                %                 disp('END Flip and rotate of images');
                %             end
            
                            %save(fullPath, 'Data', 'header');
            
            
                            % Get time and duration
                            try
                                time=handles.image.time;
                                duration=handles.image.duration;
                            catch
                                disp('imlook4d/Savemat_Callback: time or duration not available');
                            end
            
                            % Save mat file
                            % Try the following alternatives:
                            %   1) Data, time, and duration
                            %   2) Data, time
                            %   3) Data
            
                            try % 1)
                                save(fullPath, 'Data','time', 'duration');
                            catch
                                disp('imlook4d/Savemat_Callback: did not save duration (maybe was not available)');
                                try % 2)
                                    save(fullPath, 'Data','time');
                                catch % 3)
                                    save(fullPath, 'Data');
                                    disp('imlook4d/Savemat_Callback: did not save time (maybe was not available)');
                                end
                            end
            
                            % Save 2D time and duration (for each slice and frame)
                            %
                            try
                                disp('Appending time2D');
                                time2D=handles.image.time2D;
                                save(fullPath, '-append', 'time2D');
                            catch
                                disp('imlook4d/Savemat_Callback: Failed appending time2D');
                            end
            
                            try
                                disp('Appending duration2D');
                                time2D=handles.image.time2D;
                                save(fullPath, '-append', 'duration2D');
                            catch
                                disp('imlook4d/Savemat_Callback: Failed appending duration2D');
                            end
            
            
                            % Save ECAT headers
                            %
                            try
                                disp('Appending ECAT headers');
                                subHeader= handles.image.subHeader;
                                mainHeader=handles.image.mainHeader;
                                dirstruct= handles.image.ECATDirStruct;
            
                                disp('Sizes subHeader mainHeader dirstruct');
                                size(handles.image.subHeader)
                                size(handles.image.mainHeader)
                                %size(handles.image.dirstruct)
            
                                save(fullPath, '-append', 'subHeader', 'mainHeader', 'dirstruct');
                            catch
                                disp('imlook4d/Savemat_Callback: Failed appending ECAT headers');
                            end
            
            
                            % Save DICOM headers
                            %
                            try
                                disp('Appending DICOM headers');
                                DICOMHeader=handles.image.dirtyDICOMHeader;
            
                                save(fullPath, '-append', 'DICOMHeader');
                            catch
                                disp('imlook4d/Savemat_Callback: Failed appending DICOM headers');
                            end
            
                            % Save isotope half time
                            %
                            try
                                disp('Appending halflife');
                                halflife=handles.image.halflife;
                                save(fullPath, '-append', 'halflife');
                            catch
                                disp('imlook4d/Savemat_Callback: Failed appending isotope halflife');
                            end
            
                            % Save file type
                            %
                            try
                                disp('Appending file type ');
                                fileType=handles.image.fileType;
                                save(fullPath, '-append', 'fileType');
                            catch
                                disp('imlook4d/Savemat_Callback: Failed appending fileType');
                            end
            
                             try
                                pixel_dims=[handles.image.pixelSizeX handles.image.pixelSizeY handles.image.sliceSpacing];
                                save(fullPath, '-append', 'pixel_dims');
                             catch
                                disp('imlook4d/Savemat_Callback: Failed appending pixel_dims');
                             end
            
            %                 try
            %                     handles_image=handles.image;
            %                     save(fullPath, '-append', 'handles_image');
            %                  catch
            %                     disp('imlook4d/Savemat_Callback:ERROR  Failed appending fileType');
            %                 end
            %
            %                 try
            %                     handles_model=handles.model;
            %                     save(fullPath, '-append', 'handles_model');
            %                 catch
            %                     disp('imlook4d/Savemat_Callback:ERROR  Failed appending fileType');
            %                 end
                        catch
                            disp(['imlook4d/Savemat_Callback:ERROR Save Data was not completed']);
                        end
        end

        % Menu selected function: SaveROI
        function SaveRoiPushbutton_Callback(app, event)
            %updateImage(hObject, eventdata, handles);
            % Save ROI
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                             % Display HELP and get out of callback
                                 if DisplayHelp(app, hObject, eventdata, handles)
                                     return
                                 end
            
                            % Make axial
                            initialOrientation = handles.orientationMenu.Value;
                            handles = resetOrientation(app, handles);
            
                            %[file,path] = uiputfile('ROIs.roi','ROI Save file name');
                            [file,path] = uiputfile({'*.roi'; '*.nii'},'ROI Save file name','ROIs.roi');
                            fullPath=[path file];
                            [~,~,ext] = fileparts(file);
                            cd(path);
            
                            if strcmp(ext,'.roi')
                                displayMessageRow('Saving rois imlook4d format ...');
            
                                % Always save ROIs
                                % in state without flipping of head-feet direction
                                if( get(handles.SwapHeadFeetRadioButton, 'Value')==1)
                                    rois=flipdim(handles.image.ROI,3);
                                    rois=sparse(double(rois(:)));
                                else
                                    rois=sparse(double(handles.image.ROI(:)));
                                end
            
                                roiNames=get(handles.ROINumberMenu,'String'); % Cell array
                                roiSize=size(handles.image.ROI); % Array
            
                                VisibleROIs=handles.image.VisibleROIs;
                                LockedROIs=handles.image.LockedROIs;
            
                                roiColors = handles.roiColors;
            
                                try
                                    version = getImlook4dVersion();
                                catch
                                    version = [];
                                end
            
                                try
                                    parentVolume = [handles.image.folder handles.image.file];
                                catch
                                    parentVolume = [];
                                end
            
                                % Save settings
                                GuiSettings.slice=round(get(handles.SliceNumSlider,'Value'));
                                GuiSettings.frame=round(get(handles.FrameNumSlider,'Value'));
                                GuiSettings.selectedROI=get(handles.ROINumberMenu,'Value');
                                GuiSettings.windowTitle = handles.figure1.Name;
            
            
                                % Save Measures
                                lobj = findobj(gcf, 'Type','images.roi.line');
                                measure = [];
                                for i = 1:length(lobj)
                                    measure(i).pos = lobj(i).Position;
            
                                    measure(i).name = lobj(i).UIContextMenu.UserData.textHandle.String;
                                    measure(i).slice = lobj(i).UIContextMenu.UserData.slice;
                                    measure(i).orientation = lobj(i).UIContextMenu.UserData.orientation;
            
                                end
            
            
                                save(fullPath, 'rois', 'roiNames', 'measure', 'parentVolume', 'roiColors', 'GuiSettings', 'roiSize','VisibleROIs','LockedROIs', 'version', '-v7.3');
            
                            end
            
            
                            if strcmp(ext,'.nii')
                                displayMessageRow('Saving rois in Nifti format ...');
                                nii = handles.image.nii;
            
                                % Make 3-dimensional (in case it was a 4D image)
                                nii.hdr.dime.dim(1)=3;
                                nii.hdr.dime.dim(5)=1;
                                nii.hdr.hist.descrip=['ROI (imlook4d ' getImlook4dVersion() ')'];
            
            
                                UINT8 = 2;
                                nii.hdr.dime.datatype = UINT8;
                                nii.hdr.dime.bitpix = UINT8;
            
                                % Always save ROIs
                                % in state without flipping of head-feet direction
                                if( get(handles.SwapHeadFeetRadioButton, 'Value')==1)
                                    nii.img=flipdim(handles.image.ROI,3);
                                else
                                    nii.img=handles.image.ROI;
                                end
            
            
                                % If opening mode is known, save with correct mode
                                if isfield(handles.image,'openingMode')
                                    IS_PROPER_NII = strcmp(handles.image.openingMode, 'load_nii');
                                    if IS_PROPER_NII
                                        save_nii( nii, fullPath);
                                    end
            
                                    IS_UNTOUCH_NII = strcmp(handles.image.openingMode, 'load_untouch_nii');
                                    if IS_UNTOUCH_NII
                                        save_untouch_nii( nii, fullPath);
                                    end
                                else
                                    % Unknown opening mode
                                    save_untouch_nii( nii, fullPath);
                                end
            
                            end


                            displayMessageRow('Done!');
            
                            guidata(handles.figure1, handles);
            
            
                            % Return to orientation
                            handles.orientationMenu.Value = initialOrientation;
                            orientationMenu_Callback(handles.orientationMenu, [], handles);
        end

        % Menu selected function: save_all_windows
        function SaveWindows_Callback(app, event)
            % Save All Windows + Layout to folder
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                     % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
                    % Get folder name
                        newPath=java_uigetdir( pwd(),'Select/create directory to save files to'); % Use java directory open dialog (nicer than windows)
                        if newPath == 0
                            disp('Cancelled by user');
                            return
                        end
            
                    % Make directory if not existing
                        fn = fullfile(newPath);
                        if ~exist(fn, 'dir')
                            disp(['Make directory = ' newPath ]);
                            mkdir(fn);
                        end
            
                        try
                            cd(newPath);                                    % Go to selected directory
                        catch
                            try
                                mkdir(newPath);
                            catch
                                error(['imlook4d ERROR - failed creating directory' newPath]);
                            end
                        end
            
            
                    % Save all windows to folder
                        g=findobj('Type', 'figure');
                        w = waitbar(0, 'Starting');
                        n = length(g);
                        for i=1:n
                            % Save only figures and imlook4d instances
                            if ( strcmp( get(g(i),'Tag'), 'imlook4d' ) || strcmp( get(g(i),'Tag'), '' ) )
                                 try
                                     filename = [ g(i).Name '.fig'];
                                     disp( ['Saving ' filename ]);
                                     waitbar(i/n, w, ['Saving ' filename ] );
            
                                     %
                                     savefig( g(i), filename )
            
                                 catch EXCEPTION
                                    dispRed( ['Failed saving ' filename ]);
                                 end
                            end
                        end
                        close(w)
        end

        % Context menu opening function: AxesContextualMenu
        function ScriptsMenu_Callback(app, event)
            % --------------------------------------------------------------------
            %
            % SCRIPTS
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to ScriptsMenu (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
            
                %disp('ScriptsMenu_Callback, exporting to workspace:')
            %     disp('     imlook4d_current_handle          (handle to current imlook4d)')
            %     disp('     handles         (copy of handles to GUI)')
            %     disp('     handles.image   (copy of stored variables)')
            %     disp('to workspace')
            
                    % Export handles to base workspace
                    assignin('base', 'imlook4d_current_handle', get(hObject,'Parent'))
                    assignin('base', 'imlook4d_current_handles', handles)
                    a = whos('handles');disp([ 'Size = ' num2str( round( a.bytes/1e6 )) ' MB']);
        end

        % Menu selected function: SetROILevel
        function SetROILevel_Callback(app, event)
            % --------------------------------------------------------------------
            % Axes
            % --------------------------------------------------------------------
            % --- Executes on mouse press over axes background.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    handles = guidata(gcf);
            
                    coordinates=get(gca,'currentpoint');
            
                    x=round(coordinates(1,1)+0.5);
                    y=round(coordinates(1,2)+0.5);
            
                    z = round(get(handles.SliceNumSlider,'Value'));
                    t = round(get(handles.FrameNumSlider,'Value'));
            
                    % Exchange x and y if image in original orientation (not FlipAndRotate)
                    if ~get(handles.FlipAndRotateRadioButton,'Value')
                        temp=x;   x=y; y=temp;
                    end
            
                    value = handles.image.Cdata(x,y,z,t);
            
                    set(handles.ROILevelEdit,'String', num2str(value));
        end

        % Value changed function: SliceNumEdit
        function SliceNumEdit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    %disp(['SliceNumEdit_Callback ' num2str(handles.figure1)  ]);
                    % Display HELP and get out of callback
                     if DisplayHelp(app, hObject, eventdata, handles)
                         return
                     end
                    setSlice(app,  handles,str2num(get(hObject,'String')) , handles.figure1)
        end

        % Value changed function: SliceNumSlider
        function SliceNumSlider_Callback(app, event)
            %updateImage(hObject, eventdata, handles);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                         % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
                         setSlice(app,  handles,round(get(hObject,'Value')) , handles.figure1)
        end

        % Button pushed function: TACT
        function TactButtonCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                  % Display HELP and get out of callback
                     if DisplayHelp(app, hObject, eventdata, handles)
                         return
                     end
            
                    % Run  script Time_activity_curve in base workspace
                    imlook4d('ScriptsMenu_Callback',handles.TACT,[],guidata(gcf));
                    evalin('base', 'Time_activity_curve')
        end

        % Value changed function: transparancyEdit
        function Transparancy_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % Display HELP and get out of callback
                    if DisplayHelp(app, hObject, eventdata, handles)
                        return
                    end
            
                    strg = get(hObject,'String');
                    if str2num(strg)<=100 && str2num(strg)>=0
                        updateImage(app, hObject, eventdata, handles)
                    else
                        if str2num(strg)>100
                            set(handles.transparancyEdit,'String','100');
                        end
            
                        if str2num(strg)<0
                            set(handles.transparancyEdit,'String','0');
                        end
                        updateImage(app, hObject, eventdata, handles);
                    end
        end

        % Menu selected function: Undo
        function Undo_ROI_Callback(app, event)
            % Undo (ROI drawing)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                        % Display HELP and get out of callback
                        if DisplayHelp(app, hObject, eventdata, handles)
                            return
                        end
                        updateROIs(app,  undoRoi(app, handles));
        end

        % Off callback: ZoomIntoggletool
        function ZoomIntoggletool_ReleasedCallback(app, event)
            %
            % Clean up
            %
            % function ZoomIntoggletool_ClickedCallback(hObject, eventdata, handles)
            %     % Display HELP and get out of callback
            %          if DisplayHelp(hObject, eventdata, handles)
            %              set(hObject,'State', 'off')
            %              return
            %          end
            %          pressedToggleButton( hObject);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    releasedToggleButton(app,  hObject);
        end

        % Off callback: ZoomOuttoggletool
        function ZoomOuttoggletool_ReleasedCallback(app, event)
            % function ZoomOuttoggletool_ClickedCallback(hObject, eventdata, handles)
            %     % Display HELP and get out of callback
            %          if DisplayHelp(hObject, eventdata, handles)
            %              set(hObject,'State', 'off')
            %              return
            %          end
            %          pressedToggleButton( hObject);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    releasedToggleButton(app,  hObject);
        end

        % Menu selected function: Zoom
        function Zoom_Callback(app, event)
            % Zoom menu
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                      % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
                    % Input dialog texts
                        prompt={'Zoom factor ( formula such as 7/5 is allowed)'};
                        title='Input zoom factor (no zoom = 1)';
                        defaultanswer={ num2str(handles.image.zoomFactorFormula) };
            
                    % Define size of text field
                        numlines=1;
                        extraChars=12;  % Number of extra charactes that fit the dialog (over the current window title length)
                        numCols=size( defaultanswer{1} ,2)+extraChars;
            
                    % Show dialog, and set window title
                        %answer=inputdlg(prompt,title,[numlines  numCols ],defaultanswer);
                        answer=inputdlg(prompt,title, numlines, defaultanswer);
                        handles.image.zoomFactorFormula=answer{1}; %str2num( eval( defaultanswer{1} ))
                        updateImage(app, hObject, eventdata, handles);
            
            %
            %         % Set axes
            %              ratio=1/eval(handles.image.zoomFactorFormula);  % Evaluate formula in string
            %
            %
            %             % X
            %             pixels=size(handles.image.Cdata,1);
            %             limits=get(handles.axes1,'XLim');   % Current limits
            %             origin=( limits(2)-limits(1) )/2;       % Midpoint
            %             delta=ratio*pixels/2;               % Distance from midpoint to either limit
            %
            %             newLimits=[ origin-delta origin+delta];
            %             set(handles.axes1,'XLim', newLimits)
            %
            %             % Y
            %             pixels=size(handles.image.Cdata,2);
            %             limits=get(handles.axes1,'YLim');   % Current limits
            %             origin=( limits(2)-limits(1) )/2;       % Midpoint
            %             delta=ratio*pixels/2;               % Distance from midpoint to either limit
            %
            %             newLimits=[ origin-delta origin+delta];
            %             set(handles.axes1,'YLim', newLimits)
            %
            %
                    % zoom(ratio)
                        zoom out                                           % Zoom to initial state
                        zoom(eval(handles.image.zoomFactorFormula) );
        end

        % Callback function: cd
        function cdPushButton_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                         % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             %set(hObject,'State', 'off')
                             return
                         end
                         disp('Pressed cdPushButton');
            
                         try
                            disp([ '  File type = '  handles.image.fileType] );
                         catch
                         end
            
                         try
                            clipboard('copy', ['''' handles.image.folder handles.image.file '''']);
            
                            disp([ '  Changing Matlab workspace directory to  "' handles.image.folder '"' ]);
                            cd( handles.image.folder )
            
                            disp('File path copied to system clipboard')
                         catch
                            disp([ 'Failed changing Matlab workspace directory to  "' handles.image.folder '"' ]);
            
                         end
        end

        % Callback function: ClearROIContextMenu, ClearRoiPushButton, 
        % ...and 1 other component
        function clearROI_Callback2(app, event)
            % --------------------------------------------------------------------
            % BUTTONS
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                   % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
                     ROINumber=get(handles.ROINumberMenu,'Value');
                    if ( handles.image.LockedROIs(ROINumber) )
                        return
                    end
            
                     handles.image.ROI(handles.image.ROI==ROINumber) = 0;
                     handles = storeUndoROI(app, handles);
                     %printUndoInfo(handles);
            
                     guidata(handles.figure1,handles);% Save handles
            
                     updateImage(app, hObject, eventdata, handles);
                     updateROIs(app, handles);
        end

        % Menu selected function: copyRoiBetweenYokes
        function copyRoiBetweenYokes_Callback(app, event)
            % ROI copy between yokes
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, evd, thisHandles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                         % Display HELP and get out of callback
                             if DisplayHelp(app, h, evd, thisHandles)
                                 return
                             end
            
            
                        % Get ROI matrix in Axial orientation
                        origOrientation = get(thisHandles.orientationMenu,'Value');
                        AXIAL = 1;
                        thisHandles = setOrientation(app, thisHandles, AXIAL);
                        axialROI = thisHandles.image.ROI;
                        thisHandles = setOrientation(app, thisHandles, origOrientation);
            
                        ROINames = get(thisHandles.ROINumberMenu,'String');
                        VisibleROIs = thisHandles.image.VisibleROIs;
                        LockedROIs = thisHandles.image.LockedROIs;
            
                        %guidata(thisHandles.figure1, thisHandles);
                        %updateImage(thisHandles.figure1, [], thisHandles);
            
                        % Loop through yoke'd images (but skip this one)
                        yokes=getappdata( thisHandles.figure1, 'yokes');
                        for i=1:length(yokes)
                            if gcf ~= yokes(i)
                            handles=guidata(yokes(i));
            
                            % Copy ROI menu, Visible and Lock info
                            set(handles.ROINumberMenu,'String',ROINames);
                            handles.image.VisibleROIs = VisibleROIs;
                            handles.image.LockedROIs = LockedROIs;
            
                            % Copy Reference ROIs
                            try
                                handles.model.common.ReferenceROINumbers = thisHandles.model.common.ReferenceROINumbers;
                            catch
                            end
            
                            % Insert axialROI in Axial view, and rotate to original
                            % view
                            origOrientation = get(handles.orientationMenu,'Value');
                            AXIAL = 1;
                            handles = setOrientation(app, handles, AXIAL);
                            handles.image.ROI = axialROI;
                            handles = setOrientation(app, handles, origOrientation);
            
                            handles = storeUndoROI(app, handles);
            
                            guidata(yokes(i),handles);
            
                            %updateImage(yokes(i), [], handles);
            
                           end
                        end
        end

        % Menu selected function: copy_TACT_to_clipboard
        function copy_TACT_to_clipboard_Callback(app, event)
            % Copy TACT to Clipboard
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                       % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
                             contents = get(handles.ROINumberMenu,'String'); % Cell array
            
                             if ( length(contents) < 2 )
                                 displayMessageRowError('No ROIs defined')
                                 return
                             end
            
                             displayMessageRow('Calculating ...');
            
            
                              TAB=sprintf('\t');
                              EOL=sprintf('\n');
            
                             [activity, NPixels, stdev]=generateTACT(handles, handles.image.ROI);
            
                             N=size(activity,1);  %Number of ROIs
                             M=size(activity,2);  %Number of frames
            
                             s='';
            
                             %%for i=1:N % Loop number of ROIs
                             for i=find(handles.image.VisibleROIs)
                                 s=[ s contents{i} TAB];
                             end
                             s=[ s(1:end-1) EOL];
            
                             for j=1:M % Loop number of frames
                                 %for i=1:N % Loop number of ROIs
                                 for i=find(handles.image.VisibleROIs)
                                    s=[ s num2str(activity(i,j)) TAB];
                                 end
                                 s=[ s(1:end-1) EOL];
                             end
            
                             disp(s)
            
                             clipboard('copy',s)
            
                             displayMessageRow('Done!');
        end

        % Menu selected function: exportAsViewedToWorkspace
        function exportAsViewedToWorkspace_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % This function exports data as viewed
            
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end


                   % Generate 4D image according to slice and frame defined in GUI
                        tempData=generateImage(app, handles, 1:size(handles.image.Cdata,3) ,  1:size(handles.image.Cdata,4)); % All slices, all frames
            
                   % Export untouched
                     exportToWorkspace_Callback(hObject, eventdata, handles);
            
                    % Export imlook4d data "as viewed" to base workspace
                    % overwriting previous imlook4d_Cdata
                    try
                        assignin('base', 'imlook4d_Cdata', tempData);
                    catch
                        disp('failed exporting imlook4d_Cdata');
                    end
        end

        % Menu selected function: exportToWorkspace
        function exportToWorkspace_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % This function exports original(untouched) data
            
            
                   % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
                     TAB=sprintf('\t');
                    %disp('Window selected (figure1_ButtonDownFcn)');
                    %disp('Set imlook4d_current_handle to current imlook4d instance');
                    %disp([ TAB get(hObject,'Name')]);
            
                    % Export handles to base workspace
                    %assignin('base', 'imlook4d_current_handle', hObject);
                    %disp(get(hObject,'Name'));
                    %assignin('base', 'imlook4d_current_handle', handles.axes1);
                    %disp(get(handles.axes1,'Name'));
                    assignin('base', 'imlook4d_current_handle', handles.figure1);
                    %disp(get(handles.figure1,'Name'));
            
                    %assignin('base', 'handles', handles);
                    assignin('base', 'imlook4d_current_handles', handles);
            
                    % Export imlook4d data to base workspace
                    %disp('Export handles.image.Cdata to imlook4d_Cdata');
                    try
                        assignin('base', 'imlook4d_Cdata', handles.image.Cdata);
                    catch
                        disp('failed exporting imlook4d_Cdata');
                    end;
            
                    %disp('Export handles.image.duration to imlook4d_time');
                    try
                        assignin('base', 'imlook4d_duration', handles.image.duration);
                    catch
                        %disp('failed exporting imlook4d_duration');
                    end;
            
                    %disp('Export handles.image.time to imlook4d_time');
                    try
                        assignin('base', 'imlook4d_time', handles.image.time);
                    catch
                        %disp('failed exporting imlook4d_time');
                    end;
            
                    % Export imlook4d ROIs to base workspace
                    %disp('Export handles.image.ROI to imlook4d_ROI');
                    try
                        assignin('base', 'imlook4d_ROI', handles.image.ROI);
                    catch
                        disp('failed exporting imlook4d_ROI');
                    end;
            
                    roiNames=get(handles.ROINumberMenu,'String'); % Cell array
                    %disp('Export roiNames to imlook4d_ROINames');
                    try
                        assignin('base', 'imlook4d_ROINames', roiNames);
                    catch
                        disp ('failed exporting imlook4d_ROINames');
                    end;
            
                    % Export imlook4d current slice, frame, ROI number to base workspace
                    slice=round(get(handles.SliceNumSlider,'Value'));
                    frame=round(get(handles.FrameNumSlider,'Value'));
                    selectedROI=get(handles.ROINumberMenu,'Value');
                    %disp('Export slice to imlook4d_slice');
                    try
                        assignin('base', 'imlook4d_slice', slice);
                    catch
                        disp ('failed exporting imlook4d_slice');
                    end;
                    %disp('Export frame to imlook4d_frame');
                    try
                        assignin('base', 'imlook4d_frame', frame);
                    catch
                        disp ('failed exporting imlook4d_frame');
                    end;
                    %disp('Export ROI number to imlook4d_ROI_number');
                    try
                        assignin('base', 'imlook4d_ROI_number', selectedROI);
                    catch
                        disp ('failed exporting imlook4d_frame');
                    end
        end

        % Key release function: figure1
        function figure1_KeyReleasedFunction(app, event)
            % --------------------------------------------------------------------
            % KEY PRESSED
            % --------------------------------------------------------------------
            % This callback is used to stop ctrl-c from interrupting the Copy_Image_Callback:
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    key=eventdata.Key;
            
            
                    direction = 0;
                    switch key
                        case 'uparrow'
                            direction = 1;
                        case 'downarrow'
                            direction = -1;
                        otherwise
                    end
            
                    scrollSlices(app, hObject, eventdata, handles, direction)
        end

        % Size changed function: figure1
        function figure1_ResizeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % Performs resizing of the imlook4d window in either of the following
                % ways:
                % Case 1: The resize is performed by mouse, and the
                % figure1OriginalPosVector is retrieved.
                % Case 2: The resize is performed by program, because smaller than original size was selected by mouse
                %
                % The strategy is to resize with kept proportions on axes1 (the image),
                % AND keeping the top left corner stationary until leaving screen.
                % - Number of pixels resized in y-direction (dy) is used to calculate resize in x-direction (dx), (keeping proportions of axes1)
                % - The number of pixels resized in figure1 is equal to number of pixels resized in axes1.
                % - If resized to smaller than original size, resize to original size
                % - GUI widgets are moved to correct position
                % - axes1 is resized
                % - figure1 is resized
                try
                    SetColorBarUpdateState(app, handles.axes1, 'on');
                catch
                end
                    %drawnow;
                    %disp(['New size=(' num2str(get(handles.figure1, 'Position') ) ')']);
            
                    %
                    % Initialize
                    %
                        try
                            set(handles.ColorBar,'Units','Pixels');              % Make colorbar possible to move correct distance
                        catch
                        end
            
                        figure1OriginalPosVector=handles.GUILayout.figure1;  % Stored at start of imlook4d
                        axes1OriginalPosVector=handles.GUILayout.axes1;      % Stored at start of imlook4d
                        originalProportions=axes1OriginalPosVector(4)/axes1OriginalPosVector(3);  % Original proportions of axes1, height/width
            
                        figure1CurrentPosVector=get(handles.figure1,'Position');    % Current size of figure1
            
                        % New position of figure window (guess same as current)
                        figure1PosVector(1)=figure1CurrentPosVector(1);
                        figure1PosVector(2)=figure1CurrentPosVector(2);
                        % figure1PosVector(3), figure1PosVector(4) is set below
            
                    %
                    % Calculate change in size relative original
                    %
                        % Case 1: Mouse-resizing:  Calculate change in size relative % original
                            dx1=figure1CurrentPosVector(3)-figure1OriginalPosVector(3);  % changed width  of figure1 relative original size
                            dy1=figure1CurrentPosVector(4)-figure1OriginalPosVector(4);  % changed height of figure1 relative original size
            
                            % Calculate new dx that keeps proportions of axes1
                            % NOTE: dx, dy is also the size difference for axes1
                            % Change width to keep proportions:
                            %   [ dy+axes1OriginalPosVector(4) ]/ [ dx+axes1OriginalPosVector(3) ]=originalProportions
            
                            dx=( dy1+axes1OriginalPosVector(4) ) /originalProportions  - axes1OriginalPosVector(3);
            
                            dy=dy1;
            
                            % Bail out if too small of a change
                            % (otherwise GUI will shake from small movement)
                            if ( abs( dx - dx1 ) < 30 )
                                return
                            end
            
                        % Case 2: Resize to original size, if window smaller than original
                            if (dy1<0)
                                % The y-position is smaller than in reality, because I don't allow shrinking below original size
                                % Therefore, make sure new position is same as before resize
                                figure1PosVector(2)=figure1PosVector(2)+dy;
                                dx=0;% Set width to original size
                                dy=0;% Set height to original size
                            end
            %
            %             % Case 3: x changed more than y.  Resize by calculating new dy
            %                 if (dy1/dx1 < originalProportions)
            %                     dx=dx1; % Set width to that of x
            %                     dy=(dx1+axes1OriginalPosVector(3))*originalProportions;   % Calculate new y
            %                 end
            %
            %             % Case 4: Resize to original size, if window smaller than original
            %                 if (dy1/dx1 > originalProportions)
            %                     dx=( dy1+axes1OriginalPosVector(4) ) /originalProportions  - axes1OriginalPosVector(3);
            %                     dy=dy1;
            %                 end
            
                     %
                     % Move GUI widgets according to resize of figure1
                     %
                           % FieldNames for GUILayout
                           try
                                guiNames=fieldnames(handles.GUILayout);
                           catch
                               disp('imlook4d/figure1_ResizeFcn:  ERROR getting field names');
                               return
                           end
            
                         guiPositions=struct2cell(handles.GUILayout);  % gui positions stored at start of imlook4d instance
                         % Loop all GUI objects except first two (figure1, axes1)
                            for i=3:size(guiPositions,1)
                                xPos=guiPositions{i}(1)+dx;
                                yPos=guiPositions{i}(2)+dy;
                                width=guiPositions{i}(3);
                                height=guiPositions{i}(4);
            
                                h=eval(['handles.' guiNames{i}]);
                                set(h,'Position',[xPos yPos width height]);
                            end
            
            
                     %
                     % Resize axes1
                     %
                          axes1PosVector(1)=axes1OriginalPosVector(1);
                          axes1PosVector(2)=axes1OriginalPosVector(2);
                          axes1PosVector(3)=handles.GUILayout.axes1(3)+dx;
                          axes1PosVector(4)=handles.GUILayout.axes1(4)+dy;
                          set(handles.axes1,'Position',axes1PosVector);
            
                     %
                     % Resize figure1
                     %
                           figure1PosVector(3)=handles.GUILayout.figure1(3)+dx;
                           figure1PosVector(4)=handles.GUILayout.figure1(4)+dy;
                           set(handles.figure1,'Position',figure1PosVector);
            
                     %
                     % Clean up
                     %
                          set(handles.ColorBar,'Units','normalized');
        end

        % Window scroll wheel function: figure1
        function figure1_ScrollWheelFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    direction=-eventdata.VerticalScrollCount;
                    scrollSlices(app, hObject, eventdata, handles, direction);
        end

        % Off callback: helpToggleTool
        function helpToggleTool_OffCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    releasedToggleButton(app,  hObject);
                    % Set textboxes etc on, to allow normal work when help button is not pressed
                    set(handles.ROILevelEdit,'Enable', 'on');
                    set(handles.BrushSize,'Enable', 'on');
                    set(handles.FrameNumEdit,'Enable', 'on');
                    set(handles.transparancyEdit,'Enable', 'on');
                    set(handles.SliceNumEdit,'Enable', 'on');
                    set(handles.PC_low_edit,'Enable', 'on');
                    set(handles.PC_high_edit,'Enable', 'on');
                    set(handles.ROINumberMenu,'Enable', 'on');
                    set(handles.orientationMenu,'Enable', 'on');
                    set(handles.PC_low_slider,'Enable', 'on');
                    set(handles.FirstFrame,'Enable', 'on');
                    set(handles.ColorBar, 'ButtonDownFcn', '@resetCurrentAxes');
            
                    % Close web browser
                    try
                        handles.webbrowser.close();
                    catch
                    end
        end

        % On callback: helpToggleTool
        function helpToggleTool_OnCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    pressedToggleButton(app,  hObject);
                    % Make text boxes and other things that shouldn't be callbacked inactive
                    set(handles.ROILevelEdit,'Enable', 'inactive');
                    set(handles.BrushSize,'Enable', 'inactive');
                    set(handles.FrameNumEdit,'Enable', 'inactive');
                    set(handles.transparancyEdit,'Enable', 'inactive');
                    set(handles.SliceNumEdit,'Enable', 'inactive');
                    set(handles.PC_low_edit,'Enable', 'inactive');
                    set(handles.PC_high_edit,'Enable', 'inactive');
                    set(handles.ROINumberMenu,'Enable', 'inactive');
                    set(handles.orientationMenu,'Enable', 'inactive');
                    set(handles.PC_low_slider,'Enable', 'inactive');
                    set(handles.FirstFrame,'Enable', 'inactive');
                    set(handles.ColorBar, 'ButtonDownFcn', @(hObject,eventdata)imlook4d('ColorBar_Callback',hObject,eventdata,guidata(hObject)) );
            
                    % Display interactive-help first page
                    if DisplayHelp(app, hObject, eventdata, handles)
                        figure(gcf) % Move to top
                        return
                    end
        end

        % Value changed function: hideROIcheckbox
        function hideROIcheckbox_Callback(app, event)
            % --------------------------------------------------------------------
            % CHECKBOXES & RADIOBUTTONS
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                   % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
                    updateImage(app, hObject, eventdata, handles);
        end

        % Menu selected function: importFromWorkspace
        function importFromWorkspace_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % This function Imports data from workspace INCLUDING imlook4d_Cdata
            
                    handles = importUntouched_Callback(hObject, eventdata, handles,varargin);
            
                     try
                         handles.image.Cdata=evalin('base', 'imlook4d_Cdata');
                     catch
                         disp('failed importing imlook4d_Cdata');
                     end;
            
            
                      % Save modified data
                     guidata(hObject,handles);
            
                     adjustSliderRanges(app, handles);
                     updateImage(app, hObject, eventdata, handles);
        end

        % Menu selected function: importUntouchedToWorkspace
        function importUntouched_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % This function Imports data from workspace EXCLUDING imlook4d_Cdata
                    % Imports everything where and letting imlook4d-variables override
                    % imlook4d_current_handles
            
                           % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
                    %
                    % Import handles structure (Except Cdata)
                    %
                       handles=evalin('base', 'imlook4d_current_handles');
            
            
                     %disp('Import handles.image.time from imlook4d_time');
                     try
                         handles.image.time=evalin('base', 'imlook4d_time');
                     catch
                         %disp('failed importing imlook4d_time');
                     end;
            
                     %disp('Import handles.image.duration from imlook4d_duration');
                     try
                         handles.image.duration=evalin('base', 'imlook4d_duration');
                     catch
                         %disp('failed importing imlook4d_duration');
                     end;
            
                     % Import imlook4d ROIs from base workspace
                     %disp('Import handles.image.ROI from imlook4d_ROI');
                     try
                         importedROIs=evalin('base', 'imlook4d_ROI');
            
                         % If ROI changed, store Undo ROI
                         previousROISize = size(handles.image.ROI);
                         if ~isequal(handles.image.ROI, importedROIs)
                            handles.image.ROI = importedROIs;
                            handles = storeUndoROI(app, handles);
                            % If ROI size changed, then clean all Undo ROIs because undo only works back to same sized ROI
                            if ~isequal( previousROISize, size(importedROIs) )
                                handles = resetUndoROI(app, handles);
                            end
                         end
            
                     catch
                         disp('failed importing imlook4d_ROI');
                     end;
            
            
                     %disp('Import roiNames to imlook4d_ROINames');
                     try
                         roiNames=evalin('base', 'imlook4d_ROINames');
                         set(handles.ROINumberMenu,'String', roiNames);
                     catch
                         disp('failed importing imlook4d_ROINames');
                     end;
            
            
                     % Create ROI status for additional ROIs
                     for i = length(handles.image.VisibleROIs)+1 : length(roiNames)-1
                            handles.image.VisibleROIs=[ handles.image.VisibleROIs 1];
                            handles.image.LockedROIs=[ handles.image.LockedROIs 0];
                     end


                     % NOT imported: imlook4d current frame
            
                     try
                         currentSlice=evalin('base', 'imlook4d_slice');
                     catch
                         disp('failed importing imlook4d_slice');
                     end;
                     setSlice(app, handles, currentSlice, handles.figure1);


                     % Resize images
            %          for h = get(handles.axes1, 'Children')
            %              set(h,'XData', [ 0.5 size(handles.image.Cdata,1)+0.5 ]);
            %              set(h,'YData', [ 0.5 size(handles.image.Cdata,2)+0.5 ]);
            %          end
            
                     % Save modified data
                     guidata(hObject,handles);
            
            
                     % Redraw
                     %axis(handles.axes1, 'auto'); % Needs to set handle to auto to fit strange matrix sizes (i.e. RDF data)
            
            
                     try
                        adjustSliderRanges(app, handles);
                        updateImage(app, hObject, eventdata, handles);
                     catch
                     end
                     guidata(hObject,handles);
                     a = whos('handles');disp([ 'Size = ' num2str( round( a.bytes/1e6 )) ' MB']);
        end

        % Menu selected function: interpolate2
        function interpolate2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                       % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
                       % Toggle Check mark
            
                       if strcmp(get(hObject,'Checked'),'on')
                           set(hObject,'Checked','off')
                           set(handles.interpolate4,'Checked','off')
                       else
                           set(hObject,'Checked','on')
                           set(handles.interpolate4,'Checked','off')
                       end
            
                       % Update image
                       updateImage(app, hObject, eventdata, handles)
                       updateROIs(app, handles)
        end

        % Menu selected function: interpolate4
        function interpolate4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                       % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
                       % Toggle Check mark
            
                       if strcmp(get(hObject,'Checked'),'on')
                           set(hObject,'Checked','off')
                       else
                           set(hObject,'Checked','on')
                           set(handles.interpolate2,'Checked','off')
                       end
            
                       % Update image
                       updateImage(app, hObject, eventdata, handles)
                       updateROIs(app, handles)
        end

        % Off callback: markerToggleTool
        function markerToggleButtonOff_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                        % Display HELP and get out of callback
                        if DisplayHelp(app, hObject, eventdata, handles)
                            set(hObject,'State', 'off')
                            return
                        end
            
                        releasedToggleButton(app,  hObject);
            
                        yokes=getappdata( handles.figure1, 'yokes');
                        for i=1:length(yokes)
                            handles=guidata(yokes(i));
                            set(handles.ImgObject4,'Visible','off');
                            set(handles.markerToggleTool,'State', 'off');
            
                            % Clear the marker when turning off markerToggleButton
                            set( handles.ImgObject4, 'CData', zeros( size( get(handles.ImgObject4,'CData')) ));
                            set( handles.ImgObject4, 'AlphaData', zeros( size( get(handles.ImgObject4,'AlphaData')) ));
                            guidata(handles.figure1, handles);
            
            
                            % Have the pointer change to cross-hair when the mouse enters an axes object:
                            hFigure = yokes(i);
                            hAxes =  handles.axes1;
                            iptPointerManager(hFigure, 'disable');
                            %set(hFigure, 'PointerShapeCData', NaN( [16 16]) );
                            %iptSetPointerBehavior(hAxes, @(hFigure, currentPoint)set(hFigure, 'Pointer', 'crosshair'));
                        end
        end

        % On callback: markerToggleTool
        function markerToggleButtonOn_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                   % Display HELP and get out of callback
                   if DisplayHelp(app, hObject, eventdata, handles)
                       set(hObject,'State', 'off')
                       return
                   end
                   pressedToggleButton(app,  hObject);
                   yokes=getappdata( handles.figure1, 'yokes');
                   for i=1:length(yokes)
                       handles=guidata(yokes(i));
                       set(handles.ImgObject4,'Visible','on');
                       set(handles.markerToggleTool,'State', 'on');
            
                       % Have the pointer change to transparent when the mouse enters an axes object:
                       hFigure = yokes(i);
                       hAxes =  handles.axes1;
                       iptPointerManager(hFigure, 'enable');
                       set(hFigure, 'PointerShapeCData', NaN( [16 16]) );
                       iptSetPointerBehavior(hAxes, @(hFigure, currentPoint)set(hFigure, 'Pointer', 'custom'));
                   end
        end

        % Callback function: measureTool
        function measureTapeToggleButton_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                        % Display HELP and get out of callback
                        if DisplayHelp(app, hObject, eventdata, handles)
                            %set(hObject,'State', 'off')
                            return
                        end
            
                        pressedToggleButton(app,  hObject);
            
                        % Name of measurement
                        lobj = findobj(gcf, 'Type','images.roi.line');
                        simpleLines = findobj(gcf, 'Type','Line', 'Tag','imlook4d_measure');
                        n = length(lobj) + length(simpleLines) + 1;
                        answer=inputdlg({'Enter ROI name:'},'Input ROI name',1,{['Measure ' num2str(n)]});
                        name=answer{1};
            
            
                        %
                        % Make measurement and contextual menus
                        %
                        slice=round(get(handles.SliceNumSlider,'Value'));
                        orientation = handles.image.plane; % 'Axial' / 'Sagital' / 'Coronal'
            
                        try
                            % If imaging toolbox missing
                            %throw( MException('MyComponent:Testing',' ')); % TEST - fall into non-image toolbox version
                            h = drawline(gca,'LineWidth',1 );  % Manually calling this : h = drawline(gca, 'Position', h.Position )
                            measureTapeContextualMenusImageToolbox(app,  h, name, slice, orientation);
            
                        catch
                            % If imaging toolbox missing, or other faults -- make a ?simpler line with less functionality
                            [x,y]= ginput(2);
                            h = line( x,y,'LineWidth',1, 'Tag','imlook4d_measure');
                            measureTapeContextualMenusNoToolbox(app,  h, name, slice, orientation);
                        end
            
            
                        releasedToggleButton(app,  hObject)
        end

        % Menu selected function: Untitled_5
        function measureTape_CopyValues(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [copyValuesContextMenuItem, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                        % Copy values to clipboard
            
                        TAB=sprintf('\t');
                        EOL=sprintf('\n');
            
                        s = [ 'name' TAB 'length [mm]' TAB 'length [pixels]' TAB 'Angle [degrees]' EOL];
                        s = [ 'placement' TAB 'name' TAB 'length [mm]' TAB 'length [pixels]' TAB 'Angle [degrees]' EOL];
            
            
                        %
                        % With imaging toolbox
                        %
                            lobj = findobj(gcf, 'Type','images.roi.line');
                            for i = 1:length(lobj)
                                pos = lobj(i).Position;
            
                                name = lobj(i).UIContextMenu.UserData.textHandle.String;
                                lineContextMenuItem = lobj(i).UIContextMenu;
            
                                switch lobj(i).UIContextMenu.UserData.orientation
                                    case 'Axial'
                                        shortOrientation = 'Ax';
                                    case 'Coronal'
                                        shortOrientation = 'Cor';
                                    case 'Sagital'
                                        shortOrientation = 'Sag';
                                end
            
                                placement = [ 'Slice=' num2str( lobj(i).UIContextMenu.UserData.slice) ' (' shortOrientation ')' ];
            
                                [ measureLength, pixels, angle_degrees ] = displayLineCoordinates(app, lineContextMenuItem, pos);
                                s = [ s placement TAB name TAB num2str(measureLength) TAB num2str(pixels) TAB num2str(angle_degrees) EOL];
            
                            end
            
                        %
                        % Without imaging toolbox
                        %
                            contextMenu = copyValuesContextMenuItem.Parent;
                            bottomLines = findobj(gcf, 'Type','Line', 'Tag','imlook4d_measure');
            
                            for i = 1:length(bottomLines)
                                line = bottomLines(i);
                                pos = [ line.XData ; line.YData]';
                                %disp(mat2str(pos,3));
                                try
                                    lineContextMenuItem = line.UIContextMenu.Children(end); % Last one is the top contextual menu, which contains the name (if imaging toolbox imline function existed)
                                    if strcmp( lineContextMenuItem.Tag, 'nameContextMenuItem')
                                        name = lineContextMenuItem.Text;
                                    else
                                        name = 'measure'; % set default name, if not imaging toolbox
                                    end
                                catch
                                    name = 'measure'; % set default name, if crashes
                                end
            
            
                                switch bottomLines(i).UIContextMenu.UserData.orientation
                                    case 'Axial'
                                        shortOrientation = 'Ax';
                                    case 'Coronal'
                                        shortOrientation = 'Cor';
                                    case 'Sagital'
                                        shortOrientation = 'Sag';
                                end
            
                                placement = [ 'Slice=' num2str( bottomLines(i).UIContextMenu.UserData.slice) ' (' shortOrientation ')' ];
            
                                [ measureLength, pixels, angle_degrees ] = displayLineCoordinates(app, lineContextMenuItem, pos);
            
                                s = [ s placement TAB name TAB num2str(measureLength) TAB num2str(pixels) TAB num2str(angle_degrees) EOL];
                            end
            
                        %
                        % To clipboard and command window
                        %
            
                            disp(' ');
                            disp('Measures copied to system clipboard :');
                            disp(' ');
            
                            disp(s);
                            clipboard('copy',s)
        end

        % Menu selected function: OpenRecent
        function openRecent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                         % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             return
                         end
            
                        % Get recent file list
                        historyFile = [ '' prefdir filesep 'imlook4d_file_open_history.mat' ''];
                        try
                            load(historyFile); % struct "history" should be loaded
                        catch
                            % make empty struct "history"
                            history = [];
                            history.time = {};
                            history.filePath = {};
                        end
            
                        % Display list
                        pathList = flip(history.filePath);  % Reverse order, newest first
                        [index,ok] = listdlg('ListString', pathList,...
                            'SelectionMode','single',...
                            'ListSize', [900 400],...
                            'Name', 'Open recent file');
            
                        % Open if not cancelled
                        if ok
                           imlook4d( pathList{index});
                        end
        end

        % Value changed function: orientationMenu
        function orientationMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                    orientationNumber=get(hObject,'Value');
                    if DisplayHelp(app, hObject, eventdata, handles)
                        set(hObject,'Value', orientationNumber); % Reset value to when clicked
                        return
                    end
            
                    % Set for foreground image
                    handles = setOrientation(app, handles, orientationNumber);
                    guidata(handles.figure1, handles);


                    % Set for background image (call orientationMenu_Callback for background image)
                    try
                        backgroundHandles = guidata(handles.image.backgroundImageHandle);  % Handles from imlook4d instance of static background image
                        set( backgroundHandles.orientationMenu,'Value', get(handles.orientationMenu,'Value'));
                        orientationMenu_Callback(backgroundHandles.orientationMenu, {}, backgroundHandles);
                    catch
                        % if not backgroundimage (this will happen when the backgroundimage itself is processed)
                    end
            
                    updateImage(app, hObject,{},handles);
            
                    % Move to same ROI in new orientation
                    if ~strcmp( handles.ROINumberMenu.String{ handles.ROINumberMenu.Value}, 'Add ROI')
                        ROINumberMenu_Callback( handles.ROINumberMenu, [], handles);
                    end
            
                    % Redraw measures by updating slice
                    slice=round(get(handles.SliceNumSlider,'Value'));
                    setSlice(app, handles, slice, handles.figure1)
        end

        % Callback function: PolyVOI
        function polyVOIToggleButton_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % Works similarly to Measures, that the contextual menu stores
                    % internal data
            
                        % Display HELP and get out of callback
                        if DisplayHelp(app, hObject, eventdata, handles)
                            set(hObject,'State', 'off')
                            return
                        end
            
                        roi_number = get(handles.ROINumberMenu,'Value');
                        numberOfRois = length(handles.ROINumberMenu.String) - 1;  % Ignore  'Add ROI' menu string
                        % Bail out if not ROI
                        if (  roi_number > numberOfRois )
                            warndlg({'The ROI is not created yet ! ',...
                                        'You need to click "Add ROI" first  ',...
                                        ''});
                            return
                        end
            
                        % Bail out if locked ROI
                        if ( handles.image.LockedROIs(roi_number) == 1 )
                            warndlg({'The ROI is locked! ',...
                                        'Locked ROIs can not be edited.  ',...
                                        'Unlock, or make a new ROI before making a polygon'});
                            return
                        end


                        slice = round(get(handles.SliceNumSlider,'Value' ));
            
                        pressedToggleButton(app,  hObject);
            
                        % interactive polygon
                        roi = drawpolygon( gca);
            
                        % Create contextual menu
                        contextMenu = roi.UIContextMenu;
                        contextMenu.Tag = 'polyVoiContextMenu';
            
                        contextMenuItem = uimenu(contextMenu,'Text','To ROI');
                        contextMenuItem.Separator = 'on'
                        contextMenuItem.MenuSelectedFcn = @(hObject,eventdata) imlook4d( 'convertPolyVoiToROI', hObject, eventdata, guidata(hObject));
            
                        contextMenuItem = uimenu(contextMenu,'Text','All to ROIs');
                        contextMenuItem.MenuSelectedFcn = @(hObject,eventdata) imlook4d( 'convertAllPolysVoiToROI', hObject, eventdata, guidata(hObject));
            
                        % Store polygon data in context menu
                        data.slice = slice;
                        data.roi_number = roi_number;
                        data.polygon = roi;
                        contextMenu.UserData = data;
            
                        % Finish
                        releasedToggleButton(app,  hObject);
                        guidata(hObject,handles)
            
                        disp(['hObject.State = ' hObject.State]);
                        hObject.UserData;
        end

        % Off callback: record_toolbar_button
        function recordOffButton_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                         % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             set(hObject,'State', 'off');
                             return
                         end
                         releasedToggleButton(app,  hObject);
            
            
                         handles.record.enabled = false;
                         guidata(handles.figure1, handles);
            
            
                         % Set all record buttons
                         buttons  = findobj('Tag','record_toolbar_button');
                         for i=1:length(buttons)
                             set(buttons(i),'State', 'off');
            
                             % Copy record struct
                             h = getParentFigure(app, buttons(i));
                             hHandles = guidata(h);
                             hHandles.record = handles.record;   % Copy the record struct
                         end
                         guidata(handles.figure1, handles);
        end

        % On callback: record_toolbar_button
        function recordOnButton_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                         % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             set(hObject,'State', 'off');
                             return
                         end
            
                         pressedToggleButton(app,  hObject);
            
            
                         EOL = sprintf('\n');
            
                         handles.record.enabled = true;
            
                         buttons  = findobj('Tag','record_toolbar_button');
            
                         % Find editor used in other imlook4ds
                         for i=1:length(buttons)
                             h = getParentFigure(app, buttons(i));
                             hHandles = guidata(h);
                             try
                                 if isfield(hHandles.record, 'editor')
                                     handles.record.editor = hHandles.record.editor;
                                 end
                             catch
                             end
                         end
            
            
                         % Set editor
                         newEditor = false;
                         try
                             % Try to use existing editor -- with dummy command
                            if ( ~ handles.record.editor.Opened() )
                                handles.record.editor = matlab.desktop.editor.newDocument(['% Script recording started at : ' datestr(now) EOL ]);
                            end
                         catch
                            % % Editor not open -- make a new one
                            % a = com.mathworks.mde.editor.MatlabEditorApplication.getInstance();
                            % handles.record.editor = a.newEditor( ['% Script recording started at : ' datestr(now) EOL ]);
                            % handles.record.editor.setCaretPosition(handles.record.editor.getLength());  % Go to end of line
                            % newEditor = true;
            
                            % Use official API instead
                            handles.record.editor = matlab.desktop.editor.newDocument(['% Script recording started at : ' datestr(now) EOL ]);
            
                            newEditor = true;
                         end


                         % Temporarily store callback
                         tempCallback = get(hObject, 'OnCallback');
            
            
                         % Set all record buttons
                       %  buttons  = findobj('Tag','record_toolbar_button');
                         for i=1:length(buttons)
                             % Inibit callbacks
                             set(buttons(i),'OnCallback', []);
            
                             % Copy record struct (editor, enabled, )
                            % imlook4d_handle = get( get( get(buttons(i),'Parent') ,'Parent'));  % Get imlook4d instance for i:th button
                            % imlook4d_handles.record = handles.record;   % Copy the record struct
            
                             % Set state
                             set(buttons(i),'State', 'on');
            
                             % Copy and store handles.record to other figures
                             h = getParentFigure(app, buttons(i));
                             hHandles = guidata(h);
                             hHandles.record = handles.record; % Copy
                             guidata(h, hHandles);
            
                             hHandles.record.editor;
                             % Reset callback functions
                             set(buttons(i),'OnCallback', tempCallback);
                         end
            
                         % Move to top
                         if newEditor
                            set(handles.figure1, 'Visible', 'off');
                            set(handles.figure1, 'Visible', 'on');
                         end
            
                         guidata(handles.figure1, handles);
        end

        % Callback function: resizePushButton
        function resizePushButton_ClickedCallback(app, event)
            % --------------------------------------------------------------------
            % TOOLBAR BUTTONS
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    % Performs resizing of the imlook4d when resize button is pressed
                    %
                    % The strategy is to resize with kept proportions on axes1 (the image),
                    % AND keeping the top left corner stationary until leaving screen.
                    % - Number of pixels resized in y-direction (dy) is used to calculate resize in x-direction (dx), (keeping proportions of axes1)
                    % - The number of pixels resized in figure1 is equal to number of pixels resized in axes1.
                    % - If resized to smaller than original size, resize to original size
                    % - GUI widgets are moved to correct position
                    % - axes1 is resized
                    % - figure1 is resized
                    %
                    % The lowerLeftY corner is calculated
                    %
                    % The fourth parameter ySizeIncrease is optional, and not used for
                    % callbacks.
                    %
                    % It is however used if you wish to set the size
                    % manually relative current size.  This is done in
                    % imlook4d_OpeningFcn
            
                    % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             %set(hObject,'State', 'off')
                             return
                         end


                    %
                    % Initialize
                    %
                        set(handles.ColorBar,'Units','Pixels');              % Make colorbar possible to move correct distance
            
                        if ~exist('ySizeIncrease','var')
                            ySizeIncrease=100;                                          % Increase in pixels relative current figure (y-direction)
                        end
            
                        figure1OriginalPosVector=handles.GUILayout.figure1;         % Stored at start of imlook4d
                        figure1CurrentPosVector=get(handles.figure1,'Position');    % Current size of figure1
            
                        axes1OriginalPosVector=handles.GUILayout.axes1;             % Stored at start of imlook4d
                        originalProportions=axes1OriginalPosVector(4)/axes1OriginalPosVector(3);  % Original proportions of axes1, height/width
            
                        screenSize=get(0,'ScreenSize');                             % Vector.  Index 3 is x, 4 is y
            
                        topLeftY=figure1CurrentPosVector(4)+figure1CurrentPosVector(2); % Y position of top left corner
            
            
                   %
                   % New size and position
                   %
            
                        % Increase:  Calculate new height (increase size until full, then jump to smallest size)
                            % dy is size increase relative original imlook4d window size
                            if (figure1CurrentPosVector(4)>(screenSize(4)-100) )
                                % Full size now, jump to original size
                                dy=0;
                            else % Not full size now, increase one step
                                dy=(figure1CurrentPosVector(4)-figure1OriginalPosVector(4) ); % Current height relative original
                                dy=dy+ySizeIncrease;                                          % New height relative original
                            end
            
                        % Decrease (CTRL button pressed): Calculate new width
                            if strcmp( get(handles.figure1, 'currentmodifier'),'control') | (strcmp( get(handles.figure1, 'currentmodifier'),'shift'))
                                dy=(figure1CurrentPosVector(4)-figure1OriginalPosVector(4) ); % Current height relative original
                                dy=dy-ySizeIncrease;                                          % New height relative original
                            end
            
                            %disp(['New size=(' num2str(get(handles.figure1, 'Position') ) ')']);
            
            
                        % If window smaller than original, set to original
                            if (dy<0)
                                dy=0;% Set height to original size
                            end
            
                        % Calculate dx, keeping proportions:
                        %   [ dy+axes1OriginalPosVector(4) ]/ [ dx+axes1OriginalPosVector(3) ]=originalProportions
                            dx=( dy+axes1OriginalPosVector(4) ) /originalProportions  - axes1OriginalPosVector(3);
            
            
                     %
                     % Move GUI widgets according to resize of figure1
                     %
            
                           % FieldNames for GUILayout
                           try
                                guiNames=fieldnames(handles.GUILayout);
                           catch
                               disp('imlook4d/figure1_ResizeFcn:  ERROR getting field names');
                               return
                           end
            
                         guiPositions=struct2cell(handles.GUILayout);  % gui positions stored at start of imlook4d instance
                         % Loop all GUI objects except first two (figure1, axes1)
                            for i=3:size(guiPositions,1)
                                xPos=guiPositions{i}(1)+dx;
                                yPos=guiPositions{i}(2)+dy;
                                width=guiPositions{i}(3);
                                height=guiPositions{i}(4);
            
                                h=eval(['handles.' guiNames{i}]);
                                set(h,'Position',[xPos yPos width height]);
                            end
            
                     %
                     % Resize axes1
                     %
                          axes1PosVector(1)=axes1OriginalPosVector(1);
                          axes1PosVector(2)=axes1OriginalPosVector(2);
                          axes1PosVector(3)=handles.GUILayout.axes1(3)+dx;
                          axes1PosVector(4)=handles.GUILayout.axes1(4)+dy;
                          set(handles.axes1,'Position',axes1PosVector);
                     %
                     % Resize figure1
                     %
                          realHeight=dy+figure1OriginalPosVector(4);  % real window height=dy+[original height]
                          figure1PosVector(1)=figure1CurrentPosVector(1);
                          figure1PosVector(2)=topLeftY-realHeight;
                          figure1PosVector(3)=handles.GUILayout.figure1(3)+dx;
                          figure1PosVector(4)=handles.GUILayout.figure1(4)+dy;
                          set(handles.figure1,'Position',figure1PosVector);
                          movegui(hObject, 'onscreen')
            
                     %
                     % Clean up
                     %
                          set(handles.ColorBar,'Units','normalized');
        end

        % Off callback: rotateToggleButton
        function rotateToggleButtonOff_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                   % Display HELP and get out of callback
                   if DisplayHelp(app, hObject, eventdata, handles)
                       set(hObject,'State', 'off')
                       return
                   end
            
                    % Bail out if not imaging toolbox
                    % (rotateToggleButtonOff_ClickedCallback called from error in
                    % rotateToggleButtonOn_ClickedCallback)
                   if ~exist('rotate3d')
                       set(hObject,'State', 'off')
                       return
                   end
            
                   handles.infoText1.Visible = 'on'; % Show infotext at bottom
            
            
                   releasedToggleButton(app,  hObject);
            
                   [az,el] = view;


                    % Fix angle error due to PlotBoxAspectRatio
                    plotboxAspectRatio = handles.axes1.PlotBoxAspectRatio;
                    ratio = plotboxAspectRatio(1) /plotboxAspectRatio(2);
                    az = 180*atan( ratio*tan(pi*az/180))/pi;
            
                    % Reverse angle if Flip + rotate is off
                    if ( handles.FlipAndRotateRadioButton.Value == 0)
                        az = -az;
                    end
            
                   % rotate
                   [handles.image.Cdata, handles.image.ROI]  = rotateUsingIsotropic(app,  handles, handles.image.Cdata, -az, handles.image.ROI);
                   view(0,90);
            
                   rotate2d_jan off
                   guidata( handles.figure1,handles);
                   disp([ 'Rotated ' num2str(az) ' degrees in ' handles.orientationMenu.String{ handles.orientationMenu.Value} ' plane']);
                   updateImage(app, handles.figure1, [], handles);
        end

        % On callback: rotateToggleButton
        function rotateToggleButtonOn_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                   % NOTE:
                   % I have cleared the ButtonDownFct for the rotate toggle button,
                   % and now use my own implementation.
                   %
                   % Reason: I could not get camzoom(1) and ActionPostCallback
                   % otherwise.
            
            
                   % Display HELP and get out of callback
                   if DisplayHelp(app, hObject, eventdata, handles)
                       set(hObject,'State', 'on')
                       return
                   end
            
            
                   % Bail out if not imaging toolbox
                   if ~exist('rotate3d')
                       errordlg({'Requires Matlab Imaging Toolbox', 'If you have a license for this, please install' });
                       set(hObject,'State', 'off')
                       return
                   end
            
                   pressedToggleButton(app,  hObject);
            
                   h = rotate2d_jan( handles.axes1);
                   h.Enable = 'on';
            
                   camzoom(1)
            
                   handles.infoText1.Visible = 'off';
        end

        % Menu selected function: show_patient_info
        function show_patient_info_Callback(app, event)
            % Preferences submenu
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                       % Display HELP and get out of callback
                             if DisplayHelp(app, hObject, eventdata, handles)
                                 return
                             end
            
                       % Toggle Check mark
            
                       if strcmp(get(hObject,'Checked'),'on')
                           set(hObject,'Checked','off')
                       else
                           set(hObject,'Checked','on')
                       end
        end

        % Window button down function: figure1
        function wbd(app, event)
            % --------------------------------------------------------------------
            % ROI drawing functions
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, evd, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                     % executes when the mouse button is down
                     % (guide couples this to the mouse button via property:  figure/WindowButtonDownFcn )
                     %get(gca,'currentpoint')
                     % Display HELP and get out of callback
                         if DisplayHelp(app, h, evd, handles)
                             return
                         end
                     %disp('wbd');
                     activeROI=get(handles.ROINumberMenu,'Value');
            
            
                    % Do not draw if Right Click
                    if strcmp( get(handles.figure1,'selectionType') , 'alt')
                        return
                    end
            
            
                     % Get out if trying to draw ROI when "Add ROI" is chosen
                     if strcmp(  get(handles.ROINumberMenu,'String'), 'Add ROI')
                         return
                     end
            
                     % Get out if trying to draw ROI when "hidden"
                     if (handles.image.VisibleROIs(activeROI)==0)
                         return
                     end
            
            
                     % get the values and store them in the figure's appdata
                     props.WindowButtonMotionFcn = get(h,'WindowButtonMotionFcn');
                     props.WindowButtonUpFcn = get(h,'WindowButtonUpFcn');
                     setappdata(h,'TestGuiCallbacks',props);
            
                     % set the new values for the WindowButtonMotionFcn and
                     % WindowButtonUpFcn
                     %set(h,'WindowButtonMotionFcn',{@app.wbm})
                     %set(h,'WindowButtonUpFcn',{@app.wbu})
                     set(h,'WindowButtonMotionFcn','imlook4d(''wbm'',gcbo,[],guidata(gcbo))');
                     set(h,'WindowButtonUpFcn','imlook4d(''wbu'',gcbo,[],guidata(gcbo))');
            
                      % Record last mouse position for drawROI track interpolation
                     coordinates=get(gca,'currentpoint');
            
                     x=round(coordinates(1,1) + 0.5);
                     y=round(coordinates(1,2) + 0.5);
                     handles.image.lastMousePosition = [x y];
                     guidata(handles.figure1,handles);
            
                     drawROI(app, h, evd, handles)
        end

        % Callback function: axes1, figure1
        function wbm2(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, evd, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    if strcmp(get(handles.markerToggleTool,'State'), 'on')
                        drawCursorInYokes2(app, handles)
                    end
        end

        % Menu selected function: windows
        function windows_Callback(app, event)
            % --------------------------------------------------------------------
            % WINDOWS
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                    % Display HELP and get out of callback
                        if DisplayHelp(app, hObject, eventdata, handles)
                             return
                        end
            
                    % Clear sub-menues
                        subMenuHandles=get(handles.windows,'Children');
            %             for i=1:length(subMenuHandles)
            %                 delete(subMenuHandles(i))
            %             end
                        delete(subMenuHandles);
            
                    % Find all windows
                    g=findobj('Type', 'figure');
            
                    % Show only figures and imlook4d instances
                        j=1;
                        for i=1:length(g)
                            % Mark current window with a checkbox
                            if ( strcmp( get(g(i),'Tag'), 'imlook4d' ) || strcmp( get(g(i),'Tag'), '' ) )
                                 h(j,1) = g(i);
                                 get(h(j),'Tag');
                                 j = j+1;
                            end
                        end
            
                    % Create html-formatted text
                        [windowDescriptions h]= htmlWindowDescriptions(app, h);  % Sorted html list
            
            
                    % Create menues
                         for i=1:size(h,1)
                             % Submenu items
                                callback='imlook4d(''windowsSubMenu_Callback'',gcbo,[],guidata(gcbo))';
            
                                htemp=uimenu(handles.windows,'Label',  windowDescriptions{i},'Callback', callback, 'UserData', h(i));  % Store handle to window in 'UserData'
            
            
                                    % Mark current window with a checkbox
                                    if (h(i) == handles.figure1)
                                        set(htemp, 'Checked', 'on')
                                    end
                         end
        end

        % Off callback: A, B, C
        function yokeOffButton_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                         % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             set(hObject,'State', 'off')
                             return
                         end
                         releasedToggleButton(app,  hObject);
            
                         imlook4d_instances=findobj('Tag','imlook4d');
                         buttonsSameNameAsPressed=findobj('Tag',get(hObject, 'Tag'));
                         this_imlook4d_instance=get(get(hObject,'Parent'),'Parent');
            
                         disp('-------------------------------------');
                         %disp([ 'This imlook4d=' num2str(this_imlook4d_instance) '   ' get(hObject, 'Tag') ' ' 'OFF' ]);
            
                         % Clear all references TO this imlook4d-instance
                          imlook4d_instances=findobj('Tag','imlook4d');
                          for i=1:length(imlook4d_instances)
                              yokes=getappdata(imlook4d_instances(i),'yokes');
                              yokes=yokes(yokes~=this_imlook4d_instance);  % Remove this_imlook4d_instance
                              setappdata(imlook4d_instances(i),'yokes',yokes);
                          end
            
                          % Clear all references IN this imlook4d-instance
                          rmappdata(this_imlook4d_instance,'currentYoke');
                          setappdata(this_imlook4d_instance,'yokes',[]);
            
                          % Clear the cursor layer
                          set( handles.ImgObject4, 'CData', zeros( size( get(handles.ImgObject4,'CData')) ));
                          set( handles.ImgObject4, 'AlphaData', zeros( size( get(handles.ImgObject4,'AlphaData')) ));
                          guidata(handles.figure1, handles);
        end

        % On callback: A, B, C
        function yokeOnButton_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                        % appdata:
                        %  currentYoke  handle to button
                        %  yokes       imlook4d-handles, where same yoke button pressed
                        % Add this imlook4d-handle to all with same button pressed
            
                         % Display HELP and get out of callback
                         if DisplayHelp(app, hObject, eventdata, handles)
                             set(hObject,'State', 'off')
                             return
                         end
                        pressedToggleButton(app,  hObject);
            
                         %imlook4d_instances=findobj('Tag','imlook4d');
                         buttonsSameNameAsPressed=findobj('Tag',get(hObject, 'Tag'));
                         this_imlook4d_instance=get(get(hObject,'Parent'),'Parent');
            
                         disp('-------------------------------------');
                         %disp([ 'This imlook4d=' num2str(this_imlook4d_instance) '   ' get(hObject, 'Tag') ' ' 'ON' ]);
            
                       % Toggle previously pressed button
                       if ( getappdata(this_imlook4d_instance,'currentYoke') ~= hObject)
                          set( getappdata(this_imlook4d_instance,'currentYoke'), 'State', 'off'); % Callback yokeOffButton_ClickedCallback
                       end
            
                       % Add this imlook4d-handle to all with same button pressed
                          sameYoke=findobj('-regexp','Tag',get(hObject, 'Tag'),'State','on');  % Buttons with same yoke
                          disp(['Found ' num2str(length(sameYoke)) ' pressed buttons with tag=' get(hObject, 'Tag')]);
                          yokes=[];
                          % Build array of all imlook4d-instances with same button pressed as this
                          for i=1:length(sameYoke)
                              imlook4d_instances(i)=get(get(sameYoke(i),'Parent'),'Parent');   % imlook4d instances with same yoke
                              yokes=[yokes imlook4d_instances(i)];
                          end
                          % Set appdata for all imlook4d-instances
                          for i=1:length(sameYoke)
                              setappdata(imlook4d_instances(i),'yokes',yokes);
                          end
            
            %            % Remove this button from this imlook4d-instance
            %                  yokes=getappdata(this_imlook4d_instance,'yokes');
            %                  yokes=yokes(yokes~=this_imlook4d_instance);  % Remove this_imlook4d_instance
            %                  setappdata(this_imlook4d_instance,'yokes',yokes);
            
            
                      setappdata(this_imlook4d_instance,'currentYoke',hObject);
                      yoke_inspection(app, this_imlook4d_instance, get(hObject, 'Tag') );
        end

        % Callback function: DataCursortoggletool
        function DataCursortoggletool_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.DataCursortoggletool.State;
            datacursormode(app.figure1, char(state));
        end

        % Callback function: Pantoggletool
        function Pantoggletool_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.Pantoggletool.State;
            pan(app.figure1, char(state));
        end

        % Callback function: ZoomOuttoggletool
        function ZoomOuttoggletool_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.ZoomOuttoggletool.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: ZoomIntoggletool
        function ZoomIntoggletool_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.ZoomIntoggletool.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'imlook4d';
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1_ResizeFcn, true);
            app.figure1.WindowButtonDownFcn = createCallbackFcn(app, @wbd, true);
            app.figure1.WindowButtonMotionFcn = createCallbackFcn(app, @wbm2, true);
            app.figure1.WindowScrollWheelFcn = createCallbackFcn(app, @figure1_ScrollWheelFcn, true);
            app.figure1.KeyReleaseFcn = createCallbackFcn(app, @figure1_KeyReleasedFunction, true);
            app.figure1.Tag = 'figure1';
            app.Save_manually = uimenu(app.figure1);
            app.Save_manually.Text = 'File';
            app.Save_manually.Tag = 'Save_manually';
            app.OpenFile = uimenu(app.Save_manually);
            app.OpenFile.Accelerator = 'O';
            app.OpenFile.Text = 'Open';
            app.OpenFile.Tag = 'OpenFile';
            app.OpenRecent = uimenu(app.Save_manually);
            app.OpenRecent.MenuSelectedFcn = createCallbackFcn(app, @openRecent_Callback, true);
            app.OpenRecent.Text = 'Open Recent';
            app.OpenRecent.Tag = 'OpenRecent';
            app.OpenAndMerge = uimenu(app.Save_manually);
            app.OpenAndMerge.Text = 'Open and merge';
            app.OpenAndMerge.Tag = 'OpenAndMerge';
            app.OpenFromPACS = uimenu(app.Save_manually);
            app.OpenFromPACS.MenuSelectedFcn = createCallbackFcn(app, @OpenFromPacs_Callback, true);
            app.OpenFromPACS.Visible = 'off';
            app.OpenFromPACS.Separator = 'on';
            app.OpenFromPACS.Text = 'Open from PACS';
            app.OpenFromPACS.Tag = 'OpenFromPACS';
            app.NetworkHostsMenu = uimenu(app.Save_manually);
            app.NetworkHostsMenu.MenuSelectedFcn = createCallbackFcn(app, @NetworkHostsMenu_Callback, true);
            app.NetworkHostsMenu.Visible = 'off';
            app.NetworkHostsMenu.Text = 'Network hosts';
            app.NetworkHostsMenu.Tag = 'NetworkHostsMenu';
            app.Open_manually = uimenu(app.Save_manually);
            app.Open_manually.Visible = 'off';
            app.Open_manually.Enable = 'off';
            app.Open_manually.Text = 'Open manually';
            app.Open_manually.Tag = 'Open_manually';
            app.OpenECAT = uimenu(app.Open_manually);
            app.OpenECAT.Text = 'Open ECAT';
            app.OpenECAT.Tag = 'OpenECAT';
            app.OpenDICOMPET = uimenu(app.Open_manually);
            app.OpenDICOMPET.Text = 'Open DICOM PET';
            app.OpenDICOMPET.Tag = 'OpenDICOMPET';
            app.DirtyDICOMOpen = uimenu(app.Open_manually);
            app.DirtyDICOMOpen.Text = 'Open DICOM (Jan)';
            app.DirtyDICOMOpen.Tag = 'DirtyDICOMOpen';
            app.OpenDICOMDIR = uimenu(app.Open_manually);
            app.OpenDICOMDIR.Text = 'Open DICOMDIR';
            app.OpenDICOMDIR.Tag = 'OpenDICOMDIR';
            app.OpenSHR = uimenu(app.Open_manually);
            app.OpenSHR.Text = 'Open SHR';
            app.OpenSHR.Tag = 'OpenSHR';
            app.OpenBinary = uimenu(app.Open_manually);
            app.OpenBinary.Text = 'Open Binary';
            app.OpenBinary.Tag = 'OpenBinary';
            app.OpenMat = uimenu(app.Open_manually);
            app.OpenMat.Text = 'Open mat-file';
            app.OpenMat.Tag = 'OpenMat';
            app.SaveFile = uimenu(app.Save_manually);
            app.SaveFile.MenuSelectedFcn = createCallbackFcn(app, @SaveFile_Callback, true);
            app.SaveFile.Separator = 'on';
            app.SaveFile.Accelerator = 'S';
            app.SaveFile.Text = 'Save';
            app.SaveFile.Tag = 'SaveFile';
            app.Save_nifti_short = uimenu(app.Save_manually);
            app.Save_nifti_short.MenuSelectedFcn = createCallbackFcn(app, @SaveFile_Callback, true);
            app.Save_nifti_short.Text = 'Save (short format)';
            app.Save_nifti_short.Tag = 'Save_nifti_short';
            app.LoadROI = uimenu(app.Save_manually);
            app.LoadROI.MenuSelectedFcn = createCallbackFcn(app, @LoadRoiPushbutton_Callback, true);
            app.LoadROI.Separator = 'on';
            app.LoadROI.Text = 'Load ROI';
            app.LoadROI.Tag = 'LoadROI';
            app.SaveROI = uimenu(app.Save_manually);
            app.SaveROI.MenuSelectedFcn = createCallbackFcn(app, @SaveRoiPushbutton_Callback, true);
            app.SaveROI.Text = 'Save ROI';
            app.SaveROI.Tag = 'SaveROI';
            app.Save_manually_1 = uimenu(app.Save_manually);
            app.Save_manually_1.Visible = 'off';
            app.Save_manually_1.Enable = 'off';
            app.Save_manually_1.Text = 'Save manually';
            app.Save_manually_1.Tag = 'Save_manually_1';
            app.SaveECAT = uimenu(app.Save_manually_1);
            app.SaveECAT.Text = 'Save ECAT-file (Orientation and PCA modifies)';
            app.SaveECAT.Tag = 'SaveECAT';
            app.SavePCtoECAT = uimenu(app.Save_manually_1);
            app.SavePCtoECAT.Text = 'Save PC-images to ECAT-file';
            app.SavePCtoECAT.Tag = 'SavePCtoECAT';
            app.DirtyDICOMSave = uimenu(app.Save_manually_1);
            app.DirtyDICOMSave.Text = 'Save DICOM (Jan)';
            app.DirtyDICOMSave.Tag = 'DirtyDICOMSave';
            app.Savemat = uimenu(app.Save_manually_1);
            app.Savemat.Text = 'Save mat-file (Orientation and PCA modifies)';
            app.Savemat.Tag = 'Savemat';
            app.SaveMat2 = uimenu(app.Save_manually);
            app.SaveMat2.MenuSelectedFcn = createCallbackFcn(app, @SaveMat_Callback, true);
            app.SaveMat2.Separator = 'on';
            app.SaveMat2.Text = 'Save Single Window State (.mat file)';
            app.SaveMat2.Tag = 'SaveMat2';
            app.save_all_windows = uimenu(app.Save_manually);
            app.save_all_windows.MenuSelectedFcn = createCallbackFcn(app, @SaveWindows_Callback, true);
            app.save_all_windows.Text = 'Save Windows + Layout (all windows)';
            app.save_all_windows.Tag = 'save_all_windows';
            app.Print = uimenu(app.Save_manually);
            app.Print.MenuSelectedFcn = createCallbackFcn(app, @Print_Image_Callback, true);
            app.Print.Separator = 'on';
            app.Print.Accelerator = 'P';
            app.Print.Text = 'Print';
            app.Print.Tag = 'Print';
            app.Close_many = uimenu(app.Save_manually);
            app.Close_many.MenuSelectedFcn = createCallbackFcn(app, @Close_many_Callback, true);
            app.Close_many.Separator = 'on';
            app.Close_many.Text = 'Close window(s)';
            app.Close_many.Tag = 'Close_many';
            app.Close_one = uimenu(app.Save_manually);
            app.Close_one.MenuSelectedFcn = createCallbackFcn(app, @Close_one_Callback, true);
            app.Close_one.Accelerator = 'W';
            app.Close_one.Text = 'Close';
            app.Close_one.Tag = 'Close_one';
            app.EditMenu = uimenu(app.figure1);
            app.EditMenu.Text = 'Edit';
            app.EditMenu.Tag = 'EditMenu';
            app.Undo = uimenu(app.EditMenu);
            app.Undo.MenuSelectedFcn = createCallbackFcn(app, @Undo_ROI_Callback, true);
            app.Undo.Accelerator = 'Z';
            app.Undo.Text = 'Undo';
            app.Undo.Tag = 'Undo';
            app.Redo = uimenu(app.EditMenu);
            app.Redo.MenuSelectedFcn = createCallbackFcn(app, @Redo_ROI_Callback, true);
            app.Redo.Accelerator = 'Y';
            app.Redo.Text = 'Redo';
            app.Redo.Tag = 'Redo';
            app.Copy_Image = uimenu(app.EditMenu);
            app.Copy_Image.MenuSelectedFcn = createCallbackFcn(app, @Copy_Image_Callback, true);
            app.Copy_Image.Separator = 'on';
            app.Copy_Image.Accelerator = 'C';
            app.Copy_Image.Text = 'Copy Image';
            app.Copy_Image.Tag = 'Copy_Image';
            app.copy_TACT_to_clipboard = uimenu(app.EditMenu);
            app.copy_TACT_to_clipboard.MenuSelectedFcn = createCallbackFcn(app, @copy_TACT_to_clipboard_Callback, true);
            app.copy_TACT_to_clipboard.Text = 'Copy TACT';
            app.copy_TACT_to_clipboard.Tag = 'copy_TACT_to_clipboard';
            app.Edit_window_title = uimenu(app.EditMenu);
            app.Edit_window_title.MenuSelectedFcn = createCallbackFcn(app, @Edit_window_title_Callback, true);
            app.Edit_window_title.Accelerator = 'T';
            app.Edit_window_title.Text = 'Window title';
            app.Edit_window_title.Tag = 'Edit_window_title';
            app.Zoom = uimenu(app.EditMenu);
            app.Zoom.MenuSelectedFcn = createCallbackFcn(app, @Zoom_Callback, true);
            app.Zoom.Text = 'Zoom';
            app.Zoom.Tag = 'Zoom';
            app.MatlabMenu = uimenu(app.EditMenu);
            app.MatlabMenu.MenuSelectedFcn = createCallbackFcn(app, @MatlabMenu_Callback, true);
            app.MatlabMenu.Accelerator = 'M';
            app.MatlabMenu.Text = 'Matlab menu';
            app.MatlabMenu.Tag = 'MatlabMenu';
            app.WindowLevelsMenu = uimenu(app.EditMenu);
            app.WindowLevelsMenu.Separator = 'on';
            app.WindowLevelsMenu.Text = 'Window Levels';
            app.WindowLevelsMenu.Tag = 'WindowLevelsMenu';
            app.EditScale = uimenu(app.WindowLevelsMenu);
            app.EditScale.MenuSelectedFcn = createCallbackFcn(app, @EditScale_Callback, true);
            app.EditScale.Accelerator = 'L';
            app.EditScale.Text = 'Edit scale';
            app.EditScale.Tag = 'EditScale';
            app.Cmaps = uimenu(app.EditMenu);
            app.Cmaps.Text = 'Color';
            app.Cmaps.Tag = 'Cmaps';
            app.ROI_menu = uimenu(app.EditMenu);
            app.ROI_menu.Text = 'ROI';
            app.ROI_menu.Tag = 'ROI_menu';
            app.GuessRoiColor = uimenu(app.ROI_menu);
            app.GuessRoiColor.MenuSelectedFcn = createCallbackFcn(app, @GuessRoiColor_Callback, true);
            app.GuessRoiColor.Text = 'Guess ROI color';
            app.GuessRoiColor.Tag = 'GuessRoiColor';
            app.ColorfulROI = uimenu(app.ROI_menu);
            app.ColorfulROI.MenuSelectedFcn = createCallbackFcn(app, @ColorfulROI_Callback, true);
            app.ColorfulROI.Text = 'Green current ROI';
            app.ColorfulROI.Tag = 'ColorfulROI';
            app.GrayROI = uimenu(app.ROI_menu);
            app.GrayROI.MenuSelectedFcn = createCallbackFcn(app, @GrayROI_Callback, true);
            app.GrayROI.Text = 'Gray ROI';
            app.GrayROI.Tag = 'GrayROI';
            app.MultiColoredROIs = uimenu(app.ROI_menu);
            app.MultiColoredROIs.MenuSelectedFcn = createCallbackFcn(app, @MultiColoredROIs_Callback, true);
            app.MultiColoredROIs.Checked = 'on';
            app.MultiColoredROIs.Text = 'Multi Colored ROIs';
            app.MultiColoredROIs.Tag = 'MultiColoredROIs';
            app.preferences = uimenu(app.EditMenu);
            app.preferences.Separator = 'on';
            app.preferences.Text = 'Preferences';
            app.preferences.Tag = 'preferences';
            app.show_patient_info = uimenu(app.preferences);
            app.show_patient_info.MenuSelectedFcn = createCallbackFcn(app, @show_patient_info_Callback, true);
            app.show_patient_info.Visible = 'off';
            app.show_patient_info.Enable = 'off';
            app.show_patient_info.Text = 'Show patient info';
            app.show_patient_info.Tag = 'show_patient_info';
            app.interpolate2 = uimenu(app.preferences);
            app.interpolate2.MenuSelectedFcn = createCallbackFcn(app, @interpolate2_Callback, true);
            app.interpolate2.Accelerator = 'I';
            app.interpolate2.Text = 'Interpolate x2';
            app.interpolate2.Tag = 'interpolate2';
            app.interpolate4 = uimenu(app.preferences);
            app.interpolate4.MenuSelectedFcn = createCallbackFcn(app, @interpolate4_Callback, true);
            app.interpolate4.Accelerator = 'J';
            app.interpolate4.Text = 'Interpolate x4';
            app.interpolate4.Tag = 'interpolate4';
            app.WorkspaceMenu = uimenu(app.figure1);
            app.WorkspaceMenu.Text = 'Workspace';
            app.WorkspaceMenu.Tag = 'WorkspaceMenu';
            app.exportAsViewedToWorkspace = uimenu(app.WorkspaceMenu);
            app.exportAsViewedToWorkspace.MenuSelectedFcn = createCallbackFcn(app, @exportAsViewedToWorkspace_Callback, true);
            app.exportAsViewedToWorkspace.Text = 'Export filtered';
            app.exportAsViewedToWorkspace.Tag = 'exportAsViewedToWorkspace';
            app.exportToWorkspace = uimenu(app.WorkspaceMenu);
            app.exportToWorkspace.MenuSelectedFcn = createCallbackFcn(app, @exportToWorkspace_Callback, true);
            app.exportToWorkspace.Text = 'Export (untouched)';
            app.exportToWorkspace.Tag = 'exportToWorkspace';
            app.importFromWorkspace = uimenu(app.WorkspaceMenu);
            app.importFromWorkspace.MenuSelectedFcn = createCallbackFcn(app, @importFromWorkspace_Callback, true);
            app.importFromWorkspace.Text = 'Import';
            app.importFromWorkspace.Tag = 'importFromWorkspace';
            app.importUntouchedToWorkspace = uimenu(app.WorkspaceMenu);
            app.importUntouchedToWorkspace.MenuSelectedFcn = createCallbackFcn(app, @importUntouched_Callback, true);
            app.importUntouchedToWorkspace.Text = 'Import (untouched)';
            app.importUntouchedToWorkspace.Tag = 'importUntouchedToWorkspace';
            app.HelpMenu = uimenu(app.figure1);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu.Tag = 'HelpMenu';
            app.Help = uimenu(app.HelpMenu);
            app.Help.Text = 'Help';
            app.Help.Tag = 'Help';
            app.Acknowledgements = uimenu(app.HelpMenu);
            app.Acknowledgements.MenuSelectedFcn = createCallbackFcn(app, @Acknowledgements_Callback, true);
            app.Acknowledgements.Text = 'Acknowledgements';
            app.Acknowledgements.Tag = 'Acknowledgements';
            app.About = uimenu(app.HelpMenu);
            app.About.MenuSelectedFcn = createCallbackFcn(app, @About_Callback, true);
            app.About.Text = 'About';
            app.About.Tag = 'About';
            app.upgrade_menu = uimenu(app.HelpMenu);
            app.upgrade_menu.Text = 'Update';
            app.upgrade_menu.Tag = 'upgrade_menu';
            app.windows = uimenu(app.figure1);
            app.windows.MenuSelectedFcn = createCallbackFcn(app, @windows_Callback, true);
            app.windows.Text = 'Windows';
            app.windows.Tag = 'windows';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.cd = uipushtool(app.uitoolbar1);
            app.cd.Tag = 'cd';
            app.cd.Tooltip = 'Set Matlab current directory to folder of this file';
            app.cd.ClickedCallback = createCallbackFcn(app, @cdPushButton_ClickedCallback, true);
            app.cd.Icon = 'cd_image.png';
            app.ZoomIntoggletool = uitoggletool(app.uitoolbar1);
            app.ZoomIntoggletool.Tag = 'ZoomIntoggletool';
            app.ZoomIntoggletool.Tooltip = 'Zoom In';
            app.ZoomIntoggletool.ClickedCallback = createCallbackFcn(app, @ZoomIntoggletool_ClickedCallback, true);
            app.ZoomIntoggletool.Icon = 'ZoomIntoggletool_image.png';
            app.ZoomIntoggletool.OffCallback = createCallbackFcn(app, @ZoomIntoggletool_ReleasedCallback, true);
            app.ZoomOuttoggletool = uitoggletool(app.uitoolbar1);
            app.ZoomOuttoggletool.Tag = 'ZoomOuttoggletool';
            app.ZoomOuttoggletool.Tooltip = 'Zoom Out';
            app.ZoomOuttoggletool.ClickedCallback = createCallbackFcn(app, @ZoomOuttoggletool_ClickedCallback, true);
            app.ZoomOuttoggletool.Icon = 'ZoomOuttoggletool_image.png';
            app.ZoomOuttoggletool.OffCallback = createCallbackFcn(app, @ZoomOuttoggletool_ReleasedCallback, true);
            app.Pantoggletool = uitoggletool(app.uitoolbar1);
            app.Pantoggletool.Tag = 'Pantoggletool';
            app.Pantoggletool.Tooltip = 'Pan';
            app.Pantoggletool.ClickedCallback = createCallbackFcn(app, @Pantoggletool_ClickedCallback, true);
            app.Pantoggletool.Icon = 'Pantoggletool_image.png';
            app.Pantoggletool.OffCallback = createCallbackFcn(app, @Pantoggletool_ReleasedCallback, true);
            app.DataCursortoggletool = uitoggletool(app.uitoolbar1);
            app.DataCursortoggletool.Tag = 'DataCursortoggletool';
            app.DataCursortoggletool.Tooltip = 'Data Cursor';
            app.DataCursortoggletool.ClickedCallback = createCallbackFcn(app, @DataCursortoggletool_ClickedCallback, true);
            app.DataCursortoggletool.Icon = 'DataCursortoggletool_image.png';
            app.DataCursortoggletool.OffCallback = createCallbackFcn(app, @DataCursortoggletool_ReleasedCallback, true);
            app.rotateToggleButton = uitoggletool(app.uitoolbar1);
            app.rotateToggleButton.Tag = 'rotateToggleButton';
            app.rotateToggleButton.Tooltip = 'Rotate the image.  Rotation is applied when button is clicked off.  Right click image to reset view';
            app.rotateToggleButton.Icon = 'rotateToggleButton_image.png';
            app.rotateToggleButton.OffCallback = createCallbackFcn(app, @rotateToggleButtonOff_ClickedCallback, true);
            app.rotateToggleButton.OnCallback = createCallbackFcn(app, @rotateToggleButtonOn_ClickedCallback, true);
            app.A = uitoggletool(app.uitoolbar1);
            app.A.Tag = 'A';
            app.A.Tooltip = 'Group with other windows';
            app.A.Icon = 'A_image.png';
            app.A.Separator = 'on';
            app.A.OffCallback = createCallbackFcn(app, @yokeOffButton_ClickedCallback, true);
            app.A.OnCallback = createCallbackFcn(app, @yokeOnButton_ClickedCallback, true);
            app.B = uitoggletool(app.uitoolbar1);
            app.B.Tag = 'B';
            app.B.Tooltip = 'Group with other windows';
            app.B.Icon = 'B_image.png';
            app.B.OffCallback = createCallbackFcn(app, @yokeOffButton_ClickedCallback, true);
            app.B.OnCallback = createCallbackFcn(app, @yokeOnButton_ClickedCallback, true);
            app.C = uitoggletool(app.uitoolbar1);
            app.C.Tag = 'C';
            app.C.Tooltip = 'Group with other windows';
            app.C.Icon = 'C_image.png';
            app.C.OffCallback = createCallbackFcn(app, @yokeOffButton_ClickedCallback, true);
            app.C.OnCallback = createCallbackFcn(app, @yokeOnButton_ClickedCallback, true);
            app.markerToggleTool = uitoggletool(app.uitoolbar1);
            app.markerToggleTool.Tag = 'markerToggleTool';
            app.markerToggleTool.Tooltip = '3D marker, in multiple views of same matrix.  Use A, B, or C, and this tool.  Shift move, to leave marker when leaving';
            app.markerToggleTool.Icon = 'markerToggleTool_image.png';
            app.markerToggleTool.OffCallback = createCallbackFcn(app, @markerToggleButtonOff_ClickedCallback, true);
            app.markerToggleTool.OnCallback = createCallbackFcn(app, @markerToggleButtonOn_ClickedCallback, true);
            app.measureTool = uipushtool(app.uitoolbar1);
            app.measureTool.Tag = 'measureTool';
            app.measureTool.Tooltip = 'Measure lengths. Click two points, and length will be displayed in workspace.  RIght-click or Shift-click on line to remove';
            app.measureTool.ClickedCallback = createCallbackFcn(app, @measureTapeToggleButton_ClickedCallback, true);
            app.measureTool.Icon = 'measureTool_image.png';
            app.PolyVOI = uitoggletool(app.uitoolbar1);
            app.PolyVOI.Tag = 'PolyVOI';
            app.PolyVOI.Tooltip = 'Draw a Polygon VOI slice by slice';
            app.PolyVOI.ClickedCallback = createCallbackFcn(app, @polyVOIToggleButton_ClickedCallback, true);
            app.PolyVOI.Icon = 'PolyVOI_image.png';
            app.record_toolbar_button = uitoggletool(app.uitoolbar1);
            app.record_toolbar_button.Tag = 'record_toolbar_button';
            app.record_toolbar_button.Tooltip = 'Record a script';
            app.record_toolbar_button.Icon = 'record_toolbar_button_image.png';
            app.record_toolbar_button.Separator = 'on';
            app.record_toolbar_button.OffCallback = createCallbackFcn(app, @recordOffButton_ClickedCallback, true);
            app.record_toolbar_button.OnCallback = createCallbackFcn(app, @recordOnButton_ClickedCallback, true);
            app.resizePushButton = uipushtool(app.uitoolbar1);
            app.resizePushButton.Tag = 'resizePushButton';
            app.resizePushButton.Tooltip = 'Enlarge window.  CTRL-click (or SHIFT-click) to decrease window size.';
            app.resizePushButton.ClickedCallback = createCallbackFcn(app, @resizePushButton_ClickedCallback, true);
            app.resizePushButton.Icon = 'resizePushButton_image.png';
            app.resizePushButton.Separator = 'on';
            app.helpToggleTool = uitoggletool(app.uitoolbar1);
            app.helpToggleTool.Tag = 'helpToggleTool';
            app.helpToggleTool.Tooltip = 'Display help on menues when this button is down.';
            app.helpToggleTool.Icon = 'helpToggleTool_image.png';
            app.helpToggleTool.OffCallback = createCallbackFcn(app, @helpToggleTool_OffCallback, true);
            app.helpToggleTool.OnCallback = createCallbackFcn(app, @helpToggleTool_OnCallback, true);
            app.orientationButton = uipushtool(app.uitoolbar1);
            app.orientationButton.Visible = 'off';
            app.orientationButton.Tag = 'orientationButton';
            app.orientationButton.Icon = 'orientationButton_image.png';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.ButtonDownFcn = createCallbackFcn(app, @wbm2, true);
            app.axes1.Tag = 'axes1';
            app.matrixSize = uilabel(app.figure1);
            app.matrixSize.Tag = 'matrixSize';
            app.matrixSize.Text = '[x,y]';
            app.frameTimeMinutesText = uilabel(app.figure1);
            app.frameTimeMinutesText.Tag = 'frameTimeMinutesText';
            app.frameTimeMinutesText.Text = ' ( ) min.';
            app.frameTimeText = uilabel(app.figure1);
            app.frameTimeText.Tag = 'frameTimeText';
            app.frameTimeText.Text = '( ) s';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'ROI';
            app.uipanel2.Tag = 'uipanel2';
            app.TACT = uibutton(app.uipanel2, 'push');
            app.TACT.ButtonPushedFcn = createCallbackFcn(app, @TactButtonCallback, true);
            app.TACT.Tag = 'TACT';
            app.TACT.Tooltip = 'Calculate Time-Activity (TACT) curve on dynamic data';
            app.TACT.Text = 'TACT';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Tooltip = 'Brush diameter=1+2*[brush size]';
            app.text7.Text = 'Brush';
            app.hideROIcheckbox = uicheckbox(app.uipanel2);
            app.hideROIcheckbox.ValueChangedFcn = createCallbackFcn(app, @hideROIcheckbox_Callback, true);
            app.hideROIcheckbox.Tag = 'hideROIcheckbox';
            app.hideROIcheckbox.Tooltip = 'Check to  hide ROI-display.';
            app.hideROIcheckbox.Text = 'Hide';
            app.ContourCheckBox = uicheckbox(app.uipanel2);
            app.ContourCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContourCheckBox_Callback, true);
            app.ContourCheckBox.Tag = 'ContourCheckBox';
            app.ContourCheckBox.Text = 'Contour';
            app.ROINumberMenu = uidropdown(app.uipanel2);
            app.ROINumberMenu.Items = {'Add ROI'};
            app.ROINumberMenu.ValueChangedFcn = createCallbackFcn(app, @ROINumberMenu_Callback, true);
            app.ROINumberMenu.Tag = 'ROINumberMenu';
            app.ROINumberMenu.Tooltip = 'Add a new ROI, or select a ROI.  Right-click to edit ROI';
            app.ROINumberMenu.Value = 'Add ROI';
            app.ROILeveltext = uilabel(app.uipanel2);
            app.ROILeveltext.Tag = 'ROILeveltext';
            app.ROILeveltext.Tooltip = 'Draws ROI only  above this intensity level. ';
            app.ROILeveltext.Text = 'Level';
            app.ROILevelEdit = uieditfield(app.uipanel2, 'text');
            app.ROILevelEdit.ValueChangedFcn = createCallbackFcn(app, @ROILevelEdit_Callback, true);
            app.ROILevelEdit.Tag = 'ROILevelEdit';
            app.ROILevelEdit.Tooltip = 'Draws ROI on pixels above this value.  Start with < to draw only below.  Right-click to reset ';
            app.ROILevelEdit.Value = '-1000';
            app.BrushSize = uieditfield(app.uipanel2, 'text');
            app.BrushSize.ValueChangedFcn = createCallbackFcn(app, @BrushSize_Callback, true);
            app.BrushSize.Tag = 'BrushSize';
            app.BrushSize.Tooltip = 'ROI drawing radius.  SHIFT-draw to erase';
            app.BrushSize.Value = '5';
            app.ClearRoiPushButton = uibutton(app.uipanel2, 'push');
            app.ClearRoiPushButton.ButtonPushedFcn = createCallbackFcn(app, @clearROI_Callback2, true);
            app.ClearRoiPushButton.Tag = 'ClearRoiPushButton';
            app.ClearRoiPushButton.Text = 'Clear ROI';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Intensity Scale ';
            app.uipanel1.Tag = 'uipanel1';
            app.PCAutoInvert = uicheckbox(app.uipanel1);
            app.PCAutoInvert.ValueChangedFcn = createCallbackFcn(app, @PCAutoInvert_Callback, true);
            app.PCAutoInvert.Tag = 'PCAutoInvert';
            app.PCAutoInvert.Tooltip = 'Automatically determine if PC images needs inverting';
            app.PCAutoInvert.Text = 'auto';
            app.transparancyPanel = uipanel(app.uipanel1);
            app.transparancyPanel.Title = 'overlay';
            app.transparancyPanel.Visible = 'off';
            app.transparancyPanel.Tag = 'transparancyPanel';
            app.transparancyText = uilabel(app.transparancyPanel);
            app.transparancyText.Tag = 'transparancyText';
            app.transparancyText.Visible = 'off';
            app.transparancyText.Text = '%';
            app.transparancyEdit = uieditfield(app.transparancyPanel, 'text');
            app.transparancyEdit.ValueChangedFcn = createCallbackFcn(app, @Transparancy_Callback, true);
            app.transparancyEdit.Tag = 'transparancyEdit';
            app.transparancyEdit.Visible = 'off';
            app.transparancyEdit.Tooltip = 'Weigth of overlay image (lower shows more of underlying image)';
            app.transparancyEdit.Value = '40';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Orientation ';
            app.uipanel5.Tag = 'uipanel5';
            app.orientationMenu = uidropdown(app.uipanel5);
            app.orientationMenu.Items = {'Ax', 'Cor', 'Sag'};
            app.orientationMenu.ValueChangedFcn = createCallbackFcn(app, @orientationMenu_Callback, true);
            app.orientationMenu.Tag = 'orientationMenu';
            app.orientationMenu.Tooltip = 'Modify display orientation (and internally, the matrix)';
            app.orientationMenu.Value = 'Ax';
            app.uipanel7 = uibuttongroup(app.figure1);
            app.uipanel7.Title = 'Image mode';
            app.uipanel7.Tag = 'uipanel7';
            app.ImageRadioButton = uiradiobutton(app.uipanel7);
            app.ImageRadioButton.Tag = 'ImageRadioButton';
            app.ImageRadioButton.Text = 'Image';
            app.ImageRadioButton.Value = true;
            app.PCImageRadioButton = uiradiobutton(app.uipanel7);
            app.PCImageRadioButton.Tag = 'PCImageRadioButton';
            app.PCImageRadioButton.Text = 'PC Image';
            app.ResidualRadiobutton = uiradiobutton(app.uipanel7);
            app.ResidualRadiobutton.Tag = 'ResidualRadiobutton';
            app.ResidualRadiobutton.Text = 'Residual';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'PCA filter';
            app.uipanel6.Tag = 'uipanel6';
            app.KaiserText = uilabel(app.uipanel6);
            app.KaiserText.Tag = 'KaiserText';
            app.KaiserText.Tooltip = 'Normalized eigenvalue of lowest order principal component. A factor extracts the equivalent of one original variable gives an eigenvalue of 1.  Kaiser suggests this as a fair threshold on what to drop.';
            app.KaiserText.Text = 'EV: 0.621';
            app.ExplainedFractionText = uilabel(app.uipanel6);
            app.ExplainedFractionText.Tag = 'ExplainedFractionText';
            app.ExplainedFractionText.Visible = 'off';
            app.ExplainedFractionText.Tooltip = 'Per cent variance of image data explained by selected filter';
            app.ExplainedFractionText.Text = '100.000%';
            app.text22 = uilabel(app.uipanel6);
            app.text22.Tag = 'text22';
            app.text22.Visible = 'off';
            app.text22.Tooltip = 'Exclude frames before first frames from PCA-filtering.';
            app.text22.Text = 'First';
            app.PC_high_slider = uislider(app.uipanel6);
            app.PC_high_slider.Limits = [1 1000];
            app.PC_high_slider.MajorTicks = [];
            app.PC_high_slider.ValueChangedFcn = createCallbackFcn(app, @PC_high_slider_Callback, true);
            app.PC_high_slider.Step = 0.03125;
            app.PC_high_slider.Tag = 'PC_high_slider';
            app.PC_high_slider.Tooltip = 'Last principal component to keep';
            app.PC_high_slider.Value = 1000;
            app.PC_low_slider = uislider(app.uipanel6);
            app.PC_low_slider.Limits = [1 100000];
            app.PC_low_slider.MajorTicks = [];
            app.PC_low_slider.ValueChangedFcn = createCallbackFcn(app, @PC_low_slider_Callback, true);
            app.PC_low_slider.Step = 0.03125;
            app.PC_low_slider.Tag = 'PC_low_slider';
            app.PC_low_slider.Visible = 'off';
            app.PC_low_slider.Tooltip = 'First principal component to keep';
            app.PC_low_slider.Value = 1;
            app.PC_high_edit = uieditfield(app.uipanel6, 'text');
            app.PC_high_edit.ValueChangedFcn = createCallbackFcn(app, @PC_high_edit_Callback, true);
            app.PC_high_edit.Tag = 'PC_high_edit';
            app.PC_high_edit.Tooltip = 'Last principal component to keep';
            app.PC_high_edit.Value = '1000';
            app.PC_low_edit = uieditfield(app.uipanel6, 'text');
            app.PC_low_edit.Tag = 'PC_low_edit';
            app.PC_low_edit.Visible = 'off';
            app.PC_low_edit.Tooltip = 'First principal component to keep';
            app.PC_low_edit.Value = '1';
            app.FirstFrame = uieditfield(app.uipanel6, 'text');
            app.FirstFrame.ValueChangedFcn = createCallbackFcn(app, @FirstFrameInPCAFilter_Callback, true);
            app.FirstFrame.Tag = 'FirstFrame';
            app.FirstFrame.Visible = 'off';
            app.FirstFrame.Tooltip = 'Exclude frames before first frames from PCA-filtering.';
            app.FirstFrame.Value = '1';
            app.FrameText = uilabel(app.figure1);
            app.FrameText.Tag = 'FrameText';
            app.FrameText.Tooltip = 'Ctrl+ScrollWheel';
            app.FrameText.Text = 'Frame';
            app.SliceText = uilabel(app.figure1);
            app.SliceText.Tag = 'SliceText';
            app.SliceText.Tooltip = 'ScrollWheel';
            app.SliceText.Text = 'Slice';
            app.FrameNumSlider = uislider(app.figure1);
            app.FrameNumSlider.Limits = [1 33];
            app.FrameNumSlider.MajorTicks = [];
            app.FrameNumSlider.ValueChangedFcn = createCallbackFcn(app, @FrameNumSlider_Callback, true);
            app.FrameNumSlider.Step = 0.03125;
            app.FrameNumSlider.Tag = 'FrameNumSlider';
            app.FrameNumSlider.Tooltip = 'Ctrl+ScrollWheel';
            app.FrameNumSlider.Value = 1;
            app.SliceNumSlider = uislider(app.figure1);
            app.SliceNumSlider.Limits = [1 63];
            app.SliceNumSlider.MajorTicks = [];
            app.SliceNumSlider.ValueChangedFcn = createCallbackFcn(app, @SliceNumSlider_Callback, true);
            app.SliceNumSlider.Step = 0.0161290322580645;
            app.SliceNumSlider.Tag = 'SliceNumSlider';
            app.SliceNumSlider.Tooltip = 'ScrollWheel';
            app.SliceNumSlider.Value = 1;
            app.SliceNumEdit = uieditfield(app.figure1, 'text');
            app.SliceNumEdit.ValueChangedFcn = createCallbackFcn(app, @SliceNumEdit_Callback, true);
            app.SliceNumEdit.Tag = 'SliceNumEdit';
            app.SliceNumEdit.Value = '1';
            app.FrameNumEdit = uieditfield(app.figure1, 'text');
            app.FrameNumEdit.ValueChangedFcn = createCallbackFcn(app, @FrameNumEdit_Callback, true);
            app.FrameNumEdit.Tag = 'FrameNumEdit';
            app.FrameNumEdit.Value = '1';
            app.versionText = uilabel(app.figure1);
            app.versionText.Tag = 'versionText';
            app.versionText.Text = 'this should display version info';
            app.floatingTextEdit1 = uieditfield(app.figure1, 'text');
            app.floatingTextEdit1.Tag = 'floatingTextEdit1';
            app.floatingTextEdit1.Visible = 'off';
            app.floatingTextEdit1.Value = 'Edit Text';
            app.infoText1 = uilabel(app.figure1);
            app.infoText1.Tag = 'infoText1';
            app.infoText1.Text = '';
            app.updateNeededText = uilabel(app.figure1);
            app.updateNeededText.Tag = 'updateNeededText';
            app.updateNeededText.Visible = 'off';
            app.updateNeededText.Text = '';
            app.ROI = uicontextmenu(app.figure1);
            app.ROI.Tag = 'ROI';
            app.ClearROIContextMenu = uimenu(app.ROI);
            app.ClearROIContextMenu.MenuSelectedFcn = createCallbackFcn(app, @clearROI_Callback2, true);
            app.ClearROIContextMenu.Text = 'Clear ROI';
            app.ClearROIContextMenu.Tag = 'ClearROIContextMenu';
            app.RenameROI = uimenu(app.ROI);
            app.RenameROI.MenuSelectedFcn = createCallbackFcn(app, @ROI_Rename_Callback, true);
            app.RenameROI.Separator = 'on';
            app.RenameROI.Text = 'Rename';
            app.RenameROI.Tag = 'RenameROI';
            app.ROIcolor = uimenu(app.ROI);
            app.ROIcolor.MenuSelectedFcn = createCallbackFcn(app, @Edit_ROI_Color_Callback, true);
            app.ROIcolor.Text = 'Edit color';
            app.ROIcolor.Tag = 'ROIcolor';
            app.MergeROIs = uimenu(app.ROI);
            app.MergeROIs.MenuSelectedFcn = createCallbackFcn(app, @ROI_Merge_Rois_Callback, true);
            app.MergeROIs.Text = 'Merge ROIs';
            app.MergeROIs.Tag = 'MergeROIs';
            app.SetRefROIs = uimenu(app.ROI);
            app.SetRefROIs.Separator = 'on';
            app.SetRefROIs.Text = 'Select Ref ROIs';
            app.SetRefROIs.Tag = 'SetRefROIs';
            app.HideROI = uimenu(app.ROI);
            app.HideROI.MenuSelectedFcn = createCallbackFcn(app, @ROI_Hide_Callback, true);
            app.HideROI.Separator = 'on';
            app.HideROI.Text = 'Hide';
            app.HideROI.Tag = 'HideROI';
            app.HideAllROIS = uimenu(app.ROI);
            app.HideAllROIS.MenuSelectedFcn = createCallbackFcn(app, @ROI_Hide_All_Callback, true);
            app.HideAllROIS.Text = 'Hide All';
            app.HideAllROIS.Tag = 'HideAllROIS';
            app.ShowAllROIS = uimenu(app.ROI);
            app.ShowAllROIS.MenuSelectedFcn = createCallbackFcn(app, @ROI_ShowAll_Callback, true);
            app.ShowAllROIS.Text = 'Show All';
            app.ShowAllROIS.Tag = 'ShowAllROIS';
            app.OnlyRefROIs = uimenu(app.ROI);
            app.OnlyRefROIs.MenuSelectedFcn = createCallbackFcn(app, @Only_Ref_ROIs_Callback, true);
            app.OnlyRefROIs.Text = 'Show Ref ROIs only';
            app.OnlyRefROIs.Tag = 'OnlyRefROIs';
            app.Lock_ROI = uimenu(app.ROI);
            app.Lock_ROI.MenuSelectedFcn = createCallbackFcn(app, @ROI_Lock_Callback, true);
            app.Lock_ROI.Separator = 'on';
            app.Lock_ROI.Text = 'Lock';
            app.Lock_ROI.Tag = 'Lock_ROI';
            app.Remove_ROI = uimenu(app.ROI);
            app.Remove_ROI.MenuSelectedFcn = createCallbackFcn(app, @ROI_Remove_Callback, true);
            app.Remove_ROI.Separator = 'on';
            app.Remove_ROI.Text = 'Remove';
            app.Remove_ROI.Tag = 'Remove_ROI';
            app.RemoveAllROIS = uimenu(app.ROI);
            app.RemoveAllROIS.MenuSelectedFcn = createCallbackFcn(app, @ROI_Remove_All_Callback, true);
            app.RemoveAllROIS.Text = 'Remove All';
            app.RemoveAllROIS.Tag = 'RemoveAllROIS';
            % Assign app.ROI
            app.ROINumberMenu.ContextMenu = app.ROI;
            app.AxesContextualMenu = uicontextmenu(app.figure1);
            app.AxesContextualMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ScriptsMenu_Callback, true);
            app.AxesContextualMenu.Tag = 'AxesContextualMenu';
            app.Untitled_1 = uimenu(app.AxesContextualMenu);
            app.Untitled_1.Text = 'Drawing:';
            app.Untitled_1.Tag = 'Untitled_1';
            app.clearROIcontextualMenu = uimenu(app.AxesContextualMenu);
            app.clearROIcontextualMenu.MenuSelectedFcn = createCallbackFcn(app, @clearROI_Callback2, true);
            app.clearROIcontextualMenu.Text = 'Clear ROI';
            app.clearROIcontextualMenu.Tag = 'clearROIcontextualMenu';
            app.SetROILevel = uimenu(app.AxesContextualMenu);
            app.SetROILevel.MenuSelectedFcn = createCallbackFcn(app, @SetROILevel_Callback, true);
            app.SetROILevel.Text = 'Set ROI Level';
            app.SetROILevel.Tag = 'SetROILevel';
            app.ResetROILevel = uimenu(app.AxesContextualMenu);
            app.ResetROILevel.MenuSelectedFcn = createCallbackFcn(app, @ResetROILevel_Callback, true);
            app.ResetROILevel.Text = 'Reset ROI level';
            app.ResetROILevel.Tag = 'ResetROILevel';
            app.Copy_ROI = uimenu(app.AxesContextualMenu);
            app.Copy_ROI.Text = 'Copy ROI';
            app.Copy_ROI.Tag = 'Copy_ROI';
            app.Untitled_2 = uimenu(app.AxesContextualMenu);
            app.Untitled_2.Separator = 'on';
            app.Untitled_2.Text = 'ROI:';
            app.Untitled_2.Tag = 'Untitled_2';
            app.Threshold_within_ROI = uimenu(app.AxesContextualMenu);
            app.Threshold_within_ROI.Text = 'Threshold within ROI';
            app.Threshold_within_ROI.Tag = 'Threshold_within_ROI';
            app.Threshold_ROI = uimenu(app.AxesContextualMenu);
            app.Threshold_ROI.Text = 'Threshold ROI';
            app.Threshold_ROI.Tag = 'Threshold_ROI';
            app.Clean_ROI = uimenu(app.AxesContextualMenu);
            app.Clean_ROI.Text = 'Clean ROI';
            app.Clean_ROI.Tag = 'Clean_ROI';
            app.Fill_ROI_Slice_Gaps = uimenu(app.AxesContextualMenu);
            app.Fill_ROI_Slice_Gaps.Text = 'Fill ROI Slice Gaps';
            app.Fill_ROI_Slice_Gaps.Tag = 'Fill_ROI_Slice_Gaps';
            app.copyRoiBetweenYokes = uimenu(app.AxesContextualMenu);
            app.copyRoiBetweenYokes.MenuSelectedFcn = createCallbackFcn(app, @copyRoiBetweenYokes_Callback, true);
            app.copyRoiBetweenYokes.Text = 'Copy to linked ROI';
            app.copyRoiBetweenYokes.Tag = 'copyRoiBetweenYokes';
            app.Untitled_4 = uimenu(app.AxesContextualMenu);
            app.Untitled_4.Separator = 'on';
            app.Untitled_4.Text = 'Measure:';
            app.Untitled_4.Tag = 'Untitled_4';
            app.Untitled_5 = uimenu(app.AxesContextualMenu);
            app.Untitled_5.MenuSelectedFcn = createCallbackFcn(app, @measureTape_CopyValues, true);
            app.Untitled_5.Text = 'Copy Values';
            app.Untitled_5.Tag = 'Untitled_5';
            app.CloseAxesContextMenu = uimenu(app.AxesContextualMenu);
            app.CloseAxesContextMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAxesContextMenu_Callback, true);
            app.CloseAxesContextMenu.Separator = 'on';
            app.CloseAxesContextMenu.Text = 'Close this menu';
            app.CloseAxesContextMenu.Tag = 'CloseAxesContextMenu';
            app.ROILevelContextualMenu = uicontextmenu(app.figure1);
            app.ROILevelContextualMenu.Tag = 'ROILevelContextualMenu';
            app.ResetROILevel2 = uimenu(app.ROILevelContextualMenu);
            app.ResetROILevel2.MenuSelectedFcn = createCallbackFcn(app, @ResetROILevel_Callback, true);
            app.ResetROILevel2.Text = 'Reset ROI level';
            app.ResetROILevel2.Tag = 'ResetROILevel2';
            % Assign app.ROILevelContextualMenu
            app.ROILevelEdit.ContextMenu = app.ROILevelContextualMenu;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imlook4d_App_5(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)imlook4d_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end